classdef Programa_OptiPar_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FuentedeluzTab                  matlab.ui.container.Tab
        DescripcindelProgramaPanel      matlab.ui.container.Panel
        versinSofaGonzlezCorreaLabel    matlab.ui.control.Label
        versinSaraRubioMorenoLabel      matlab.ui.control.Label
        MagnLapuertaAmigoLabel          matlab.ui.control.Label
        versinJoaqunDazLozanoLabel      matlab.ui.control.Label
        DirigidoporLabel                matlab.ui.control.Label
        RealizadoporLabel               matlab.ui.control.Label
        Label_titulo1                   matlab.ui.control.Label
        Label_titulo2                   matlab.ui.control.Label
        OptiParLabel                    matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ContactoPanel                   matlab.ui.container.Panel
        CoordinadordelainterfazLabel    matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        DesarrolladoradelainterfazLabel  matlab.ui.control.Label
        AgregarespectroButton           matlab.ui.control.Button
        RepresentarButton               matlab.ui.control.Button
        FuentedeluzDropDown             matlab.ui.control.DropDown
        UITable_luz                     matlab.ui.control.Table
        UIAxes_luz                      matlab.ui.control.UIAxes
        MaterialesTab                   matlab.ui.container.Tab
        ndicederefraccinDropDown_SAND   matlab.ui.control.DropDown
        ReglademezclaDropDown           matlab.ui.control.DropDown
        ReglademezclaDropDownLabel      matlab.ui.control.Label
        UITable_material                matlab.ui.control.Table
        EjedeordenadasButtonGroup       matlab.ui.container.ButtonGroup
        ParteimaginariaMCheckBox        matlab.ui.control.CheckBox
        ParterealMCheckBox              matlab.ui.control.CheckBox
        ParteimaginariaECheckBox        matlab.ui.control.CheckBox
        ParterealECheckBox              matlab.ui.control.CheckBox
        PermitividadButton              matlab.ui.control.RadioButton
        ndicederefraccinButton          matlab.ui.control.RadioButton
        EjedeAbscisasButtonGroup        matlab.ui.container.ButtonGroup
        mnimomximoLabel                 matlab.ui.control.Label
        maximoEditField                 matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        minimoEditField                 matlab.ui.control.NumericEditField
        FrecuenciaHzButton              matlab.ui.control.RadioButton
        EnergaeVButton                  matlab.ui.control.RadioButton
        LongituddeondanmButton          matlab.ui.control.RadioButton
        ndicederefraccinDropDown_ASH    matlab.ui.control.DropDown
        AgregarmineralButton            matlab.ui.control.Button
        UITable_minerales               matlab.ui.control.Table
        ndicederefraccinDropDown_DUST   matlab.ui.control.DropDown
        ndicederefraccinDropDown_ICE    matlab.ui.control.DropDown
        ndicederefraccinDropDown_WATER  matlab.ui.control.DropDown
        ParmetrosdeLabel                matlab.ui.control.Label
        ndicederefraccinLabel           matlab.ui.control.Label
        NosciladoresLabel               matlab.ui.control.Label
        FactormasaLabel                 matlab.ui.control.Label
        FactormasaEditField             matlab.ui.control.NumericEditField
        ParmetrosdeDropDown_GRAFITO     matlab.ui.control.DropDown
        ndicederefraccinDropDown_DIAMANTE  matlab.ui.control.DropDown
        ndicederefraccinDropDown_GRAFITO  matlab.ui.control.DropDown
        NosciladoresSpinner             matlab.ui.control.Spinner
        ParmetrosdeDropDown_SOOT        matlab.ui.control.DropDown
        UITableosciladores              matlab.ui.control.Table
        ndicederefraccinDropDown_SOOT   matlab.ui.control.DropDown
        MaterialLabel                   matlab.ui.control.Label
        RepresentarButton_2             matlab.ui.control.Button
        MaterialDropDown                matlab.ui.control.DropDown
        AgregarmaterialButton           matlab.ui.control.Button
        UIAxes_material                 matlab.ui.control.UIAxes
        CenterPanel                     matlab.ui.container.Panel
        EstudiorugosidadPanel           matlab.ui.container.Panel
        CalcularperfilButton            matlab.ui.control.Button
        ProcesarimagenButton            matlab.ui.control.Button
        CargarimagenButton              matlab.ui.control.Button
        ngulomediodemnimoscuadradosradEditField  matlab.ui.control.NumericEditField
        ngulomediodemnimoscuadradosradEditFieldLabel  matlab.ui.control.Label
        UIAxes_imagen_perfil            matlab.ui.control.UIAxes
        UIAxes_imagen_procesada         matlab.ui.control.UIAxes
        UIAxes_imagen_inicial           matlab.ui.control.UIAxes
        DatoscontaminacinsuperficiePanel  matlab.ui.container.Panel
        EspesorcmEditField              matlab.ui.control.NumericEditField
        EspesorcmEditFieldLabel         matlab.ui.control.Label
        ounadistribucindetamaosdepartculasLabel  matlab.ui.control.Label
        EstudioDropDownLabel            matlab.ui.control.Label
        ResultadosmantocontaminadoButton  matlab.ui.control.Button
        EstudioDropDown                 matlab.ui.control.DropDown
        divisionesEditField             matlab.ui.control.NumericEditField
        divisionesEditFieldLabel        matlab.ui.control.Label
        rgdesviacinnmEditField          matlab.ui.control.NumericEditField
        rgdesviacinnmEditFieldLabel     matlab.ui.control.Label
        rgmedianmEditField              matlab.ui.control.NumericEditField
        DeseaintroduciruntamaonicoLabel  matlab.ui.control.Label
        rgmedianmEditFieldLabel         matlab.ui.control.Label
        ConcentracincontaminanteppmEditField  matlab.ui.control.NumericEditField
        ConcentracincontaminanteppmEditFieldLabel  matlab.ui.control.Label
        UIAxesDIST                      matlab.ui.control.UIAxes
        EstudioparamtricoPanel          matlab.ui.container.Panel
        EjecucinparamtricaButton        matlab.ui.control.Button
        VariableparametrizadaAbscisasButtonGroup  matlab.ui.container.ButtonGroup
        TreeAbcisas                     matlab.ui.container.Tree
        LongituddeondanmNode            matlab.ui.container.TreeNode
        DimetrodepartculamNode          matlab.ui.container.TreeNode
        DimetroadimensionaldelapartculaNode  matlab.ui.container.TreeNode
        NdepartculasprimariasNode       matlab.ui.container.TreeNode
        EnergaeVNode                    matlab.ui.container.TreeNode
        FrecuenciaHzNode                matlab.ui.container.TreeNode
        ValormnimomximoEditFieldLabel   matlab.ui.control.Label
        ValormaximoEditField            matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        ValorminimoEditField            matlab.ui.control.NumericEditField
        TipodeestudioButtonGroup        matlab.ui.container.ButtonGroup
        EjecucinindividualButton_2      matlab.ui.control.RadioButton
        EstudioparamtricoButton         matlab.ui.control.RadioButton
        IntroduccindedatosPanel         matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        HumedadEditField                matlab.ui.control.NumericEditField
        HumedadEditFieldLabel           matlab.ui.control.Label
        EstudiorugosidadButton          matlab.ui.control.Button
        ProfundidadmnmxcmLabel          matlab.ui.control.Label
        gdifEditField                   matlab.ui.control.NumericEditField
        gdifEditFieldLabel              matlab.ui.control.Label
        grefEditField                   matlab.ui.control.NumericEditField
        grefEditFieldLabel              matlab.ui.control.Label
        fdEditField                     matlab.ui.control.NumericEditField
        fdEditFieldLabel                matlab.ui.control.Label
        FactordeasimetraDropDown        matlab.ui.control.DropDown
        FactordeasimetraDropDownLabel   matlab.ui.control.Label
        AlbedosuperficieinferiorDropDown  matlab.ui.control.DropDown
        AlbedosuperficieinferiorDropDownLabel  matlab.ui.control.Label
        AadiralbedoButton               matlab.ui.control.Button
        ProfundidaddelmantoButtonGroup  matlab.ui.container.ButtonGroup
        cmEditField                     matlab.ui.control.NumericEditField
        cmEditFieldLabel                matlab.ui.control.Label
        SemiinfinitaButton              matlab.ui.control.RadioButton
        Button_cm                       matlab.ui.control.RadioButton
        Profundidad_min                 matlab.ui.control.NumericEditField
        Profundidad_max                 matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        RepresentardensidadButton       matlab.ui.control.Button
        Densidadsuperficialkgm3EditField  matlab.ui.control.NumericEditField
        Densidadsuperficialkgm3EditFieldLabel  matlab.ui.control.Label
        NubosidadEditField              matlab.ui.control.NumericEditField
        NubosidadEditFieldLabel         matlab.ui.control.Label
        AlbedoDropDown                  matlab.ui.control.DropDown
        AlbedoDropDownLabel             matlab.ui.control.Label
        ngulocenitalEditField           matlab.ui.control.NumericEditField
        ngulocenitalEditFieldLabel      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        EjecucinindividualButton        matlab.ui.control.Button
        DimensinfractalEditField        matlab.ui.control.NumericEditField
        DimensinfractalEditFieldLabel   matlab.ui.control.Label
        NdepartculasprimariasEditField  matlab.ui.control.NumericEditField
        NdepartculasprimariasEditFieldLabel  matlab.ui.control.Label
        RadiodelapartculaesfricanmEditField  matlab.ui.control.NumericEditField
        RadiodelapartculaesfricanmEditFieldLabel  matlab.ui.control.Label
        LongituddeondanmEditField       matlab.ui.control.NumericEditField
        LongituddeondanmEditFieldLabel  matlab.ui.control.Label
        EstudiomantocontaminadoButton   matlab.ui.control.Button
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        vEditField                      matlab.ui.control.NumericEditField
        vEditFieldLabel                 matlab.ui.control.Label
        TreeMethodRmax                  matlab.ui.container.Tree
        MtodoestimacinradiomximoNode    matlab.ui.container.TreeNode
        MtodogeomtricoNode              matlab.ui.container.TreeNode
        MtodoaproximadoNode             matlab.ui.container.TreeNode
        TreeRDG_ME                      matlab.ui.container.Tree
        RayleighDebyeGansNode           matlab.ui.container.TreeNode
        MountainMulhollandNode          matlab.ui.container.TreeNode
        KylFaethNode                    matlab.ui.container.TreeNode
        MedioEfectivoNode               matlab.ui.container.TreeNode
        ReglademezclaNode               matlab.ui.container.TreeNode
        MaxwellGarnettNode              matlab.ui.container.TreeNode
        BruggemanNode                   matlab.ui.container.TreeNode
        LorentzLorenzNode               matlab.ui.container.TreeNode
        LakatiaVitkamNode               matlab.ui.container.TreeNode
        LummeRaholaNode                 matlab.ui.container.TreeNode
        PromediopotencialNode           matlab.ui.container.TreeNode
        TreeMethod                      matlab.ui.container.Tree
        MtododeRayleighNode             matlab.ui.container.TreeNode
        RayleighNode                    matlab.ui.container.TreeNode
        BohrenHuffmannNode              matlab.ui.container.TreeNode
        DobbinsMegaridisNode            matlab.ui.container.TreeNode
        MtododeMieNode                  matlab.ui.container.TreeNode
        TreeAglomerados                 matlab.ui.container.Tree
        AerosolesNode                   matlab.ui.container.TreeNode
        PartculasesfricasNode           matlab.ui.container.TreeNode
        AglomeradosdeesferasNode        matlab.ui.container.TreeNode
        MantodepartculasNode            matlab.ui.container.TreeNode
        EstudiodelmaterialLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Panel_canales                   matlab.ui.container.Panel
        MostrarButton                   matlab.ui.control.Button
        Canal10CheckBox                 matlab.ui.control.CheckBox
        Canal9CheckBox                  matlab.ui.control.CheckBox
        Canal8CheckBox                  matlab.ui.control.CheckBox
        Canal7CheckBox                  matlab.ui.control.CheckBox
        Canal6CheckBox                  matlab.ui.control.CheckBox
        Canal5CheckBox                  matlab.ui.control.CheckBox
        Canal4CheckBox                  matlab.ui.control.CheckBox
        Canal3CheckBox                  matlab.ui.control.CheckBox
        Canal2CheckBox                  matlab.ui.control.CheckBox
        Canal1CheckBox                  matlab.ui.control.CheckBox
        MediaCheckBox                   matlab.ui.control.CheckBox
        TodosCheckBox                   matlab.ui.control.CheckBox
        UITable_alb_medio               matlab.ui.control.Table
        UITable_estudio                 matlab.ui.control.Table
        UITable_contaminado             matlab.ui.control.Table
        EjedeordenadasPanel             matlab.ui.container.Panel
        IrradianciaCheckBox             matlab.ui.control.CheckBox
        RadianciaCheckBox               matlab.ui.control.CheckBox
        CoalbedodispersinCheckBox       matlab.ui.control.CheckBox
        AlbedodispersinCheckBox         matlab.ui.control.CheckBox
        ExtincinCheckBox                matlab.ui.control.CheckBox
        DispersinCheckBox               matlab.ui.control.CheckBox
        AbsorcinCheckBox                matlab.ui.control.CheckBox
        TreeOrdenadas                   matlab.ui.container.Tree
        EficienciasNode                 matlab.ui.container.TreeNode
        Seccionespticasm2Node           matlab.ui.container.TreeNode
        Seccionesespecficasm2gNode      matlab.ui.container.TreeNode
        AlbedoCoalbedodedispersinNode   matlab.ui.container.TreeNode
        RadianciaIrradianciaNode        matlab.ui.container.TreeNode
        FactordeasimetraNode            matlab.ui.container.TreeNode
        ResultadosButtonGroup           matlab.ui.container.ButtonGroup
        NumricosButton                  matlab.ui.control.RadioButton
        GrficosButton                   matlab.ui.control.RadioButton
        ResultadosLabel                 matlab.ui.control.Label
        UIAxes_arriba                   matlab.ui.control.UIAxes
        UIAxes_abajo                    matlab.ui.control.UIAxes
        ArchivoMenu                     matlab.ui.container.Menu
        GuardarMenu                     matlab.ui.container.Menu
        GuardarComoMenu                 matlab.ui.container.Menu
        ExportarMenu                    matlab.ui.container.Menu
        HojadeClculoxlsxMenu            matlab.ui.container.Menu
        BlocdeNotastxtMenu              matlab.ui.container.Menu
        InformacinMenu                  matlab.ui.container.Menu
        DescripcindelprogramaMenu       matlab.ui.container.Menu
        ContactoMenu                    matlab.ui.container.Menu
        IdiomaMenu                      matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        InglsMenu                       matlab.ui.container.Menu
        AyudaMenu                       matlab.ui.container.Menu
        ManualdeUsuarioMenu             matlab.ui.container.Menu
        AcercadeAppDesignerMenu         matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        EjeXMenu                        matlab.ui.container.Menu
        LogartmicoMenu                  matlab.ui.container.Menu
        LinealMenu                      matlab.ui.container.Menu
        EjeYMenu                        matlab.ui.container.Menu
        LogartmicoMenu_2                matlab.ui.container.Menu
        LinealMenu_3                    matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        EjeXMenu_2                      matlab.ui.container.Menu
        LogartmicoMenu_3                matlab.ui.container.Menu
        LinealMenu_4                    matlab.ui.container.Menu
        EjeYMenu_2                      matlab.ui.container.Menu
        LogartmicoMenu_4                matlab.ui.container.Menu
        LinealMenu_5                    matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        CerrarMenu                      matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        EjeXMenu_3                      matlab.ui.container.Menu
        LogartmicoMenu_5                matlab.ui.container.Menu
        LinealMenu_6                    matlab.ui.container.Menu
        EjeYMenu_3                      matlab.ui.container.Menu
        LogartmicoMenu_6                matlab.ui.container.Menu
        LinealMenu_7                    matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        EjeXMenu_4                      matlab.ui.container.Menu
        LogartmicoMenu_7                matlab.ui.container.Menu
        LinealMenu_8                    matlab.ui.container.Menu
        EjeYMenu_4                      matlab.ui.container.Menu
        LogartmicoMenu_8                matlab.ui.container.Menu
        LinealMenu_9                    matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        InfoMenu                        matlab.ui.container.Menu
        ContextMenu7                    matlab.ui.container.ContextMenu
        CerrarMenu_2                    matlab.ui.container.Menu
        ContextMenu8                    matlab.ui.container.ContextMenu
        CerrarMenu_3                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    methods (Access = private)

        %% ------------------------------------------------------------------------------
        % Funciones necesarias para empelar con el tratamiento de imágenes
        % de la Rugosidad

        function [v] = mi_profile(BW)
            % BW es una imagen binaria
            % sus valores son true o false por defecto
            % por eso se pone ~ para invertirla

            BW2 = ~BW;
            v = zeros(1,size(BW,2));
            for i = 1:length(v)
                v(i) = sum(BW2(:,i));
            end

        end

        function [BW2] = limpia_bordes(BW)

            BW2 = BW;

            % Se quita la fila superior
            BW2(1,:) = 1;

            % Se quitan los píxeles del lado izquierdo que no se encuentren en la parte
            % inferior
            dentro = true;
            for i = size(BW2,1):-1:1
                if (dentro && BW2(i,1))
                    dentro = false;
                elseif (~dentro && ~BW2(i,1))
                    BW2(i,1) = 1;
                end
            end

            % Se quitan los píxeles del lado derecho que no se encuentren en la parte
            % inferior
            dentro = true;
            L = size(BW2,2);
            for i = size(BW2,1):-1:1
                if (dentro && BW2(i,L))
                    dentro = false;
                elseif (~dentro && ~BW2(i,L))
                    BW2(i,L) = 1;
                end
            end

        end

        function [med,desv] = diferencias(grupos, umbral)

            % Función en la que se analiza cada grupo. En primer lugar, se realizan las
            % restas entre un punto y el siguiente (para cada grupo). Idealmente, como
            % los puntos son consecutivos, dicha resta sería la distancia que hay entre
            % cada punto, o, lo que es lo mismo, el lado de un cuadrado. No obstante,
            % como hay ruido, es esperable que haya puntos que sean falsos, por lo que
            % se eliminan dichas mediciones. Para descartar, se calcula la media y la
            % desviación estándar de cada grupo y se eliminan aquellos puntos que no
            % cumplan.
            % Posteriormente, con el grupo ya más reducido, se comprueba de nuevo la
            % desviación estándar. Si no cumpliera con el umbral establecido, se
            % considera que todo el grupo es ruido y, por tanto, se desecha del
            % cálculo.

            v = cell(size(grupos));
            w = [];
            j = 1;
            for i = 1:length(grupos)
                v{i} = diff(grupos{i}(:,1));
                desv = std(v{i});
                med = mean(v{i});
                aux = v{i}(v{i} < (med+desv/2) & v{i} > (med-desv/2));
                desv = std(aux);
                if desv < umbral
                    w = [w;aux];
                end
            end

            % Con los puntos permitidos, se calcula la desviación estándar y la media y
            % se vuelven a eliminar los outliers.

            med = mean(w);
            desv = std(w);

            % Se quitan los outliers
            w = w(w < (med+desv/2) & w > (med-desv/2));

            % Se calculan los resultados finales
            med = mean(w);
            desv = std(w);

            % figure; hold on;
            % for i = 1:length(v)
            %     plot(i*ones(size(v{i})),v{i},'ro')
            % end

        end

        function [w] = separacion_grupos(v, umbral_grupos)
            % v matriz de dos columnas, ordenada la segunda columna (eje X)

            % En primer lugar, se busca cuando cambia de fila. Para ello, se calculará
            % la diferencia en la coordenada X de los puntos y se buscarán los índices
            % de aquellos que cambien muy bruscamente

            % Obtención de índices
            aux = 1:length(v); % vector auxiliar con todos los índices
            i = aux(diff(v(:,2)) > 1); % Se escogen los índices de aquellos que cambien mucho
            i = [1,i,length(v)]; % Se añaden el primer y el último índice

            % Se separa el vector v en una celda en la que se colocan los grupos de
            % puntos
            w = cell(1,length(i)-1);
            for j = 2:length(i)
                if j == 2
                    grupo = v(i(j-1):i(j),:);
                else
                    grupo = v(i(j-1)+1:i(j),:);
                end
                % Se ordena cada grupo. De esta forma. Los puntos van de izquierda a
                % derecha
                [aux,indices] = sort(grupo(:,1));
                grupo = [aux,grupo(indices,2)];
                w{j-1} = grupo;
            end
        end
        %% ------------------------------------------------------------------------------


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Cuando se abra la interfaz por defecto el idioma es español,
            % por si en la anterior ejecución se quedó guardado en inglés
            Idioma = 0;
            save Idioma Idioma

            %% establecer el contador para indicar si se trata de un estudio del manto contaminado o no
            % Se inicia el contador en 0 en el caso de que esté como último
            % valor guardado para que no aparezcan las dos gráficas ni el
            % panel de estudio de manto contaminado
            load cont cont
            if cont == 2
                cont = 0; % se inicia
            end
            save cont cont

            % Panel de descripción del programa aparece oculto y sólo se
            % visualiza cuando se indica en el menú correspondiente
            app.DescripcindelProgramaPanel.Visible = 'Off';

            % Se establece un contador para saber si el usuario ha deseado
            % realizar el estudio o no. Se inicializa con 0 y se cambiará a
            % 1 si en el Panel Estudio Rugosidad se calcula el perfil
            rug = 0;
            save rug rug


            load mat mat
            % Cambiar el título de la pantalla del estudio
            % Si está hielo hay que ocultar cosas de hollín
            % Ocultar el factor de asimetría porque es sólo Debye para hollín
            if mat == 1 % hielo
                app.EstudiodelmaterialLabel.Text ='Estudio del hielo';
                app.RadiodelapartculaesfricanmEditField.Value = 800000; % 800 micras
                app.TreeRDG_ME.Enable = 'Off';
                app.TreeMethodRmax.Enable = 'Off';
                app.NdepartculasprimariasEditField.Enable ='Off';
                app.DimensinfractalEditField.Enable ='Off';

            elseif mat == 2 % Hollín
                app.EstudiodelmaterialLabel.Text ='Estudio del hollín';
                app.RadiodelapartculaesfricanmEditField.Value = 12.5;
                app.TreeRDG_ME.Enable = 'On';
                app.TreeMethodRmax.Enable = 'On';
                app.NdepartculasprimariasEditField.Enable ='On';
                app.DimensinfractalEditField.Enable ='On';

                app.FactordeasimetraDropDown.Enable = 'Off';
                app.FactordeasimetraDropDownLabel.Enable = 'Off';
                app.fdEditField.Enable = 'Off';
                app.fdEditFieldLabel.Enable = 'Off';
                app.grefEditField.Enable = 'Off';
                app.grefEditFieldLabel.Enable = 'Off';
                app.gdifEditField.Enable = 'Off';
                app.gdifEditFieldLabel.Enable = 'Off';
                app.AlbedoDropDown.Enable ='Off';
                app.AlbedoDropDownLabel.Enable ='Off';
                app.ngulocenitalEditField.Enable = 'Off';
                app.ngulocenitalEditFieldLabel.Enable = 'Off';
                app.ProfundidaddelmantoButtonGroup.Enable = 'Off';
                app.Button_cm.Enable = 'Off';
                app.cmEditFieldLabel.Enable = 'Off';
                app.SemiinfinitaButton.Enable = 'Off';
                app.cmEditField.Enable = 'Off';
                app.Densidadsuperficialkgm3EditField.Enable = 'Off';
                app.Densidadsuperficialkgm3EditFieldLabel.Enable = 'Off';
                app.ProfundidadmnmxcmLabel.Enable = 'Off';
                app.Profundidad_min.Enable = 'Off';
                app.Profundidad_max.Enable = 'Off';
                app.RepresentardensidadButton.Enable = 'Off';

            elseif mat == 3 % Agua
                app.EstudiodelmaterialLabel.Text ='Estudio del agua';
            elseif mat == 4 % Grafito
                app.EstudiodelmaterialLabel.Text ='Estudio del grafito';
            elseif mat == 5 % Diamante
                app.EstudiodelmaterialLabel.Text ='Estudio del diamante';
            elseif mat == 6 % Polvo
                app.EstudiodelmaterialLabel.Text ='Estudio del polvo';
            elseif mat == 9 % Arena
                app.EstudiodelmaterialLabel.Text ='Estudio de la arena';
            elseif mat == 7 % Ceniza
                app.EstudiodelmaterialLabel.Text ='Estudio de la ceniza';
            elseif mat == 8 % Nuevo material
                app.EstudiodelmaterialLabel.Text ='Estudio del nuevo material';
            end
            load cont cont
            % ESTUDIO MANTO CONTAMINADOtree.to
            if cont == 2  % se ha hecho un estudio de los dos materiales
                app.EstudiodelmaterialLabel.Text ='Estudio del manto contaminado';
                app.DatoscontaminacinsuperficiePanel.Enable = 'On';
                app.MostrarButton.Visible = 'On';
                app.ResultadosButtonGroup.Enable = 'On';
                app.UITable_contaminado.Visible = 'Off';
                app.UITable_estudio.Visible = 'Off';
                app.AlbedoDropDown.Enable ='Off';
                app.ngulocenitalEditField.Enable = 'Off';
                app.Button_cm.Enable = 'Off';
                app.SemiinfinitaButton.Enable = 'Off';
                app.cmEditField.Enable = 'Off';
                % Si es un estudio de distribución de tamaños hay que
                % mostrar el bloque de los canales
                if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                    app.Panel_canales.Enable = 'On';
                    app.MostrarButton.Enable = 'On';
                else
                    app.Panel_canales.Enable = 'Off';
                    app.MostrarButton.Enable = 'Off';
                end

            elseif cont == 1 || cont == 0
                % si no es manto contaminado se oculta panel Datos Análisis manto contaminado
                app.DatoscontaminacinsuperficiePanel.Enable = 'Off';
                app.MostrarButton.Enable = 'Off';
                % Mostrar si se ha contaminado el manto la opción de resultados
                % gráficos o numéricos porque se muestran 2 gráficas o 2 tablas
                app.ResultadosButtonGroup.Enable = 'Off'; % numéricos o gráficos
                app.UITable_contaminado.Visible = 'Off';
                app.Panel_canales.Enable= 'Off';
                app.MostrarButton.Enable = 'Off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {683, 683, 683};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {683, 683};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {495, '1x', 360};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: ManualdeUsuarioMenu
        function ManualdeUsuarioMenuSelected(app, event)
            winopen('Guía_de_Usuario.pdf')
        end

        % Menu selected function: DescripcindelprogramaMenu
        function DescripcindelprogramaMenuSelected(app, event)
            app.DescripcindelProgramaPanel.Visible = 'On';
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('https://www.uclm.es');
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('https://blog.uclm.es/areammt/');
        end

        % Menu selected function: EspaolMenu
        function EspaolMenu_Selected(app, event)
            % Panel de Descripción del programa
            app.Label_titulo1.Text = "Cálculo de las propiedades ópticas de partículas esféricas o aglomerados";
            app.Label_titulo2.Text = "en forma de aerosoles o depositados sobre superficies";
            app.RealizadoporLabel.Text = "Realizado por:";
            app.versinJoaqunDazLozanoLabel.Text = "1º versión: Joaquín Díaz Lozano ";
            app.versinSaraRubioMorenoLabel.Text = "2º versión: Sara Rubio Moreno";
            app.versinSofaGonzlezCorreaLabel.Text = "3º versión Sofía González Correa";
            app.DirigidoporLabel.Text = "Dirigido por:";

            % Pestaña de fuente de luz
            app.FuentedeluzTab.Title = "Fuente de luz";
            app.RepresentarButton.Text = "Representar";
            app.UITable_luz.ColumnName = ["Longitud de onda (nm)", "Irradiancia (W*m^-2*nm^-1)"];
            app.FuentedeluzDropDown.Items = {'Espectro Solar' 'Fuente Halógena' 'Otra fuente de luz'};
            app.AgregarespectroButton.Text = "Agregar espectro";
            xlabel(app.UIAxes_luz,'Longitud de onda (nm)')
            ylabel(app.UIAxes_luz,'Irradiancia (W*m^-2*nm^-1)')

            % Pestaña de materiales
            app.MaterialesTab.Title = "Materiales";
            app.RepresentarButton_2.Text = "Representar";
            app.UITable_material.ColumnName = ["Abscisas", "Parte real", "Parte imaginaria"];
            app.AgregarmaterialButton.Text = "Agregar material";
            app.MaterialDropDown.Items = {'Hollín' 'Hielo' 'Grafito' 'Diamante' 'Agua' 'Polvo' 'Arena' 'Ceniza' 'Nuevo material'};
            app.EjedeordenadasButtonGroup.Title = "Eje de ordenadas";
            app.ndicederefraccinButton.Text = "Índice de refracción";
            app.PermitividadButton.Text = "Permitividad";
            app.ParterealMCheckBox.Text = "Parte real";
            app.ParteimaginariaMCheckBox.Text = "Parte imaginaria";
            app.ParterealECheckBox.Text = "Parte real";
            app.ParteimaginariaECheckBox.Text = "Parte imaginaria";
            app.EjedeAbscisasButtonGroup.Title = "Eje de Abscisas";
            app.LongituddeondanmButton.Text = "Longitud de onda (nm)";
            app.EnergaeVButton.Text = " Energía (eV)";
            app.FrecuenciaHzButton.Text = "Frecuencia (Hz)";
            app.mnimomximoLabel.Text = "minimum - maximum";
            app.FactormasaLabel.Text = "Factor masa";
            app.UITableosciladores.ColumnName = ["Osciladores", "n (e-/m^3)", "g (s^-1)","wres (s^-1)"];
            app.NosciladoresLabel.Text = "Nº oscildores";
            app.ndicederefraccinLabel.Text = "Índice de refracción";
            app.ParmetrosdeLabel.Text = "Parametros de";
            app.ReglademezclaDropDownLabel.Text = "Regla de mezcla";
            app.ndicederefraccinDropDown_SOOT.Items = {'Liu & Bescond (2015)' 'Chang & Charalampopoulos (1990)' 'Modelo de dispersión'};
            app.ParmetrosdeDropDown_SOOT.Items = {'Dazell & Sarofim (1969)' 'Lee & Tien (1981)' 'Harbib & Vervisch (1988)' 'Stagg & Charalampopoulos (1993)' 'Otros'};
            app.ndicederefraccinDropDown_GRAFITO.Items = {'Djurišić & Li (1999)' 'Modelo de dispersión'};
            app.ParmetrosdeDropDown_GRAFITO.Items = {'Stagg & Charalampopoulos (1993)''Otros'};
            app.ndicederefraccinDropDown_DIAMANTE.Items = {'Philip & Taft (1964)'};
            app.ndicederefraccinDropDown_DUST.Items = {'Ponderación Composición Mineralógica'};
            app.ndicederefraccinDropDown_SAND.Items = {'Origen Sahariano (Patterson (1977))' 'Ponderación Composición Mineralógica'};
            app.ndicederefraccinDropDown_ASH.Items = {'Cumbre Vieja (2021)' 'Etna (Dequine (2020))' 'Eyjafjallajökull (Dequine (2020))' 'Modelo lineal (Liu 1993)'};
            app.UITable_minerales.ColumnName = ["Minerales", "Fracción volumétrica"];
            app.AgregarmineralButton.Text = "Añadir material";

            % Panel Contacto
            app.ContactoPanel.Title = 'Contacto';
            app.DesarrolladoradelainterfazLabel.Text = 'Desarrolladora de la interfaz';
            app.CoordinadordelainterfazLabel.Text = 'Coordinador de la interfaz';

            % Panel Rugosidad
            app.EstudiorugosidadPanel.Title = 'Estudio rugosidad';
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.ProcesarimagenButton.Text = 'Procesar imagen';
            app.CalcularperfilButton.Text = 'Calcular perfil';
            app.ngulomediodemnimoscuadradosradEditFieldLabel.Text = 'Ángulo de mínimos cuadrados (rad)';
            app.UIAxes_imagen_inicial.Title = 'Imagen inicial';
            app.UIAxes_imagen_procesada.Title = 'Imagen procesada';
            app.UIAxes_imagen_perfil.Title = 'Perfil de rugosidad';
            xlabel( app.UIAxes_imagen_perfil,'Distancia (cm)')

            % Menú superior
            app.ArchivoMenu.Text = 'Archivo';
            app.GuardarMenu.Text = 'Guardar';
            app.GuardarComoMenu.Text = 'Guardar Como';
            app.ExportarMenu.Text = 'Exportar';
            app.HojadeClculoxlsxMenu.Text = 'Hoja de Cálculo (.xlsx)';
            app.BlocdeNotastxtMenu.Text = 'Bloc de Notas (.txt)';
            app.InformacinMenu.Text = 'Información';
            app.DescripcindelprogramaMenu.Text = 'Descripción del programa';
            app.ContactoMenu.Text = 'Contacto';
            app.IdiomaMenu.Text = 'Idioma';
            app.EspaolMenu.Text = 'Español';
            app.InglsMenu.Text = 'Inglés';
            app.AyudaMenu.Text = 'Ayuda';
            app.ManualdeUsuarioMenu.Text = 'Manual de Usuario';
            app.AcercadeAppDesignerMenu.Text = 'Acerca de AppDesigner';

            % Resultados
            app.ResultadosLabel.Text = 'Resultados';
            app.NumricosButton.Text = 'Numéricos';
            app.GrficosButton.Text = 'Gráficos';
            app.EjedeordenadasPanel.Title = 'Eje de ordenadas';
            app.EficienciasNode.Text = 'Eficiencias';
            app.Seccionespticasm2Node.Text = 'Secciones ópticas (m^2)';
            app.Seccionesespecficasm2gNode.Text = 'Secciones específicas (m^2/g)';
            app.AlbedoCoalbedodedispersinNode.Text = 'Albedo/Coalbedo';
            app.RadianciaIrradianciaNode.Text = 'Radiancia/Irradiancia';
            app.FactordeasimetraNode.Text = 'Factor de asimetría';
            app.AbsorcinCheckBox.Text = 'Absorción';
            app.DispersinCheckBox.Text = 'Dispersión';
            app.ExtincinCheckBox.Text = 'Extinción';
            app.AlbedodispersinCheckBox.Text = 'Albedo';
            app.CoalbedodispersinCheckBox.Text = 'Coalbedo';
            app.RadianciaCheckBox.Text = 'Radiancia';
            app.IrradianciaCheckBox.Text = 'Irradiancia';
            app.MostrarButton.Text = 'Mostrar';
            app.MediaCheckBox.Text = 'Media';
            app.TodosCheckBox.Text = 'Todos';
            app.UITable_contaminado.ColumnName = ["Longitud de onda (nm)", "Albedo", "Coalbedo"];
            app.UITable_estudio.ColumnName = ["Abcisas", "Eficiencia de absorción","Eficiencia de dispersión","Eficiencia de extinción"];
            app.UITable_alb_medio.ColumnName = ["Albedo medio nieve", "Albedo medio suelo","Albedo medio nieve 'trasparente'"];

            load mat mat
            % Estudio del material
            if mat == 1 % hielo
                app.EstudiodelmaterialLabel.Text ='Estudio del hielo';
            elseif mat == 2 % hollín
                app.EstudiodelmaterialLabel.Text ='Estudio del hollín';
            elseif mat == 3 % Agua
                app.EstudiodelmaterialLabel.Text ='Estudio del agua';
            elseif mat == 4 % Grafito
                app.EstudiodelmaterialLabel.Text ='Estudio del grafito';
            elseif mat == 5 % Diamante
                app.EstudiodelmaterialLabel.Text ='Estudio del diamante';
            elseif mat == 6 % Polvo
                app.EstudiodelmaterialLabel.Text ='Estudio del polvo';
            elseif mat == 9 % Arena
                app.EstudiodelmaterialLabel.Text ='Estudio de la arena';
            elseif mat == 7 % Ceniza
                app.EstudiodelmaterialLabel.Text ='Estudio de la ceniza';
            elseif mat == 8 % Nuevo material
                app.EstudiodelmaterialLabel.Text ='Estudio del nuevo material';
            else
                app.EstudiodelmaterialLabel.Text = 'Estudio del material';
            end
            % Columna 1º
            app.AerosolesNode.Text = 'Aesosoles';
            app.PartculasesfricasNode.Text = 'Partículas esféricas';
            app.AglomeradosdeesferasNode.Text = 'Aglomerados de esferas';
            app.MantodepartculasNode.Text = 'Manto de partículas';
            app.MtododeRayleighNode.Text = 'Método de Rayleigh';
            app.MtododeMieNode.Text = 'Método de Mie';
            app.MedioEfectivoNode.Text = 'Medio Efectivo';
            app.ReglademezclaNode.Text = 'Regla de mezcla';
            app.PromediopotencialNode.Text = 'Promedio potencial';
            app.MtodoestimacinradiomximoNode.Text = 'Método de estimación radio máximo';
            app.MtodogeomtricoNode.Text = 'Método geométrico';
            app.MtodoaproximadoNode.Text = 'Método aproximado';
            app.EstudiomantocontaminadoButton.Text = 'Estudio del manto contaminado';

            % Columna 2º
            app.TipodeestudioButtonGroup.Title = 'Tipo de estudio';
            app.EstudioparamtricoButton.Text = 'Estudio paramétrico';
            app.EjecucinindividualButton_2.Text = 'Ejecución individual';
            app.IntroduccindedatosPanel.Title = 'Introducción de datos';
            app.LongituddeondanmEditFieldLabel.Text = 'Longitud de onda (nm)';
            app.RadiodelapartculaesfricanmEditFieldLabel.Text = 'Radio de partículas esféricas(nm)';
            app.NdepartculasprimariasEditFieldLabel.Text = 'Nº de partículas primarias';
            app.DimensinfractalEditFieldLabel.Text = 'Dimensión fractal';
            app.EjecucinindividualButton.Text = 'Ejecución individual';
            app.FactordeasimetraDropDownLabel.Text = 'Factor de asimetría';
            app.AlbedosuperficieinferiorDropDownLabel.Text = 'Albeso superficie inferior';
            app.AlbedosuperficieinferiorDropDown.Items = {'Albedo asfalto' 'Nuevo albedo'};
            app.AadiralbedoButton.Text = 'Añadir albedo';
            app.AlbedoDropDown.Items = {'Directo' 'Difuso' 'Neto'};
            app.ngulocenitalEditFieldLabel.Text = 'Ángulo cenital';
            app.NubosidadEditFieldLabel.Text = 'Nubosidad';
            app.ProfundidaddelmantoButtonGroup.Title = 'Profundidad del manto nevado';
            app.SemiinfinitaButton.Text = 'Semi-infinita';
            app.Densidadsuperficialkgm3EditFieldLabel.Text = 'Densidad superficial (kg/m^3)';
            app.ProfundidadmnmxcmLabel.Text = 'Profundidad min-max (cm)';
            app.RepresentardensidadButton.Text = 'Representar densidad';

            % Columna 3º
            app.EstudioparamtricoPanel.Title = 'Estudio paramétrico';
            app.VariableparametrizadaAbscisasButtonGroup.Title = 'Variable partametrizada (Abscisas)';
            app.LongituddeondanmNode.Text = 'Longitud de onda (nm)';
            app.DimetrodepartculamNode.Text = 'Diámetro de partícula (nm)';
            app.DimetroadimensionaldelapartculaNode.Text = 'Diámetro de partícula adimensional';
            app.NdepartculasprimariasNode.Text = 'Nº de partículas primarias';
            app.ValormnimomximoEditFieldLabel.Text = 'Valor mínimo-máximo';
            app.EnergaeVNode.Text = 'Energía (eV)';
            app.FrecuenciaHzNode.Text = 'Frequencia (Hz)';
            app.EjecucinparamtricaButton.Text = 'Ejecución paramétrica';
            app.DatoscontaminacinsuperficiePanel.Title = 'Datos contaminación superficie';
            app.ResultadosmantocontaminadoButton.Text = 'Resultados manto contaminado';
            app.EstudioDropDownLabel.Text = 'Estudio';
            app.divisionesEditFieldLabel.Text = 'Divisiones';
            app.rgdesviacinnmEditFieldLabel.Text = 'rg desviación (nm)';
            app.rgmedianmEditFieldLabel.Text = 'rg media (nm)';
            app.DeseaintroduciruntamaonicoLabel.Text = '¿Desea introducir un tamaño único';
            app.ounadistribucindetamaosdepartculasLabel.Text = 'o una distribución de tamaño de partículas?';
            app.ConcentracincontaminanteppmEditFieldLabel.Text = 'Concentración contaminante(ppm)';
            app.EspesorcmEditFieldLabel.Text = 'Espesor (cm)';

            % Asignar el idioma a una variable: Idioma
            % si es 1 es inglés y si es 0 es español
            Idioma = 0;
            save Idioma Idioma
        end

        % Menu selected function: InglsMenu
        function InglsMenu_Selected(app, event)
            % Panel de Descripción del programa
            app.Label_titulo1.Text = "Determination of the optical properties of spherical particles or";
            app.Label_titulo2.Text = "aggregates as aerosols or deposited on surfaces";
            app.RealizadoporLabel.Text = "Made by:";
            app.versinJoaqunDazLozanoLabel.Text = "1rst version: Joaquín Díaz Lozano ";
            app.versinSaraRubioMorenoLabel.Text = "2nd version: Sara Rubio Moreno";
            app.versinSofaGonzlezCorreaLabel.Text = "3rd version: Sofía González Correa";
            app.DirigidoporLabel.Text = "Directed by:";

            % Pestaña de fuente de luz
            app.FuentedeluzTab.Title = "Light source";
            app.RepresentarButton.Text = "Plot";
            app.UITable_luz.ColumnName = ["Wavelength (nm)", "Irradiance (W*m^-2*nm^-1)"];
            app.FuentedeluzDropDown.Items = {'Solar Spectra' 'Halogen source' 'Other light source'};
            app.AgregarespectroButton.Text = "Add light source";
            xlabel(app.UIAxes_luz,'Wavelength (nm)')
            ylabel(app.UIAxes_luz,'Irradiance (W*m^-2*nm^-1)')

            % Pestaña de materiales
            app.MaterialesTab.Title = "Materials";
            app.RepresentarButton_2.Text = "Plot";
            app.UITable_material.ColumnName = ["Abscisae", "Real part", "Imaginary part"];
            app.AgregarmaterialButton.Text = "Add material";
            app.MaterialDropDown.Items = {'Soot' 'Ice' 'Graphite' 'Diamond' 'Water' 'Dust' 'Sand' 'Ash' 'New material'};
            app.EjedeordenadasButtonGroup.Title = "Ordinate axis";
            app.ndicederefraccinButton.Text = "Refractive index";
            app.PermitividadButton.Text = "Permitivity";
            app.ParterealMCheckBox.Text = "Real part";
            app.ParteimaginariaMCheckBox.Text = "Imaginary part";
            app.ParterealECheckBox.Text = "Real part";
            app.ParteimaginariaECheckBox.Text = "Imaginary part";
            app.EjedeAbscisasButtonGroup.Title = "Eje de Abscisas";
            app.LongituddeondanmButton.Text = "Wavelength (nm)";
            app.EnergaeVButton.Text = " Energy (eV)";
            app.FrecuenciaHzButton.Text = "Frecuency (Hz)";
            app.mnimomximoLabel.Text = "minimum - maximum";
            app.FactormasaLabel.Text = "Mass factor";
            app.UITableosciladores.ColumnName = ["Oscilators", "n (e-/m^3)", "g (s^-1)","wres (s^-1)"];
            app.NosciladoresLabel.Text = "Nº oscilators";
            app.ndicederefraccinLabel.Text = "Refractive index";
            app.ParmetrosdeLabel.Text = "Parameters of";
            app.ReglademezclaDropDownLabel.Text = "Mixing rule";
            app.ndicederefraccinDropDown_SOOT.Items = {'Liu & Bescond (2015)' 'Chang & Charalampopoulos (1990)' 'Dispersion model'};
            app.ParmetrosdeDropDown_SOOT.Items = {'Dazell & Sarofim (1969)' 'Lee & Tien (1981)' 'Harbib & Vervisch (1988)' 'Stagg & Charalampopoulos (1993)' 'Others'};
            app.ndicederefraccinDropDown_GRAFITO.Items = {'Djurišić & Li (1999)' 'Dispersion model'};
            app.ParmetrosdeDropDown_GRAFITO.Items = {'Stagg & Charalampopoulos (1993)''Others'};
            app.ndicederefraccinDropDown_DIAMANTE.Items = {'Philip & Taft (1964)'};
            app.ndicederefraccinDropDown_DUST.Items = {'Saharan Origin (Patterson (1977))' 'Mineralogical Composition Weighting'};
            app.ndicederefraccinDropDown_SAND.Items = {'Mineralogical Composition Weighting'};
            app.ndicederefraccinDropDown_ASH.Items = {'Cumbre Vieja (2021)' 'Etna (Dequine (2020))' 'Eyjafjallajökull (Dequine (2020))' 'Linear model (Liu 1993)'};
            app.UITable_minerales.ColumnName = ["Minerals", "Volumetric fraction"];
            app.AgregarmineralButton.Text = "Add Mineral";

            % Panel Contacto
            app.ContactoPanel.Title = 'Contact';
            app.DesarrolladoradelainterfazLabel.Text = 'Programe developer';
            app.CoordinadordelainterfazLabel.Text = 'Programe supervisor';

            % Panel Rugosidad
            app.EstudiorugosidadPanel.Title = 'Rugosity analysis';
            app.CargarimagenButton.Text = 'Load image';
            app.ProcesarimagenButton.Text = 'Process imagen';
            app.CalcularperfilButton.Text = 'Calculate profile';
            app.ngulomediodemnimoscuadradosradEditFieldLabel.Text = 'Root mean square angle (rad)';
            app.UIAxes_imagen_inicial.Title = 'Initial image';
            app.UIAxes_imagen_procesada.Title = 'Processed image';
            app.UIAxes_imagen_perfil.Title = 'Rugosity profile';
            xlabel( app.UIAxes_imagen_perfil,'Distance (cm)')

            % Menú superior
            app.ArchivoMenu.Text = 'File';
            app.GuardarMenu.Text = 'Save';
            app.GuardarComoMenu.Text = 'Save as';
            app.ExportarMenu.Text = 'Export';
            app.HojadeClculoxlsxMenu.Text = 'Excel sheet (.xlsx)';
            app.BlocdeNotastxtMenu.Text = 'Notepad (.txt)';
            app.InformacinMenu.Text = 'Information';
            app.DescripcindelprogramaMenu.Text = 'Program description';
            app.ContactoMenu.Text = 'Contact';
            app.IdiomaMenu.Text = 'Language';
            app.EspaolMenu.Text = 'Spahish';
            app.InglsMenu.Text = 'English';
            app.AyudaMenu.Text = 'Help';
            app.ManualdeUsuarioMenu.Text = 'User Guide';
            app.AcercadeAppDesignerMenu.Text = 'About AppDesigner';

            % Resultados
            app.ResultadosLabel.Text = 'Results';
            app.NumricosButton.Text = 'Numeric';
            app.GrficosButton.Text = 'Graphic';
            app.EjedeordenadasPanel.Title = 'Ordenate axis';
            app.EficienciasNode.Text = 'Eficiencies';
            app.Seccionespticasm2Node.Text = 'Optical sections (m^2)';
            app.Seccionesespecficasm2gNode.Text = 'Optical cross sections (m^2/g)';
            app.AlbedoCoalbedodedispersinNode.Text = 'Albedo/Coalbedo';
            app.RadianciaIrradianciaNode.Text = 'Radiance/Irradiance';
            app.FactordeasimetraNode.Text = 'Asimmetry factor';
            app.AbsorcinCheckBox.Text = 'Absorption';
            app.DispersinCheckBox.Text = 'Scattering';
            app.ExtincinCheckBox.Text = 'Extinction';
            app.AlbedodispersinCheckBox.Text = 'Albedo';
            app.CoalbedodispersinCheckBox.Text = 'Coalbedo';
            app.RadianciaCheckBox.Text = 'Radiance';
            app.IrradianciaCheckBox.Text = 'Irradiance';
            app.MostrarButton.Text = 'Show';
            app.MediaCheckBox.Text = 'Mean';
            app.TodosCheckBox.Text = 'All';
            app.UITable_contaminado.ColumnName = ["Wavelength (nm)", "Albedo", "Coalbedo"];
            app.UITable_estudio.ColumnName = ["Abcisae", "Absorption eficency", "Scattering eficency","Extinction eficency"];
            app.UITable_alb_medio.ColumnName = ["Broadband snow albedo", "Broadband cover albedo","Broadband 'trasparent' snow albedo"];

            load mat mat
            % Estudio del material
            if mat == 1 % hielo
                app.EstudiodelmaterialLabel.Text = 'Ice study';
            elseif mat == 2 % hollín
                app.EstudiodelmaterialLabel.Text ='Soot study';
            elseif mat == 3 % Agua
                app.EstudiodelmaterialLabel.Text ='Water study';
            elseif mat == 4 % Grafito
                app.EstudiodelmaterialLabel.Text ='Graphite study';
            elseif mat == 5 % Diamante
                app.EstudiodelmaterialLabel.Text ='Diamond study';
            elseif mat == 6 % Polvo
                app.EstudiodelmaterialLabel.Text ='Dust study';
            elseif mat == 9 % Arena
                app.EstudiodelmaterialLabel.Text ='Sand study';
            elseif mat == 7 % Ceniza
                app.EstudiodelmaterialLabel.Text ='Ash study';
            elseif mat == 8 % Nuevo material
                app.EstudiodelmaterialLabel.Text ='New material study';
            else
                app.EstudiodelmaterialLabel.Text = 'Material study';
            end
            % Columna 1º
            app.AerosolesNode.Text = 'Aesosols';
            app.PartculasesfricasNode.Text = 'Spherical particles';
            app.AglomeradosdeesferasNode.Text = 'Sphere agglomerates';
            app.MantodepartculasNode.Text = 'Particle cover';
            app.MtododeRayleighNode.Text = 'Rayleigh method';
            app.MtododeMieNode.Text = 'Mie method';
            app.MedioEfectivoNode.Text = 'Effective medium';
            app.ReglademezclaNode.Text = 'Mixing rule';
            app.PromediopotencialNode.Text = 'Potential average';
            app.MtodoestimacinradiomximoNode.Text = 'Maximum radii estimation method';
            app.MtodogeomtricoNode.Text = 'Geometrical method';
            app.MtodoaproximadoNode.Text = 'Aproximate method';
            app.EstudiomantocontaminadoButton.Text = 'Contaminated cover study';

            % Columna 2º
            app.TipodeestudioButtonGroup.Title = 'Study type';
            app.EstudioparamtricoButton.Text = 'Parametric study';
            app.EjecucinindividualButton_2.Text = 'Single execution';
            app.IntroduccindedatosPanel.Title = 'Data introduction';
            app.LongituddeondanmEditFieldLabel.Text = 'Wavelength(nm)';
            app.RadiodelapartculaesfricanmEditFieldLabel.Text = 'Spherical particle radii (nm)';
            app.NdepartculasprimariasEditFieldLabel.Text = 'Nº of primary particles';
            app.DimensinfractalEditFieldLabel.Text = 'Fractal dimension';
            app.EjecucinindividualButton.Text = 'Single execution';
            app.FactordeasimetraDropDownLabel.Text = 'Asimmetry factor';
            app.AlbedosuperficieinferiorDropDownLabel.Text = 'Inferior cover albedo';
            app.AlbedosuperficieinferiorDropDown.Items = {'Asphalt albedo' 'New albedo'};
            app.AadiralbedoButton.Text = 'Add albedo';
            app.AlbedoDropDown.Items = {'Direct' 'Diffuse' 'Net'};
            app.ngulocenitalEditFieldLabel.Text = 'Cenith angle';
            app.NubosidadEditFieldLabel.Text = 'Cloudiness';
            app.ProfundidaddelmantoButtonGroup.Title = 'Cover depth';
            app.SemiinfinitaButton.Text = 'Semi-infinite';
            app.Densidadsuperficialkgm3EditFieldLabel.Text = 'Surface density (kg/m^3)';
            app.ProfundidadmnmxcmLabel.Text = 'Minimum-Maximum depth (cm)';
            app.RepresentardensidadButton.Text = 'Plot density';

            % Columna 3º
            app.EstudioparamtricoPanel.Title = 'Parametric study';
            app.VariableparametrizadaAbscisasButtonGroup.Title = 'Parameterized variable (Abscisae)';
            app.LongituddeondanmNode.Text = 'Wavelength (nm)';
            app.DimetrodepartculamNode.Text = 'Particle diameter (nm)';
            app.DimetroadimensionaldelapartculaNode.Text = 'Dimensionless particle diameter';
            app.NdepartculasprimariasNode.Text = 'Nº of primary particles';
            app.ValormnimomximoEditFieldLabel.Text = 'Minimum-maximum value';
            app.EnergaeVNode.Text = 'Energy (eV)';
            app.FrecuenciaHzNode.Text = 'Frequency (Hz)';
            app.EjecucinparamtricaButton.Text = 'Parametric execution';
            app.DatoscontaminacinsuperficiePanel.Title = 'Contaminated surface data';
            app.ResultadosmantocontaminadoButton.Text = 'Contaminated cover results';
            app.EstudioDropDownLabel.Text = 'Study';
            app.divisionesEditFieldLabel.Text = 'Divisions';
            app.rgdesviacinnmEditFieldLabel.Text = 'rg deviation (nm)';
            app.rgmedianmEditFieldLabel.Text = 'rg mean (nm)';
            app.DeseaintroduciruntamaonicoLabel.Text = 'Would you like to introduce a unique size';
            app.ounadistribucindetamaosdepartculasLabel.Text = 'or a particle distribution?';
            app.ConcentracincontaminanteppmEditFieldLabel.Text = 'Contaminant concentration (ppm)';
            app.EspesorcmEditFieldLabel.Text = 'Thickness (cm)';

            % Asignar el idioma a una variable: Idioma
            % si es 1 es inglés y si es 0 es español
            Idioma = 1;
            save Idioma Idioma
        end

        % Button pushed function: RepresentarButton
        function RepresentarButtonPushed(app, event)
            % FUENTE DE LUZ
            if app.FuentedeluzDropDown.Value == "Espectro Solar" || app.FuentedeluzDropDown.Value == "Solar Spectra"
                % Se cargan los datos del Espectro Solar
                load EspectroSolar.mat RadiacionIncidente
                landa = RadiacionIncidente(:,1);
                irradiancia = RadiacionIncidente(:,2);

            elseif app.FuentedeluzDropDown.Value == "Fuente Halógena" || app.FuentedeluzDropDown.Value == "Halogen source"
                load FuenteHalogena.mat FuenteHalogena
                landa = FuenteHalogena(:,1);
                irradiancia = FuenteHalogena(:,2);

            elseif app.FuentedeluzDropDown.Value == "Otra fuente de luz" || app.FuentedeluzDropDown.Value == "Other light source"
                % Se cargan los datos del nuevo espectro que están en el excel NuevoEspectro
                [landa,irradiancia] =readvars('NuevoEspectro.xlsx');
            end

            % Representar la irradiancia vs longitud de onda
            plot(app.UIAxes_luz,landa,irradiancia);
            load Idioma Idioma
            if Idioma == 1 % Inglés
                app.UITable_luz.ColumnName = ["Wavelength (nm)", "Irradiance (W*m^-2*nm^-1)"];
            else % Español
                app.UITable_luz.ColumnName = ["Longitud de onda (nm)", "Irradiancia (W*m^-2*nm^-1)"];
            end
            app.UITable_luz.ColumnFormat = {'short','shortE'};
            Datos = [landa irradiancia];
            % CARGAR datos en la tabla
            app.UITable_luz.Data = Datos;
            save Datos
        end

        % Button pushed function: AgregarespectroButton
        function AgregarespectroButtonPushed(app, event)
            winopen('NuevoEspectro.xlsx')
            % Guardar el nombre del nuevo material y ponerlo en el
            % dropdown de Espectro Solar, Fuente Halógena
            nombre = readcell('NuevoEspectro.xlsx');
            nombre = nombre(1,2);
            app.FuentedeluzDropDown.Items = ['Espectro Solar' 'Fuente Halógena' nombre];
        end

        % Menu selected function: LogartmicoMenu
        function LogartmicoMenuSelected(app, event)
            set(app.UIAxes_luz,'xscale','log')
        end

        % Menu selected function: LinealMenu
        function LinealMenuSelected(app, event)
            set(app.UIAxes_luz,'xscale','linear')
        end

        % Menu selected function: LogartmicoMenu_2
        function LogartmicoMenu_2Selected(app, event)
            set(app.UIAxes_luz,'yscale','log')
        end

        % Menu selected function: LinealMenu_3
        function LinealMenu_3Selected(app, event)
            set(app.UIAxes_luz,'yscale','linear')
        end

        % Value changed function: FuentedeluzDropDown
        function FuentedeluzDropDownValueChanged(app, event)
            if app.FuentedeluzDropDown.Value == "Espectro Solar" || app.FuentedeluzDropDown.Value == "Solar Spectra"
                app.AgregarespectroButton.Visible = 'Off';
            elseif app.FuentedeluzDropDown.Value == "Fuente Halógena" || app.FuentedeluzDropDown.Value == "Halogen source"
                app.AgregarespectroButton.Visible = 'Off';
            elseif app.FuentedeluzDropDown.Value == "Otra fuente de luz" ||app.FuentedeluzDropDown.Value == "Other light source"
                app.AgregarespectroButton.Visible = 'On';
            end
        end

        % Button pushed function: RepresentarButton_2
        function RepresentarButton_2Pushed(app, event)
            % MATERIALES
            load Idioma.mat Idioma

            % Se define el parámetro "cont" para indicar si se ha escogido
            % contaminar en la pantalla "Estudio_mat.mlapp" para saber si se debe
            % escoger el nuevo material con el que se va a contaminar el manto
            % Si el cont es mayor que cero, es que en la pantalla siguiente se ha
            % escogido contaminar el manto, por lo que cont = 1, y tras esta
            % pantalla debe ser cont=2, de ahí que se le sume uno en el bucle
            load cont cont
            if cont == 1
                cont = cont+1;
            elseif cont >= 2
                cont = 0;
            end
            save cont cont

            % Se define el parámetro "mat" para indicar si el material escogido es
            % Hielo: mat = 1;
            % hollín: mat = 2;
            % grafito: mat = 4;
            % diamante: mat = 5;
            % Agua: mat = 3;
            % Polvo: mat = 6;
            % Arena : mat = 9;
            % Ceniza: mat = 7;
            % Otro material: mat = 8;
            mat = 0;

            % Se elige representar el índice de refracción o la cte dieléctrica
            seleccion_ordenadas = app.EjedeordenadasButtonGroup.SelectedObject;
            % Se elige representar en función de landa, frecuencia en eV o en Hz
            seleccion_abscisas = app.EjedeAbscisasButtonGroup.SelectedObject;

            %--------------------------------------------------------------------------------------------------------------------
            %--------------------------------------------------------------------------------------------------------------------
            %% HIELO
            if app.MaterialDropDown.Value == "Hielo" || app.MaterialDropDown.Value == "Ice"
                mat = 1;  % Se ha escogido hielo
                save mat mat
                % Se escoge entre el índice de refracción de Warren &
                % Brandt o el de Picard et al.

                if app.ndicederefraccinDropDown_ICE.Value == "Warren & Brandt (2008)"
                    % índice de refracción Sara
                    %                 load ice_Sara.mat
                    % índice refracción Sofía (diferencia en visible parte imaginaria)
                    load Hielo.mat Hielo
                    landa = Hielo(:,1); % Longitud de onda
                    n = Hielo(:,2); % Parte real índice de refración
                    k = Hielo(:,3); % Parte imaginaria índice de refración

                elseif app.ndicederefraccinDropDown_ICE.Value == "Picard et al. (2016)"
                    load Picard2016.mat Picard2016
                    landa = Picard2016(:,1); % Longitud de onda
                    n = Picard2016(:,2); % Parte real índice de refración
                    k = Picard2016(:,3); % Parte imaginaria índice de refración

                end

                % Hacer una interpolación para coger el valor exacto
                % indicado por landa de usuario de parte real e imaginaria
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;

                landa_hielo = landa( landa >= min & landa <= max );
                save landa_hielo landa_hielo

                %------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% AGUA
            elseif app.MaterialDropDown.Value == "Agua" || app.MaterialDropDown.Value == "Water"
                mat = 3;  % Se ha escogido AGUA
                save mat mat
                load Agua.mat Agua
                landa = Agua(:,1); % Longitud de onda
                n = Agua(:,2); % Parte real índice de refración
                k = Agua(:,3); % Parte imaginaria índice de refración

                %                 % Hacer una interpolación para coger el valor exacto
                %                 % indicado por landa de usuario de parte real e imaginaria
                %                 min = app.minimoEditField.Value;
                %                 max = app.maximoEditField.Value;

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% HOLLÍN
            elseif app.MaterialDropDown.Value == "Hollín" || app.MaterialDropDown.Value == "Soot"
                mat = 2;  % Se ha escogido hollín
                save mat mat
                % Hay varias formas: con correlacione o empleando Modelo de Dispersión
                %---------------------------------------------------------------------------------------------------------
                % Se escoge valores de Liu & Bescond (2015)
                if app.ndicederefraccinDropDown_SOOT.Value == "Liu & Bescond (2015)"
                    % %                     % Si se mete como una base de datos
                    % %                     load m_L&B.mat
                    % %                     landa = nuevohollin(:,1);
                    % %                     n = nuevohollin(:,2);
                    % %                     k = nuevohollin(:,3);
                    % Si se hace la correlación coger el rango
                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                    % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                    % Longitud de onda
                    if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                        landamin=min;
                        landamax=max;
                        % Frecuencia en eV
                    elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                        landamin=1240/min;
                        landamax=1240/max;
                        % Frecuencia en Hz
                    elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                        landamin=(3*10^8*10^9)/min;
                        landamax=(3*10^8*10^9)/max;
                    end

                    % Generar bucle y la correlación de Ruiz
                    load cont.mat cont
                    % Si manto contaminado (cont=2) tiene que tener mismo
                    % tamaño landa que el del hielo
                    if cont == 2
                        load landa_hielo landa_hielo
                        landa = landa_hielo;
                    else
                        landa=(landamin:landamax)';
                    end
                    n = -3.793E-12.*landa.^4 + 1.494E-08.*landa.^3 - 2.096E-05.*landa.^2 + 1.264E-02.*landa - 1.082;
                    k = -1.044E-11.*landa.^4 + 2.986E-08.*landa.^3 - 2.921E-05.*landa.^2 + 1.132E-02.*landa - 6.520E-01;

                    % La correlación sólo es válida para landas entre 266 y 1064 nm
                    n_266 = 1.06; k_266 = 0.81;
                    n_1064 = 1.78; k_1064 = 0.92;

                    %                     if min < 266
                    %                         n_prima = n_266;
                    %                         k_prima = k_266;
                    %
                    %                     elseif max > 1064
                    %                         n_prima = n_1064;
                    %                         k_prima = k_1064;
                    %                     end

                    n(landa<266) = n_266;
                    k(landa<266) = k_266;
                    n(landa>1064) = n_1064;
                    k(landa>1064) = k_1064;

                    %---------------------------------------------------------------------------------------------------------
                    % Se escoge valores de Chang & Charalampopoulos (1990)
                elseif app.ndicederefraccinDropDown_SOOT.Value == "Chang & Charalampopoulos (1990)"
                    % Si se mete como una base de datos
                    %                     load m_C&C.mat
                    %                     landa = nuevohollin(:,1);
                    %                     n = nuevohollin(:,2);
                    %                     k = nuevohollin(:,3);
                    % Si se hace la correlación
                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                    % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                    % Longitud de onda
                    if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                        landamin=min;
                        landamax=max;
                        % Frecuencia en eV
                    elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                        landamin=1240/min;
                        landamax=1240/max;
                        % Frecuencia en Hz
                    elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                        % factor de conversión de eV a Hz
                        hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                        landamin=(1240/min)/hz;
                        landamax=(1240/max)/hz;
                    end

                    % En la correlación landa va en micrómetros, de ahí que se divida entre 1000
                    load cont.mat cont
                    % Si manto contaminado (cont=2) tiene que tener mismo
                    % tamaño landa que el del hielo
                    if cont == 2
                        load landa_hielo landa_hielo
                        landa = landa_hielo;
                    else
                        landa=(landamin:landamax)';
                    end
                    n=1.811+0.1263.*log(landa/1000)+0.027.*(log(landa/1000)).^2+0.0417.*(log(landa/1000)).^3;
                    k=0.5821+0.1213.*log(landa/1000)+0.02309.*(log(landa/1000)).^2+0.01.*(log(landa/1000)).^3;


                    %---------------------------------------------------------------------------------------------------------
                    % Se opta por emplear el modelo de dispersión
                elseif app.ndicederefraccinDropDown_SOOT.Value == "Modelo de dispersión" || app.ndicederefraccinDropDown_SOOT.Value == "Dispersion model"
                    % Constantes para el cálculo del modelo de dispersión
                    e = 1.60217646E-19;  % [C]
                    masa = 9.10938188E-31; % [Kg]
                    e0 = 8.8541878176E-12; % [C^2*s^2/m^2*Kg] Permitividad del vacío
                    R = (e^2)/(masa*e0); % [m^3/s]
                    c = 299792458; % [m/s]
                    %                     kB = 1.38064852E-23; % [m2kg s-2K-1]
                    %                     T = 1450; % [K]

                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                    % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                    % Longitud de onda
                    if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                        landamin=min;
                        landamax=max;
                        % Frecuencia en eV
                    elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                        landamin=1240/min;
                        landamax=1240/max;
                        % Frecuencia en Hz
                    elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                        % factor de conversión de eV a Hz
                        hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                        landamin=(1240/min)/hz;
                        landamax=(1240/max)/hz;
                    end
                    load cont.mat cont
                    % Si manto contaminado (cont=2) tiene que tener mismo
                    % tamaño landa que el del hielo
                    if cont == 2
                        load landa_hielo landa_hielo
                        landa = landa_hielo;
                    else
                        landa=(landamin:landamax)';
                    end

                    %                     landa=landamin:landamax; % [nm]
                    % cálculo frecuencia ( landa en micras, no en nm)
                    omega = 2*pi*c./((landa/1000)*0.000001);
                    % Factor masa es es todos los casos 1/18=0.0556, salvo en D&S que es 1
                    factor_masa = app.FactormasaEditField.Value;

                    % Del modelo se tiene otro Dropdown con las constantes para el cálculo de tres autores:
                    % Se cogen los parámetros empleados por Dazell & Sarofim (1969)
                    if app.ParmetrosdeDropDown_SOOT.Value == "Dazell & Sarofim (1969)"

                        % CARGA DE LAS CONSTANTES PARTICULARES DE ESTOS AUTORES
                        load ctes_DS.mat ctes_DS
                        osciladores = ctes_DS(:,1);
                        n = ctes_DS(:,2);
                        g = ctes_DS(:,3);
                        wres = ctes_DS(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                        % Se cogen los parámetros empleados por Lee & Tien (1981)
                    elseif app.ParmetrosdeDropDown_SOOT.Value == "Lee & Tien (1981)"

                        % CARGA DE LAS CONSTANTES PARTICULARES DE ESTOS AUTORES
                        load ctes_LT.mat ctes_LT
                        osciladores = ctes_LT(:,1);
                        n = ctes_LT(:,2);
                        g = ctes_LT(:,3);
                        wres = ctes_LT(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        % tener en cuenta que la masa es la 1/18 parte
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                        % Se cogen los parámetros empleados por Harbib & Vervisch (1988)
                    elseif app.ParmetrosdeDropDown_SOOT.Value == "Harbib & Vervisch (1988)"

                        % CARGA DE LAS CONSTANTES PARTICULARES DE ESTOS AUTORES
                        load ctes_HV.mat ctes_HV
                        osciladores = ctes_HV(:,1);
                        n = ctes_HV(:,2);
                        g = ctes_HV(:,3);
                        wres = ctes_HV(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                        % Se introducen parámetros Stagg & Charalampopoulos (1993)
                    elseif app.ParmetrosdeDropDown_SOOT.Value == "Stagg & Charalampopoulos (1993)"

                        load ctes_SC.mat;
                        osciladores = ctes_SC(:,1);
                        n = ctes_SC(:,2);
                        g = ctes_SC(:,3);
                        wres = ctes_SC(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';


                        % Se introducen nuevos parámetros
                    elseif app.ParmetrosdeDropDown_SOOT.Value == "Otros" || app.ParmetrosdeDropDown_SOOT.Value == "Others"

                        load ctes_Otros.mat ctes_Otros
                        osciladores = ctes_Otros(:,1);
                        n = ctes_Otros(:,2);
                        g = ctes_Otros(:,3);
                        wres = ctes_Otros(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                        %---------------------------------------------------------------------------------
                        %% EN EL CASO DE TENER MÁS OSCILADORES INTRODUCIDOS POR USUARIO
                        %
                        %                         load ctes_Otros_soot.mat;
                        %                         osciladores = ctes_Otros_soot(:,1);
                        %                         n = ctes_Otros_soot(:,2);
                        %                         g = ctes_Otros_soot(:,3);
                        %                         wres = ctes_Otros_soot(:,4);
                        %                         Datos2 = [osciladores n g wres];
                        %                         app.UITableosciladores.Data = Datos2;
                        %
                        %                         % CÁLCULO DE n Y de k
                        %                         % se calcula la frecuencia de plasma (wp^2)
                        %                         omega_p_2 = R.*n;
                        %                         % se calcula el del e- libre de conducción
                        %                         Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        %                         % Bucle para meter más osciladores
                        %                         for j=1:app.NosciladoresSpinner.Value
                        %                             Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);
                        %
                        %                             for i = 1:length(landa)
                        %                                 % se calcula el del tipo 1
                        %                                 Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                        %                                 Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);
                        %
                        %                                 Eprima_1 = [Eprima_1 i];
                        %                                 % se calcula el del tipo 2
                        %                                 Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                        %                                 Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                        %                                 % se calculan las partes reales  imaginarias de la permitivdad
                        %                                 Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                        %                                 Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                        %                                 % se pasa a  parte real e imag del índice de refracción
                        %                                 n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        %                                 k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        %                             end
                        %                         end
                        %                         landa = landa';
                        %                         k = k';
                        %
                        %-------------------------------------------------------------------------------

                    end % END de la elección de los parámetros del modelo de dispersión
                end

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% GRAFITO
            elseif app.MaterialDropDown.Value == "Grafito" || app.MaterialDropDown.Value == "Graphite"
                mat = 4;  % Se ha escogido GRAFITO
                save mat mat
                %---------------------------------------------------------------------------------------------------------
                % Se escoge valores de Grafito de Djurišić-Li
                if app.ndicederefraccinDropDown_GRAFITO.Value == "Djurišić & Li (1999)"
                    % Se cargan los datos numéricos de Djurišić & Li (1999)
                    load m_D&L.mat n k landa

                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                    %                     % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                    %                     % Longitud de onda
                    %                     if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                    %                         landamin=min;
                    %                         landamax=max;
                    %                         % Frecuencia en eV
                    %                     elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                    %                         landamin=1240/min;
                    %                         landamax=1240/max;
                    %                         % Frecuencia en Hz
                    %                     elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                    %                         % factor de conversión de eV a Hz
                    %                         hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                    %                         landamin=(1240/min)/hz;
                    %                         landamax=(1240/max)/hz;
                    %                     end
                    %                     %                     landa = landa( landa >= landamin & landa <= landamax );
                    %                     %                     n = n( landa >= landamin & landa <= landamax );
                    %                     %                     k = k( landa >= landamin & landa <= landamax );
                    %                     n = n( landa >= min & landa <= max );
                    %                     k = k( landa >= min & landa <= max );
                    %                     landa = landa( landa >= min & landa <= max );

                    %---------------------------------------------------------------------------------------------------------
                    % Se opta por emplear el modelo de dispersión
                elseif app.ndicederefraccinDropDown_GRAFITO.Value == "Modelo de dispersión" || app.ndicederefraccinDropDown_GRAFITO.Value == "Dispersion Model"
                    % Constantes para el cálculo del modelo de dispersión
                    e = 1.60217646E-19;  % [C]
                    m = 9.10938188E-31; % [Kg]
                    e0 = 8.8541878176E-12; % [C^2*s^2/m^2*Kg]
                    R = (e^2)/(m*e0); % [m^3/s]
                    c = 299792458; % [m/s]
                    %                     kB = 1.38064852E-23; % [m2kg s-2K-1]
                    %                     T = 1450; % [K]

                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                    % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                    % Longitud de onda
                    if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                        landamin=min;
                        landamax=max;
                        % Frecuencia en eV
                    elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                        landamin=1240/min;
                        landamax=1240/max;
                        % Frecuencia en Hz
                    elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                        % factor de conversión de eV a Hz
                        hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                        landamin=(1240/min)/hz;
                        landamax=(1240/max)/hz;
                    end
                    landa=landamin:landamax; % [nm]

                    % cálculo frecuencia ( landa en micras, no en nm)
                    omega = 2*pi*c./((landa/1000)*0.000001);
                    factor_masa = app.FactormasaEditField.Value;

                    % Del modelo se tiene otro Dropdown con las constantes para el cálculo de tres autores:
                    % Se cogen los parámetros empleados por Stagg & Charalampopoulos (1993) (GRAFITO)
                    if app.ParmetrosdeDropDown_GRAFITO.Value == "Stagg & Charalampopoulos (1993)"

                        % CARGA DE LAS CONSTANTES PARTICULARES DEL GRAFITO Stagg & Charalampopoulos

                        load ctes_SC_grafito.mat ctes_SC_grafito
                        osciladores = ctes_SC_grafito(:,1);
                        n = ctes_SC_grafito(:,2);
                        g = ctes_SC_grafito(:,3);
                        wres = ctes_SC_grafito(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                        % Se introducen nuevos parámetros
                    elseif app.ParmetrosdeDropDown_GRAFITO.Value == "Otros" || app.ParmetrosdeDropDown_GRAFITO.Value == "Others"

                        load ctes_Otros_grafito.mat ctes_Otros_grafito
                        osciladores = ctes_Otros_grafito(:,1);
                        n = ctes_Otros_grafito(:,2);
                        g = ctes_Otros_grafito(:,3);
                        wres = ctes_Otros_grafito(:,4);
                        Datos2 = [osciladores n g wres];
                        app.UITableosciladores.Data = Datos2;

                        % CÁLCULO DE n Y de k
                        % se calcula la frecuencia de plasma (wp^2)
                        omega_p_2 = R.*n;
                        % se calcula el del e- libre de conducción
                        Eprima_c = (omega_p_2(1)/factor_masa)./(g(1).^2 + omega.^2);
                        Eprima2_c = ((omega_p_2(1).*g(1))/factor_masa)./((g(1).^2+omega.^2).*omega);

                        for i = 1:length(landa)
                            % se calcula el del tipo 1
                            Eprima_1(i) = omega_p_2(2).*(wres(2).^2-omega(i).^2)/((wres(2).^2-omega(i).^2).^2+g(2).^2*omega(i).^2);
                            Eprima2_1(i) = omega_p_2(2).*omega(i).*g(2)./((wres(2).^2-omega(i).^2).^2+g(2).^2.*omega(i).^2);

                            Eprima_1 = [Eprima_1 i];
                            % se calcula el del tipo 2
                            Eprima_2(i) = omega_p_2(3).*(wres(3).^2-omega(i).^2)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            Eprima2_2(i) = omega_p_2(3).*omega(i)*g(3)/((wres(3).^2-omega(i).^2).^2+g(3).^2*omega(i).^2);
                            % se calculan las partes reales  imaginarias de la permitivdad
                            Ereal(i) = 1-Eprima_c(i)+Eprima_1(i)+Eprima_2(i) ;
                            Eimag(i) = Eprima2_c(i)+Eprima2_1(i)+Eprima2_2(i);
                            % se pasa a  parte real e imag del índice de refracción
                            n(i) = ((Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                            k(i)= ((-Ereal(i)+(Ereal(i).^2+Eimag(i).^2).^0.5).^0.5)/(2^0.5);
                        end
                        landa = landa';
                        k = k';

                    end
                    % Los valores mínimo y máximo dependen de la selección
                    min = app.minimoEditField.Value;
                    max = app.maximoEditField.Value;
                end

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% DIAMANTE
            elseif app.MaterialDropDown.Value == "Diamante" || app.MaterialDropDown.Value == "Diamond"
                mat = 5;  % Se ha escogido diamante
                save mat mat
                %---------------------------------------------------------------------------------------------------------
                % Se escoge valores de Philip & Taft (1964)
                if app.ndicederefraccinDropDown_DIAMANTE.Value == "Philip & Taft (1964)"
                    % Datos de Philip & Taft (1964)
                    load m_P&T.mat m_PT
                    landa = m_PT(:,1); % Longitud de onda
                    n = m_PT(:,2); % Parte real índice de refración
                    k = m_PT(:,3); % Parte imaginaria índice de refración
                end

                % Los valores mínimo y máximo dependen de la selección
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;
                %                     % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
                %                     % Longitud de onda
                %                     if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                %                         landamin=min;
                %                         landamax=max;
                %                         % Frecuencia en eV
                %                     elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                %                         landamin=1240/min;
                %                         landamax=1240/max;
                %                         % Frecuencia en Hz
                %                     elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                %                         % factor de conversión de eV a Hz
                %                         hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                %                         landamin=(1240/min)/hz;
                %                         landamax=(1240/max)/hz;
                %                     end
                %                     %                     landa = landa( landa >= landamin & landa <= landamax );
                %                     %                     n = n( landa >= landamin & landa <= landamax );
                %                     %                     k = k( landa >= landamin & landa <= landamax );
                %                     n = n( landa >= min & landa <= max );
                %                     k = k( landa >= min & landa <= max );
                %                     landa = landa( landa >= min & landa <= max );

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% POLVO
            elseif app.MaterialDropDown.Value == "Polvo" || app.MaterialDropDown.Value == "Dust"
                mat = 6;  % Se ha escogido polvo
                save mat mat

                if app.ndicederefraccinDropDown_DUST.Value == "Origen sahariano (Patterson (1977))" || app.ndicederefraccinDropDown_DUST.Value == "Saharan Origin (Patterson (1977))"
                    load polvo.mat polvo
                    landa = polvo(:,1); % Longitud de onda
                    n = polvo(:,2); % Parte real índice de refración
                    k = polvo(:,3); % Parte imaginaria índice de refración

                elseif app.ndicederefraccinDropDown_DUST.Value == "Volz (1972)"
                    load m_Volz_polvo.mat m_Volz_polvo
                    landa = m_Volz_polvo(:,1); % Longitud de onda
                    n = m_Volz_polvo(:,2); % Parte real índice de refración
                    k = m_Volz_polvo(:,3); % Parte imaginaria índice de refración

                elseif app.ndicederefraccinDropDown_DUST.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_DUST.Value == "Mineralogical Composition Weighting"
                    % Se calcula el índice de refracción o permitividad del polvo ponderando en función de la composición de
                    % minerales que contenga, especificando la fracción volumétrica de cada mineral
                    % Fracciones volumétricas de los materiales
                    [minerales,fv] =readvars('fv_minerales.xlsx');

                    % %                     %PONER ALGO PARA DECIR QUE LA SUMNA DE TODAS LAS
                    % %                     %FRACCIONES VOLUMÉTRICAS SUME EL 100% verficarlo
                    % %                       ver = sum(fv);
                    % %                       if ver ~= 1
                    % %                          msgbox('El conjunto de fracciones volumétricas no suman el 100 %','Información','help')
                    % %                       end
                    %%                   ¡¡¡ PONERLO DE OTRA FORMA Y NO DENTRO DE BUTTON REPRESENTAR EL CONTROL DEL 100% !!!

                    % Se cargan los valores de la permitividad para todos los minerales en el orden que se indica en la tabla
                    [Landa_minerales] = readvars('Landa_minerales.xlsx');


                    [e_hematite,e_illite,e_quartz,e_calcite,e_kaolinite,e_montmorillonite] =readvars('e_minerales.xlsx');
                    % se agrupan todos en un vector
                    e = [e_hematite e_illite e_quartz e_calcite e_kaolinite e_montmorillonite];
                    % Pasar de formato cell a double
                    e = str2double(e);
                    % %
                    % %                       e=readtable('e_minerales.xlsx');
                    % %                       ee = table2array(e);
                    % falta pasar de cell a double que sale NAN

                    % longitud de onda correspondiente a los valores de permitividad
                    landa = Landa_minerales;

                    if app.ReglademezclaDropDown.Value == "Bruggeman"
                        v = fv'; % cambio de nombre
                        ei = e; % cambio de no,mbre: cada material es ei
                        e = linspace(2+0.01i,2.5+0.02i,length(ei)); % valor de "e" es el inicial
                        eps = 1e-12; % valor que indica la salida bucle iteación
                        modulo = zeros(size(e))';
                        for i = 1:length(ei)
                            suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                            suma_den(i,:) = sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                            real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                            modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                            while modulo(i,:) > eps
                                e(i) = e(i) + suma(i,:)/suma_den(i,:);
                                suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                                suma_den(i,:)= sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                                real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                                modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                            end
                        end
                        e_ponderado = e';

                    elseif app.ReglademezclaDropDown.Value == "Lorentz-Lorenz"
                        % Se aplica regla de mezcla de Lorentz-Lorentz calculando el cociente G
                        G = (e-1)./(e+2);
                        G_multiplicado = G.*fv';
                        % Se tienen que sumas las filas de la ponderación de cada G con la fv de cada material
                        G_ponderado = sum(G_multiplicado,2);

                        % Se despeja la permitividad ponderada con la G_ponderada
                        e_ponderado = (2.*G_ponderado+1)./(1-G_ponderado);
                    end

                    % Se despeja el índice de refracción de la permitividad
                    m_ponderado = e_ponderado.^0.5;
                    n = real(m_ponderado); k = imag(m_ponderado);

                end

                % Hacer una interpolación para coger el valor exacto
                % indicado por landa de usuario de parte real e imaginaria
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;

                %------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% ARENA
            elseif app.MaterialDropDown.Value == "Arena" || app.MaterialDropDown.Value == "Sand"
                mat = 9;  % Se ha escogido arena
                save mat mat

                %                 if  app.ndicederefraccinDropDown_SAND.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_SAND.Value == "Mineralogical Composition Weighting"
                % Se calcula el índice de refracción o permitividad de la arena ponderando en función de la composición de
                % minerales que contenga, especificando la fracción volumétrica de cada mineral
                % Fracciones volumétricas de los materiales
                [minerales,fv] =readvars('fv_compuestos_arena.xlsx');

                % %                     %PONER ALGO PARA DECIR QUE LA SUMNA DE TODAS LAS
                % %                     %FRACCIONES VOLUMÉTRICAS SUME EL 100% verficarlo
                % %                       ver = sum(fv);
                % %                       if ver ~= 1
                % %                          msgbox('El conjunto de fracciones volumétricas no suman el 100 %','Información','help')
                % %                       end
                %%                   ¡¡¡ PONERLO DE OTRA FORMA Y NO DENTRO DE BUTTON REPRESENTAR EL CONTROL DEL 100% !!!

                % Se cargan los valores de la permitividad para todos los minerales en el orden que se indica en la tabla
                [Landa_minerales] = readvars('Landa_minerales.xlsx');

                %                     [e_SiO2,e_calcio,e_aceto,e_dolomita] =readvars('e_minerales_arena.xlsx');
                %                     % se agrupan todos en un vector
                %                     e = [e_SiO2,e_calcio,e_aceto,e_dolomita];


                % Se ha quitado el compuesto de aceto
                [e_SiO2,e_calcio,e_dolomita] = readvars('e_minerales_arena.xlsx');
                % se agrupan todos en un vector
                e = [e_SiO2,e_calcio,e_dolomita];

                % Pasar de formato cell a double
                e = str2double(e);
                % %
                % %                       e=readtable('e_minerales.xlsx');
                % %                       ee = table2array(e);
                % falta pasar de cell a double que sale NAN

                % longitud de onda correspondiente a los valores de permitividad
                landa = Landa_minerales;

                if app.ReglademezclaDropDown.Value == "Bruggeman"
                    v = fv'; % cambio de nombre
                    ei = e; % cambio de nombre: cada material es ei
                    e = linspace(2+0.01i,2.5+0.02i,length(ei)); % valor de "e" es el inicial
                    eps = 1e-12; % valor que indica la salida bucle iteración
                    modulo = zeros(size(e))';
                    for i = 1:length(ei)
                        suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                        suma_den(i,:) = sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                        real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                        modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                        while modulo(i,:) > eps
                            e(i) = e(i) + suma(i,:)/suma_den(i,:);
                            suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                            suma_den(i,:)= sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                            real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                            modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                        end
                    end
                    e_ponderado = e';

                elseif app.ReglademezclaDropDown.Value == "Lorentz-Lorenz"
                    % Se aplica regla de mezcla de Lorentz-Lorentz calculando el cociente G
                    G = (e-1)./(e+2);
                    G_multiplicado = G.*fv';
                    % Se tienen que sumas las filas de la ponderación de cada G con la fv de cada material
                    G_ponderado = sum(G_multiplicado,2);

                    % Se despeja la permitividad ponderada con la G_ponderada
                    e_ponderado = (2.*G_ponderado+1)./(1-G_ponderado);
                end

                % Se despeja el índice de refracción de la permitividad
                m_ponderado = e_ponderado.^0.5;
                n = real(m_ponderado); k = imag(m_ponderado);

                %                 end

                % Hacer una interpolación para coger el valor exacto
                % indicado por landa de usuario de parte real e imaginaria
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% CENIZA
            elseif app.MaterialDropDown.Value == "Ceniza" || app.MaterialDropDown.Value == "Ash"
                mat = 7;  % Se ha escogido nuevo material
                save mat mat

                if app.ndicederefraccinDropDown_ASH.Value == "Cumbre Vieja (2021)"
                    % Se conocen las fracciones volumétricas de la ceniza
                    % recogida del volcán Cumbre Vieja de la isla de la Palma
                    % Franklinita   18.85 %
                    % Cordierita	6.71 %
                    % Albita	    71.14 %
                    % Olivino	    3.30 %


                    % Se calcula el índice de refracción o permitividad del polvo ponderando en función de la composición de
                    % minerales que contenga, especificando la fracción volumétrica de cada mineral
                    % Fracciones volumétricas de los materiales
                    [minerales,fv] =readvars('fv_compuestos_ceniza.xlsx');

                    % %                     %PONER ALGO PARA DECIR QUE LA SUMNA DE TODAS LAS
                    % %                     %FRACCIONES VOLUMÉTRICAS SUME EL 100% verficarlo
                    % %                       ver = sum(fv);
                    % %                       if ver ~= 1
                    % %                          msgbox('El conjunto de fracciones volumétricas no suman el 100 %','Información','help')
                    % %                       end
                    %%                   ¡¡¡ PONERLO DE OTRA FORMA Y NO DENTRO DE BUTTON REPRESENTAR EL CONTROL DEL 100% !!!

                    % Se cargan los valores de la permitividad para todos los minerales en el orden que se indica en la tabla
                    [Landa_minerales] =readvars('Landa_minerales.xlsx');


                    [e_Franklinita,e_Cordierita,e_Albita,e_Olivino] =readvars('e_minerales_ceniza.xlsx');
                    % se agrupan todos en un vector
                    e = [e_Franklinita,e_Cordierita,e_Albita,e_Olivino];
                    % Pasar de formato cell a double
                    e = str2double(e);
                    % %
                    % %                       e=readtable('e_minerales.xlsx');
                    % %                       ee = table2array(e);
                    % falta pasar de cell a double que sale NAN

                    % longitud de onda correspondiente a los valores de permitividad
                    landa = Landa_minerales;

                    if app.ReglademezclaDropDown.Value == "Bruggeman"
                        v = fv'; % cambio de nombre
                        ei = e; % cambio de no,mbre: cada material es ei
                        e = linspace(2+0.01i,2.5+0.02i,length(ei)); % valor de "e" es el inicial
                        eps = 1e-12; % valor que indica la salida bucle iteación
                        modulo = zeros(size(e))';
                        for i = 1:length(ei)
                            suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                            suma_den(i,:) = sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                            real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                            modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                            while modulo(i,:) > eps
                                e(i) = e(i) + suma(i,:)/suma_den(i,:);
                                suma(i,:) = sum(v.*((ei(i,:)-e(i))./(ei(i,:)+2*e(i))));
                                suma_den(i,:)= sum(3*v.*ei(i,:)./(ei(i,:)+2*e(i)).^2);
                                real_suma(i,:) = real(suma(i,:)); imag_suma(i,:) = imag(suma(i,:));
                                modulo(i,:) = (real_suma(i,:).^2+imag_suma(i,:).^2).^0.5;
                            end
                        end
                        e_ponderado = e';

                    elseif app.ReglademezclaDropDown.Value == "Lorentz-Lorenz"
                        % Se aplica regla de mezcla de Lorentz-Lorentz calculando el cociente G
                        G = (e-1)./(e+2);
                        G_multiplicado = G.*fv';
                        % Se tienen que sumas las filas de la ponderación de cada G con la fv de cada material
                        G_ponderado = sum(G_multiplicado,2);

                        % Se despeja la permitividad ponderada con la G_ponderada
                        e_ponderado = (2.*G_ponderado+1)./(1-G_ponderado);
                    end

                    % Se despeja el índice de refracción de la permitividad
                    m_ponderado = e_ponderado.^0.5;
                    n = real(m_ponderado); k = imag(m_ponderado);


                elseif app.ndicederefraccinDropDown_ASH.Value == "Etna (Dequine (2020))"
                    load m_Etna.mat m_Etna
                    landa = m_Etna(:,1);
                    n = m_Etna(:,2);
                    k = m_Etna(:,3);

                elseif app.ndicederefraccinDropDown_ASH.Value == "Eyjafjallajökull (Dequine (2020))"
                    load m_Islandia.mat m_Islandia
                    landa = m_Islandia(:,1);
                    n = m_Islandia(:,2);
                    k = m_Islandia(:,3);

                elseif app.ndicederefraccinDropDown_ASH.Value == "Linear model (Liu 1993)"
                    % Modelo lineal propuesto por Liu 1993
                    % EN la correlación se deben introducir la longitud de onda en nm
                    % La parte real permanece invariante durante todo el rango evaluado (0.3 a 3 micras)
                    landa = 300:3000;
                    n = zeros(size(landa));
                    k = zeros(size(landa));
                    for i = 1:length(landa)
                        if n(i) == 0
                            n(i) = 1.5;
                        elseif landa(i) <= 1000
                            k(i) = 10.^(-4.6+2.2.*(landa(i)/1000-0.5)); % landa en micras
                        elseif landa(i) >= 1000
                            k(i) = 10^(-3.5);
                        end
                    end

                end
                % Hacer una interpolación para coger el valor exacto
                % indicado por landa de usuario de parte real e imaginaria
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;

                %--------------------------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------------------------
                %% NUEVO MATERIAL
            elseif app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                mat = 8;  % Se ha escogido nuevo material
                save mat mat

                % Se cargan los datos del nuevo espectro que están en el excel NuevoEspectro
                [landa,n,k] =readvars('Nuevomaterial.xlsx');
                landa = landa';

                % Hacer una interpolación para coger el valor exacto
                % indicado por landa de usuario de parte real e imaginaria
                min = app.minimoEditField.Value;
                max = app.maximoEditField.Value;
            end
            %% FIN ELECCIÓN MATERIALES

            % Los valores mínimo y máximo dependen de la selección
            min = app.minimoEditField.Value;
            max = app.maximoEditField.Value;
            % Conversión del mínimo y máximo a longitud de onda para poder meterlo en la correlación
            % Longitud de onda
            if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                landamin=min;
                landamax=max;
                % Frecuencia en eV
            elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                landamin=1240/min;
                landamax=1240/max;
                % Frecuencia en Hz
            elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                landamin=(3*10^8*10^9)/min;
                landamax=(3*10^8*10^9)/max;
            end

            %% INTERPOLAR LOS VALORES PARA QUE HAYA DESDE EL VALOR MARCADO COMO MÍNIMO HASTA EL MÁXIMO Y CON VALORES 1 A 1
            %             lambda = (landamin:landamax)';
            lambda = (landamin:10:landamax)';
            n = interp1(landa,n,lambda,'linear');
            k = interp1(landa,k,lambda,'linear');

            % Si algún valor de k es menor de 0 se establece ese valor
            % límite
            k(k < 0) = 0;

            % Ahora el vector de longitud de onda es con el que se ha interpolado
            landa = lambda;
            assignin('base','landa',landa)
            assignin('base','n',n)
            assignin('base','k',k)

            e_real = n.^2-k.^2; % Parte real cte dieléctrica
            e_imag = 2.*k.*n;   % Parte imaginaria cte dieléctrica

            f_eV = 1240./landa; % Frecuencia en eV
            % factor de conversión de eV a Hz
            hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
            f_Hz = f_eV/hz;% Frecuencia en Hz

            assignin('base','e_real',e_real)
            assignin('base','e_imag',e_imag)
            assignin('base','f_Hz',f_Hz)
            assignin('base','f_eV',f_eV)

            % Selección de escoger representar índice de refracción,
            % parte real o imaginarias de forma independiente
            real_m = app.ParterealMCheckBox.Value;
            imag_m = app.ParteimaginariaMCheckBox.Value;
            real_e = app.ParterealECheckBox.Value;
            imag_e = app.ParteimaginariaECheckBox.Value;

            % índice de refracción
            if seleccion_ordenadas.Value == app.ndicederefraccinButton.Value
                % Longitud de onda
                if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                    n = n( landa >= min & landa <= max );
                    k = k( landa >= min & landa <= max );
                    landa = landa( landa >= min & landa <= max );

                    % Representar sólo parte real
                    if  real_m == 1 && imag_m == 0
                        plot(app.UIAxes_material,landa,n,'b');
                        legend(app.UIAxes_material,'n')

                        % Representar sólo parte imag
                    elseif real_m == 0 && imag_m == 1
                        plot(app.UIAxes_material,landa,k,'b--');
                        legend(app.UIAxes_material,'k')

                        % Representar las dos partes
                    elseif  real_m == 1 && imag_m == 1
                        plot(app.UIAxes_material,landa,n,'b',landa,k,'b--');
                        legend(app.UIAxes_material,'n','k')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Wavelength(nm)')
                        app.UITable_material.ColumnName = ["Wavelength (nm)", "Real part", "Imaginary part"];
                    elseif Idioma == 0
                        xlabel(app.UIAxes_material,'Longitud de onda (nm)')
                        app.UITable_material.ColumnName = ["Longitud de onda (nm)", "Parte real", "Parte imaginaria"];
                    end
                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [landa' n k];
                    else
                        Datos = [landa n k];
                    end

                    %                     if mat == 1
                    %                         Datos = [landa n k];
                    %                     else
                    %                         Datos = [landa n k];
                    %                        if cont ~= 2
                    %                             assignin('base','lambda_prueba0',landa)
                    %                             assignin('base','n_prueba0',n)
                    %                             assignin('base','k_prueba0',k)
                    % %                             Datos = [landa' n k];
                    %                             Datos = [landa n k];
                    %                         else
                    %                             assignin('base','lambda_prueba',landa)
                    %                             assignin('base','n_prueba',n)
                    %                             assignin('base','k_prueba',k)
                    %                             Datos = [landa' n k];
                    %                         end
                    %                     end

                    % Frecuencia en eV
                elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                    n = n( f_eV <= min & f_eV >= max );
                    k = k( f_eV <= min & f_eV >= max );
                    f_eV = f_eV( f_eV <= min & f_eV >= max );

                    % Representar sólo parte real
                    if  real_m == 1 && imag_m == 0
                        plot(app.UIAxes_material,f_eV,n,'b');
                        legend(app.UIAxes_material,'n')

                        % Representar sólo parte imag
                    elseif real_m == 0 && imag_m == 1
                        plot(app.UIAxes_material,f_eV,k,'b--');
                        legend(app.UIAxes_material,'k')

                        % Representar las dos partes
                    elseif  real_m == 1 && imag_m == 1
                        plot(app.UIAxes_material,f_eV,n,'b',f_eV,k,'b--');
                        legend(app.UIAxes_material,'n','k')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Energy (eV)')
                        app.UITable_material.ColumnName = ["Energy (eV)", "Real part", "Imaginary part"];
                    else
                        xlabel(app.UIAxes_material,'Energía (eV)')
                        app.UITable_material.ColumnName = ["Energía (eV)", "Parte real", "Parte imaginaria"];
                    end
                    %                     Datos = [f_eV n k];
                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [f_eV' n k];
                    else
                        Datos = [f_eV n k];
                    end

                    % Frecuencia en Hz
                elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                    n = n( f_Hz <= min & f_Hz >= max );
                    k = k( f_Hz <= min & f_Hz >= max );
                    f_Hz = f_Hz( f_Hz <= min & f_Hz >= max );

                    % Representar sólo parte real
                    if  real_m == 1 && imag_m == 0
                        plot(app.UIAxes_material,f_Hz,n,'b');
                        legend(app.UIAxes_material,'n')

                        % Representar sólo parte imag
                    elseif real_m == 0 && imag_m == 1
                        plot(app.UIAxes_material,f_Hz,k,'b--');
                        legend(app.UIAxes_material,'k')

                        % Representar las dos partes
                    elseif  real_m == 1 && imag_m == 1
                        plot(app.UIAxes_material,f_Hz,n,'b',f_Hz,k,'b--');
                        legend(app.UIAxes_material,'n','k')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Frecuency (Hz)')
                        app.UITable_material.ColumnName = ["Frecuency (Hz)", "Real part", "Imaginary part"];
                    else
                        xlabel(app.UIAxes_material,'Frecuencia (Hz)')
                        app.UITable_material.ColumnName = ["Frecuencia (Hz)", "Parte real", "Parte imaginaria"];
                    end
                    %                     Datos = [f_Hz n k];
                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [f_Hz' n k];
                    else
                        Datos = [f_Hz n k];
                    end
                end

                % Constante dieléctrica
            elseif seleccion_ordenadas.Value == app.PermitividadButton.Value
                % Longitud de onda
                if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                    e_real = e_real( landa >= min & landa <= max );
                    e_imag = e_imag( landa >= min & landa <= max );
                    landa = landa( landa >= min & landa <= max );

                    % Representar sólo parte real
                    if  real_e == 1 && imag_e == 0
                        plot(app.UIAxes_material,landa,e_real,'b');
                        legend(app.UIAxes_material,'\epsilon_{real}')

                        % Representar sólo parte imag
                    elseif real_e == 0 && imag_e == 1
                        plot(app.UIAxes_material,landa,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{imag}')

                        % Representar las dos partes
                    elseif  real_e == 1 && imag_e == 1
                        plot(app.UIAxes_material,landa,e_real,'b',landa,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{real}','\epsilon_{imag}')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Wavelength(nm)')
                        app.UITable_material.ColumnName = ["Wavelength (nm)", "Real part", "Imaginary part"];
                    else
                        xlabel(app.UIAxes_material,'Longitud de onda (nm)')
                        app.UITable_material.ColumnName = ["Longitud de onda (nm)", "Parte real", "Parte imaginaria"];
                    end

                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [landa' e_real e_imag];
                    else
                        Datos = [landa e_real e_imag];
                    end

                    % Frecuencia en eV
                elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                    e_real = e_real( f_eV <= min & f_eV >= max );
                    e_imag = e_imag( f_eV <= min & f_eV >= max );
                    f_eV = f_eV( f_eV <= min & f_eV >= max );

                    % Representar sólo parte real
                    if  real_e == 1 && imag_e == 0
                        plot(app.UIAxes_material,f_eV,e_real,'b');
                        legend(app.UIAxes_material,'\epsilon_{real}')

                        % Representar sólo parte imag
                    elseif real_e == 0 && imag_e == 1
                        plot(app.UIAxes_material,f_eV,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{imag}')

                        % Representar las dos partes
                    elseif  real_e == 1 && imag_e == 1
                        plot(app.UIAxes_material,f_eV,e_real,'b',f_eV,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{real}','\epsilon_{imag}')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Energy (eV)')
                        app.UITable_material.ColumnName = ["Energy (eV)", "Real part", "Imaginary part"];
                    else
                        xlabel(app.UIAxes_material,'Energía (eV)')
                        app.UITable_material.ColumnName = ["Energía (eV)", "Parte real", "Parte imaginaria"];
                    end

                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [f_eV' e_real e_imag];
                    else
                        Datos = [f_eV e_real e_imag];
                    end

                    % Frecuencia en Hz
                elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                    e_real = e_real( f_Hz <= min & f_Hz >= max );
                    e_imag = e_imag( f_Hz <= min & f_Hz >= max );
                    f_Hz = f_Hz( f_Hz <= min & f_Hz >= max );

                    % Representar sólo parte real
                    if  real_e == 1 && imag_e == 0
                        plot(app.UIAxes_material,f_Hz,e_real,'b');
                        legend(app.UIAxes_material,'\epsilon_{real}')

                        % Representar sólo parte imag
                    elseif real_e == 0 && imag_e == 1
                        plot(app.UIAxes_material,f_Hz,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{imag}')

                        % Representar las dos partes
                    elseif  real_e == 1 && imag_e == 1
                        plot(app.UIAxes_material,f_Hz,e_real,'b',f_Hz,e_imag,'b--');
                        legend(app.UIAxes_material,'\epsilon_{real}','\epsilon_{imag}')
                    end
                    if Idioma == 1
                        xlabel(app.UIAxes_material,'Frecuency (Hz)')
                        app.UITable_material.ColumnName = ["Frecuency (Hz)", "Real part", "Imaginary part"];
                    else
                        xlabel(app.UIAxes_material,'Frecuencia (Hz)')
                        app.UITable_material.ColumnName = ["Frecuencia (Hz)", "Parte real", "Parte imaginaria"];
                    end

                    % Agrupar los valores para mostrarlos en la tabla
                    if app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                        Datos = [f_Hz' e_real e_imag];
                    else
                        Datos = [f_Hz e_real e_imag];
                    end
                end
            end
            app.UITable_material.ColumnFormat = {'short','short','shortE'};
            % Después de elegir la representación, se guardan los datos calculados
            app.UITable_material.Data = Datos;

            %------------------------------------------
            % Cambiar el título de la pantalla del estudio
            % Si está hielo hay que ocultar cosas de hollín
            % Ocultar el factor de asimetría porque es sólo Debye para hollín
            if mat == 1 % hielo
                app.EstudiodelmaterialLabel.Text ='Estudio del hielo';
                app.RadiodelapartculaesfricanmEditField.Value = 800000; % 800 micras
                app.TreeRDG_ME.Enable = 'Off';
                app.TreeMethodRmax.Enable = 'Off';
                app.NdepartculasprimariasEditField.Enable ='Off';
                app.DimensinfractalEditField.Enable ='Off';

                app.FactordeasimetraDropDown.Enable = 'On';
                app.FactordeasimetraDropDownLabel.Enable = 'On';
                app.fdEditField.Enable = 'On';
                app.fdEditFieldLabel.Enable = 'On';
                app.grefEditField.Enable = 'On';
                app.grefEditFieldLabel.Enable = 'On';
                app.gdifEditField.Enable = 'On';
                app.gdifEditFieldLabel.Enable = 'On';
                app.AlbedoDropDown.Enable ='On';
                app.AlbedoDropDownLabel.Enable ='On';
                app.ngulocenitalEditField.Enable = 'On';
                app.ngulocenitalEditFieldLabel.Enable = 'On';
                app.ProfundidaddelmantoButtonGroup.Enable = 'On';
                app.Button_cm.Enable = 'On';
                app.cmEditFieldLabel.Enable = 'On';
                app.SemiinfinitaButton.Enable = 'On';
                app.cmEditField.Enable = 'On';
                app.Densidadsuperficialkgm3EditField.Enable = 'On';
                app.Densidadsuperficialkgm3EditFieldLabel.Enable = 'On';
                app.ProfundidadmnmxcmLabel.Enable = 'On';
                app.Profundidad_min.Enable = 'On';
                app.Profundidad_max.Enable = 'On';
                app.RepresentardensidadButton.Enable = 'On';

            elseif mat == 2 % Hollín
                app.EstudiodelmaterialLabel.Text ='Estudio del hollín';
                app.RadiodelapartculaesfricanmEditField.Value = 12.5;
                app.TreeRDG_ME.Enable = 'On';
                app.TreeMethodRmax.Enable = 'On';
                app.NdepartculasprimariasEditField.Enable ='On';
                app.DimensinfractalEditField.Enable ='On';

                app.FactordeasimetraDropDown.Enable = 'Off';
                app.FactordeasimetraDropDownLabel.Enable = 'Off';
                app.fdEditField.Enable = 'Off';
                app.fdEditFieldLabel.Enable = 'Off';
                app.grefEditField.Enable = 'Off';
                app.grefEditFieldLabel.Enable = 'Off';
                app.gdifEditField.Enable = 'Off';
                app.gdifEditFieldLabel.Enable = 'Off';
                app.AlbedoDropDown.Enable ='Off';
                app.AlbedoDropDownLabel.Enable ='Off';
                app.ngulocenitalEditField.Enable = 'Off';
                app.ngulocenitalEditFieldLabel.Enable = 'Off';
                app.ProfundidaddelmantoButtonGroup.Enable = 'Off';
                app.Button_cm.Enable = 'Off';
                app.cmEditFieldLabel.Enable = 'Off';
                app.SemiinfinitaButton.Enable = 'Off';
                app.cmEditField.Enable = 'Off';
                app.Densidadsuperficialkgm3EditField.Enable = 'Off';
                app.Densidadsuperficialkgm3EditFieldLabel.Enable = 'Off';
                app.ProfundidadmnmxcmLabel.Enable = 'Off';
                app.Profundidad_min.Enable = 'Off';
                app.Profundidad_max.Enable = 'Off';
                app.RepresentardensidadButton.Enable = 'Off';

            elseif mat == 3 % Agua
                app.EstudiodelmaterialLabel.Text ='Estudio del agua';
            elseif mat == 4 % Grafito
                app.EstudiodelmaterialLabel.Text ='Estudio del grafito';
            elseif mat == 5 % Diamante
                app.EstudiodelmaterialLabel.Text ='Estudio del diamante';
            elseif mat == 6 % Polvo
                app.EstudiodelmaterialLabel.Text ='Estudio del polvo';
            elseif mat == 6 % Arena
                app.EstudiodelmaterialLabel.Text ='Estudio de la arena';
            elseif mat == 7 % Ceniza
                app.EstudiodelmaterialLabel.Text ='Estudio de la ceniza';
            elseif mat == 8 % Nuevo material
                app.EstudiodelmaterialLabel.Text ='Estudio del nuevo material';
            end
            load cont cont
            % ESTUDIO MANTO CONTAMINADOtree.to
            if cont == 2  % se ha hecho un estudio de los dos materiales
                app.EstudiodelmaterialLabel.Text ='Estudio del manto contaminado';
                app.DatoscontaminacinsuperficiePanel.Enable = 'On';
                app.MostrarButton.Visible = 'On';
                app.ResultadosButtonGroup.Enable = 'On';
                app.UITable_contaminado.Visible = 'Off';
                app.UITable_estudio.Visible = 'Off';
                app.AlbedoDropDown.Enable ='Off';
                app.ngulocenitalEditField.Enable = 'Off';
                app.Button_cm.Enable = 'Off';
                app.SemiinfinitaButton.Enable = 'Off';
                app.cmEditField.Enable = 'Off';
                % Si es un estudio de distribución de tamaños hay que
                % mostrar el bloque de los canales
                if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                    app.Panel_canales.Enable = 'On';
                    app.MostrarButton.Enable = 'On';
                else
                    app.Panel_canales.Enable = 'Off';
                    app.MostrarButton.Enable = 'Off';
                end

            elseif cont == 1 || cont == 0
                % si no es manto contaminado se oculta panel Datos Análisis manto contaminado
                app.DatoscontaminacinsuperficiePanel.Enable = 'Off';
                app.MostrarButton.Enable = 'Off';
                % Mostrar si se ha contaminado el manto la opción de resultados
                % gráficos o numéricos porque se muestran 2 gráficas o 2 tablas
                app.ResultadosButtonGroup.Enable = 'Off'; % numéricos o gráficos
                app.UITable_contaminado.Visible = 'Off';
                app.Panel_canales.Enable= 'Off';
                app.MostrarButton.Enable = 'Off';
            end
            %------------------------------------------

            save mat mat
            save Datos Datos
            save indice_refraccion landa n k f_eV f_Hz
            save permitividad f_eV e_real e_imag
        end

        % Value changed function: ndicederefraccinDropDown_SOOT
        function ndicederefraccinDropDown_SOOTValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ndicederefraccinDropDown_SOOT.Value == "Liu & Bescond (2015)"
                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
            elseif app.ndicederefraccinDropDown_SOOT.Value == "Chang & Charalampopoulos (1990)"
                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
            elseif app.ndicederefraccinDropDown_SOOT.Value == "Modelo de dispersión" || app.ndicederefraccinDropDown_SOOT.Value == "Dispersion model"
                app.ParmetrosdeDropDown_SOOT.Visible = 'On';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'On';
                app.ParmetrosdeLabel.Visible = 'On';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='On';
                app.FactormasaLabel.Visible='On';
            end
        end

        % Value changed function: ParmetrosdeDropDown_SOOT
        function ParmetrosdeDropDown_SOOTValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ParmetrosdeDropDown_SOOT.Value == "Dazell & Sarofim (1969)"
                app.NosciladoresLabel.Visible='Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.FactormasaEditField.Value=1;
            elseif app.ParmetrosdeDropDown_SOOT.Value == "Lee & Tien (1981)"
                app.NosciladoresLabel.Visible='Off';
                app.NosciladoresSpinner.Visible = 'Off';
            elseif app.ParmetrosdeDropDown_SOOT.Value == "Harbib & Vervisch (1988)"
                app.NosciladoresLabel.Visible='Off';
                app.NosciladoresSpinner.Visible = 'Off';
            elseif app.ParmetrosdeDropDown_SOOT.Value == "Otros" || app.ParmetrosdeDropDown_SOOT.Value == "Others"
                app.NosciladoresLabel.Visible='On';
                app.NosciladoresSpinner.Visible = 'On';
                % Añadir los valores de la tabla
                % Coger el valor del número de osciladores (n_osc)
                n_osc = app.NosciladoresSpinner.Value;
                % Generar la tabla con el número de osciladores +1
                app.UITableosciladores.Data = zeros(n_osc+1,4);
                app.UITableosciladores.Data(:,1) = 0:n_osc;
            end
        end

        % Value changed function: NosciladoresSpinner
        function NosciladoresSpinnerValueChanged(app, event)
            % Número de osciladores
            n_osc = app.NosciladoresSpinner.Value;
            if (n_osc-app.UITableosciladores.Data(end,1)) > 0
                for i = 1:(n_osc-app.UITableosciladores.Data(end,1))
                    app.UITableosciladores.Data = [app.UITableosciladores.Data; n_osc 0 0 0];
                end
            else
                app.UITableosciladores.Data = app.UITableosciladores.Data(1:n_osc+1,:);
            end
        end

        % Value changed function: ndicederefraccinDropDown_GRAFITO
        function ndicederefraccinDropDown_GRAFITOValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ndicederefraccinDropDown_GRAFITO.Value == "Djurišić & Li (1999)"
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';

            elseif app.ndicederefraccinDropDown_GRAFITO.Value == "Modelo de dispersión" || app.ndicederefraccinDropDown_GRAFITO.Value == "Dispersion model"
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'On';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'On';
                app.ParmetrosdeLabel.Visible = 'On';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='On';
                app.FactormasaLabel.Visible='On';
            end
        end

        % Value changed function: ParmetrosdeDropDown_GRAFITO
        function ParmetrosdeDropDown_GRAFITOValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ParmetrosdeDropDown_GRAFITO.Value == "Stagg & Charalampopoulos (1993)"
                app.NosciladoresLabel.Visible='Off';
                app.NosciladoresSpinner.Visible = 'Off';

            elseif app.ParmetrosdeDropDown_GRAFITO.Value == "Otros" || app.ParmetrosdeDropDown_GRAFITO.Value == "Others"
                app.NosciladoresLabel.Visible='On';
                app.NosciladoresSpinner.Visible = 'On';
            end
        end

        % Selection changed function: EjedeAbscisasButtonGroup
        function EjedeAbscisasButtonGroupSelectionChanged(app, event)
            seleccion_abscisas = app.EjedeAbscisasButtonGroup.SelectedObject;
            % Longitud de onda (nm)
            if seleccion_abscisas.Value == app.LongituddeondanmButton.Value
                app.minimoEditField.Value = 300;
                app.maximoEditField.Value = 2000;
                % Energía (eV)
            elseif seleccion_abscisas.Value == app.EnergaeVButton.Value
                app.minimoEditField.Value = 4.13;
                app.maximoEditField.Value = 0.62;
                % Frecuencia
            elseif seleccion_abscisas.Value == app.FrecuenciaHzButton.Value
                app.minimoEditField.Value = 1E15;
                app.maximoEditField.Value = 1.5E14;
            end
        end

        % Button pushed function: AgregarmaterialButton
        function AgregarmaterialButtonPushed(app, event)
            winopen('Nuevomaterial.xlsx')
            % Guardar el nombre del nuevo material y ponerlo en el
            % dropdown de Espectro Solar, Fuente Halógena
            nombre = readcell('Nuevomaterial.xlsx');
            nombre = nombre(2,4);
            app.MaterialDropDown.Items = ['Hielo' 'Grafito' 'Grafito' 'Diamante' 'Agua' 'Polvo' 'Arena' 'Ceniza' nombre];
        end

        % Value changed function: ndicederefraccinDropDown_DUST
        function ndicederefraccinDropDown_DUSTValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ndicederefraccinDropDown_DUST.Value == "Origen sahariano (Patterson (1977))" || app.ndicederefraccinDropDown_DUST.Value == "Saharan Origin (Patterson (1977))"
                app.UITable_minerales.Visible='Off';
                app.AgregarmineralButton.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';

            elseif app.ndicederefraccinDropDown_DUST.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_DUST.Value == "Mineralogical Composition Weighting"
                app.UITable_minerales.Visible='On';
                app.ReglademezclaDropDownLabel.Visible = 'On';
                app.ReglademezclaDropDown.Visible = 'On';
                app.AgregarmineralButton.Visible = 'On';
                t = readtable('fv_minerales.xlsx');
                app.UITable_minerales.Data = t;
            end
        end

        % Button pushed function: AgregarmineralButton
        function AgregarmineralButtonPushed(app, event)
            % abrir el archivo para meter minerales y fracciones
            % volumétricas
            winopen('fv_minerales.xlsx')

            % tiene que abrir el archivo para meter índices de refracción
            % del mineral/es introducido/s
            winopen('e_minerales.xlsx')
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            % HIELO
            if app.MaterialDropDown.Value == "Hielo" || app.MaterialDropDown.Value == "Ice"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'On';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % HOLLÍN
            elseif  app.MaterialDropDown.Value == "Hollín" || app.MaterialDropDown.Value == "Soot"
                app.ndicederefraccinDropDown_SOOT.Visible = 'On';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.ParmetrosdeDropDown_SOOT.Visible = 'On';
                app.NosciladoresSpinner.Visible = 'On';
                app.UITableosciladores.Visible = 'On';
                app.ParmetrosdeLabel.Visible = 'On';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='On';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % GRAFITO
            elseif  app.MaterialDropDown.Value == "Grafito" || app.MaterialDropDown.Value == "Graphite"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'On';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_GRAFITO.Visible = 'On';
                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'On';
                app.ParmetrosdeLabel.Visible = 'On';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % DIAMANTE
            elseif  app.MaterialDropDown.Value == "Diamante" || app.MaterialDropDown.Value == "Diamond"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'On';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';


                % AGUA
            elseif app.MaterialDropDown.Value == "Agua" || app.MaterialDropDown.Value == "Water"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'On';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % POLVO
            elseif app.MaterialDropDown.Value == "Polvo" || app.MaterialDropDown.Value == "Dust"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'On';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % ARENA
            elseif app.MaterialDropDown.Value == "Arena" || app.MaterialDropDown.Value == "Sand"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'On';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.UITable_minerales.Visible='On';
                app.ReglademezclaDropDownLabel.Visible = 'On';
                app.ReglademezclaDropDown.Visible = 'On';
                app.AgregarmineralButton.Visible = 'On';
                t = readtable('fv_compuestos_arena.xlsx');
                app.UITable_minerales.Data = t;

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';


                % CENIZA
            elseif app.MaterialDropDown.Value == "Ceniza" || app.MaterialDropDown.Value == "Ash"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'On';
                app.ndicederefraccinLabel.Visible='On';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'Off';

                % NUEVO MATERIAL
            elseif app.MaterialDropDown.Value == "Nuevo material" || app.MaterialDropDown.Value == "New material"
                app.ndicederefraccinDropDown_SOOT.Visible = 'Off';
                app.ndicederefraccinDropDown_GRAFITO.Visible = 'Off';
                app.ndicederefraccinDropDown_DIAMANTE.Visible = 'Off';
                app.ndicederefraccinDropDown_WATER.Visible = 'Off';
                app.ndicederefraccinDropDown_ICE.Visible = 'Off';
                app.ndicederefraccinDropDown_DUST.Visible = 'Off';
                app.ndicederefraccinDropDown_SAND.Visible = 'Off';
                app.ndicederefraccinDropDown_ASH.Visible = 'Off';
                app.ndicederefraccinLabel.Visible='Off';
                app.UITable_minerales.Visible = 'Off';
                app.AgregarmineralButton.Visible ='Off';

                app.ParmetrosdeDropDown_SOOT.Visible = 'Off';
                app.ParmetrosdeDropDown_GRAFITO.Visible = 'Off';
                app.NosciladoresSpinner.Visible = 'Off';
                app.UITableosciladores.Visible = 'Off';
                app.ParmetrosdeLabel.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
                app.NosciladoresLabel.Visible='Off';
                app.FactormasaEditField.Visible='Off';
                app.FactormasaLabel.Visible='Off';
                app.AgregarmaterialButton.Visible = 'On';
            end
        end

        % Menu selected function: AcercadeAppDesignerMenu
        function AcercadeAppDesignerMenuSelected(app, event)
            web('https://es.mathworks.com/products/matlab/app-designer.html');
        end

        % Menu selected function: LogartmicoMenu_3
        function LogartmicoMenu_3Selected(app, event)
            set(app.UIAxes_material,'xscale','log')
        end

        % Menu selected function: LinealMenu_4
        function LinealMenu_4Selected(app, event)
            set(app.UIAxes_material,'xscale','linear')
        end

        % Menu selected function: LogartmicoMenu_4
        function LogartmicoMenu_4Selected(app, event)
            set(app.UIAxes_material,'yscale','log')
        end

        % Menu selected function: LinealMenu_5
        function LinealMenu_5Selected(app, event)
            set(app.UIAxes_material,'yscale','linear')
        end

        % Menu selected function: CerrarMenu
        function CerrarMenuSelected(app, event)
            app.DescripcindelProgramaPanel.Visible = 'Off';
        end

        % Button pushed function: EjecucinindividualButton
        function EjecucinindividualButtonPushed(app, event)
            %Recoger los valores introducidos por el usuario
            lambda = app.LongituddeondanmEditField.Value;
            rp0 = app.RadiodelapartculaesfricanmEditField.Value;
            np0 = app.NdepartculasprimariasEditField.Value;
            Df = app.DimensinfractalEditField.Value;

            % Cargar datos del índice de refracción
            load indice_refraccion.mat landa n k f_eV f_Hz
            % Interpolación si el valor introducido no es exacto al disponible en la base de datos del índice de refracción
            if lambda ~= landa
                % Parte real
                vector_n_menor = n(landa<lambda); cont_n = vector_n_menor(end);
                vector_n_mayor = n(landa>lambda); cont1_n = vector_n_mayor(1);
                landa_menor = landa(landa<lambda); cont_landa = landa_menor(end);
                landa_mayor = landa(landa>lambda); cont1_landa = landa_mayor(1);

                parte_real = ((cont1_n-cont_n)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_n;

                % Parte imaginaria
                vector_k_menor = k(landa<lambda); cont_k = vector_k_menor(end);
                vector_k_mayor = k(landa>lambda); cont1_k = vector_k_mayor(1);

                parte_imag = ((cont1_k-cont_k)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_k;
                m = parte_real- parte_imag*1i;
            else
                landa = landa (landa == lambda);
                n = n(landa == lambda);
                k = k(landa == lambda);
                m = n - k*1i;
            end

            %%% DENSIDADES
            % en kg/m^3
            % CARGAR MATERIAL QUE SE TRATE:
            load mat.mat mat
            if mat == 2 % hollín
                ro = 1850;
            elseif mat == 6 % polvo
                %densidad como dato o como ponderación de las composiones
                if app.ndicederefraccinDropDown_DUST.Value == "Origen sahariano (Patterson (1977))" || app.ndicederefraccinDropDown_DUST.Value == "Saharan Origin (Patterson (1977))"
                    ro = 2500; % polvo atmosférico del sáhara
                elseif app.ndicederefraccinDropDown_DUST.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_DUST.Value == "Mineralogical Composition Weighting"
                    [minerales,fv,densidad] =readvars('fv_minerales.xlsx');
                    % Ponderación densidad cada mineral con su composición
                    ro = sum(fv.*densidad);
                end
            elseif mat == 7 % ceniza
                ro = 2600; %Flanner et al. 2021
            end

            % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
            mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000; % g
            % Parámetros comunes
            x=(2*pi*rp0)/lambda; % Diámetro adimensional

            assignin('base','mp',mp)

            %%%% AEROSOLES
            %%% PARTÍCULAS ESFÉRICAS
            if app.TreeAglomerados.SelectedNodes.NodeData == 2
                %% MÉTODO DE RAYLEIGH
                % Ecuación de Rayleigh
                if app.TreeMethod.SelectedNodes.NodeData == 8
                    Qabs=-4*x*(imag(((m^2)-1)/((m^2)+2)));
                    Qsca=(8/3)*x^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qext=Qabs+Qsca;

                    % Ecuación de Bohren & Huffmann
                elseif app.TreeMethod.SelectedNodes.NodeData == 9
                    Qabs=-4*x*imag(((m^2)-1)/((m^2)+2)*(1+((((x^2)/15))*(((m^4)+27*(m^2)+38)/(2*(m^2)+3)))+((8/3)*x.^4*real(((m^2)-1)/((m^2)+2))^2)-((8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2))));
                    Qsca=(8/3)*x^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qext=Qabs+Qsca;

                    % Ecuación de Dobbins & Megaridis
                elseif app.TreeMethod.SelectedNodes.NodeData == 10
                    Qabs=-4*x*((imag(((m^2)-1)/((m^2)+2)))+imag((((x^2)/15))*((m^2)-1)/((m^2)+2)*(((m^4)+27*(m^2)+38)/(2*(m^2)+3))));
                    Qsca=(8/3)*x^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qext=Qabs+Qsca;

                    %% MÉTODO DE MIE
                elseif app.TreeMethod.SelectedNodes.NodeData == 11
                    y=m*x;
                    n=1;nmax=round(2+x+4*x^(1/3));
                    Eext_sum=0;g_sum=0;Esca_sum=0;
                    for n=1:nmax
                        shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                        dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                        shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                        dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                        psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                        dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                        an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix);
                        bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix);
                        Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                        Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                        As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                        if (abs(an(n,1))<1e-8)
                            break
                        end
                        if (abs(bn(n,1))<1e-8)
                            break
                        end
                    end
                    nmat=n;
                    for t=1:(n-1)
                        g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                    end
                    Qext=2/x^2*Eext_sum;
                    Qsca=2/x^2*Esca_sum;
                    Qabs=Qext-Qsca;
                    FAsymmetryDebye=g_sum; % Factor de asimetría de Debye
                end
                % Como Ap está en nm hay que pasarlo a m porque es un área
                Ap0=(pi()*(rp0/(10^9))^2); % m^2
                %Definimoos Ap_prima para poder poner el cálculo de CSabs...
                %fuera del bucle y valga para todos los casos
                Ap_prima=Ap0;

                %%% AGLOMERADOS DE ESFERAS
            elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                %% MÉTODO DE RAYLEIGH
                Ap0=(pi()*rp0^2);
                z1=log(np0)/(log((0.8488*np0)+0.1512));
                z3=1/(0.6667+(0.2003/log(np0)));
                z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                Ap=Ap0*(np0^(1/z));
                K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                rg=rp0*((np0/Kf)^(1/Df));
                K=(2*pi)/lambda;

                % Ecuación de Rayleigh
                if app.TreeMethod.SelectedNodes.NodeData == 8
                    Qabsp0=-4*x*(imag(((m^2)-1)/((m^2)+2)));
                    Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qabs=(np0^(1-(1/z)))*Qabsp0;
                    Qsca=(np0^(2-(1/z)))*Qscap0;
                    Qext = Qabs+Qsca;
                    % Cálculo de MAC Y CROSS-SECTIONS
                    % Cross-section = eficiencia * área proyectada
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    % (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); %m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); %m^2
                    mp = mp*np0; % g


                    %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                    %% Se escoge el de (Mountain-Mullhollad) Fisher-Burford
                    if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                        GFB=(1+((4*(K^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                        Qsca=(np0^(2-(1/z)))*Qscap0*GFB;
                        Qext=Qabs+Qsca;

                        %% Se escoge el de Köylü-Faeth
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                        beta=(3*Df)/(8*(K*rg)^2);
                        if beta>=1
                            GKF=1-((2*(K*rg)^2)/3);
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                            Qext=Qabs+Qsca;
                        elseif beta<1 && Df==2
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                            Qext=Qabs+Qsca;
                        else
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                            Qext=Qabs+Qsca;
                        end
                    end

                    % Ecuación de Bohren & Huffmann
                elseif app.TreeMethod.SelectedNodes.NodeData == 9
                    Qabsp0=-4*x*imag(((m^2)-1)/((m^2)+2)*(1+((((x^2)/15))*(((m^4)+27*(m^2)+38)/(2*(m^2)+3)))+((8/3)*x.^4*real(((m^2)-1)/((m^2)+2))^2)-((8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2))));
                    Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qabs=(np0^(1-(1/z)))*Qabsp0;
                    Qsca=(np0^(2-(1/z)))*Qscap0;
                    Qext = Qabs+Qsca;
                    % Cálculo de MAC Y CROSS-SECTIONS
                    % Cross-section = eficiencia * área proyectada
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    % (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); %m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); %m^2
                    mp = mp*np0; % g

                    %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                    %% Se escoge el de Fisher-Burford
                    if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                        GFB=(1+((4*(K^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                        Qsca=(np0^(2-(1/z)))*Qscap0*GFB;
                        Qext=Qabs+Qsca;

                        %% Se escoge el de Köylü-Faeth
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                        beta=(3*Df)/(8*(K*rg)^2);
                        if beta>=1
                            GKF=1-((2*(K*rg)^2)/3);
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        elseif beta<1 && Df==2
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        else
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        end
                        Qext=Qabs+Qsca;
                    end

                    % Ecuación de Dobbins & Megaridis
                elseif app.TreeMethod.SelectedNodes.NodeData == 10
                    Qabsp0=-4*x*((imag(((m^2)-1)/((m^2)+2)))+imag((((x^2)/15))*((m^2)-1)/((m^2)+2)*(((m^4)+27*(m^2)+38)/(2*(m^2)+3))));
                    Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qabs=(np0^(1-(1/z)))*Qabsp0;
                    Qsca=(np0^(2-(1/z)))*Qscap0;
                    Qext = Qabs+Qsca;
                    % Cálculo de MAC Y CROSS-SECTIONS
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    % (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); %m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); %m^2
                    mp = mp*np0; % g

                    %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                    %% Se escoge el de Fisher-Burford
                    if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                        GFB=(1+((4*(K^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                        Qsca=(np0^(2-(1/z)))*Qscap0*GFB;
                        Qext=Qabs+Qsca;

                        %% Se escoge el de Köylü-Faeth
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                        beta=(3*Df)/(8*(K*rg)^2);
                        if beta>=1
                            GKF=1-((2*(K*rg)^2)/3);
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        elseif beta<1 && Df==2
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        else
                            GKF=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                            Qsca=(np0^(2-(1/z)))*Qscap0*GKF;
                        end
                        Qext=Qabs+Qsca;
                    end

                    %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                elseif app.TreeMethod.SelectedNodes.NodeData == 11
                    % Parámetros comunes independientemente de la regla de mezcla
                    incr=0.000000001;
                    in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                    in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;

                    %% Método de estimación del radio máximo
                    % Método geométrico
                    if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                        R1=rp0*np0;
                        R2=((2*in2)+1)*rp0;
                        R3=((2*in3)+1)*rp0;
                        R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                        fv=(np0*(rp0^3))/(exp(R)^3);

                        % Método aproximado
                    elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                        fv = np0.^(1-(3/Df));
                        % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                        % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                        Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                        Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                        Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                        % Generar con esos puntos los valores de A,B,C
                        % polyfit(x,y,2) devuelve un vector con los coeficientes del polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                        % Para representar la recta, se sacan los puntos con polyval
                        a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                        A=a(1); B=a(2); C=a(3);
                        Kf = A*(Df.^2)+B*Df+C;
                        rg = rp0*((np0/Kf).^(1/Df));
                        R = rg*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                        R = log(R);
                    end

                    x=(2*pi*exp(R))/lambda;
                    n=1;nmax=round(2+x+4*x^(1/3));

                    % área proyectada
                    Ap0=(pi()*(rp0/(10^9))^2); %m^2
                    z1=log(np0)/(log((0.8488*np0)+0.1512));
                    z3=1/(0.6667+(0.2003/log(np0)));
                    z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                    Ap=Ap0*(np0^(1/z)); %m^2
                    Ap_prima=Ap;  % para poder sacar cálculo fuera bucle if
                    mp = mp*np0; % g

                    % PRIMERO CALCULAR EL MEFF SEGÚN CADA REGLA DE MEZCLA QUE SE ESCOJA
                    % Se escoge Maxwell-Garnett
                    if app.TreeRDG_ME.SelectedNodes.NodeData == 17
                        m2MG=((((m^2)*((2*fv)+1))+(2*(-fv+1)))/(((m^2)*((-1*fv)+1))+((fv+2))))^0.5;

                        % Se escoge Bruggeman
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 18
                        m2B=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                        m2MG = m2B;

                        % Se escoge Lorentz-Lorenz
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                        m2LL=((1+(2*fv*(((m^2)-1)/((m^2)+2))))/(1-(fv*(((m^2)-1)/((m^2)+2)))))^0.5;
                        m2MG = m2LL;

                        % Se escoge Lakatia-Vitkam
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                        k=2*pi/lambda;
                        alfa=(m^2-1)/(1-(m^2-1)*(2/3*(1-k*rp0*1i)*exp(k*rp0*1i)-1));
                        m2LV=((1+(2*alfa*fv)/3)^0.5)/((1-(alfa*fv)/3)^0.5);
                        m2MG = m2LV;

                        %Se escoge Lumme-Rahola
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                        % Se coge el valor del parámetro v
                        v=app.vEditField.Value;

                        % Expresión general (para v=0 se obtiene Maxwell Garnett)
                        a = v;
                        b = m^2+2-2*v -fv*(m^2-1)*(v+1);
                        c = v - (m^2+2) - fv*(m^2-1)*(2-v);
                        % Soluciones lineales en el caso de v=0 porque sale indeterminación
                        if v == 0
                            mef1 = ((-c/b)^0.5);
                            mef2 = -((-c/b)^0.5);
                            mef = [mef1,mef2];

                            % Soluciones a la bicuadrático
                        else
                            mef1 = ((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                            mef2 = -((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                            mef3 = ((-b-(b^2-4*a*c)^0.5)/(2*a))^0.5;
                            mef4 = -((-b-(b^2-4*a*c)^0.5)/(2*a))^0.5;
                            mef = [mef1,mef2,mef3,mef4];
                        end
                        %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                        m2LR = mef(real(mef) > 0 & imag(mef) < 0);
                        m2MG = m2LR;

                        %Se escoge Promedio Potencial
                    elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                        % Se coge el valor del parámetro "a" de la pontencial
                        a=app.aEditField.Value;
                        mef=(fv*(m^a)+(1-fv))^1/a;
                        m2MG = mef;

                    end

                    % Tras haber calculado el índice de refracción se sacan las propiedades
                    yMG=sqrt(m2MG)*x;
                    sumaMG=0; sumascaMG=0;
                    while n<=nmax
                        shix=(pi*x/2)^0.5*besselj(n+0.5,x);shix1=(pi*(x+incr)/2)^0.5*besselj(n+0.5,x+incr);
                        dshix=(shix1-shix)/incr;
                        shiyMG=(pi*yMG/2)^0.5*besselj(n+0.5,yMG);shiy1MG=(pi*(yMG+incr)/2)^0.5*besselj(n+0.5,yMG+incr);
                        dshiyMG=(shiy1MG-shiyMG)/incr;
                        psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);psix1=(pi*(x+incr)/2)^0.5*besselh(n+0.5,2,x+incr);
                        dpsix=(psix1-psix)/incr;
                        anMG=(dshiyMG*shix-sqrt(m2MG)*shiyMG*dshix)/(dshiyMG*psix-sqrt(m2MG)*shiyMG*dpsix);
                        bnMG=(sqrt(m2MG)*dshiyMG*shix-shiyMG*dshix)/(sqrt(m2MG)*dshiyMG*psix-shiyMG*dpsix);
                        sumaMG=sumaMG+(2*n+1)*real(anMG+bnMG);
                        sumascaMG=sumascaMG+(2*n+1)*((abs(anMG))^2+(abs(bnMG))^2);
                        n=n+1;
                    end
                    Qext=2/x^2*sumaMG;
                    Qsca=2/x^2*sumascaMG;

                end
            end

            Qabs=Qext-Qsca;
            Alb=Qsca/Qext;
            Coalb=1-Alb;
            CSabs = Qabs*Ap_prima; % m^2
            CSsca = Qsca*Ap_prima;
            CSext = Qext*Ap_prima;
            MAC = CSabs/mp; % m^2/g
            MSC = CSsca/mp;
            MEC = CSext/mp;

            % Mostrar los resultados en la tabla
            Datos =  [lambda Qabs Qsca Qext CSabs CSsca CSext MAC MSC MEC Alb Coalb];
            app.UITable_estudio.Data = Datos;
            % GUardar datos para poder extrapolarlos a excel
            save Datos_estudio_individual Datos
            % estudio individual se asigna estudio 0 y si es paramétrico 1,
            % para indicar al exportar en qué archivo guardarlo
            estudio = 0;
            save estudio estudio
        end

        % Selection changed function: TreeAglomerados
        function TreeAglomeradosSelectionChanged(app, event)
            %% MANTO DE PARTÍCULAS
            if app.TreeAglomerados.SelectedNodes.NodeData == 4
                % Si es hollín u otro material que no sea hielo no se he desarrollado
                load mat mat
                if mat == 1
                    app.TreeMethod.Enable = 'On';
                    app.TreeRDG_ME.Enable = 'On';
                else
                    % Mensaje de: NO DISPONIBLE
                    msgbox('No está disponible en esta versión. Se espera desarrollar en futuras versiones para posibilitar el cálculo del oscurecimiento de filtros cargados de hollín','No disponible','help')
                    app.TreeMethod.Enable = 'Off';
                    app.TreeRDG_ME.Enable = 'Off';
                end
            else
                app.TreeMethod.Enable = 'On';
                app.TreeRDG_ME.Enable = 'On';
            end
            % Deshabilitar la opción de RDG y ME en el caso de partículas esféricas
            if app.TreeAglomerados.SelectedNodes.NodeData == 2
                app.TreeRDG_ME.Enable = 'Off';
            else
                app.TreeRDG_ME.Enable = 'On';
            end
        end

        % Button pushed function: EjecucinparamtricaButton
        function EjecucinparamtricaButtonPushed(app, event)
            %Recoger los valores introducidos por el usuario
            lambda = app.LongituddeondanmEditField.Value;
            rp0 = app.RadiodelapartculaesfricanmEditField.Value;
            np0 = app.NdepartculasprimariasEditField.Value;
            Df = app.DimensinfractalEditField.Value;
            % Cargar datos del índice de refracción
            load indice_refraccion.mat landa n k f_eV f_Hz

            % Valores del rango del estudio escogido
            min = app.ValorminimoEditField.Value;
            max = app.ValormaximoEditField.Value;

            % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
            % Coger parámetros factor de asimetría.
            % Debye ninguno, Macke gref y gdif y Yang añade fd
            fd = app.fdEditField.Value;
            gref = app.grefEditField.Value;
            gdif = app.gdifEditField.Value;
            % Debye sólo válido en hollín con esféricas

            %%% DENSIDADES
            % en kg/m^3
            % CARGAR MATERIAL QUE SE TRATE:
            load mat.mat mat
            if mat == 2 % hollín
                ro = 1850;
            elseif mat == 6 % polvo
                %densidad como dato o como ponderación de las composiones
                if app.ndicederefraccinDropDown_DUST.Value == "Origen sahariano (Patterson (1977))" || app.ndicederefraccinDropDown_DUST.Value == "Saharan Origin (Patterson (1977))"
                    ro = 2500; % polvo atmosférico del sáhara
                elseif app.ndicederefraccinDropDown_DUST.Value == "Volz (1972)"
                    ro = 2500; % VAMOS A TOMAR EL DEL SÁHARA PARA VOLZ
                    %!!!!!!!!!!!   %%%%%%%% !!!!!!!!!!!
                elseif app.ndicederefraccinDropDown_DUST.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_DUST.Value == "Mineralogical Composition Weighting"
                    [minerales,fv,densidad] =readvars('fv_minerales.xlsx');
                    % Ponderación densidad cada mineral con su composición
                    ro = sum(fv.*densidad);
                end
            elseif mat == 9 % arena
                ro = 2500;
                % no es seguro que sea esta densidad
            elseif mat == 7 % ceniza
                ro = 2600; %Flanner et al. 2021

            elseif mat == 1 % hielo
                % Parámetros de la densidad y de profundidad manto
                Profundidadmin = app.Profundidad_min.Value;
                Profundidadmax = app.Profundidad_max.Value;
                Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;
                Profundidad=(Profundidadmin):1:(Profundidadmax);
                ro = zeros(size(Profundidad)); % se inicia la variable densidad ("ro")
                for j=1:size(Profundidad,2)
                    ro(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
                end

            end
            save ro ro % se guarda la densidad del material

            %%% EJE DE ABCISAS: SELECCIÓN DEL BARRIDO A REALIZAR
            %---------------------------------------------------------------------------------
            %---------------------------------------------------------------------------------
            %%%%% Barrido de la longitud de onda
            if app.TreeAbcisas.SelectedNodes.NodeData == 111
                landa = landa( landa >= min & landa <= max );
                n = n( landa >= min & landa <= max );
                k = k( landa >= min & landa <= max );
                m = n-k*1i;
                lambda = landa;
                assignin('base','lambda_nieve', lambda)

                % Si no es hielo se hace este estudio
                if mat ~= 1
                    %%%% AEROSOLES
                    %%% PARTÍCULAS ESFÉRICAS
                    if app.TreeAglomerados.SelectedNodes.NodeData == 2

                        % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                        mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000; % g
                        % Como Ap está en nm hay que pasarlo a m porque es un área
                        Ap0=(pi()*(rp0/(10^9))^2); % m^2

                        %% MÉTODO DE RAYLEIGH
                        for j = 1:length(lambda)
                            x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m(j).^2)-1)/((m(j).^2)+2)).^2);
                        end

                        % Ecuación de Rayleigh
                        if app.TreeMethod.SelectedNodes.NodeData == 8
                            for j=1:length(lambda)
                                Qabs(j)=-4*x(j)*(imag(((m(j).^2)-1)/((m(j).^2)+2)));
                                Qext(j)=Qabs(j)+Qsca(j);
                            end

                            % Ecuación de Bohren & Huffmann
                        elseif app.TreeMethod.SelectedNodes.NodeData == 9
                            for j=1:length(lambda)
                                Qabs(j)=-4.*x(j).*imag(((m(j).^2)-1)/((m(j).^2)+2).*(1+((((x(j).^2)./15)).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3)))+((8/3).*x(j).^4.*real(((m(j).^2)-1)./((m(j).^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m(j).^2)-1)./((m(j).^2)+2)).^2))));
                                Qext(j)=Qabs(j)+Qsca(j);
                            end

                            % Ecuación de Dobbins & Megaridis
                        elseif app.TreeMethod.SelectedNodes.NodeData == 10
                            for j=1:length(lambda)
                                Qabs(j)=-4.*x(j).*((imag(((m(j).^2)-1)./((m(j).^2)+2)))+imag((((x(j).^2)./15)).*((m(j).^2)-1)./((m(j).^2)+2).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3))));
                                Qext(j)=Qabs(j)+Qsca(j);
                            end

                            %% MÉTODO DE MIE
                        elseif app.TreeMethod.SelectedNodes.NodeData == 11
                            incr=0.000000001;
                            dp=2*rp0;
                            for j=1:length(lambda)
                                x=2*pi*rp0/lambda(j);
                                y=m(j)*x;
                                n=1;
                                nmax=round(2+x+4*x^(1/3));
                                suma=0;sumasca=0;
                                while n<=nmax
                                    shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                    dshix=(shix1-shix)./incr;
                                    shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                    dshiy=(shiy1-shiy)./incr;
                                    psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                    dpsix=(psix1-psix)./incr;
                                    an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                    bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                    suma=suma+(2.*n+1).*real(an+bn);
                                    sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                    n=n+1;
                                end
                                Qext(j)=(2/x^2)*suma;
                                Qsca(j)=(2/x^2)*sumasca;
                                Qabs(j) = Qext(j)-Qsca(j);
                                Alb(j)=Qsca(j)/Qext(j);
                                Coalb(j) = 1-Alb(j);
                            end
                            %-----------------------------------------------------------------------------------------------------------------------
                            % Cálculo del valor del factor de asimetría
                            refractive_index=m;
                            for j=1:length(lambda)
                                x=2*pi*rp0/lambda(j);
                                m=refractive_index(j);
                                nmax=round(2+x+4*x^(1/3));
                                y=m*x;
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                Alb(j)=Qsca(j)/Qext(j);
                                g(j)=4/(x^2*Qsca(j))*g_sum; % Ecuación Debye por términos de Mie
                                FAsymmetry=g;
                            end
                            %-----------------------------------------------------------------------------------------------------------------------
                        end

                        for j=1:length(lambda)
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j)=1-Alb(j);

                            CSabs(j)= Qabs(j)*Ap0; % m^2
                            CSsca(j)= Qsca(j)*Ap0;
                            CSext(j)= Qext(j)*Ap0;
                            MAC(j)= CSabs(j)/mp; % m^2/g
                            MSC(j)= CSsca(j)/mp;
                            MEC(j)= CSext(j)/mp;
                        end

                        % Si estudio hielo calcular factor de asimetría
                        if mat == 1
                            if app.FactordeasimetraDropDown.Value == "Debye"
                                FAsymmetry=g;
                            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                                for j=1:length(lambda)
                                    FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                end
                            elseif app.FactordeasimetraDropDown.Value == "Yang"
                                for j=1:length(lambda)
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                end
                            end
                        end

                        %%% AGLOMERADOS DE ESFERAS
                    elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                        Ap0=(pi()*rp0^2);
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap=Ap0*(np0^(1/z));
                        K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                        K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                        in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                        K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                        Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                        rg=rp0*((np0/Kf)^(1/Df));
                        K=(2*pi)./lambda;
                        x=(2*pi*rp0)./lambda;

                        % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                        Ap0_prima=(pi()*(rp0/(10^9))^2); % m^2
                        Ap_prima=Ap0_prima*(np0^(1/z)); % m^2
                        % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                        mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g

                        %% MÉTODO DE RAYLEIGH
                        % Ecuación de Rayleigh
                        if app.TreeMethod.SelectedNodes.NodeData == 8
                            for i = 1: length(lambda)
                                Qabsp0(i)=-4*x(i)*(imag(((m(i)^2)-1)/((m(i)^2)+2)));
                                Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                            end

                            %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                            %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                            if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                                for i = 1: length(lambda)
                                    GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                                end

                                %% Se escoge el de Köylü-Faeth
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                                for i = 1: length(lambda)
                                    beta=(3*Df)/(8*(K(i)*rg)^2);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    if beta>=1
                                        GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    elseif beta<1 && Df==2
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    else
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    end
                                end
                            end
                            for i = 1: length(lambda)
                                Qext(i)=Qabs(i)+Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap_prima; % m^2
                                CSsca(i) = Qsca(i)*Ap_prima;
                                CSext(i) = Qext(i)*Ap_prima;

                                MAC(i) = CSabs(i)/mp;
                                MSC(i) = CSsca(i)/mp;
                                MEC(i) = CSext(i)/mp;
                            end

                            % Ecuación de Bohren & Huffmann
                        elseif app.TreeMethod.SelectedNodes.NodeData == 9
                            for i = 1: length(lambda)
                                Qabsp0(i)=-4*x(i)*imag(((m(i)^2)-1)/((m(i)^2)+2)*(1+((((x(i)^2)/15))*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3)))+((8/3)*x(i).^4*real(((m(i)^2)-1)/((m(i)^2)+2))^2)-((8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2))));
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                            end

                            %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                            %% Se escoge el de Fisher-Burford
                            if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                                for i = 1: length(lambda)
                                    GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                                end

                                %% Se escoge el de Köylü-Faeth
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                                for i = 1: length(lambda)
                                    beta=(3*Df)/(8*(K(i)*rg)^2);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    if beta>=1
                                        GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    elseif beta<1 && Df==2
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    else
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    end
                                end
                            end
                            for i = 1: length(lambda)
                                Qext(i)=Qabs(i)+Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap_prima; % m^2
                                CSsca(i) = Qsca(i)*Ap_prima;
                                CSext(i) = Qext(i)*Ap_prima;

                                MAC(i) = CSabs(i)/mp;
                                MSC(i) = CSsca(i)/mp;
                                MEC(i) = CSext(i)/mp;
                            end

                            % Ecuación de Dobbins & Megaridis
                        elseif app.TreeMethod.SelectedNodes.NodeData == 10
                            for i = 1: length(lambda)
                                Qabsp0(i)=-4*x(i)*((imag(((m(i)^2)-1)/((m(i)^2)+2)))+imag((((x(i)^2)/15))*((m(i)^2)-1)/((m(i)^2)+2)*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3))));
                                Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                            end

                            %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                            %% Se escoge el de Fisher-Burford
                            if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                                for i = 1: length(lambda)
                                    GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                                end

                                %% Se escoge el de Köylü-Faeth
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                                for i = 1: length(lambda)
                                    beta=(3*Df)/(8*(K(i)*rg)^2);
                                    Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                    if beta>=1
                                        GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    elseif beta<1 && Df==2
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    else
                                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                        Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                    end
                                end
                            end
                            for i = 1: length(lambda)
                                Qext(i)=Qabs(i)+Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap_prima; % m^2
                                CSsca(i) = Qsca(i)*Ap_prima;
                                CSext(i) = Qext(i)*Ap_prima;

                                MAC(i) = CSabs(i)/mp;
                                MSC(i) = CSsca(i)/mp;
                                MEC(i) = CSext(i)/mp;
                            end

                            %----------------------------
                            %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                        elseif app.TreeMethod.SelectedNodes.NodeData == 11

                            %% Método de estimación del radio máximo
                            % Método geométrico
                            if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                                in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                                R1=rp0*np0;
                                R2=((2*in2)+1)*rp0;
                                R3=((2*in3)+1)*rp0;
                                R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                                fv=(np0*(rp0^3))/(exp(R)^3);

                                % Método aproximado
                            elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                fv = np0.^(1-(3/Df));
                                % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                                % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                                Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                                Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                                in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                                Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                                % Generar con esos puntos los valores de A,B,C
                                % polyfit(x,y,2) devuelve un vector con los coeficientes del
                                % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                                % Para representar la recta, se sacan los puntos con polyval
                                a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                                A=a(1); B=a(2); C=a(3);
                                Kf = A*(Df.^2)+B*Df+C;
                                rg = rp0*((np0/Kf).^(1/Df));
                                R = rg*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                                R = log(R);
                            end
                            % Parámetros comunes independientemente de la regla de mezcla
                            incr=0.000000001;
                            load indice_refraccion.mat landa n k f_eV f_Hz
                            real_part=n; imag_part=k;

                            for i=1:length(lambda)
                                x=(2*pi*exp(R))./lambda;
                                n=1;nmax=round(2+x+4*x.^(1/3));
                                if landa(i)==floor(lambda(i))% revisar que aproxime al más próximo
                                    m(i) = real_part(i)-imag_part(i)*1i;
                                end
                            end
                            % área proyectada
                            Ap0=(pi()*(rp0/(10^9))^2); %m^2
                            z1=log(np0)/(log((0.8488*np0)+0.1512));
                            z3=1/(0.6667+(0.2003/log(np0)));
                            z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                            Ap=Ap0*(np0^(1/z)); %m^2

                            % densidad hollín
                            ro = 1850; % en kg/m^3
                            % Como MAC suele darse en m^2/g se pasa masa a gramos
                            mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g


                            % Se escoge Bruggeman
                            if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                                for i=1:length(lambda)
                                    m2B(i)=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m(i)^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m(i)^2))^2+(2*m(i)^2)))/2)^0.5;
                                    yB=sqrt(m2B(i))*x;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end

                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end

                                % Se escoge Maxwell-Garnett
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                                for i=1:length(lambda)
                                    %                                     m2MG=((((m^2)*((2*fv)+1))+(2*(-fv+1)))/(((m^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                    m2MG(i)=((((m(i)^2)*((2*fv)+1))+(2*(-fv+1)))/(((m(i)^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                    m2B = m2MG;
                                    yMG=sqrt(m2MG(i))*x; yB=yMG;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end
                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end

                                % Se escoge Lorentz-Lorenz
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                                for i=1:length(lambda)
                                    m2LL(i)=((1+(2*fv*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv*(((m(i)^2)-1)/((m(i)^2)+2)))))^0.5;
                                    m2B = m2LL;
                                    yLL=sqrt(m2LL(i))*x; yB=yLL;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end
                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end


                                % Se escoge Lakatia-Vitkam
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                                for i=1:length(lambda)
                                    k_(i)=2*pi/lambda(i); % k_ para diferenciarlo de la parte imaginaria de m
                                    alfa(i)=(m(i)^2-1)/(1-(m(i)^2-1)*(2/3*(1-k_(i)*rp0*1i)*exp(k_(i)*rp0*1i)-1));
                                    m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                    m2B = m2LV;
                                    yLV=sqrt(m2LV(i))*x; yB=yLV;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end
                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end

                                % Se escoge Lumme-Rahola
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                                % Se coge el valor del parámetro v
                                v=app.vEditField.Value;
                                for i=1:length(lambda)
                                    % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                    a = v;
                                    b(i) = m(i)^2+2-2*v -fv*(m(i)^2-1)*(v+1);
                                    c(i) = v - (m(i)^2+2) - fv*(m(i)^2-1)*(2-v);
                                    % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                    if v == 0
                                        mef1(i) = ((-c(i)/b(i))^0.5);
                                        mef2(i) = -((-c(i)/b(i))^0.5);
                                        mef = [mef1;mef2];

                                        % Soluciones a la bicuadrático
                                    else
                                        mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef = [mef1;mef2;mef3;mef4];
                                    end
                                    %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                    m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                    m2B = m2LL;
                                    yLL=sqrt(m2B(i))*x; yB = yLL;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end
                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end

                                % Se escoge Promedio Potencial
                            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                                % Se coge el valor del parámetro "a" de la potencial
                                for i = 1:length(lambda)
                                    a=app.aEditField.Value;
                                    mef(i)=(fv*(m(i)^a)+(1-fv))^1/a;
                                    m2B = mef;
                                    yLL=sqrt(m2B(i))*x; yB = yLL;
                                    sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                    while n<=nmax
                                        shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                        dshix=(shix1-shix)/incr;
                                        shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                        dshiyB=(shiy1B-shiyB)/incr;
                                        psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                        dpsix=(psix1-psix)/incr;
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                        sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                        sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                        n=n+1;
                                    end
                                    Qext(i)=(2/x(i)^2)*sumaB;
                                    Qsca(i)=(2/x(i)^2)*sumascaB;
                                end
                            end
                            for i = 1:length(lambda)
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap; % m^2
                                CSsca(i) = Qsca(i)*Ap;
                                CSext(i) = Qext(i)*Ap;

                                MAC(i) = CSabs(i)/mp; % m^2/g
                                MSC(i) = CSsca(i)/mp;
                                MEC(i) = CSext(i)/mp;
                            end
                        end
                    end

                    %% MANTO DE PARTÍCULAS EN EL CASO DEL HIELO
                elseif mat == 1

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    ronieve = ro; % se ha calculado antes

                    % Valor del ángulo cenital y de la nubosidad
                    AngZenith = app.ngulocenitalEditField.Value;
                    Cloud = app.NubosidadEditField.Value;

                    % En función de si tiene humedad o no se emplea el
                    % índice de refracción del hielo sólo o ponderado con
                    % medio efectivo con el del agua, sacando el parámetro
                    % G que es el cociente de la relación de índices de
                    % refracción
                    Humedad = app.HumedadEditField.Value; % [%]

                    if Humedad ~= 0

                        % cambiar nombre índice de refracción del hielo
                        landa_hielo = landa;
                        n_hielo = n;
                        k_hielo = k;
                        m_hielo = n_hielo-k_hielo*1i;
                        e_hielo = m_hielo.^2;

                        % Cargar índice de refracción del agua:
                        load Agua.mat Agua
                        landa_agua = Agua(:,1); % Longitud de onda
                        n_agua = Agua(:,2); % Parte real índice de refración
                        k_agua = Agua(:,3); % Parte imaginaria índice de refración

                        % Como el índice de refracción del agua tiene un
                        % tamaño diferente al del hielo, se realiza una
                        % interpolación
                        n_agua  = interp1(landa_agua,n_agua,landa_hielo,'linear');
                        k_agua = interp1(landa_agua,k_agua,landa_hielo,'linear');

                        m_agua = n_agua-k_agua*1i;
                        e_agua = m_agua.^2;
                        assignin('base','m_agua',m_agua)

                        % la fracción volumétrica que tiene cada uno es la
                        % proporción de agua
                        Humedad = Humedad/100; %pasaralo a tanto por uno, estaba en %
                        fv_agua = Humedad;
                        fv_hielo = 1- Humedad;

                        % Se aplica regla de mezcla de Lorentz-Lorentz calculando el cociente G
                        G = fv_agua*((e_agua-1)./(e_agua+2))+fv_hielo*((e_hielo-1)./(e_hielo+2));
                        % Se desepeja de G la constante dieléctrica
                        e_ponderado = (2.*G+1)./(1-G);
                        % Se despeja el índice de refracción
                        m_ponderado = e_ponderado.^0.5;
                        m = m_ponderado;
                        assignin('base','e_ponderado_humedad',e_ponderado)
                        assignin('base','G_ponderado_humedad',G)
                        assignin('base','m_ponderado_humedad',m_ponderado)


                    else %Si humedad es 0, no tiene que considerarse el agua
                    end

                    % Se escoge hacer el estudio con MÉTODO DE MIE
                    if app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        % EN función del factor de asimetría se calcula de
                        % un método o otro, por eso Mie en cada uno. daba
                        % problemas ponerlo junto todo

                        % Cálculo del valor del factor de asimetría
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            refractive_index=m;
                            for j=1:length(lambda)
                                x=2*pi*rp0/lambda(j);
                                m=refractive_index(j);
                                nmax=round(2+x+4*x^(1/3));
                                y=m*x;
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                Alb(j)=Qsca(j)/Qext(j);
                                g(j)=4/(x^2*Qsca(j))*g_sum; % Ecuación Debye por términos de Mie
                                FAsymmetry=g;
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                                R=Cloud/100;
                                mu=cosd(AngZenith);
                                AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));

                                if AlbED(j)>1.0
                                    AlbED(j)=0.99999;
                                end
                                aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                                bED(j)=(FAsymmetryED(j)/aED(j));
                                ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                                P(j)=(2*ED(j))/(3*aED(j));
                                AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                                AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                                AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                                AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                                AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                                AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;
                                % Selección tipo de Albedo
                                if app.AlbedoDropDown.Value == "Directo"
                                    Alb = AlbDir;
                                elseif app.AlbedoDropDown.Value == "Difuso"
                                    Alb = AlbDif;
                                elseif app.AlbedoDropDown.Value == "Neto"
                                    Alb = AlbNet;
                                end
                                radio_nieve = rp0;

                                % Para mostrar tiempo de ejecución en el botón
                                cont=(j/length(lambda))*100;
                                cont= [num2str(round(cont)) ' %'];
                                app.EjecucinparamtricaButton.Text = cont;
                                drawnow
                            end

                        else % Macke o Yang
                            refractive_index=m;
                            for j=1:length(lambda)
                                %                                 %                                   x=2*pi*rp0/lambda(j);
                                %                                 %                                 y=m(j)*x;
                                %                                 %                                 n=1;
                                %                                 %                                 nmax=round(2+x+4*x^(1/3));
                                %                                 x=2*pi*rp0/lambda(j);
                                %                                 y=m(j).*x;
                                %                                 n=1;
                                %                                 nmax=round(2+x+4.*x.^(1/3));
                                %                                 suma=0;sumasca=0;
                                %                                 while n<=nmax
                                %                                     shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                %                                     dshix=(shix1-shix)./incr;
                                %                                     shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                %                                     dshiy=(shiy1-shiy)./incr;
                                %                                     psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                %                                     dpsix=(psix1-psix)./incr;
                                %                                     an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                %                                     bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                %                                     suma=suma+(2.*n+1).*real(an+bn);
                                %                                     sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                %                                     n=n+1;
                                %                                 end
                                %                                 Qext(j)=(2/x^2)*suma;
                                %                                 Qsca(j)=(2/x^2)*sumasca;
                                %                                 Qabs(j) = Qext(j)-Qsca(j);
                                %                                 Alb(j)=Qsca(j)/Qext(j);
                                %                                 Coalb(j) = 1-Alb(j);

                                x=2*pi*rp0/lambda(j);
                                m=refractive_index(j);
                                nmax=round(2+x+4*x^(1/3));
                                y=m*x;
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                Alb(j)=Qsca(j)/Qext(j);
                                Coalb(j) = 1-Alb(j);


                                if app.FactordeasimetraDropDown.Value == "Macke et al."
                                    FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                    FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                                elseif app.FactordeasimetraDropDown.Value == "Yang"
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    % Para fd distinto de 0 no se aplica eddington posterior
                                    if fd==0
                                        FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));
                                    else
                                        FAsymmetryED(j)=FAsymmetry(j);
                                    end
                                end

                                R=Cloud/100;
                                mu=cosd(AngZenith);
                                AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));
                                if AlbED(j)>1.0
                                    AlbED(j)=0.99999;
                                end
                                aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                                bED(j)=(FAsymmetryED(j)/aED(j));
                                ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                                P(j)=(2*ED(j))/(3*aED(j));
                                AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                                AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                                AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                                AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                                AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                                AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;

                                % Para mostrar tiempo de ejecución en el botón
                                cont=(j/length(lambda))*100;
                                cont= [num2str(round(cont)) ' %'];
                                app.EjecucinparamtricaButton.Text = cont;
                                drawnow
                            end

                            % Selección tipo de Albedo
                            if app.AlbedoDropDown.Value == "Directo"
                                Alb = AlbDir;
                            elseif app.AlbedoDropDown.Value == "Difuso"
                                Alb = AlbDif;
                            elseif app.AlbedoDropDown.Value == "Neto"
                                Alb = AlbNet;
                            end
                            radio_nieve = rp0;

                        end %end Debye o los otros dos
                    end

                    %% EFECTO DE LA RUGOSIDAD
                    %----------------------------------------------
                    % Modificación del albedo debido a la rugosidad
                    % en el caso de que se haya hecho el estudio
                    load rug rug
                    % Si no se ha hecho el estudio no se modifica el albedo
                    if rug == 0
                        Alb = Alb;

                        % Si se ha hecho el estudio
                    elseif rug == 1
                        % Se carga el ángulo calculado con el perfil:beta(rad)
                        load beta beta

                        % Se pasa el ángulo cenital en grados a radianes
                        % Como el ángulo cenital que la fórmula de ns
                        % considera es desde la horizontal, se debe meter
                        % el complementario de AngZenith
                        theta = (90-AngZenith)*pi/180; % rad

                        % Se calcula ns: nº de interacciones medio
                        ns = 1 + 0.355332*(cos(theta)^4)*beta - 1.08275*(1-exp(1.75*(cos(theta)^0.25)*beta.^4)); % Fórmula 7 Manninen 2021 (los exponentes van con el coseno y el beta fuera)

                        assignin('base','Alb_sin_rugosidad',Alb)
                        assignin('base','ns',ns)

                        % Se corrige el albedo obtenido
                        Alb = Alb.^ns;
                        assignin('base','Alb_rugosidad',Alb)
                    end
                    %----------------------------------------------

                    %----------------------------------------------
                    % Estudio profundidad finita y semi-infinita
                    % Tomar la profundidad a la que se desea realizar el cálculo del manto
                    h = app.cmEditField.Value;
                    save h
                    radio = app.RadiodelapartculaesfricanmEditField.Value; % nm

                    % Indicar si es profundidad semi-infinita o finita, por lo que el albedo de
                    % la superficie de abajo afecta. Profundidad a partir de la cuál es semi infinito,
                    % profundidad límite  tiene que ser una expresión que depende del tamaño del grano
                    % y de la longitud de onda
                    % Existen 2 PROPUESTAS: modelo propio y Wiscombe & Warren

                    %                   % Wiscombe & Warren : potencial en base a los tres datos
                    %                     hmax = 0.2631*(radio^0.6316);

                    % MODELO PROPIO
                    % Dentro del modelo propio las expresiones de las
                    % correlaciones dependen del índice de refracción
                    % escogido del hielo (Warren & Brandt 2008 o el de
                    % Picard et al. 2016)

                    % Los parámetos de las correlaciones para cada índice
                    % de refracción
                    if app.ndicederefraccinDropDown_ICE.Value == "Warren & Brandt (2008)"
                        b1 = 1.9e-4;
                        b2 = 1.951;
                        h1 = 0;
                        h2 = 0.564;
                        p1 = 7.1e-04;
                        p2 = 0.069;

                    elseif app.ndicederefraccinDropDown_ICE.Value == "Picard et al. (2016)"
                        b1 = 1.9e-4;
                        b2 = 2;
                        h1 = 0;
                        h2 = 0.588;
                        p1 = 6.2e-04;
                        p2 = 0.075;

                    end

                    % Correlaciones de los parámetros de la función similar
                    % a la de densidad de probabilidad de Weibull
                    gs = radio/1000; % gs es gran siez en micras

                    beta = b1*gs + b2;
                    mu = h1*gs + h2;
                    phi = p1*gs + p2;
                    
                    landa_h = landa/1000; % lambda en micras
                    
                    hmax = phi*(beta/mu).*((landa_h/mu).^(beta-1)).*exp((landa_h/mu).^beta);


                    % Selección toogle button semi-infinita o finita
                    selectedButton = app.ProfundidaddelmantoButtonGroup.SelectedObject;
                    % Condición del valor más grande de profundidad
                    % calculado para el primer valor de la longitud de onda
                    % del vector para indicar si hacer cálculo semi-inf o finito
                    load hmax_condicion hmax_condicion

                    % Si es profundidad finita
%                     if selectedButton.Value == app.Button_cm.Value && h < hmax_condicion
                    if selectedButton.Value == app.Button_cm.Value 
                        % INTRODUCIR ALBEDO SUPERFICIE DE ABAJO SI ES FINITO
                        if app.AlbedosuperficieinferiorDropDown.Value ==  "Albedo asfalto"
                            % Se cargan los datos del nuevo albedo que están en el excel NuevoEspectro
                            [landa,alb_suelo] =readvars('AlbedoAsfalto.xlsx');

                        elseif app.AlbedosuperficieinferiorDropDown.Value == "Nuevo albedo"
                            % Se cargan los datos del nuevo albedo que están en el excel NuevoEspectro
                            [landa,alb_suelo] =readvars('NuevoAlbedo.xlsx');

                        end
                        % Interpolar los datos para que el valor del albedo
                        % del suelo coresponda con la misma longitud de
                        % onda que el vector del manto nevado
                        alb_suelo = interp1(landa,alb_suelo,lambda,'linear');

                        % Expresión que cuantifica ese albedo del suelo
                        %       alb_suelo        Albedo de superficie de abajo
                        %           Alb             Albedo de la nieve
                        % EXPRESIÓN LINEAL (pero no se usa esta)
                        % Albedo_sup_conjunta(i) = alb_suelo(i)*((hmax-h)/hmax)+ Alb(i)*(1-((hmax-h)/hmax));
                        % LEY DE WIEBE
                        % Primero se calcula el factor de forma donde los parámetos de las correlaciones para cada índice
                        % de refracción
                        if app.ndicederefraccinDropDown_ICE.Value == "Warren & Brandt (2008)"
                            a1 = 6.2e-05;
                            a2 = 0.209;
                            c1 = 2.2e-05;
                            c2 = 0.558;
                            m_av = 0.968;

                        elseif app.ndicederefraccinDropDown_ICE.Value == "Picard et al. (2016)"
                            a1 = 5.2e-05;
                            a2 = 0.199;
                            c1 = 3.1e-05;
                            c2 = 0.567;
                            m_av = 0.968;
                        end

                        A = a1*gs + a2; % radio en micras
                        C = c1*gs + c2;
                        for i = 1:length(lambda)
                            if lambda(i) <= 1200
                                m_ff(i) = A*(lambda(i)/1000)^2+C; % m factor de forma, no índice refracción; lambda en micras
                            else
                                m_ff(i) = m_av;
                            end
                        end

                        % Expresión de Wiebe
%                         Albedo_trasparente = alb_suelo+(Alb'-alb_suelo).*(1-exp(-6.9078.*(h./hmax).^m_ff));
                         Albedo_trasparente = alb_suelo+(Alb'-alb_suelo).*(1-exp(-6.9078.*(h./hmax).^m_ff'));

                        % Se cambia para el balance el albedo de la nieve al albedo trasparente
                        Albedo_nieve = Alb;
                        Alb =  Albedo_trasparente';

                        Profundidad = h;

                        save Albedo_finito lambda Albedo_trasparente Albedo_nieve alb_suelo

                        assignin('base','Profundidad',Profundidad)
                        assignin('base','hmax',hmax)
                        assignin('base','radio_profundidad',radio)
                        assignin('base','m_ff',m_ff)
                        assignin('base','Albedo_trasparente',Albedo_trasparente)
                        assignin('base','alb_suelo',alb_suelo)
                        assignin('base','Albedo_nieve',Albedo_nieve)
                        assignin('base','lambda',lambda)

                        % Si es profundidad semi-infinita
%                         else selectedButton.Value == app.SemiinfinitaButton.Value && h >= hmax_condicion;
                    else selectedButton.Value == app.SemiinfinitaButton.Value
                        % cálculo ya introducido anteriormente en programa
                        Profundidad = hmax_condicion;
                        save Albedo_semi-infinito lambda Alb
                    end

                    % CÁLCULO ALBEDO MEDIO
                    load EspectroSolar RadiacionIncidente
                    % Bucle para los valores de longitud de onda del albedo contaminado
                    for i = 1: length(lambda)
                        % Bucle para la longitud de onda de la irradiancia (espectro solar)
                        for j =1:size(RadiacionIncidente(:,1))
                            % Condición de que sean iguales las landas para generar el bucle de
                            % irradiancia con las lambdas del Albedo
                            if RadiacionIncidente(j,1) == lambda(i)
                                irrad(i) = RadiacionIncidente(j,2);
                            end
                        end
                    end
                    assignin('base','irrad',irrad)
                    % Cálculo del albedo medio
                    % Mostrar albedo medio en la tabla ( si es estudio
                    % profundidad finita se debe indicar el albedo medio
                    % de la nieve, del suelo y de la nieve "trasparente" y
                    % si es semi-infinita sólo el del manto nevado)

                    % Si es profundidad finita
%                     if selectedButton.Value == app.Button_cm.Value && h < hmax_condicion
                    if selectedButton.Value == app.Button_cm.Value 
                        albedo_medio_nieve = (sum((lambda(2)-lambda(1)).*Albedo_nieve.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                        albedo_medio_suelo = (sum((lambda(2)-lambda(1)).*alb_suelo'.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                        albedo_medio_trasparente = (sum((lambda(2)-lambda(1)).*Albedo_trasparente'.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                        app.UITable_alb_medio.ColumnName = ["Albedo medio nieve", "Albedo medio suelo", "Albedo medio nieve trasparente"];
                        app.UITable_alb_medio.Data = [albedo_medio_nieve albedo_medio_suelo albedo_medio_trasparente];
                        radiancia = Albedo_trasparente.*irrad;
                        assignin('base','albedo_medio_nieve',albedo_medio_nieve)
                        assignin('base','albedo_medio_suelo',albedo_medio_suelo)
                        assignin('base','albedo_medio_trasparente',albedo_medio_trasparente)

                        % Si es profundidad semi-infinita
%                          else selectedButton.Value == app.SemiinfinitaButton.Value && h >= hmax_condicion
                    else selectedButton.Value == app.SemiinfinitaButton.Value
                        albedo_medio = (sum((lambda(2)-lambda(1)).*Alb.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                        app.UITable_alb_medio.ColumnName = ["Albedo medio"];
                        app.UITable_alb_medio.Data = [albedo_medio];
                        radiancia = Alb.*irrad;
                        assignin('base','albedo_medio',albedo_medio)
                        assignin('base','radiancia',radiancia)
                    end
                    save Radiancias lambda radiancia irrad
                    %----------------------------------------------
                    save Albedo_nieve Alb lambda radio_nieve FAsymmetry FAsymmetryED Profundidad

                end

                % Mostrar los resultados en la tabla
                if mat == 1
                    Coalb = 1-Alb;
                    % en el caso de ser manto nevado
                    if app.TreeAglomerados.SelectedNodes.NodeData == 4
                        CoalbDir = 1-AlbDir;
                        CoalbDif = 1-AlbDif;
                        CoalbNet = 1-AlbNet;

                        % para mostrar en tabla se indican todos los albedos
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            Datos =  [lambda Qabs' Qsca' Qext' AlbDir' CoalbDir' AlbDif' CoalbDif' AlbNet' CoalbNet'  FAsymmetryED'];
                        else
                            Datos =  [lambda Qabs' Qsca' Qext' AlbDir' CoalbDir' AlbDif' CoalbDif' AlbNet' CoalbNet' FAsymmetry'];
                        end
                        app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Albedo directo","Coalbedo directo", "Albedo difuso","Coalbedo difuso", "Albedo neto","Coalbedo neto","Factor de asimetría"];
                        app.UITable_estudio.ColumnFormat = {'short','shortE','short','short','short','shortE','short','shortE','short','shortE','short'};
                        % shortEng es para varios decimales a diferencia de
                        % shortE que coge sólo la priemra cifra
                        app.UITable_estudio.Data = Datos;
                        save Datos_estudio_nieve Datos

                        % En caso de manto contaminado para guardar el albedo escogido se coge sólo el
                        % directo, difuso o neto, en su caso
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetryED'];
                        else
                            Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetry'];
                        end

                        % en el caso de aerosoles de hielo
                    else
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetryED'];
                        else
                            Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetry'];
                        end
                        app.UITable_estudio.Data = Datos;
                        save Datos_estudio_paramétrico Datos
                    end

                    % Para otros materiales que no sean hielo
                else
                    assignin('base','lambda',lambda)
                    assignin('base','Qabs',Qabs); assignin('base','Qsca',Qsca); assignin('base','Qext',Qext);
                    assignin('base','CSabs',CSabs); assignin('base','CSsca',CSsca); assignin('base','CSext',CSext);
                    assignin('base','MAC',MAC); assignin('base','MSC',MSC); assignin('base','MEC',MEC);
                    assignin('base','Alb',Alb); assignin('base','Coalb',Coalb)

                    Datos =  [lambda Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                    %                     Datos =  [lambda' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                    app.UITable_estudio.ColumnFormat = {'short','shortE','shortE','shortE','shortE','shortE','shortE','short','short','short','short','shortE'};
                    app.UITable_estudio.Data = Datos;
                end

                Datos2=Datos;
                load cont cont
                % DATOS DE LA PRIMERA EJECUCIÓN
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;
                rad = app.RadianciaCheckBox.Value;
                irra = app.IrradianciaCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qabs,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qsca,lambda,Qext); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, Qabs,'r',lambda,Qsca,'b',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSabs,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSabs,'r',lambda,CSsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,CSabs,'r',lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,CSsca,lambda,CSext); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, CSabs,'r',lambda,CSsca,'b',lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MAC,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MSC,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MAC,'r',lambda,MSC,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,MAC,'r',lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,MSC,lambda,MEC); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, MAC,'r',lambda,MSC,'b',lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Si es profundidad semi infinita o finita se plotea
                    % sólo albedo nieve o albedo nieve, nieve trasaprente y del suelo

                    % MANTO NEVADO
                    if app.TreeAglomerados.SelectedNodes.NodeData == 4

                        % Si es profundidad finita
                        if selectedButton.Value == app.Button_cm.Value && h < hmax_condicion
                            % Albedo_nieve,Albedo_trasparente,alb_suelo
                            % Albedo
                            if alb == 1 && coalb == 0
                                plot(app.UIAxes_arriba,lambda,Albedo_nieve,'b',lambda,Albedo_trasparente,'--b',lambda,alb_suelo,'k');
                                xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                                if mat == 1
                                    if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                        ylabel(app.UIAxes_arriba,'Albedo');
                                        legend(app.UIAxes_arriba,{'Albedo nieve','Albedo trasparente','Albedo suelo'},'Location','best')
                                    else % aerosoles de hielo
                                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                                    end
                                else
                                    ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                    legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                                end
                                % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                                % Coalbedo
                            elseif alb == 0 && coalb == 1
                                plot(app.UIAxes_arriba,lambda,1-Albedo_nieve,'b',lambda,1-Albedo_trasparente,'--b',lambda,1-alb_suelo,'k');
                                xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                                if mat == 1
                                    if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                        ylabel(app.UIAxes_arriba,'Coalbedo');
                                    else % aerosoles de hielo
                                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                        legend(app.UIAxes_arriba,{'Coalbedo nieve','Coalbedo trasparente','Coalbedo suelo'},'Location','best')
                                    end
                                else
                                    ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                    legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                                end
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                                % Albedo y coalbedo
                            elseif alb == 1 && coalb == 1
                                plot(app.UIAxes_arriba,lambda,Albedo_trasparente,'k',lambda,1-Albedo_trasparente,'--k'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
                                legend(app.UIAxes_arriba,{'Albedo trasparente', 'Coalbedo trasparente'},'Location','best')
                                % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)
                            end

                            % semi infinita
                        elseif selectedButton.Value == app.SemiinfinitaButton.Value || h >= hmax_condicion
                            % Albedo
                            if alb == 1 && coalb == 0
                                plot(app.UIAxes_arriba,lambda,Alb,'k');
                                xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                                if mat == 1
                                    if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                        ylabel(app.UIAxes_arriba,'Albedo del manto nevado');
                                    else % aerosoles de hielo
                                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                    end
                                else
                                    ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                end
                                legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                                % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                                % Coalbedo
                            elseif alb == 0 && coalb == 1
                                plot(app.UIAxes_arriba,lambda,Coalb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                                if mat == 1
                                    if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                        ylabel(app.UIAxes_arriba,'Coalbedo del manto nevado');
                                    else % aerosoles de hielo
                                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                    end
                                else
                                    ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                end
                                legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                                % Albedo y coalbedo
                            elseif alb == 1 && coalb == 1
                                plot(app.UIAxes_arriba,lambda,Alb,'k',lambda, Coalb,'--k');
                                xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                                legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                                % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                                xticks(app.UIAxes_arriba,min:((max-min)/5):max)
                            end
                        end
                        % no manto partículas
                    else
                        % Albedo
                        if alb == 1 && coalb == 0
                            plot(app.UIAxes_arriba,lambda,Alb,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                            if mat == 1
                                if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                    ylabel(app.UIAxes_arriba,'Albedo');
                                    legend(app.UIAxes_arriba,{'Albedo nieve'},'Location','best')
                                else % aerosoles de hielo
                                    ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                    legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                                end
                            else
                                ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                                legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                            end
                            % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                            xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                            % Coalbedo
                        elseif alb == 0 && coalb == 1
                            plot(app.UIAxes_arriba,lambda,1-Alb,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                            if mat == 1
                                if app.TreeAglomerados.SelectedNodes.NodeData == 4
                                    ylabel(app.UIAxes_arriba,'Coalbedo');
                                else % aerosoles de hielo
                                    ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                    legend(app.UIAxes_arriba,{'Coalbedo nieve'},'Location','best')
                                end
                            else
                                ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                                legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                            end
                            xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                            % Albedo y coalbedo
                        elseif alb == 1 && coalb == 1
                            plot(app.UIAxes_arriba,lambda,Alb,'k',lambda,1-Alb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                            legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                            % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                            xticks(app.UIAxes_arriba,min:((max-min)/5):max)
                        end
                    end

                    %                 % RADIANCIA / IRRADIANCIA
                    %                 elseif app.TreeOrdenadas.SelectedNodes.NodeData == 206
                    %                     if rad == 1 && irra == 0
                    %                         plot(app.UIAxes_arriba,lambda,radiancia,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    %                         legend(app.UIAxes_arriba,{'Radiancia'},'Location','best')
                    %                         ylabel(app.UIAxes_arriba,'Radiancia');
                    %
                    %                     elseif rad == 0 && irra == 1
                    %                         plot(app.UIAxes_arriba,lambda,irrad,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    %                         legend(app.UIAxes_arriba,{'Irradiancia'},'Location','best')
                    %                         ylabel(app.UIAxes_arriba,'Irradiancia');
                    %
                    %                     elseif rad == 1 && irra == 1
                    %                         plot(app.UIAxes_arriba,lambda,radiancia,'m',lambda,irrad,'--m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    %                         legend(app.UIAxes_arriba,{'Radiancia','Irradiancia'},'Location','best')
                    %
                    %                     end
                    %
                    %                     xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,lambda,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                    xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                end
                yticks(app.UIAxes_arriba,'auto')

                %--------------------------------------------------------------------------------------------------
                %--------------------------------------------------------------------------------------------------
                %%%% Barrido del tamaño de partícula
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 112
                rp0 = min:max;

                % Cargar datos del índice de refracción
                load indice_refraccion.mat landa n k f_eV f_Hz
                % Interpolación si el valor introducido no es exacto al disponible en la base de datos del índice de refracción
                if lambda ~= landa
                    % Parte real
                    vector_n_menor = n(landa<lambda); cont_n = vector_n_menor(end);
                    vector_n_mayor = n(landa>lambda); cont1_n = vector_n_mayor(1);
                    landa_menor = landa(landa<lambda); cont_landa = landa_menor(end);
                    landa_mayor = landa(landa>lambda); cont1_landa = landa_mayor(1);

                    parte_real = ((cont1_n-cont_n)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_n;

                    % Parte imaginaria
                    vector_k_menor = k(landa<lambda); cont_k = vector_k_menor(end);
                    vector_k_mayor = k(landa>lambda); cont1_k = vector_k_mayor(1);

                    parte_imag = ((cont1_k-cont_k)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_k;
                    m = parte_real- parte_imag*1i;
                else
                    landa = landa (landa == lambda);

                    n = n(landa == lambda);
                    k = k(landa == lambda);
                    m = n - k*1i;
                end
                % densidad hollín
                ro = 1850; % en kg/m^3

                %%%% AEROSOLES
                %%% PARTÍCULAS ESFÉRICAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 2
                    for j=1:length(rp0)
                        x(j)=(2*pi*rp0(j))/lambda; % Diámetro adimensional
                        % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                        mp(j) = (4/3)*pi*((rp0(j)/(10^9)).^3)*ro*1000; % g
                        % Como Ap está en nm hay que pasarlo a m porque es un área
                        Ap0(j)=(pi()*(rp0(j)/(10^9))^2); % m^2
                        dp0(j)=2*rp0(j); %Diámetro de la partícula primaria (nm)
                    end

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        % Eficiencias y albedo de dispersión
                        for j=1:length(rp0)
                            Qabs(j)=-4*x(j)*(imag(((m^2)-1)/((m^2)+2)));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m.^2)-1)/((m.^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for j=1:length(rp0)
                            Qabs(j)=-4.*x(j).*imag(((m.^2)-1)/((m.^2)+2).*(1+((((x(j).^2)./15)).*(((m.^4)+27.*(m.^2)+38)./(2.*(m.^2)+3)))+((8/3).*x(j)^4.*real(((m.^2)-1)./((m.^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m.^2)-1)./((m.^2)+2)).^2))));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m.^2)-1)/((m.^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                            % Ecuación simplificada de BH
                            QabsBHs(j)=-4*x(j)*(imag(((m.^2)-1)/((m.^2)+2)))*(1+((4*(x(j)^3))/3)*imag(((m.^2)-1)/((m.^2)+2)));
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for j=1:length(rp0)
                            Qabs(j)=-4.*x(j).*((imag(((m.^2)-1)./((m.^2)+2)))+imag((((x(j).^2)./15)).*((m.^2)-1)./((m.^2)+2).*(((m.^4)+27.*(m.^2)+38)./(2.*(m.^2)+3))));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m.^2)-1)/((m.^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        %% MÉTODO DE MIE
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        for j=1:length(rp0)
                            x=2*pi*rp0(j)/lambda;y=m*x;
                            %                             n=1;
                            nmax=round(2+x+4*x^(1/3));
                            Eext_sum=0;g_sum=0;Esca_sum=0;
                            for n=1:nmax
                                shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                if (abs(an(n,1))<1e-8)
                                    break
                                end
                                if (abs(bn(n,1))<1e-8)
                                    break
                                end
                            end
                            nmat(j,1)=n;
                            for t=1:(n-1)
                                g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                            end

                            Qext(j)=2/x^2*Eext_sum;
                            Qsca(j)=2/x^2*Esca_sum;
                            Qabs(j)=Qext(j)-Qsca(j);
                            FAsymmetry(j,1)=4/(x^2*Qsca(j))*g_sum;
                            g=FAsymmetry;
                        end

                    end
                    for j=1:length(rp0)
                        Alb(j)=Qsca(j)/Qext(j);
                        Coalb(j)=1-Alb(j);
                        CSabs(j)= Qabs(j)*Ap0(j); % m^2
                        CSsca(j)= Qsca(j)*Ap0(j);
                        CSext(j)= Qext(j)*Ap0(j);
                        MAC(j)= CSabs(j)/mp(j); % m^2/g
                        MSC(j)= CSsca(j)/mp(j);
                        MEC(j)= CSext(j)/mp(j);
                    end

                    % Si estudio hielo calcular factor de asimetría
                    if mat == 1
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            FAsymmetry=g;
                        elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                            for j=1:length(lambda)
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                            end
                        elseif app.FactordeasimetraDropDown.Value == "Yang"
                            for j=1:length(lambda)
                                FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                            end
                        end
                    end

                    %%% AGLOMERADOS DE ESFERAS
                elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                    for i=1:length(rp0)
                        Ap0(i)=(pi()*rp0(i)^2);
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap(i)=Ap0(i)*(np0^(1/z));
                        K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                        K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                        in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                        K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                        Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                        rg(i)=rp0(i)*((np0/Kf)^(1/Df));
                        K=(2*pi)/lambda;
                        x(i)=(2*pi*rp0(i))/lambda;
                        dp0(i)=2*rp0(i); %Diámetro de la partícula primaria (nm)
                        % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                        Ap0_prima(i)=(pi()*(rp0(i)/(10^9))^2); % m^2
                        Ap_prima(i)=Ap0_prima(i)*(np0^(1/z)); % m^2
                        % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                        mp(i) = (4/3)*pi*((rp0(i)/(10^9)).^3)*ro*1000*np0; % g
                    end

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*(imag(((m.^2)-1)/((m.^2)+2)));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*imag(((m.^2)-1)/((m.^2)+2)*(1+((((x(i)^2)/15))*(((m.^4)+27*(m.^2)+38)/(2*(m.^2)+3)))+((8/3)*x(i).^4*real(((m.^2)-1)/((m.^2)+2))^2)-((8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2))));
                            Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*((imag(((m.^2)-1)/((m.^2)+2)))+imag((((x(i)^2)/15))*((m.^2)-1)/((m.^2)+2)*(((m.^4)+27*(m.^2)+38)/(2*(m.^2)+3))));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            for i = 1: length(rp0)
                                R1(i)=rp0(i)*np0;
                                R2(i)=((2*in2)+1)*rp0(i);
                                R3(i)=((2*in3)+1)*rp0(i);
                                R(i)=(((log(R1(i))-(2*log(R2(i)))+log(R3(i)))/2)*Df^2)+((((-5*log(R1(i)))+(8*log(R2(i)))-(3*log(R3(i))))/2)*Df)+((3*log(R1(i)))-(3*log(R2(i)))+log(R3(i)));
                                fv(i)=(np0*(rp0(i)^3))/(exp(R(i))^3);
                                dp0(i) = 2*rp0(i);
                            end

                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            fv = np0.^(1-(3/Df));
                            % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                            % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                            Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                            Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                            % Generar con esos puntos los valores de A,B,C
                            % polyfit(x,y,2) devuelve un vector con los coeficientes del
                            % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                            % Para representar la recta, se sacan los puntos con polyval
                            a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                            A=a(1); B=a(2); C=a(3);
                            Kf = A*(Df.^2)+B*Df+C;
                            for i = 1:length(dp0)
                                rg(i) = rp0(i)*((np0/Kf).^(1/Df));
                                R(i) = rg(i)*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                                R(i) = log(R(i));
                            end
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        n=1;
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        % densidad hollín
                        ro = 1850; % en kg/m^3

                        for i=1:length(rp0)
                            x=(2*pi*exp(R))./lambda;
                            nmax=round(2+x+4*x.^(1/3));
                            % área proyectada
                            Ap0(i)=(pi()*(rp0(i)/(10^9))^2); %m^2
                            Ap(i)=Ap0(i)*(np0^(1/z)); %m^2
                            % Como MAC suele darse en m^2/g se pasa masa a gramos
                            mp(i) = (4/3)*pi*((rp0(i)/(10^9)).^3)*ro*1000*np0; % g
                        end

                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2B(i)=((((-1.5*fv(i))+1)+(((1.5*fv(i))-0.5)*m^2)+sqrt((((1.5*fv(i))-1)-(((1.5*fv(i))-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yB=sqrt(m2B(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2B=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yB=sqrt(m2B).*x;
                                end

                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i = 1: length(rp0)
                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2B(i)=((((m^2)*((2*fv(i))+1))+(2*(-fv(i)+1)))/(((m^2)*((-1*fv(i))+1))+((fv(i)+2))))^0.5;
                                    yMG=sqrt(m2B(i))*x;
                                    assignin('base','yMG',yMG)

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2B=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yMG=sqrt(m2B).*x;
                                    assignin('base','yMG_aprox',yMG)
                                end

                                yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2LL(i)=((1+(2*fv(i)*(((m^2)-1)/((m^2)+2))))/(1-(fv(i)*(((m^2)-1)/((m^2)+2)))))^0.5;
                                    yLL=sqrt(m2LL(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2LL=((1+(2*fv*(((m^2)-1)/((m^2)+2))))/(1-(fv*(((m^2)-1)/((m^2)+2)))))^0.5;
                                    yLL=sqrt(m2LL).*x;
                                end

                                m2B = m2LL; yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end


                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(rp0)
                                k_=2*pi/lambda; % k_ para diferenciarlo de la parte imaginaria de m
                                alfa(i)=(m^2-1)/(1-(m^2-1)*(2/3*(1-k_*rp0(i)*1i)*exp(k_*rp0(i)*1i)-1));

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2LV(i)=(1+(2*alfa(i)*fv(i))/3)^0.5/(1-(alfa(i)*fv(i))/3)^0.5;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                end

                                m2B = m2LV;
                                yLV=sqrt(m2LV(i))*x; yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;

                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                    a = v;
                                    b(i) = m^2+2-2*v -fv(i)*(m^2-1)*(v+1);
                                    c(i) = v - (m^2+2) - fv(i)*(m^2-1)*(2-v);
                                    % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                    if v == 0
                                        mef1(i) = ((-c(i)/b(i))^0.5);
                                        mef2(i) = -((-c(i)/b(i))^0.5);
                                        mef = [mef1;mef2];

                                        % Soluciones a la bicuadrático
                                    else
                                        mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef = [mef1;mef2;mef3;mef4];
                                    end
                                    %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                    m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                    m2B = m2LL; yLL=sqrt(m2B(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                    a = v;
                                    b = m^2+2-2*v -fv*(m^2-1)*(v+1);
                                    c = v - (m^2+2) - fv*(m^2-1)*(2-v);
                                    % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                    if v == 0
                                        mef1 = ((-c/b)^0.5);
                                        mef2 = -((-c/b)^0.5);
                                        mef = [mef1;mef2];

                                        % Soluciones a la bicuadrático
                                    else
                                        mef1(i) = ((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef2(i) = -((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef3(i) = ((-b-(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef4(i) = -((-b-(b^2-4*a*CSext)^0.5)/(2*a))^0.5;
                                        mef = [mef1;mef2;mef3;mef4];
                                    end
                                    %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                    m2LL = mef(real(mef) > 0 & imag(mef) < 0);
                                    m2B = m2LL; yLL=sqrt(m2B)*x;
                                end

                                yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            for i = 1:length(rp0)
                                a=app.aEditField.Value;

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    mef(i)=(fv(i)*(m^a)+(1-fv(i)))^1/a;
                                    m2B = mef;   yLL=sqrt(m2B(i))*x;
                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    mef=(fv*(m^a)+(1-fv))^1/a;
                                    m2B = mef; yLL=sqrt(m2B)*x;
                                end

                                yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end
                        end
                    end
                end

                %% MANTO DE PARTÍCULAS EN EL CASO DEL HIELO
                if mat == 1

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    % Parámetros de la densidad y de profundidad manto
                    Profundidadmin = app.Profundidad_min.Value;
                    Profundidadmax = app.Profundidad_max.Value;
                    Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;
                    Profundidad=(Profundidadmin):1:(Profundidadmax);
                    for j=1:size(Profundidad,2)
                        ronieve(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
                    end

                    % Valor del ángulo cenital y de la nubosidad
                    AngZenith = app.ngulocenitalEditField.Value;
                    Cloud = app.NubosidadEditField.Value;

                    % Se escoge hacer el estudio con MÉTODO DE MIE
                    if app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(rp0)
                            x=2*pi*rp0(j)/lambda;
                            y=m*x; n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m.*shiy.*dshix)./(dshiy.*psix-m.*shiy.*dpsix);
                                bn=(m.*dshiy.*shix-shiy.*dshix)./(m.*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            Qext(j)=(2/x^2)*suma(j);
                            Qsca(j)=(2/x^2)*sumasca(j);
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)./Qext(j);

                            % Cálculo del valor del factor de asimetría
                            if app.FactordeasimetraDropDown.Value == "Debye"
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                g(j,1)=4/(x^2*Qsca(j))*g_sum;% Ecuación Debye por términos de Mie
                                Alb(j)=Qsca(j)/Qext(j);
                                FAsymmetry=g;
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Yang"
                                % Para fd distinto de 0 no se aplica eddington posterior
                                if fd==0
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));
                                else
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=FAsymmetry(j);
                                end
                            end

                            R=Cloud/100;
                            mu=cosd(AngZenith);
                            AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));
                            if AlbED(j)>1.0
                                AlbED(j)=0.99999;
                            end
                            aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                            bED(j)=(FAsymmetryED(j)/aED(j));
                            ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                            P(j)=(2*ED(j))/(3*aED(j));
                            AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                            AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                            AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                            AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                            AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                            AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;

                            % Selección tipo de Albedo
                            if app.AlbedoDropDown.Value == "Directo"
                                Alb = AlbDir;
                            elseif app.AlbedoDropDown.Value == "Difuso"
                                Alb = AlbDif;
                            elseif app.AlbedoDropDown.Value == "Neto"
                                Alb = AlbNet;
                            end
                        end
                    end
                    % Mostrar los resultados en la tabla
                    Datos =  [rp0 Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb' FAsymmetryED'];
                end

                dp0 = 2*rp0;

                % Mostrar los resultados en la tabla
                Datos =  [dp0' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                app.UITable.Data = Datos;

                Datos2=Datos;
                load cont cont
                % DATOS DE LA PRIMERA EJECUCIÓN
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,Qabs,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,Qsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,dp0,Qext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,dp0,Qabs,'r',dp0,Qsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,dp0,Qabs,'r',dp0,Qext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0,Qsca,dp0,Qext); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0, Qabs,'r',dp0,Qsca,'b',dp0,Qext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,CSabs,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,CSsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,dp0,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,dp0,CSabs,'r',dp0,CSsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,dp0,CSabs,'r',dp0,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0,CSsca,dp0,CSext); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0, CSabs,'r',dp0,CSsca,'b',dp0,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,MAC,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,dp0,MSC,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,dp0,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,dp0,MAC,'r',dp0,MSC,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula(nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,dp0,MAC,'r',dp0,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0,MSC,dp0,MEC); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,dp0, MAC,'r',dp0,MSC,'b',dp0,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,dp0,Alb,'k'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,dp0,MSC,'--k'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,dp0,Alb,'k',dp0, Coalb,'--k'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,dp0,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    xticks(app.UIAxes_arriba,'auto')
                end

                %------------------------------------------------------------------------------------------
                %-------------------------------------------------------------------------------------------
                %% Barrido del tamaño de partícula adimensional
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 113
                % min y max son del diámetro adimensinal
                min_rp0 = (min*lambda)/(2*pi);
                max_rp0 = (max*lambda)/(2*pi);
                rp0 = min_rp0:max_rp0;
                assignin('base','rp0',rp0)
                x = (2*pi*rp0)/lambda;
                assignin('base','x',x)

                % Cargar datos del índice de refracción
                load indice_refraccion.mat landa n k f_eV f_Hz
                % Interpolación si el valor introducido no es exacto al disponible en la base de datos del índice de refracción
                if lambda ~= landa
                    % Parte real
                    vector_n_menor = n(landa<lambda); cont_n = vector_n_menor(end);
                    vector_n_mayor = n(landa>lambda); cont1_n = vector_n_mayor(1);
                    landa_menor = landa(landa<lambda); cont_landa = landa_menor(end);
                    landa_mayor = landa(landa>lambda); cont1_landa = landa_mayor(1);

                    parte_real = ((cont1_n-cont_n)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_n;

                    % Parte imaginaria
                    vector_k_menor = k(landa<lambda); cont_k = vector_k_menor(end);
                    vector_k_mayor = k(landa>lambda); cont1_k = vector_k_mayor(1);

                    parte_imag = ((cont1_k-cont_k)/(cont1_landa-cont_landa))*(lambda-cont_landa)+cont_k;
                    m = parte_real- parte_imag*1i;
                else
                    landa = landa (landa == lambda);

                    n = n(landa == lambda);
                    k = k(landa == lambda);
                    m = n - k*1i;
                end
                % densidad hollín
                ro = 1850; % en kg/m^3

                %%%% AEROSOLES
                %%% PARTÍCULAS ESFÉRICAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 2
                    for j=1:length(rp0)
                        % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                        mp(j) = (4/3)*pi*((rp0(j)/(10^9)).^3)*ro*1000; % g
                        % Como Ap está en nm hay que pasarlo a m porque es un área
                        Ap0(j)=(pi()*(rp0(j)/(10^9))^2); % m^2
                        dp0(j)=2*rp0(j); %Diámetro de la partícula primaria (nm)
                    end

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        % Eficiencias y albedo de dispersión
                        for j=1:length(rp0)
                            Qabs(j)=-4*x(j)*(imag(((m^2)-1)/((m^2)+2)));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m^2)-1)/((m^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                        end
                        assignin('base','Qabs',Qabs)
                        assignin('base','Qsca',Qsca)
                        assignin('base','Qext',Qext)


                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for j=1:length(rp0)
                            Qabs(j)=-4.*x(j).*imag(((m.^2)-1)/((m.^2)+2).*(1+((((x(j).^2)./15)).*(((m.^4)+27.*(m.^2)+38)./(2.*(m.^2)+3)))+((8/3).*x(j)^4.*real(((m.^2)-1)./((m.^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m.^2)-1)./((m.^2)+2)).^2))));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m.^2)-1)/((m.^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                            % Ecuación simplificada de BH
                            QabsBHs(j)=-4*x(j)*(imag(((m.^2)-1)/((m.^2)+2)))*(1+((4*(x(j)^3))/3)*imag(((m.^2)-1)/((m.^2)+2)));
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for j=1:length(rp0)
                            Qabs(j)=-4.*x(j).*((imag(((m.^2)-1)./((m.^2)+2)))+imag((((x(j).^2)./15)).*((m.^2)-1)./((m.^2)+2).*(((m.^4)+27.*(m.^2)+38)./(2.*(m.^2)+3))));
                            Qsca(j)=(8/3)*x(j).^4*(abs(((m.^2)-1)/((m.^2)+2)).^2);
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        %% MÉTODO DE MIE
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        for j=1:length(rp0)
                            %                             x=2*pi*rp0(j)/lambda;
                            y=m*x;n=1;nmax=round(2+x+4*x^(1/3));
                            Eext_sum=0;g_sum=0;Esca_sum=0;
                            for n=1:nmax
                                shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                if (abs(an(n,1))<1e-8)
                                    break
                                end
                                if (abs(bn(n,1))<1e-8)
                                    break
                                end
                            end
                            nmat(j,1)=n;
                            for t=1:(n-1)
                                g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                            end
                            Qext(j)=2/x^2*Eext_sum;
                            Qsca(j)=2/x^2*Esca_sum;
                            Qabs(j)=Qext(j)-Qsca(j);
                            FAsymmetry(j,1)=4/(x^2*Qsca(j))*g_sum;
                            g=FAsymmetry;
                        end

                    end
                    for j=1:length(rp0)
                        Alb(j)=Qsca(j)/Qext(j);
                        Coalb(j)=1-Alb(j);
                        CSabs(j)= Qabs(j)*Ap0(j); % m^2
                        CSsca(j)= Qsca(j)*Ap0(j);
                        CSext(j)= Qext(j)*Ap0(j);
                        MAC(j)= CSabs(j)/mp(j); % m^2/g
                        MSC(j)= CSsca(j)/mp(j);
                        MEC(j)= CSext(j)/mp(j);
                    end

                    % Si estudio hielo calcular factor de asimetría
                    if mat == 1
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            FAsymmetry=g;
                        elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                            for j=1:length(lambda)
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                            end
                        elseif app.FactordeasimetraDropDown.Value == "Yang"
                            for j=1:length(lambda)
                                FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                            end
                        end
                    end

                    %%% AGLOMERADOS DE ESFERAS
                elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                    for i=1:length(rp0)
                        Ap0(i)=(pi()*rp0(i)^2);
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap(i)=Ap0(i)*(np0^(1/z));
                        K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                        K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                        in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                        K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                        Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                        rg(i)=rp0(i)*((np0/Kf)^(1/Df));
                        K=(2*pi)/lambda;
                        %                         x(i)=(2*pi*rp0(i))/lambda;
                        dp0(i)=2*rp0(i); %Diámetro de la partícula primaria (nm)
                        % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                        Ap0_prima(i)=(pi()*(rp0(i)/(10^9))^2); % m^2
                        Ap_prima(i)=Ap0_prima(i)*(np0^(1/z)); % m^2
                        % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                        mp(i) = (4/3)*pi*((rp0(i)/(10^9)).^3)*ro*1000*np0; % g
                    end

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*(imag(((m.^2)-1)/((m.^2)+2)));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end
                        assignin('base','Qabs',Qabs)
                        assignin('base','Qsca',Qsca)
                        assignin('base','Qext',Qext)
                        assignin('base','Alb',Alb)

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*imag(((m.^2)-1)/((m.^2)+2)*(1+((((x(i)^2)/15))*(((m.^4)+27*(m.^2)+38)/(2*(m.^2)+3)))+((8/3)*x(i).^4*real(((m.^2)-1)/((m.^2)+2))^2)-((8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2))));
                            Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end
                        assignin('base','mp',mp)
                        assignin('base','MAC',MAC)
                        assignin('base','CSabs',CSabs)
                        assignin('base','Qabs',Qabs)

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for i = 1: length(rp0)
                            Qabsp0(i)=-4*x(i)*((imag(((m.^2)-1)/((m.^2)+2)))+imag((((x(i)^2)/15))*((m.^2)-1)/((m.^2)+2)*(((m.^4)+27*(m.^2)+38)/(2*(m.^2)+3))));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(rp0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(rp0)
                                beta(i)=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m.^2)-1)/((m.^2)+2))^2);
                                if beta(i)>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta(i)<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta(i)/2*(3-3*beta(i)+2*beta(i)^2)-beta(i)^2*(rg(i)*K)^2/3*(3-4*beta(i)+3*beta(i)^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta(i)^(1-Df/2)*(1/(2-Df)-2*beta(i)/(4-Df)+2*beta(i)^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(rp0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            for i = 1: length(rp0)
                                R1(i)=rp0(i)*np0;
                                R2(i)=((2*in2)+1)*rp0(i);
                                R3(i)=((2*in3)+1)*rp0(i);
                                R(i)=(((log(R1(i))-(2*log(R2(i)))+log(R3(i)))/2)*Df^2)+((((-5*log(R1(i)))+(8*log(R2(i)))-(3*log(R3(i))))/2)*Df)+((3*log(R1(i)))-(3*log(R2(i)))+log(R3(i)));
                                fv(i)=(np0*(rp0(i)^3))/(exp(R(i))^3);
                                dp0(i) = 2*rp0(i);
                            end

                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            fv = np0.^(1-(3/Df));
                            % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                            % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                            Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                            Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                            % Generar con esos puntos los valores de A,B,C
                            % polyfit(x,y,2) devuelve un vector con los coeficientes del
                            % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                            % Para representar la recta, se sacan los puntos con polyval
                            a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                            A=a(1); B=a(2); C=a(3);
                            Kf = A*(Df.^2)+B*Df+C;
                            for i = 1:length(dp0)
                                rg(i) = rp0(i)*((np0/Kf).^(1/Df));
                                R(i) = rg(i)*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                                R(i) = log(R(i));
                            end
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        n=1;
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        % densidad hollín
                        ro = 1850; % en kg/m^3

                        for i=1:length(rp0)
                            x=(2*pi*exp(R))./lambda;
                            nmax=round(2+x+4*x.^(1/3));
                            % área proyectada
                            Ap0(i)=(pi()*(rp0(i)/(10^9))^2); %m^2
                            Ap(i)=Ap0(i)*(np0^(1/z)); %m^2
                            % Como MAC suele darse en m^2/g se pasa masa a gramos
                            mp(i) = (4/3)*pi*((rp0(i)/(10^9)).^3)*ro*1000*np0; % g
                        end

                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2B(i)=((((-1.5*fv(i))+1)+(((1.5*fv(i))-0.5)*m^2)+sqrt((((1.5*fv(i))-1)-(((1.5*fv(i))-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yB=sqrt(m2B(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2B=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yB=sqrt(m2B).*x;
                                end

                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i = 1: length(rp0)
                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2B(i)=((((m^2)*((2*fv(i))+1))+(2*(-fv(i)+1)))/(((m^2)*((-1*fv(i))+1))+((fv(i)+2))))^0.5;
                                    yMG=sqrt(m2B(i))*x;
                                    assignin('base','yMG',yMG)

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2B=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                    yMG=sqrt(m2B).*x;
                                    assignin('base','yMG_aprox',yMG)
                                end

                                yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2LL(i)=((1+(2*fv(i)*(((m^2)-1)/((m^2)+2))))/(1-(fv(i)*(((m^2)-1)/((m^2)+2)))))^0.5;
                                    yLL=sqrt(m2LL(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2LL=((1+(2*fv*(((m^2)-1)/((m^2)+2))))/(1-(fv*(((m^2)-1)/((m^2)+2)))))^0.5;
                                    yLL=sqrt(m2LL).*x;
                                end

                                m2B = m2LL; yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end


                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(rp0)
                                k_=2*pi/lambda; % k_ para diferenciarlo de la parte imaginaria de m
                                alfa(i)=(m^2-1)/(1-(m^2-1)*(2/3*(1-k_*rp0(i)*1i)*exp(k_*rp0(i)*1i)-1));

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    m2LV(i)=(1+(2*alfa(i)*fv(i))/3)^0.5/(1-(alfa(i)*fv(i))/3)^0.5;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                end

                                m2B = m2LV;
                                yLV=sqrt(m2LV(i))*x; yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;

                            for i=1:length(rp0)

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                    a = v;
                                    b(i) = m^2+2-2*v -fv(i)*(m^2-1)*(v+1);
                                    c(i) = v - (m^2+2) - fv(i)*(m^2-1)*(2-v);
                                    % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                    if v == 0
                                        mef1(i) = ((-c(i)/b(i))^0.5);
                                        mef2(i) = -((-c(i)/b(i))^0.5);
                                        mef = [mef1;mef2];

                                        % Soluciones a la bicuadrático
                                    else
                                        mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                        mef = [mef1;mef2;mef3;mef4];
                                    end
                                    %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                    m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                    m2B = m2LL; yLL=sqrt(m2B(i))*x;

                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                    a = v;
                                    b = m^2+2-2*v -fv*(m^2-1)*(v+1);
                                    c = v - (m^2+2) - fv*(m^2-1)*(2-v);
                                    % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                    if v == 0
                                        mef1 = ((-c/b)^0.5);
                                        mef2 = -((-c/b)^0.5);
                                        mef = [mef1;mef2];

                                        % Soluciones a la bicuadrático
                                    else
                                        mef1(i) = ((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef2(i) = -((-b+(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef3(i) = ((-b-(b^2-4*a*c)^0.5)/(2*a))^0.5;
                                        mef4(i) = -((-b-(b^2-4*a*CSext)^0.5)/(2*a))^0.5;
                                        mef = [mef1;mef2;mef3;mef4];
                                    end
                                    %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                    m2LL = mef(real(mef) > 0 & imag(mef) < 0);
                                    m2B = m2LL; yLL=sqrt(m2B)*x;
                                end

                                yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            for i = 1:length(rp0)
                                a=app.aEditField.Value;

                                % Método geométrico: fv(i)
                                if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                                    mef(i)=(fv(i)*(m^a)+(1-fv(i)))^1/a;
                                    m2B = mef;   yLL=sqrt(m2B(i))*x;
                                    % Método aproximado: fv
                                elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                    mef=(fv*(m^a)+(1-fv))^1/a;
                                    m2B = mef; yLL=sqrt(m2B)*x;
                                end

                                yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    % Si método aprox el m no es vector
                                    if app.TreeMethodRmax.SelectedNodes.NodeData == 16
                                        anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                                    else
                                        anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                        bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    end
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);

                                CSabs(i) = Qabs(i)*Ap(i); % m^2
                                CSsca(i) = Qsca(i)*Ap(i);
                                CSext(i) = Qext(i)*Ap(i);

                                MAC(i) = CSabs(i)/mp(i); % m^2/g
                                MSC(i) = CSsca(i)/mp(i);
                                MEC(i) = CSext(i)/mp(i);
                            end
                        end
                    end
                end

                %% MANTO DE PARTÍCULAS EN EL CASO DEL HIELO
                if mat == 1

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    % Parámetros de la densidad y de profundidad manto
                    Profundidadmin = app.Profundidad_min.Value;
                    Profundidadmax = app.Profundidad_max.Value;
                    Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;
                    Profundidad=(Profundidadmin):1:(Profundidadmax);
                    for j=1:size(Profundidad,2)
                        ronieve(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
                    end

                    % Valor del ángulo cenital y de la nubosidad
                    AngZenith = app.ngulocenitalEditField.Value;
                    Cloud = app.NubosidadEditField.Value;

                    % Se escoge hacer el estudio con MÉTODO DE MIE
                    if app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(rp0)
                            x=2*pi*rp0(j)/lambda;
                            y=m*x; n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m.*shiy.*dshix)./(dshiy.*psix-m.*shiy.*dpsix);
                                bn=(m.*dshiy.*shix-shiy.*dshix)./(m.*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            Qext(j)=(2/x^2)*suma(j);
                            Qsca(j)=(2/x^2)*sumasca(j);
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)./Qext(j);

                            % Cálculo del valor del factor de asimetría
                            if app.FactordeasimetraDropDown.Value == "Debye"
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                g(j,1)=4/(x^2*Qsca(j))*g_sum;% Ecuación Debye por términos de Mie
                                Alb(j)=Qsca(j)/Qext(j);
                                FAsymmetry=g;
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Yang"
                                % Para fd distinto de 0 no se aplica eddington posterior
                                if fd==0
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));
                                else
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=FAsymmetry(j);
                                end
                            end

                            R=Cloud/100;
                            mu=cosd(AngZenith);
                            AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));
                            if AlbED(j)>1.0
                                AlbED(j)=0.99999;
                            end
                            aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                            bED(j)=(FAsymmetryED(j)/aED(j));
                            ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                            P(j)=(2*ED(j))/(3*aED(j));
                            AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                            AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                            AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                            AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                            AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                            AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;

                            % Selección tipo de Albedo
                            if app.AlbedoDropDown.Value == "Directo"
                                Alb = AlbDir;
                            elseif app.AlbedoDropDown.Value == "Difuso"
                                Alb = AlbDif;
                            elseif app.AlbedoDropDown.Value == "Neto"
                                Alb = AlbNet;
                            end
                        end
                    end
                    % Mostrar los resultados en la tabla
                    Datos =  [x Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb' FAsymmetryED'];
                end

                dp0 = 2*rp0;

                % Mostrar los resultados en la tabla
                Datos =  [x' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                app.UITable_estudio.Data = Datos;

                Datos2=Datos;
                load cont cont
                % DATOS DE LA PRIMERA EJECUCIÓN
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,Qabs,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,Qsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,x,Qext,'g');xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,x,Qabs,'r',x,Qsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,x,Qabs,'r',x,Qext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_imagen_inicial,x,Qsca,x,Qext); xlabel(app.UIAxes_imagen_inicial,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_imagen_inicial,'Eficiencia');
                        legend(app.UIAxes_imagen_inicial,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_imagen_inicial,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,x, Qabs,'r',x,Qsca,'b',x,Qext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,CSabs,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,CSsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,x,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,x,CSabs,'r',x,CSsca,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,x,CSabs,'r',x,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,x,CSsca,x,CSext); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,x, CSabs,'r',x,CSsca,'b',x,CSext,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,MAC,'r'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,x,MSC,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,x,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,x,MAC,'r',x,MSC,'b'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,x,MAC,'r',x,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,x,MSC,x,MEC); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,x, MAC,'r',x,MSC,'b',x,MEC,'g'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,x,Alb,'k'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,x,MSC,'--k'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,x,Alb,'k',x, Coalb,'--k'); xlabel(app.UIAxes_arriba,'Diámetro partícula (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,x,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Diámetro partícula adimensional')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    xticks(app.UIAxes_arriba,'auto')
                end


                %------------------------------------------------------------------------------------------
                %-------------------------------------------------------------------------------------------
                %% Barrido de partículas primarias
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 114
                % Se hace el barrido de np0 con el vector de valor mínimo y máximo indicado por usuario en pantalla
                np0 = min:max;

                % Cargar datos del índice de refracción
                load indice_refraccion.mat landa n k f_eV f_Hz
                % Interpolación si el valor introducido no es exacto al disponible en la base de datos del índice de refracción
                n = n(landa == lambda);
                k = k(landa == lambda);
                m = n - k*1i;

                assignin('base','m_interpolado',m)

                %%% No se hace barrido para partículas esféricas, ya que se necesita tener un aglomerado con un nº de partículas primarias

                %%%% AEROSOLES
                %%% AGLOMERADOS DE ESFERAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 3
                    Ap0=(pi()*rp0^2);
                    Ap0_prima=(pi()*(rp0/(10^9))^2); % m^2
                    % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    K = (2*pi)/lambda;
                    x = (2*pi*rp0)/lambda;
                    for i = 1: length(np0)
                        %                             z1(i)=log(np0(i))/(log((0.8488*np0(i))+0.1512));
                        %                             z3(i)=1/(0.6667+(0.2003/log(np0(i))));
                        %                             z(i)=z1(i)-1+((z3(i)+1-z1(i)).^(((Df-1)/2)^1.95));
                        z1=log(np0(i))/(log((0.8488*np0(i))+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0(i))));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap=Ap0*(np0(i)^(1/z));
                        %                             Ap(i)=Ap0*(np0(i)^(1/z(i)));
                        K1(i)=np0(i)/(((3/5)+(1/3)*(np0(i)^2-1))^0.5);
                        K2(i)=np0(i)/((3/5)+((10*(np0(i)^2)-(8*np0(i))-2)/(18*np0(i))));
                        in(i)=((((3*np0(i))/20)+((1/120)*((324*(np0(i)^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0(i))/20)+((1/120)*((324*(np0(i)^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                        K3(i)=((((10/3)*(in(i)^3))+(5*(in(i)^2))+((11/3)*in(i))+1)^(5/2))/((((28/5)*(in(i)^5))+(14*(in(i)^4))+(18*(in(i)^3))+(13*(in(i)^2))+((22/5)*in(i))+(3/5))^(3/2));
                        Kf(i)=(((K1(i)/2)-K2(i)+(K3(i)/2))*(Df^2))+((((-5/2)*K1(i))+(4*K2(i))-((3/2)*K3(i)))*Df)+((3*K1(i))-(3*K2(i))+K3(i));
                        rg(i)=rp0*((np0(i)./Kf(i)).^(1/Df));

                        Ap_prima(i)=Ap0_prima*(np0(i).^(1/z));
                        %                             Ap_prima(i)=Ap0_prima*(np0(i)^(1/z(i))); % m^2
                        % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                        mp(i) = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0(i); % g

                    end

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        Qabsp0=-4*x*(imag(((m^2)-1)/((m^2)+2)));
                        for i = 1: length(np0)
                            Qabs(i)=(np0(i).^(1-(1/z)))*Qabsp0;
                            %                                 Qabs(i)=(np0(i).^(1-(1/z(i)))).*Qabsp0;
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                            for i = 1: length(np0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GFB(i);

                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(np0)
                                beta=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(np0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        Qabsp0=-4*x*imag(((m^2)-1)/((m^2)+2)*(1+((((x^2)/15))*(((m^4)+27*(m^2)+38)/(2*(m^2)+3)))+((8/3)*x.^4*real(((m^2)-1)/((m^2)+2))^2)-((8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2))));
                        Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                        for i = 1: length(np0)
                            Qabs(i)=(np0(i).^(1-(1/z)))*Qabsp0;
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                            for i = 1: length(np0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(np0)
                                beta=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(np0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        Qabsp0=-4*x*((imag(((m^2)-1)/((m^2)+2)))+imag((((x^2)/15))*((m^2)-1)/((m^2)+2)*(((m^4)+27*(m^2)+38)/(2*(m^2)+3))));
                        for i = 1: length(np0)
                            Qabs(i)=(np0(i).^(1-(1/z)))*Qabsp0;
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                            for i = 1: length(np0)
                                GFB(i)=(1+((4*(K^2)*(rg(i)^2))/(3*Df)))^(-Df*0.5);
                                Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(np0)
                                beta=(3*Df)/(8*(K*rg(i))^2);
                                Qscap0=(8/3)*x.^4*(abs(((m^2)-1)/((m^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K*rg(i))^2)/3);
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(i)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(i)*K)^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0(i)^(2-(1/z)))*Qscap0*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(np0)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                            CSsca(i) = Qsca(i)*Ap_prima(i);
                            CSext(i) = Qext(i)*Ap_prima(i);

                            MAC(i) = CSabs(i)/mp(i);
                            MSC(i) = CSsca(i)/mp(i);
                            MEC(i) = CSext(i)/mp(i);
                        end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11

                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            for i=1:length(np0)
                                in2(i)=(sqrt((4*np0(i))-1)/(2*sqrt(3)))-(1/2);
                                in3(i)=(3*np0(i)/20+((324*np0(i)^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0(i)/20+(((324*np0(i)^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                                R1(i)=rp0*np0(i);
                                R2(i)=((2*in2(i))+1)*rp0;
                                R3(i)=((2*in3(i))+1)*rp0;
                                R(i)=(((log(R1(i))-(2*log(R2(i)))+log(R3(i)))/2)*Df^2)+((((-5*log(R1(i)))+(8*log(R2(i)))-(3*log(R3(i))))/2)*Df)+((3*log(R1(i)))-(3*log(R2(i)))+log(R3(i)));
                                fv(i)=(np0(i)*(rp0^3))/(exp(R(i))^3);
                            end
                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            a = zeros(length(np0),3);
                            A = zeros(length(np0),1); B = zeros(length(np0),1); C = zeros(length(np0),1);
                            for i=1:length(np0)
                                fv(i) = np0(i).^(1-(3/Df));
                                % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                                % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                                Kf1(i)= np0(i)/(((3/5)+(1/3)*((np0(i).^2)-1)).^0.5);
                                Kf2(i)= np0(i)/(3/5+((10*np0(i).^2-8*np0(i)-47)/(18*np0(i))));
                                in3(i)=(3*np0(i)/20+((324*np0(i)^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0(i)/20+(((324*np0(i)^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                                Kf3(i)= ((((3/10)*(in3(i)^3))+(5*(in3(i)^2))+((11/3)*in3(i))+1)^(5/2))/(((((118/5)*(in3(i)^3))+(14*(in3(i)^4))+(13*(in3(i)^2))+((23/5)*in3(i))+(3/5)))^(3/2));
                                % Generar con esos puntos los valores de A,B,C
                                % polyfit(x,y,2) devuelve un vector con los coeficientes del
                                % polinomio (x es la Df(1,2,3), "y" son los valores de Kf y 2 es el grado)
                                % Para representar la recta, se sacan los puntos con polyval
                                Kf1_=Kf1(i); Kf2_=Kf2(i); Kf3_=Kf3(i);
                                a(i,:) = polyfit([1 2 3],[Kf1_' Kf2_' Kf3_'],2);
                                A(i)=a(i,1); B(i)=a(i,2); C(i)=a(i,3);
                                Kf(i) = A(i)*(Df.^2)+B(i)*Df+C(i);
                                rg(i) = rp0*((np0(i)/Kf(i)).^(1/Df));
                                R(i) = rg(i)*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                                R(i) = log(R(i));
                            end
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        % área proyectada
                        Ap0=(pi()*(rp0/(10^9))^2); %m^2
                        % densidad hollín
                        ro = 1850; % en kg/m^3

                        for i=1:length(np0)
                            x(i)=(2*pi*exp(R(i)))./lambda;
                            n=1; nmax(i)=round(2+x(i)+4*x(i).^(1/3));
                            z1(i)=log(np0(i))/(log((0.8488*np0(i))+0.1512));
                            z3(i)=1/(0.6667+(0.2003/log(np0(i))));
                            z(i)=z1(i)-1+((z3(i)+1-z1(i))^(((Df-1)/2)^1.95));
                            Ap(i)=Ap0*(np0(i)^(1/z(i))); %m^2
                            % Como MAC suele darse en m^2/g se pasa masa a gramos
                            mp(i) = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0(i); % g
                        end

                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(np0)
                                m2B(i)=((((-1.5*fv(i))+1)+(((1.5*fv(i))-0.5)*m^2)+sqrt((((1.5*fv(i))-1)-(((1.5*fv(i))-0.5)*m^2))^2+(2*m^2)))/2)^0.5;
                                yB(i)=sqrt(m2B(i))*x(i);
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i=1:length(np0)
                                m2MG(i)=((((m^2)*((2*fv(i))+1))+(2*(-fv(i)+1)))/(((m^2)*((-1*fv(i))+1))+((fv(i)+2))))^0.5;
                                m2B = m2MG;
                                yMG(i)=sqrt(m2MG(i))*x(i); yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end


                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(np0)
                                m2LL(i)=((1+(2*fv(i)*(((m^2)-1)/((m^2)+2))))/(1-(fv(i)*(((m^2)-1)/((m^2)+2)))))^0.5;
                                m2B = m2LL;
                                yLL(i)=sqrt(m2LL(i))*x(i); yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end


                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(np0)
                                k_=2*pi/lambda; % k_ para diferenciarlo de la imagen de m
                                alfa=(m^2-1)/(1-(m^2-1)*(2/3*(1-k_*rp0*1i)*exp(k_*rp0*1i)-1));
                                m2LV(i)=(1+(2*alfa*fv(i))/3)^0.5/(1-(alfa*fv(i))/3)^0.5;
                                m2B = m2LV;
                                yLV(i)=sqrt(m2LV(i))*x(i); yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;

                            for i=1:length(np0)
                                % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                a = v;
                                b(i) = m^2+2-2*v -fv(i)*(m^2-1)*(v+1);
                                c(i) = v - (m^2+2) - fv(i)*(m^2-1)*(2-v);
                                % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                if v == 0
                                    mef1(i) = ((-c(i)/b(i))^0.5);
                                    mef2(i) = -((-c(i)/b(i))^0.5);
                                    mef = [mef1(i);mef2(i)];

                                    % Soluciones a la bicuadrática
                                else
                                    mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef = [mef1(i);mef2(i);mef3(i);mef4(i)];
                                end
                                %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                m2LL(i) = mef(real(mef) > 0 & imag(mef) < 0);

                                m2B = m2LL;
                                yLL(i)=sqrt(m2LL(i))*x(i); yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            a=app.aEditField.Value;
                            for i=1:length(np0)
                                mef(i)=(fv(i)*(m^a)+(1-fv(i)))^1/a;
                                m2B(i)=mef(i);
                                yB(i)=sqrt(m2B(i))*x(i);
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=2/x(i)^2*sumaB;
                                Qsca(i)=2/x(i)^2*sumascaB;
                                Qabs(i)=Qext(i)-Qsca(i);
                                Alb(i)=Qsca(i)/Qext(i);
                                Coalb(i)=1-Alb(i);
                            end
                        end
                    end
                end

                for i=1:length(np0)
                    Qabs(i)=Qext(i)-Qsca(i);
                    Alb(i)=Qsca(i)/Qext(i);
                    Coalb(i)=1-Alb(i);

                    %                         CSabs(i) = Qabs(i)*Ap(i); % m^2
                    %                         CSsca(i) = Qsca(i)*Ap(i);
                    %                         CSext(i) = Qext(i)*Ap(i);
                    CSabs(i) = Qabs(i)*Ap_prima(i); % m^2
                    CSsca(i) = Qsca(i)*Ap_prima(i);
                    CSext(i) = Qext(i)*Ap_prima(i);

                    MAC(i) = CSabs(i)/mp(i); % m^2/g
                    MSC(i) = CSsca(i)/mp(i);
                    MEC(i) = CSext(i)/mp(i);
                end

                assignin('base','rg',rg)
                assignin('base','Alb',Alb)
                assignin('base','Qsca',Qsca)
                assignin('base','Qext',Qext)
                assignin('base','MSC',MSC)
                assignin('base','MEC',MEC)
                assignin('base','np0',np0)
                assignin('base','Df',Df)
                prueba = [np0; rg; Alb; Qext; Qsca; MSC; MEC]';
                assignin('base','prueba',prueba)


                % Mostrar los resultados en la tabla
                app.UITable_estudio.ColumnName = ["Número de partículas primarias", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Sección óptica de absorción","Sección óptica de dispersión","Sección óptica de extinción","Sección específica de absorción","Sección específica de dispersión","Sección específica de extinción","Albedo","Coalbedo"];
                Datos =  [np0' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                app.UITable_estudio.Data = Datos;

                Datos2=Datos;
                % DATOS DE LA PRIMERA EJECUCIÓN
                load cont cont
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_imagen_inicial,np0_arriba,Qabs,'r'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,np0,Qsca,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,np0,Qext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,np0,Qabs,'r',np0,Qsca,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,np0,Qabs,'r',np0,Qext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0,Qsca,np0,Qext); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0, Qabs,'r',np0,Qsca,'b',np0,Qext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,np0,CSabs,'r'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,np0,CSsca,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,np0,CSext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,np0,CSabs,'r',np0,CSsca,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,np0,CSabs,'r',np0,CSext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0,CSsca,np0,CSext); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0, CSabs,'r',np0,CSsca,'b',np0,CSext,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,np0,MAC,'r'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,np0,MSC,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,np0,MEC,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,np0,MAC,'r',np0,MSC,'b'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,np0,MAC,'r',np0,MEC,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0,MSC,np0,MEC); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,np0, MAC,'r',np0,MSC,'b',np0,MEC,'g'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,np0,Alb,'k'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,np0,MSC,'--k'); xlabel(app.UIAxes_arriba,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,np0,Alb,'k',np0, Coalb,'--k'); xlabel(app.UIAxes_imagen_inicial,'Número de partículas primarias')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)
                    end
                end


                %------------------------------------------------------------------------------------------
                %-------------------------------------------------------------------------------------------
                %%%%% Barrido de energía (eV)
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 115
                % Los valores mínimo y máximo dependen de la selección
                min_f_ev = app.ValorminimoEditField.Value;
                max_f_ev = app.ValormaximoEditField.Value;

                % Frecuencia en eV
                min=1240/min_f_ev;
                max=1240/max_f_ev;

                n = n( landa >= min & landa <= max );
                k = k( landa >= min & landa <= max );
                landa = landa( landa >= min & landa <= max );

                %                 landa = landa( landa >= min & landa <= max );
                %                 n = n( landa >= min & landa <= max );
                %                 k = k( landa >= min & landa <= max );
                m = n-k*1i;
                lambda = landa;

                f_eV = 1240./landa; % Frecuencia en eV

                %%%% AEROSOLES
                %%% PARTÍCULAS ESFÉRICAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 2

                    % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000; % g
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    Ap0=(pi()*(rp0/(10^9))^2); % m^2

                    %% MÉTODO DE RAYLEIGH
                    for j = 1:length(lambda)
                        x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                        Qsca(j)=(8/3)*x(j).^4*(abs(((m(j).^2)-1)/((m(j).^2)+2)).^2);
                    end

                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for j=1:length(lambda)
                            Qabs(j)=-4*x(j)*(imag(((m(j).^2)-1)/((m(j).^2)+2)));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*imag(((m(j).^2)-1)/((m(j).^2)+2).*(1+((((x(j).^2)./15)).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3)))+((8/3).*x(j).^4.*real(((m(j).^2)-1)./((m(j).^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m(j).^2)-1)./((m(j).^2)+2)).^2))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*((imag(((m(j).^2)-1)./((m(j).^2)+2)))+imag((((x(j).^2)./15)).*((m(j).^2)-1)./((m(j).^2)+2).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        %% MÉTODO DE MIE
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            y=m(j)*x;
                            n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            Qext(j)=(2/x^2)*suma;
                            Qsca(j)=(2/x^2)*sumasca;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j) = 1-Alb(j);
                        end
                    end

                    for j=1:length(lambda)
                        Alb(j)=Qsca(j)/Qext(j);
                        Coalb(j)=1-Alb(j);

                        CSabs(j)= Qabs(j)*Ap0; % m^2
                        CSsca(j)= Qsca(j)*Ap0;
                        CSext(j)= Qext(j)*Ap0;
                        MAC(j)= CSabs(j)/mp; % m^2/g
                        MSC(j)= CSsca(j)/mp;
                        MEC(j)= CSext(j)/mp;
                    end

                    % Si estudio hielo calcular factor de asimetría
                    if mat == 1
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            FAsymmetry=g;
                        elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                            for j=1:length(lambda)
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                            end
                        elseif app.FactordeasimetraDropDown.Value == "Yang"
                            for j=1:length(lambda)
                                FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                            end
                        end
                    end

                    %%% AGLOMERADOS DE ESFERAS
                elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                    Ap0=(pi()*rp0^2);
                    z1=log(np0)/(log((0.8488*np0)+0.1512));
                    z3=1/(0.6667+(0.2003/log(np0)));
                    z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                    Ap=Ap0*(np0^(1/z));
                    K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                    K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                    in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                    K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                    Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                    rg=rp0*((np0/Kf)^(1/Df));
                    K=(2*pi)./lambda;
                    x=(2*pi*rp0)./lambda;

                    % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); % m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); % m^2
                    % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*(imag(((m(i)^2)-1)/((m(i)^2)+2)));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*imag(((m(i)^2)-1)/((m(i)^2)+2)*(1+((((x(i)^2)/15))*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3)))+((8/3)*x(i).^4*real(((m(i)^2)-1)/((m(i)^2)+2))^2)-((8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2))));
                            Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*((imag(((m(i)^2)-1)/((m(i)^2)+2)))+imag((((x(i)^2)/15))*((m(i)^2)-1)/((m(i)^2)+2)*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3))));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % %                            % Ecuación de Mie
                        % %                         elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        % %                             incr=0.000000001;
                        % %                             for j=1:length(lambda)
                        % %                                 x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                        % %                                 y(j)=m(j).*x(j); nn=1;
                        % %                                 nmax(j)=round(2+x(j)+4*x(j)^(1/3));
                        % %                                 suma=0; sumasca=0; g_sum=0;
                        % %                                 while nn<=nmax
                        % %                                     shix(j)=(pi*x(j)/2)^0.5*besselj(nn+0.5,x(j));shix1(j)=(pi*(x(j)+incr)/2)^0.5*besselj(nn+0.5,x(j)+incr);
                        % %                                     dshix(j)=(shix1(j)-shix(j))/incr;
                        % %                                     shiy(j)=(pi*y(j)/2)^0.5*besselj(nn+0.5,y(j));shiy1(j)=(pi*(y(j)+incr)/2)^0.5*besselj(nn+0.5,y(j)+incr);
                        % %                                     dshiy(j)=(shiy1(j)-shiy(j))/incr;
                        % %                                     psix(j)=(pi*x(j)/2)^0.5*besselh(nn+0.5,2,x(j));psix1(j)=(pi*(x(j)+incr)/2)^0.5*besselh(nn+0.5,2,x(j)+incr);
                        % %                                     dpsix(j)=(psix1(j)-psix(j))/incr;
                        % %                                     an(j)=(dshiy(j)*shix(j)-m(j)*shiy(j)*dshix(j))/(dshiy(j)*psix(j)-m(j)*shiy(j)*dpsix(j));
                        % %                                     bn(j)=(m(j)*dshiy(j)*shix(j)-shiy(j)*dshix(j))/(m(j)*dshiy(j)*psix(j)-shiy(j)*dpsix(j));
                        % %                                     suma=suma+(2*nn+1)*real(an(j)+bn(j));
                        % %                                     sumasca=sumasca+(2*nn+1)*((abs(an(j)))^2+(abs(bn(j)))^2);
                        % %                                 end
                        % %
                        % %                                 Qext(j)=(2/x(j)^2)*suma;
                        % %                                 Qsca(j)=(2/x(j)^2)*sumasca;
                        % %                                 Qabs(j)=Qext(j)-Qsca(j);
                        % %                                 Alb(j)=Qsca(j)/Qext(j);
                        % %                                 Coalb(j)=1-Alb(j);
                        % %
                        % %                                 CSabs(j)= Qabs(j)*Ap0; % m^2
                        % %                                 CSsca(j)= Qsca(j)*Ap0;
                        % %                                 CSext(j)= Qext(j)*Ap0;
                        % %                                 MAC(j)= CSabs(j)/mp; % m^2/g
                        % %                                 MSC(j)= CSsca(j)/mp;
                        % %                                 MEC(j)= CSext(j)/mp;
                        % %                             end
                        % %                             %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        % %                             %% Se escoge el de Fisher-Burford
                        % %                             if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                        % %                                 for i = 1: length(lambda)
                        % %                                     GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                        % %                                     Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                        % %                                 end
                        % %
                        % %                                 %% Se escoge el de Köylü-Faeth
                        % %                             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                        % %                                 for i = 1: length(lambda)
                        % %                                     beta=(3*Df)/(8*(K(i)*rg)^2);
                        % %                                     if beta>=1
                        % %                                         GKF(i)=1-((2*(K(i)*rg)^2)/3);
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     elseif beta<1 && Df==2
                        % %                                         GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     else
                        % %                                         GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     end
                        % %                                 end
                        % %                             end
                        % %                             for i = 1: length(lambda)
                        % %                                 Qext(i)=Qabs(i)+Qsca(i);
                        % %                                 Alb(i)=Qsca(i)/Qext(i);
                        % %                                 Coalb(i)=1-Alb(i);
                        % %
                        % %                                 CSabs(i) = Qabs(i)*Ap_prima; % m^2
                        % %                                 CSsca(i) = Qsca(i)*Ap_prima;
                        % %                                 CSext(i) = Qext(i)*Ap_prima;
                        % %
                        % %                                 MAC(i) = CSabs(i)/mp;
                        % %                                 MSC(i) = CSsca(i)/mp;
                        % %                                 MEC(i) = CSext(i)/mp;
                        % %                             end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11

                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            R1=rp0*np0;
                            R2=((2*in2)+1)*rp0;
                            R3=((2*in3)+1)*rp0;
                            R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                            fv=(np0*(rp0^3))/(exp(R)^3);

                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            fv = np0.^(1-(3/Df));
                            % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                            % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                            Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                            Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                            % Generar con esos puntos los valores de A,B,C
                            % polyfit(x,y,2) devuelve un vector con los coeficientes del
                            % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                            % Para representar la recta, se sacan los puntos con polyval
                            a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                            A=a(1); B=a(2); C=a(3);
                            Kf = A*(Df.^2)+B*Df+C;
                            rg = rp0*((np0/Kf).^(1/Df));
                            R = rg*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                            R = log(R);
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        load indice_refraccion.mat landa n k f_eV f_Hz
                        real_part=n; imag_part=k;

                        for i=1:length(lambda)
                            x=(2*pi*exp(R))./lambda;
                            n=1;nmax=round(2+x+4*x.^(1/3));
                            if landa(i)==floor(lambda(i))% revisar que aproxime al más próximo
                                m(i) = real_part(i)-imag_part(i)*1i;
                            end
                        end
                        % área proyectada
                        Ap0=(pi()*(rp0/(10^9))^2); %m^2
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap=Ap0*(np0^(1/z)); %m^2

                        % densidad hollín
                        ro = 1850; % en kg/m^3
                        % Como MAC suele darse en m^2/g se pasa masa a gramos
                        mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g


                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(lambda)
                                m2B(i)=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m(i)^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m(i)^2))^2+(2*m(i)^2)))/2)^0.5;
                                yB=sqrt(m2B(i))*x;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i=1:length(lambda)
                                %                                     m2MG=((((m^2)*((2*fv)+1))+(2*(-fv+1)))/(((m^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                m2MG(i)=((((m(i)^2)*((2*fv)+1))+(2*(-fv+1)))/(((m(i)^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                m2B = m2MG;
                                yMG=sqrt(m2MG(i))*x; yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(lambda)
                                m2LL(i)=((1+(2*fv*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv*(((m(i)^2)-1)/((m(i)^2)+2)))))^0.5;
                                m2B = m2LL;
                                yLL=sqrt(m2LL(i))*x; yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end


                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(lambda)
                                k_(i)=2*pi/lambda(i); % k_ para diferenciarlo de la parte imaginaria de m
                                alfa(i)=(m(i)^2-1)/(1-(m(i)^2-1)*(2/3*(1-k_(i)*rp0*1i)*exp(k_(i)*rp0*1i)-1));
                                m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                m2B = m2LV;
                                yLV=sqrt(m2LV(i))*x; yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;
                            for i=1:length(lambda)
                                % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                a = v;
                                b(i) = m(i)^2+2-2*v -fv*(m(i)^2-1)*(v+1);
                                c(i) = v - (m(i)^2+2) - fv*(m(i)^2-1)*(2-v);
                                % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                if v == 0
                                    mef1(i) = ((-c(i)/b(i))^0.5);
                                    mef2(i) = -((-c(i)/b(i))^0.5);
                                    mef = [mef1;mef2];

                                    % Soluciones a la bicuadrático
                                else
                                    mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef = [mef1;mef2;mef3;mef4];
                                end
                                %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                m2B = m2LL;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            for i = 1:length(lambda)
                                a=app.aEditField.Value;
                                mef(i)=(fv*(m(i)^a)+(1-fv))^1/a;
                                m2B = mef;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end
                        end
                        for i = 1:length(lambda)
                            Qabs(i)=Qext(i)-Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap; % m^2
                            CSsca(i) = Qsca(i)*Ap;
                            CSext(i) = Qext(i)*Ap;

                            MAC(i) = CSabs(i)/mp; % m^2/g
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end
                    end
                end

                %% MANTO DE PARTÍCULAS EN EL CASO DEL HIELO
                if mat == 1

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    % Parámetros de la densidad y de profundidad manto
                    Profundidadmin = app.Profundidad_min.Value;
                    Profundidadmax = app.Profundidad_max.Value;
                    Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;
                    Profundidad=(Profundidadmin):1:(Profundidadmax);
                    for j=1:size(Profundidad,2)
                        ronieve(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
                    end

                    % Valor del ángulo cenital y de la nubosidad
                    AngZenith = app.ngulocenitalEditField.Value;
                    Cloud = app.NubosidadEditField.Value;

                    % Se escoge hacer el estudio con MÉTODO DE MIE
                    if app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            y=m(j)*x;
                            n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            assignin('base','suma',suma)
                            Qext(j)=(2/x^2)*suma;
                            Qsca(j)=(2/x^2)*sumasca;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j) = 1-Alb(j);

                            % Cálculo del valor del factor de asimetría
                            if app.FactordeasimetraDropDown.Value == "Debye"
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                g(j,1)=4/(x^2*Qsca(j))*g_sum;% Ecuación Debye por términos de Mie
                                Alb(j)=Qsca(j)/Qext(j);
                                FAsymmetry=g;
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Yang"
                                % Para fd distinto de 0 no se aplica eddington posterior
                                if fd==0
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));
                                else
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=FAsymmetry(j);
                                end
                            end

                            R=Cloud/100;
                            mu=cosd(AngZenith);
                            AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));
                            if AlbED(j)>1.0
                                AlbED(j)=0.99999;
                            end
                            aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                            bED(j)=(FAsymmetryED(j)/aED(j));
                            ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                            P(j)=(2*ED(j))/(3*aED(j));
                            AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                            AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                            AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                            AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                            AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                            AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;

                            % Selección tipo de Albedo
                            if app.AlbedoDropDown.Value == "Directo"
                                Alb = AlbDir;
                            elseif app.AlbedoDropDown.Value == "Difuso"
                                Alb = AlbDif;
                            elseif app.AlbedoDropDown.Value == "Neto"
                                Alb = AlbNet;
                            end
                        end
                    end

                end
                % Mostrar los resultados en la tabla
                if mat == 1
                    if app.FactordeasimetraDropDown.Value == "Debye"
                        Datos =  [f_eV Qabs' Qsca' Qext' Alb' Coalb' FAsymmetryED'];
                    else
                        Datos =  [f_eV Qabs' Qsca' Qext' Alb' Coalb' FAsymmetry'];
                    end
                    app.UITable.ColumnName = ["Energía (eV)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Albedo","Coalbedo", "Factor de asimetría"];
                    app.UITable.ColumnFormat = {'shortE','shortE','short','short','short','shortE','short'};
                    % shortEng es para varios decimales a diferencia de
                    % shortE que coge sólo la priemra cifra
                else
                    app.UITable.ColumnName = ["Energía (eV)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Sección óptica de absorción","Sección óptica de dispersión","Sección óptica de extinción","Sección específica de absorción","Sección específica de dispersión","Sección específica de extinción","Albedo","Coalbedo"];
                    app.UITable.ColumnFormat = {'short','shortE','short','short','short','shortE','short'};
                    Datos =  [f_eV Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                    %                      Datos =  [f_eV' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                end
                app.UITable.Data = Datos;
                Datos2=Datos;
                load cont cont
                % DATOS DE LA PRIMERA EJECUCIÓN
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end


                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,Qabs,'r'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,Qsca,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,f_eV,Qext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,Qabs,'r',f_eV,Qsca,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,Qabs,'r',f_eV,Qext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,min:((max-min)/5):max)

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,Qsca,f_eV,Qext); xlabel(app.UIAxes_imagen_inicial,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV, Qabs,'r',f_eV,Qsca,'b',f_eV,Qext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,CSabs,'r'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,CSsca,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,f_eV,CSext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,CSabs,'r',f_eV,CSsca,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,CSabs,'r',f_eV,CSext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,CSsca,f_eV,CSext); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV, CSabs,'r',f_eV,CSsca,'b',f_eV,CSext,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,MAC,'r'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,MSC,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,f_eV,MEC,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_eV,MAC,'r',f_eV,MSC,'b'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,MAC,'r',f_eV,MEC,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV,MSC,f_eV,MEC); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_eV, MAC,'r',f_eV,MSC,'b',lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,f_eV,Alb,'k'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,f_eV,MSC,'--k'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,f_eV,Alb,'k',f_eV, Coalb,'--k'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,f_eV,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Energía (eV)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    xticks(app.UIAxes_arriba,'auto')
                end


                %------------------------------------------------------------------------------------------
                %-------------------------------------------------------------------------------------------
                %%%%% Barrido de frecuencia (Hz)
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 116
                % Los valores mínimo y máximo dependen de la selección
                min_f_Hz = app.ValorminimoEditField.Value;
                max_f_Hz = app.ValormaximoEditField.Value;

                % factor de conversión de eV a Hz
                hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                min=(1240/min_f_Hz)/hz;
                max=(1240/max_f_Hz)/hz;

                n = n( landa >= min & landa <= max );
                k = k( landa >= min & landa <= max );
                landa = landa( landa >= min & landa <= max );

                %                 landa = landa( landa >= min & landa <= max );
                %                 n = n( landa >= min & landa <= max );
                %                 k = k( landa >= min & landa <= max );
                m = n-k*1i;
                lambda = landa;

                f_eV = 1240./landa; % Frecuencia en eV
                % factor de conversión de eV a Hz
                hz=4.1356655385381E-15; % 1 Jz es 4.13 E-15 eV
                f_Hz = f_eV/hz;% Frecuencia en Hz


                landa = landa( landa >= min & landa <= max );
                n = n( landa >= min & landa <= max );
                k = k( landa >= min & landa <= max );
                m = n-k*1i;
                lambda = landa;

                %%%% AEROSOLES
                %%% PARTÍCULAS ESFÉRICAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 2

                    % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000; % g
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    Ap0=(pi()*(rp0/(10^9))^2); % m^2

                    %% MÉTODO DE RAYLEIGH
                    for j = 1:length(lambda)
                        x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                        Qsca(j)=(8/3)*x(j).^4*(abs(((m(j).^2)-1)/((m(j).^2)+2)).^2);
                    end

                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for j=1:length(lambda)
                            Qabs(j)=-4*x(j)*(imag(((m(j).^2)-1)/((m(j).^2)+2)));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*imag(((m(j).^2)-1)/((m(j).^2)+2).*(1+((((x(j).^2)./15)).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3)))+((8/3).*x(j).^4.*real(((m(j).^2)-1)./((m(j).^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m(j).^2)-1)./((m(j).^2)+2)).^2))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*((imag(((m(j).^2)-1)./((m(j).^2)+2)))+imag((((x(j).^2)./15)).*((m(j).^2)-1)./((m(j).^2)+2).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        %% MÉTODO DE MIE
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            y=m(j)*x;
                            n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            Qext(j)=(2/x^2)*suma;
                            Qsca(j)=(2/x^2)*sumasca;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j) = 1-Alb(j);
                        end
                    end

                    for j=1:length(lambda)
                        Alb(j)=Qsca(j)/Qext(j);
                        Coalb(j)=1-Alb(j);

                        CSabs(j)= Qabs(j)*Ap0; % m^2
                        CSsca(j)= Qsca(j)*Ap0;
                        CSext(j)= Qext(j)*Ap0;
                        MAC(j)= CSabs(j)/mp; % m^2/g
                        MSC(j)= CSsca(j)/mp;
                        MEC(j)= CSext(j)/mp;
                    end

                    % Si estudio hielo calcular factor de asimetría
                    if mat == 1
                        if app.FactordeasimetraDropDown.Value == "Debye"
                            FAsymmetry=g;
                        elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                            for j=1:length(lambda)
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                            end
                        elseif app.FactordeasimetraDropDown.Value == "Yang"
                            for j=1:length(lambda)
                                FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                            end
                        end
                    end

                    %%% AGLOMERADOS DE ESFERAS
                elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                    Ap0=(pi()*rp0^2);
                    z1=log(np0)/(log((0.8488*np0)+0.1512));
                    z3=1/(0.6667+(0.2003/log(np0)));
                    z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                    Ap=Ap0*(np0^(1/z));
                    K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                    K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                    in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                    K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                    Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                    rg=rp0*((np0/Kf)^(1/Df));
                    K=(2*pi)./lambda;
                    x=(2*pi*rp0)./lambda;

                    % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); % m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); % m^2
                    % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*(imag(((m(i)^2)-1)/((m(i)^2)+2)));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*imag(((m(i)^2)-1)/((m(i)^2)+2)*(1+((((x(i)^2)/15))*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3)))+((8/3)*x(i).^4*real(((m(i)^2)-1)/((m(i)^2)+2))^2)-((8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2))));
                            Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*((imag(((m(i)^2)-1)/((m(i)^2)+2)))+imag((((x(i)^2)/15))*((m(i)^2)-1)/((m(i)^2)+2)*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3))));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % %                            % Ecuación de Mie
                        % %                         elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        % %                             incr=0.000000001;
                        % %                             for j=1:length(lambda)
                        % %                                 x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                        % %                                 y(j)=m(j).*x(j); nn=1;
                        % %                                 nmax(j)=round(2+x(j)+4*x(j)^(1/3));
                        % %                                 suma=0; sumasca=0; g_sum=0;
                        % %                                 while nn<=nmax
                        % %                                     shix(j)=(pi*x(j)/2)^0.5*besselj(nn+0.5,x(j));shix1(j)=(pi*(x(j)+incr)/2)^0.5*besselj(nn+0.5,x(j)+incr);
                        % %                                     dshix(j)=(shix1(j)-shix(j))/incr;
                        % %                                     shiy(j)=(pi*y(j)/2)^0.5*besselj(nn+0.5,y(j));shiy1(j)=(pi*(y(j)+incr)/2)^0.5*besselj(nn+0.5,y(j)+incr);
                        % %                                     dshiy(j)=(shiy1(j)-shiy(j))/incr;
                        % %                                     psix(j)=(pi*x(j)/2)^0.5*besselh(nn+0.5,2,x(j));psix1(j)=(pi*(x(j)+incr)/2)^0.5*besselh(nn+0.5,2,x(j)+incr);
                        % %                                     dpsix(j)=(psix1(j)-psix(j))/incr;
                        % %                                     an(j)=(dshiy(j)*shix(j)-m(j)*shiy(j)*dshix(j))/(dshiy(j)*psix(j)-m(j)*shiy(j)*dpsix(j));
                        % %                                     bn(j)=(m(j)*dshiy(j)*shix(j)-shiy(j)*dshix(j))/(m(j)*dshiy(j)*psix(j)-shiy(j)*dpsix(j));
                        % %                                     suma=suma+(2*nn+1)*real(an(j)+bn(j));
                        % %                                     sumasca=sumasca+(2*nn+1)*((abs(an(j)))^2+(abs(bn(j)))^2);
                        % %                                 end
                        % %
                        % %                                 Qext(j)=(2/x(j)^2)*suma;
                        % %                                 Qsca(j)=(2/x(j)^2)*sumasca;
                        % %                                 Qabs(j)=Qext(j)-Qsca(j);
                        % %                                 Alb(j)=Qsca(j)/Qext(j);
                        % %                                 Coalb(j)=1-Alb(j);
                        % %
                        % %                                 CSabs(j)= Qabs(j)*Ap0; % m^2
                        % %                                 CSsca(j)= Qsca(j)*Ap0;
                        % %                                 CSext(j)= Qext(j)*Ap0;
                        % %                                 MAC(j)= CSabs(j)/mp; % m^2/g
                        % %                                 MSC(j)= CSsca(j)/mp;
                        % %                                 MEC(j)= CSext(j)/mp;
                        % %                             end
                        % %                             %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        % %                             %% Se escoge el de Fisher-Burford
                        % %                             if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                        % %                                 for i = 1: length(lambda)
                        % %                                     GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                        % %                                     Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                        % %                                 end
                        % %
                        % %                                 %% Se escoge el de Köylü-Faeth
                        % %                             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                        % %                                 for i = 1: length(lambda)
                        % %                                     beta=(3*Df)/(8*(K(i)*rg)^2);
                        % %                                     if beta>=1
                        % %                                         GKF(i)=1-((2*(K(i)*rg)^2)/3);
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     elseif beta<1 && Df==2
                        % %                                         GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     else
                        % %                                         GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                        % %                                         Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                        % %                                     end
                        % %                                 end
                        % %                             end
                        % %                             for i = 1: length(lambda)
                        % %                                 Qext(i)=Qabs(i)+Qsca(i);
                        % %                                 Alb(i)=Qsca(i)/Qext(i);
                        % %                                 Coalb(i)=1-Alb(i);
                        % %
                        % %                                 CSabs(i) = Qabs(i)*Ap_prima; % m^2
                        % %                                 CSsca(i) = Qsca(i)*Ap_prima;
                        % %                                 CSext(i) = Qext(i)*Ap_prima;
                        % %
                        % %                                 MAC(i) = CSabs(i)/mp;
                        % %                                 MSC(i) = CSsca(i)/mp;
                        % %                                 MEC(i) = CSext(i)/mp;
                        % %                             end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11

                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            R1=rp0*np0;
                            R2=((2*in2)+1)*rp0;
                            R3=((2*in3)+1)*rp0;
                            R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                            fv=(np0*(rp0^3))/(exp(R)^3);

                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            fv = np0.^(1-(3/Df));
                            % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                            % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                            Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                            Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                            % Generar con esos puntos los valores de A,B,C
                            % polyfit(x,y,2) devuelve un vector con los coeficientes del
                            % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                            % Para representar la recta, se sacan los puntos con polyval
                            a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                            A=a(1); B=a(2); C=a(3);
                            Kf = A*(Df.^2)+B*Df+C;
                            rg = rp0*((np0/Kf).^(1/Df));
                            R = rg*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                            R = log(R);
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        load indice_refraccion.mat landa n k f_eV f_Hz
                        real_part=n; imag_part=k;

                        for i=1:length(lambda)
                            x=(2*pi*exp(R))./lambda;
                            n=1;nmax=round(2+x+4*x.^(1/3));
                            if landa(i)==floor(lambda(i))% revisar que aproxime al más próximo
                                m(i) = real_part(i)-imag_part(i)*1i;
                            end
                        end
                        % área proyectada
                        Ap0=(pi()*(rp0/(10^9))^2); %m^2
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap=Ap0*(np0^(1/z)); %m^2

                        % densidad hollín
                        ro = 1850; % en kg/m^3
                        % Como MAC suele darse en m^2/g se pasa masa a gramos
                        mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g


                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(lambda)
                                m2B(i)=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m(i)^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m(i)^2))^2+(2*m(i)^2)))/2)^0.5;
                                yB=sqrt(m2B(i))*x;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i=1:length(lambda)
                                %                                     m2MG=((((m^2)*((2*fv)+1))+(2*(-fv+1)))/(((m^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                m2MG(i)=((((m(i)^2)*((2*fv)+1))+(2*(-fv+1)))/(((m(i)^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                m2B = m2MG;
                                yMG=sqrt(m2MG(i))*x; yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(lambda)
                                m2LL(i)=((1+(2*fv*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv*(((m(i)^2)-1)/((m(i)^2)+2)))))^0.5;
                                m2B = m2LL;
                                yLL=sqrt(m2LL(i))*x; yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end


                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(lambda)
                                k_(i)=2*pi/lambda(i); % k_ para diferenciarlo de la parte imaginaria de m
                                alfa(i)=(m(i)^2-1)/(1-(m(i)^2-1)*(2/3*(1-k_(i)*rp0*1i)*exp(k_(i)*rp0*1i)-1));
                                m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                m2B = m2LV;
                                yLV=sqrt(m2LV(i))*x; yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;
                            for i=1:length(lambda)
                                % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                a = v;
                                b(i) = m(i)^2+2-2*v -fv*(m(i)^2-1)*(v+1);
                                c(i) = v - (m(i)^2+2) - fv*(m(i)^2-1)*(2-v);
                                % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                if v == 0
                                    mef1(i) = ((-c(i)/b(i))^0.5);
                                    mef2(i) = -((-c(i)/b(i))^0.5);
                                    mef = [mef1;mef2];

                                    % Soluciones a la bicuadrático
                                else
                                    mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef = [mef1;mef2;mef3;mef4];
                                end
                                %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                m2B = m2LL;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            for i = 1:length(lambda)
                                a=app.aEditField.Value;
                                mef(i)=(fv*(m(i)^a)+(1-fv))^1/a;
                                m2B = mef;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end
                        end
                        for i = 1:length(lambda)
                            Qabs(i)=Qext(i)-Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap; % m^2
                            CSsca(i) = Qsca(i)*Ap;
                            CSext(i) = Qext(i)*Ap;

                            MAC(i) = CSabs(i)/mp; % m^2/g
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end
                    end
                end

                %% MANTO DE PARTÍCULAS EN EL CASO DEL HIELO
                if mat == 1

                    % Si se escoge el análisis de hielo hay que calcular el factor de asimetría
                    % Coger parámetros factor de asimetría.
                    % Debye ninguno, Macke gref y gdif y Yang añade fd
                    fd = app.fdEditField.Value;
                    gref = app.grefEditField.Value;
                    gdif = app.gdifEditField.Value;

                    % Parámetros de la densidad y de profundidad manto
                    Profundidadmin = app.Profundidad_min.Value;
                    Profundidadmax = app.Profundidad_max.Value;
                    Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;
                    Profundidad=(Profundidadmin):1:(Profundidadmax);
                    for j=1:size(Profundidad,2)
                        ronieve(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
                    end

                    % Valor del ángulo cenital y de la nubosidad
                    AngZenith = app.ngulocenitalEditField.Value;
                    Cloud = app.NubosidadEditField.Value;

                    % Se escoge hacer el estudio con MÉTODO DE MIE
                    if app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            y=m(j)*x;
                            n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            assignin('base','suma',suma)
                            Qext(j)=(2/x^2)*suma;
                            Qsca(j)=(2/x^2)*sumasca;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j) = 1-Alb(j);

                            % Cálculo del valor del factor de asimetría
                            if app.FactordeasimetraDropDown.Value == "Debye"
                                Eext_sum=0;g_sum=0;Esca_sum=0;
                                for n=1:nmax
                                    shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                    dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                    shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                    dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                    psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                    dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                    an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                    Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                    Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                    As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                    if (abs(an(n,1))<1e-8)
                                        break
                                    end
                                    if (abs(bn(n,1))<1e-8)
                                        break
                                    end
                                end
                                nmat(j,1)=n;
                                for t=1:(n-1)
                                    g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                                end
                                Qext(j)=2/x^2*Eext_sum;
                                Qsca(j)=2/x^2*Esca_sum;
                                Qabs(j) = Qext(j)-Qsca(j);
                                g(j,1)=4/(x^2*Qsca(j))*g_sum;% Ecuación Debye por términos de Mie
                                Alb(j)=Qsca(j)/Qext(j);
                                FAsymmetry=g;
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                                FAsymmetry(j)=((gdif./(2*Alb(j)))+((1-(1./(2*Alb(j))))*gref));
                                FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));

                            elseif app.FactordeasimetraDropDown.Value == "Yang"
                                % Para fd distinto de 0 no se aplica eddington posterior
                                if fd==0
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=(FAsymmetry(j))/(1+FAsymmetry(j));
                                else
                                    FAsymmetry(j)=fd+(gdif/(2*Alb(j)))+(gref*(1-fd-(1/(2*Alb(j)))));
                                    FAsymmetryED(j)=FAsymmetry(j);
                                end
                            end

                            R=Cloud/100;
                            mu=cosd(AngZenith);
                            AlbED(j)=((1-(FAsymmetry(j)^2))*Alb(j))/(1-((FAsymmetry(j)^2)*Alb(j)));
                            if AlbED(j)>1.0
                                AlbED(j)=0.99999;
                            end
                            aED(j)=1-(AlbED(j)*FAsymmetryED(j));
                            bED(j)=(FAsymmetryED(j)/aED(j));
                            ED(j)=(3*aED(j)*(1-AlbED(j)))^(0.5);
                            P(j)=(2*ED(j))/(3*aED(j));
                            AlbDir(j)=(AlbED(j)*(1-(bED(j)*ED(j)*mu)))/((1+P(j))*(1+(ED(j)*mu)));
                            AlbDif(j)=((2*AlbED(j))/(1+P(j)))*(((1+bED(j))/(ED(j)^2))*(ED(j)-log(1+ED(j)))-(bED(j)/2));
                            AlbNet(j)=(R*AlbDif(j))+((1-R)*AlbDir(j));
                            AlbDir(AlbDir<0)=0; AlbDir(AlbDir>1)=1;
                            AlbDif(AlbDif<0)=0; AlbDif(AlbDif>1)=1;
                            AlbNet(AlbNet<0)=0; AlbNet(AlbNet>1)=1;

                            % Selección tipo de Albedo
                            if app.AlbedoDropDown.Value == "Directo"
                                Alb = AlbDir;
                            elseif app.AlbedoDropDown.Value == "Difuso"
                                Alb = AlbDif;
                            elseif app.AlbedoDropDown.Value == "Neto"
                                Alb = AlbNet;
                            end
                            Coalb = 1-Alb;
                        end
                    end

                end
                % Mostrar los resultados en la tabla
                if mat == 1
                    if app.FactordeasimetraDropDown.Value == "Debye"
                        Datos =  [f_Hz Qabs' Qsca' Qext' Alb' Coalb' FAsymmetryED'];
                    else
                        Datos =  [f_Hz Qabs' Qsca' Qext' Alb' Coalb' FAsymmetry'];
                    end
                    app.UITable.ColumnName = ["Frecuencia (Hz)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Albedo","Coalbedo", "Factor de asimetría"];
                    app.UITable.ColumnFormat = {'short','shortE','short','short','short','shortE','short'};
                    % shortEng es para varios decimales a diferencia de
                    % shortE que coge sólo la priemra cifra
                else
                    app.UITable.ColumnName = ["Frecuencia (Hz)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción", "Sección óptica de absorción","Sección óptica de dispersión","Sección óptica de extinción","Sección específica de absorción","Sección específica de dispersión","Sección específica de extinción","Albedo","Coalbedo"];
                    app.UITable.ColumnFormat = {'short','shortE','short','short','short','shortE','short'};
                    Datos =  [f_Hz Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                    %                      Datos =  [f_Hz' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb' Coalb'];
                end
                app.UITable.Data = Datos;
                Datos2=Datos;
                load cont cont
                % DATOS DE LA PRIMERA EJECUCIÓN
                if cont == 1
                    save  Datos2.mat
                    % DATOS DE LA SEGUNDA EJECUCUÓN CON NUEVO MATERIAL
                elseif   cont > 2
                    save Datos2_bis.mat Datos
                end

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,Qabs,'r'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,Qsca,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_imagen_inicial,f_Hz,Qext,'g'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_imagen_inicial,'Eficiencia');
                        legend(app.UIAxes_imagen_inicial,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_imagen_inicial,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,Qabs,'r',f_Hz,Qsca,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,Qabs,'r',f_Hz,Qext,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,Qsca,f_Hz,Qext); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz, Qabs,'r',f_Hz,Qsca,'b',f_Hz,Qext,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,CSabs,'r'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,CSsca,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,f_Hz,CSext,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,CSabs,'r',f_Hz,CSsca,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,CSabs,'r',f_Hz,CSext,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,CSsca,f_Hz,CSext); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz, CSabs,'r',f_Hz,CSsca,'b',f_Hz,CSext,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,MAC,'r'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,MSC,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,f_Hz,MEC,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,f_Hz,MAC,'r',f_Hz,MSC,'b'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,MAC,'r',f_Hz,MEC,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz,MSC,f_Hz,MEC); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,f_Hz, MAC,'r',f_Hz,MSC,'b',f_Hz,MEC,'g'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,f_Hz,Alb,'k'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,f_Hz,MSC,'--k'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,f_Hz,Alb,'k',f_Hz, Coalb,'--k'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,f_Hz,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Frecuencia (Hz)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                    xticks(app.UIAxes_arriba,'auto')
                end
                % -------------------------------------------------------------------------------
                % -------------------------------------------------------------------------------
            end % Selección barrido abscisas

            % GUardad datos para poder extrapolarlos a excel
            save Datos_estudio_paramétrico Datos
            % estudio individual se asigna estudio 0 y si es paramétrico 1,
            % para indicar al exportar en qué archivo guardarlo
            estudio = 1;
            save estudio estudio

            %% CAMBIAR EL NOMBRE DE LA TABLA DEL ESTUDIO PARAMÉTRICO (1ª COLUMNA)
            % si no es hielo, ya que en el caso de hielom ya se ha
            % especificado anteriormente
            if mat ~= 1 % si no es hielo
                % Barrido de la longitud de onda
                if app.TreeAbcisas.SelectedNodes.NodeData == 111
                    app.UITable_estudio.ColumnName = {"Longitud de onda (nm)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};

                    % Barrido tamaño partícula
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 112
                    app.UITable_estudio.ColumnName = {"Diámetro de partícula (nm)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};

                    % Diámetro adimensional
                elseif  app.TreeAbcisas.SelectedNodes.NodeData == 113
                    app.UITable_estudio.ColumnName = {"Diámetro adimensional []", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};

                    % nº partícula primarias
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 114
                    app.UITable_estudio.ColumnName = {"Número de partíclas primarias", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};

                    % energía
                elseif  app.TreeAbcisas.SelectedNodes.NodeData == 115
                    app.UITable_estudio.ColumnName = {"Energía (eV)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};

                    % frecuencia
                elseif   app.TreeAbcisas.SelectedNodes.NodeData == 116
                    app.UITable_estudio.ColumnName = {"Frecuencia (Hz)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción","Sección específica de absorción (m^2)","Sección específica de dispersión (m^2)","Sección específica de extinción (m^2)","Sección óptica de absorción (m^2/g)","Sección óptica de dispersión (m^2/g)","Sección óptica de extinción(m^2/g)","Albedo de dispersión","Coalbedo de dispersión"};
                end
            end
        end

        % Menu selected function: LogartmicoMenu_5
        function LogartmicoMenu_5Selected(app, event)
            set(app.UIAxes_abajo,'xscale','log')
        end

        % Menu selected function: LinealMenu_6
        function LinealMenu_6Selected(app, event)
            set(app.UIAxes_abajo,'xscale','linear')
        end

        % Menu selected function: LogartmicoMenu_6
        function LogartmicoMenu_6Selected(app, event)
            set(app.UIAxes_abajo,'yscale','log')
        end

        % Menu selected function: LinealMenu_7
        function LinealMenu_7Selected(app, event)
            set(app.UIAxes_abajo,'yscale','linear')
        end

        % Menu selected function: LogartmicoMenu_7
        function LogartmicoMenu_7Selected(app, event)
            set(app.UIAxes_arriba,'xscale','log')
        end

        % Menu selected function: LinealMenu_8
        function LinealMenu_8Selected(app, event)
            set(app.UIAxes_arriba,'xscale','linear')
        end

        % Menu selected function: LogartmicoMenu_8
        function LogartmicoMenu_8Selected(app, event)
            set(app.UIAxes_arriba,'yscale','log')
        end

        % Menu selected function: LinealMenu_9
        function LinealMenu_9Selected(app, event)
            set(app.UIAxes_arriba,'yscale','linear')
        end

        % Menu selected function: HojadeClculoxlsxMenu
        function HojadeClculoxlsxMenuSelected(app, event)


            %Cargar valor indica tipo de estudio (individual o paramétrico)
            load estudio estudio
            % Si estudio es 0 es un estudio INDIVIDUAL
            if estudio == 0
                load Datos_estudio_individual Datos
                % Se colocan vertical los datos en el Excel
                Datos = Datos';
                xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_individual','B1');

                % Si estudio es 1 es un estudio PARAMÉTRICO
            elseif estudio == 1
                % diferentes parámetros si es hielo u otros materiales
                % como el hollín. Por eso se carga el indicador del material
                % a realizar el estudio

                load mat mat
                % Hielo
                if mat == 1
                    % si son partículas esféricas
                    if app.TreeAglomerados.SelectedNodes.NodeData == 2
                        load Datos_estudio_paramétrico Datos
                        xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_paramétrico_hielo','A2');

                        % SI es paramétrico, el archivo del manto nevado es diferente al de los
                        % otros materiales, por lo que se tiene que abrir otra pestaña
                        % si es un manto nevado
                    elseif app.TreeAglomerados.SelectedNodes.NodeData == 4
                        load Datos_estudio_nieve Datos
                        xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_paramétrico_nieve','A2');
                    end

                    % Otros materiales
                else
                    load Datos_estudio_paramétrico Datos
                    xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_paramétrico_soot','A2');
                end

                % En función del barrido se tiene la primera columna distinta
                % Barrido de longitud de onda
                if app.TreeAbcisas.SelectedNodes.NodeData == 111
                    writematrix('Longitud de onda (nm)','Resultados_estudios.xlsx')

                    % Barrido del tamaño de partícula
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 112
                    writematrix('Diámetro de la partícula (nm)','Resultados_estudios.xlsx')

                    % Barrido del tamaño de partícula adimensional
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 113
                    writematrix('Diámetro adimensional []','Resultados_estudios.xlsx')

                    % Barrido del nº de partículas primarias
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 114
                    writematrix('Nº de partículas primarias','Resultados_estudios.xlsx')

                    % Energía
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 115
                    writematrix('Energía (eV)','Resultados_estudios.xlsx')

                    % Frecuencia Hz
                elseif app.TreeAbcisas.SelectedNodes.NodeData == 116
                    writematrix('Frecuencia (Hz)','Resultados_estudios.xlsx')
                end

            end

            load cont cont
            if cont == 2
                % CARGAR DATOS Y EXPORTARLOS A EXCEL EN CADA PESTAÑA
                % manto contaminado
                load Datos_sup_cont Datos_sup_cont
                xlswrite('Resultados_estudios.xlsx',Datos_sup_cont,'Estudio_manto_contaminado','A2');

                % hollín
                load Datos_estudio_paramétrico Datos
                xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_paramétrico_soot','A2');

                % manto nevado
                load Datos_estudio_nieve Datos
                xlswrite('Resultados_estudios.xlsx',Datos,'Estudio_paramétrico_nieve','A2');
            end

            % ABRIR EL DOCUMENTO AL PULSAR SOBRE EL BOTÓN
            winopen('Resultados_estudios.xlsx')
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            a = app.aEditField.Value;
            if a == 1
                msgbox('En el caso de escoger la unidad del parámetro a, la regla de mezcla potencial hace referencia a una interpolación lineal','Información','help')
            elseif a == 0.5 %1/2
                msgbox('El valor de a = 1/2 se corresponde con el empleado por Birchak en 1974','Información','help')
            elseif a == 0.33 %1/3
                msgbox('El valor de a = 1/3 se corresponde con el empleado por Looyenga en 1965','Información','help')
            end
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            v = app.vEditField.Value;
            if v == 2
                msgbox('Si el valor del parámetro v es 2, la expresión empleada para determinar el índice de refracción efectivo se corresponde con la propuesta por Polder-Van Santen en 1949','Información','help')
            elseif v == 0
                msgbox('Si el valor del parámetro v es 0, la expresión empleada para determinar el índice de refracción efectivo se corresponde con la propuesta por Maxwell-Garnett','Información','help')
            end
        end

        % Selection changed function: TreeRDG_ME
        function TreeRDG_MESelectionChanged(app, event)
            % Habilitar los parámetros en el caso de Lumme Rahola (v) y en la regla potencia (a)
            %Lumme-Rahola
            if app.TreeRDG_ME.SelectedNodes.NodeData == 21
                app.vEditField.Enable = 'On';
                app.aEditField.Enable = 'Off';
                % Regla potencial
            elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                app.aEditField.Enable = 'On';
                app.vEditField.Enable = 'Off';
            else
                app.aEditField.Enable = 'Off';
                app.vEditField.Enable = 'Off';
            end
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            % Descripción de los parámetros necesarios a introducir en el
            % caso de que se escoja una distribución de partículas o una
            % caracterización morfológica media de partículas
            msgbox('Si se escoge contaminar el manto con una caracterización morfológica media de partículas se debe introducir los parámetros de dimensión fractal, radio de las partículas primarias y número de partículas primarias. En el caso de seleccionar una distribución de partículas, se deberá introducir el radio de giro medio y la desviación típica de la distribución, al igual que el número de divisiones y el radio de las partículas primarias. Se distingue entre dos análisis dentro de la distribución de partículas: imponer Df o imponer np0','Información','help')
        end

        % Button pushed function: EstudiomantocontaminadoButton
        function EstudiomantocontaminadoButtonPushed(app, event)
            load cont cont
            if app.TreeAbcisas.SelectedNodes.NodeData ~= 111
                % Aviso de manto contamindo si se hace barrido de longitud de onda
                msgbox('El estudio del manto contaminado implica parametrizar la longitud de onda','Información','help')
            end

            % Ya se ha realizado el estudio para un material y se escoge qué material se va a contaminar el manto
            cont = cont+1; % paso de 0 a 1
            % se guarda cont nuevo para saber que se debe analizar el segundo material
            save cont cont

            app.DatoscontaminacinsuperficiePanel.Enable = 'On';

            msgbox('Se debe seleccionar el material con el que contaminar el manto','Información','help')
        end

        % Selection changed function: TipodeestudioButtonGroup
        function TipodeestudioButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodeestudioButtonGroup.SelectedObject;
            if selectedButton.Value == app.EstudioparamtricoButton.Value
                app.EstudioparamtricoPanel.Visible = 'On';
                app.UIAxes_arriba.Visible = 'On';
                app.UITable_contaminado.Visible = 'Off';
                app.EjecucinindividualButton.Enable ='Off';
            elseif selectedButton.Value == app.EjecucinindividualButton_2.Value
                app.EstudioparamtricoPanel.Visible = 'Off';
                app.UIAxes_arriba.Visible = 'Off';
                app.UITable_contaminado.Visible = 'Off';
                app.EjecucinindividualButton.Enable ='On';
            end
        end

        % Button pushed function: RepresentardensidadButton
        function RepresentardensidadButtonPushed(app, event)
            Profundidadmin = app.Profundidad_min.Value;
            Profundidadmax = app.Profundidad_max.Value;
            Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;

            Profundidad=(Profundidadmin):1:(Profundidadmax);
            ronieve=Densidadsuperficial-((20470./Profundidad).*(1-exp(-Profundidad./67.3)));
            %             for j=1:size(Profundidad,2)
            %                 ronieve(j)=Densidadsuperficial-((20470/Profundidad(j))*(1-exp(-Profundidad(j)/67.3)));
            %             end
            plot(app.UIAxes_arriba,Profundidad,ronieve,'k')
            xlabel(app.UIAxes_arriba,'Profundidad del manto (cm)')
            ylabel(app.UIAxes_arriba,'Densidad nieve (kg/m^3)');
            xticks(app.UIAxes_arriba,'auto'); yticks(app.UIAxes_arriba,'auto')
            app.UITable_estudio.Data = [Profundidad' ronieve'];
            app.UITable_estudio.ColumnName = ["Profundidad del manto (cm)" "Densidad de la nieve (kg/m^3)"];
            save Densidad_nieve Profundidad ronieve
        end

        % Selection changed function: TreeOrdenadas
        function TreeOrdenadasSelectionChanged(app, event)
            %% MOSTRAR LOS CHECKBOX RESPECTIVOS DE CADA SELECCIÓN:
            % EFICIENCIAS, SECCIONES ÓPTICAS Y ESPECÍFICAS
            if app.TreeOrdenadas.SelectedNodes.NodeData == 201 || app.TreeOrdenadas.SelectedNodes.NodeData == 202 || app.TreeOrdenadas.SelectedNodes.NodeData == 203
                app.AbsorcinCheckBox.Visible = 'On';
                app.DispersinCheckBox.Visible = 'On';
                app.ExtincinCheckBox.Visible = 'On';
                app.AlbedodispersinCheckBox.Visible = 'Off';
                app.CoalbedodispersinCheckBox.Visible = 'Off';
                app.RadianciaCheckBox.Visible = 'Off';
                app.IrradianciaCheckBox.Visible = 'Off';

                % ALBEDO/COALBEDO
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                app.AbsorcinCheckBox.Visible = 'Off';
                app.DispersinCheckBox.Visible = 'Off';
                app.ExtincinCheckBox.Visible = 'Off';
                app.AlbedodispersinCheckBox.Visible = 'On';
                app.CoalbedodispersinCheckBox.Visible = 'On';
                app.RadianciaCheckBox.Visible = 'Off';
                app.IrradianciaCheckBox.Visible = 'Off';

                % RADIANCIA/IRRADIANCIA
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 206
                app.AbsorcinCheckBox.Visible = 'Off';
                app.DispersinCheckBox.Visible = 'Off';
                app.ExtincinCheckBox.Visible = 'Off';
                app.AlbedodispersinCheckBox.Visible = 'Off';
                app.CoalbedodispersinCheckBox.Visible = 'Off';
                app.RadianciaCheckBox.Visible = 'On';
                app.IrradianciaCheckBox.Visible = 'On';

                % FACTOR DE ASIMETRÍA
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                app.AbsorcinCheckBox.Visible = 'Off';
                app.DispersinCheckBox.Visible = 'Off';
                app.ExtincinCheckBox.Visible = 'Off';
                app.AlbedodispersinCheckBox.Visible = 'Off';
                app.CoalbedodispersinCheckBox.Visible = 'Off';
                app.RadianciaCheckBox.Visible = 'Off';
                app.IrradianciaCheckBox.Visible = 'Off';
            end

            %             %% REPRESENTAR LOS VALORES EN FUNCIÓN DE LO QUE SE ESCOJA

            %             % Plotear en función de lo que se decida (ORDENADAS)
            %             % Valores que indican si están seleccionados la
            %             % absorción, dispersión, extinción, albedo o coalbedo
            %             abso = app.AbsorcinCheckBox.Value;
            %             sca = app.DispersinCheckBox.Value;
            %             ext = app.ExtincinCheckBox.Value;
            %             alb = app.AlbedodispersinCheckBox.Value;
            %             coalb = app.CoalbedodispersinCheckBox.Value;
            %             rad = app.RadianciaCheckBox.Value;
            %             irra = app.IrradianciaCheckBox.Value;
            %
            %              % RADIANCIA / IRRADIANCIA
            %             if app.TreeOrdenadas.SelectedNodes.NodeData == 206
            %                 load Radiancias
            %                 if rad == 1 && irra == 0
            %                     plot(app.UIAxes_imagen_inicial,lambda,radiancia,'m'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
            %                     legend(app.UIAxes_imagen_inicial,{'Radiancia'},'Location','best')
            %                     ylabel(app.UIAxes_imagen_inicial,'Radiancia');
            %
            %                 elseif rad == 0 && irra == 1
            %                     plot(app.UIAxes_imagen_inicial,lambda,irrad,'m'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
            %                     legend(app.UIAxes_imagen_inicial,{'Irradiancia'},'Location','best')
            %                     ylabel(app.UIAxes_imagen_inicial,'Irradiancia');
            %
            %                 elseif rad == 1 && irra == 1
            %                     plot(app.UIAxes_imagen_inicial,lambda,radiancia,'m',lambda,irrad,'--m'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
            %                     legend(app.UIAxes_imagen_inicial,{'Radiancia','Irradiancia'},'Location','best')
            %
            %                 end
            %                 xticks(app.UIAxes_imagen_inicial,'auto')
            %                 yticks(app.UIAxes_imagen_inicial,'auto')
            %             end

        end

        % Selection changed function: TreeAbcisas
        function TreeAbcisasSelectionChanged(app, event)

            % Barrido de la longitud de onda
            if app.TreeAbcisas.SelectedNodes.NodeData == 111
                min = 300;
                max = 2000;
                % Barrido diámetro partículas
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 112
                min = 20;
                max = 400;
                % Barrido diámetro partículas adimensional
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 113
                min = 0.1;
                max = 2;
                % nº partículas adimensional
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 114
                min = 10;
                max = 1000;
                % energía (eV)
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 115
                min = 4.13;
                max = 0.62;
                % frecuencia (Hz)
            elseif app.TreeAbcisas.SelectedNodes.NodeData == 116
                min = 1e15;
                max = 1.5e14;
            end
            app.ValorminimoEditField.Value = min;
            app.ValormaximoEditField.Value = max;
        end

        % Value changed function: FactordeasimetraDropDown
        function FactordeasimetraDropDownValueChanged(app, event)
            if app.FactordeasimetraDropDown.Value == "Debye"
                app.fdEditField.Visible = 'Off';
                app.grefEditField.Visible = 'Off';
                app.gdifEditField.Visible = 'Off';
                app.fdEditFieldLabel.Visible = 'Off';
                app.grefEditFieldLabel.Visible = 'Off';
                app.gdifEditFieldLabel.Visible = 'Off';
            elseif app.FactordeasimetraDropDown.Value == "Macke et al."
                app.fdEditField.Visible = 'Off';
                app.grefEditField.Visible = 'On';
                app.gdifEditField.Visible = 'On';
                app.fdEditFieldLabel.Visible = 'Off';
                app.grefEditFieldLabel.Visible = 'On';
                app.gdifEditFieldLabel.Visible = 'On';
                app.grefEditField.Value = 0.78;
                app.gdifEditField.Value = 0.997;
            elseif app.FactordeasimetraDropDown.Value == "Yang"
                app.fdEditField.Visible = 'On';
                app.grefEditField.Visible = 'On';
                app.gdifEditField.Visible = 'On';
                app.fdEditFieldLabel.Visible = 'On';
                app.grefEditFieldLabel.Visible = 'On';
                app.gdifEditFieldLabel.Visible = 'On';
                app.fdEditField.Value = 0.02;
                app.grefEditField.Value = 0.785;
                app.gdifEditField.Value = 0.987;
            end
        end

        % Value changed function: AlbedoDropDown
        function AlbedoDropDownValueChanged(app, event)
            if app.AlbedoDropDown.Value == "Directo"
                app.ngulocenitalEditField.Visible ='On';
                app.ngulocenitalEditFieldLabel.Visible ='On';
                app.NubosidadEditField.Visible ='Off';
                app.NubosidadEditFieldLabel.Visible ='Off';
            elseif app.AlbedoDropDown.Value == "Difuso"
                app.ngulocenitalEditField.Visible ='Off';
                app.ngulocenitalEditFieldLabel.Visible ='Off';
                app.NubosidadEditField.Visible ='Off';
                app.NubosidadEditFieldLabel.Visible ='Off';
            elseif app.AlbedoDropDown.Value == "Neto"
                app.ngulocenitalEditField.Visible ='On';
                app.ngulocenitalEditFieldLabel.Visible ='On';
                app.NubosidadEditField.Visible ='On';
                app.NubosidadEditFieldLabel.Visible ='On';
            end
        end

        % Selection changed function: ResultadosButtonGroup
        function ResultadosButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResultadosButtonGroup.SelectedObject;
            % Mostrar las dos gráficas
            if selectedButton.Value == app.GrficosButton.Value
                app.UITable_contaminado.Visible = 'Off';
                app.UITable_estudio.Visible = 'Off';

                % mostrar las dos tablas
            elseif selectedButton.Value == app.NumricosButton.Value
                app.UITable_contaminado.Visible = 'On';
                app.UITable_estudio.Visible = 'On';
            end
        end

        % Button pushed function: ResultadosmantocontaminadoButton
        function ResultadosmantocontaminadoButtonPushed(app, event)
            % Botón para analizar un manto contaminado (para ello es necesario tener los datos guardados de dos
            % materiales: el del manto y con la contaminación del otro material)
            load mat mat
            load cont cont

            %--------------------------------------------------------------------------------------------------
            %%%%%% ESTUDIO MATERIAL CON EL QUE CONTAMINA EL MANTO
            %--------------------------------------------------------------------------------------------------
            % Asignar los valores de los canales que se deseen mostrar
            Todos = app.TodosCheckBox.Value;
            Media = app.MediaCheckBox.Value;
            Canal_1 = app.Canal1CheckBox.Value;
            Canal_2 = app.Canal2CheckBox.Value;
            Canal_3 = app.Canal3CheckBox.Value;
            Canal_4 = app.Canal4CheckBox.Value;
            Canal_5 = app.Canal5CheckBox.Value;
            Canal_6 = app.Canal6CheckBox.Value;
            Canal_7 = app.Canal7CheckBox.Value;
            Canal_8 = app.Canal8CheckBox.Value;
            Canal_9 = app.Canal9CheckBox.Value;
            Canal_10 = app.Canal10CheckBox.Value;

            if Todos ~= 1
                % Aviso de que se debe haber seleccionado todos los canales para ver el efecto de la concentración y realizar el análisis
                msgbox('Para poder analizar el manto contaminado se ha debido marcar "Todos" en los canales','Información','help')
            end

            %Recoger los valores introducidos por el usuario
            lambda = app.LongituddeondanmEditField.Value;
            rp0 = app.RadiodelapartculaesfricanmEditField.Value;
            np0 = app.NdepartculasprimariasEditField.Value;
            Df = app.DimensinfractalEditField.Value;
            % Cargar datos del índice de refracción
            load indice_refraccion.mat landa n k f_eV f_Hz

            % Valores del rango del estudio escogido
            min = app.ValorminimoEditField.Value;
            maximo = app.ValormaximoEditField.Value;

            % densidad hollín
            ro = 1850; % en kg/m^3
            %             landa = landa( landa >= min & landa <= max );
            load Albedo_nieve lambda
            landa = lambda;
            %             load landa_hielo landa_hielo
            %             landa = landa_hielo; % tiene que tener el mismo tamaño de longitud de onda que el manto nevado

            n = n( landa >= min & landa <= maximo );
            k = k( landa >= min & landa <= maximo );
            m = n-k*1i;
            lambda = landa;
            lambda_soot = lambda;
            assignin('base','lambda_hollin',lambda)
            %-----------------------------------------------------------------------------------------------
            %-----------------------------------------------------------------------------------------------
            %%%% CÁLCULO DISTRIBUCIÓN

            % --------------------------------------------------------
            divisiones = app.divisionesEditField.Value;
            rg_media = app.rgmedianmEditField.Value;
            rg_desv = app.rgdesviacinnmEditField.Value;

            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                if divisiones <= 10
                    %introducir cambios en iteraciones y distribución
                    nd = divisiones;
                    % Introducir la media y la desviación para sacar la distribución gaussiana
                    % LA ECUACIÓN VIENE CON EL DIÁMETRO, de ahí que haya que multiplicar por 2 la media y la desviación
                    mu=2*rg_media; % la expresión de la normal viene en diámetro expresada
                    sigma=2*rg_desv;
                    % se sacan los límites de la distribución para sacar el
                    % vector "y" de las abcisas y la función gaussiana "f"
                    lim_inf=mu-2*sigma;
                    lim_sup=mu+2*sigma;
                    y = lim_inf:0.1:lim_sup;
                    f = exp(-(((y)-(mu)).^2)./(2*(sigma)^2))./((sigma)*sqrt(2*pi));

                    % Para generar el diagrama de barras con la contribución
                    % de cada tamaño se debe generar un vector de tamaños:
                    % Crear dos vectores que vayan desde la media, máximo, hasta los límites especificados ("mm" y "p") y juntarlos en un vector "t"
                    a=0;
                    % Si es par
                    if rem(nd,2)==0
                        a=nd/2;
                        mm=mu-(nd-1)*sigma/a:sigma*2/a:mu-sigma/a;
                        p=mu+sigma/a:sigma*2/a:mu+(nd-1)*sigma/a;
                        t=[mm p];

                        % Si sólo hay una división es la media
                    elseif nd==1
                        t=mu;

                        % SI es impar la media está en el centro
                    elseif rem(nd,2)~=0
                        a=nd/2;
                        b=1:100;
                        c=nd-nd/2-1/2;
                        mm=mu-c*sigma*4/nd:(sigma*4/nd):mu-(sigma*4/nd);
                        p=mu+sigma*4/nd:sigma*4/nd:mu+c*sigma*4/nd;
                        t=[mm mu p];
                    end
                    rg=t/2;

                    % crear el vector de tamaño el de "t" para poder crear el diagrama de barras
                    d=[];
                    if rem(nd,2)==0
                        for i=1:length(y)/2
                            for j=1:length(t)
                                if y(i) == round(t(j))
                                    d=[d f(i)];
                                end
                            end
                        end
                        e=fliplr(d);
                        d=[d e];
                    elseif rem(nd,2)~=0 && nd~=1
                        for l=1:length(y)/2
                            for k=1:length(t)
                                if y(l)== round(t(k))
                                    d=[d f(l)];
                                end
                            end
                        end
                        e=fliplr(d);

                        d=[d max(f) e];
                    elseif nd==1
                        d=max(f);
                    end
                    if rem(nd,2)==0
                        g=d/0.93; %factor del error del 95.5% y de la discretización
                    elseif rem(nd,2)~=0
                        g=d;
                    elseif nd==1
                        g=d;
                    end

                    %-------------------------------------------------------
                    % plot de diámetro de giro con diagrama de barras
                    dg=2*rg; % diámetro de giro
                    ancho=(lim_sup-lim_inf)/nd;
                    for rep=1:length(dg)
                        % Se define la función a integrar
                        fun=@(w) exp(-(((w)-(mu)).^2)./(2*(sigma)^2))./((sigma)*sqrt(2*pi()));
                        if nd==1
                            I(rep)=integral(fun,lim_inf,lim_sup);
                        else
                            ancho=dg(2)-dg(1);
                            I(rep)=integral(fun ,dg(rep)-(ancho/2),dg(rep)+(ancho/2));
                            %I(rep)=integral(fun,160,200);
                        end
                        H(rep)=(I(rep))/ancho;
                        H(rep)=H(rep)/0.9545;
                        % considerar que el alto no es el total, se ha limitado para 95.45%, y no es en tanto por uno
                        % Esta H va a ser fn (FRACCIÓN NUMÉRICA) empleada en la siguiente pantalla
                    end

                    n=divisiones; %número divisionses
                    Liminf=lim_inf; %ímite inferior
                    a=ancho; %ancho columna
                    q=0; %contador
                    pos=1; %contador

                    %Eje de abcisas
                    x_eje=ones(1,n*3+1)*Liminf;
                    for i=3:length(x_eje)
                        if q==3
                            q=0;
                            pos=pos+1;
                        end
                        x_eje(i)=x_eje(i)+pos*a;
                        q=q+1;
                    end
                    H=H*ancho;

                    %Eje de ordenadas
                    y_eje=[0];
                    for j=1:length(H)
                        y_eje=[y_eje,H(j),H(j),0];
                    end

                    %representación
                    plot(app.UIAxesDIST,x_eje,y_eje,y,f*ancho,'r','LineWidth',1.5)
                    xlabel(app.UIAxesDIST,'Diámetro de giro (nm)'); ylabel(app.UIAxesDIST,'Np/Npt');
                    xticks(app.UIAxesDIST,'auto')
                    %----------------------------------------------------------------------
                end
            end
            %-----------------------------------------------------

            % Selección del estudio:
            %-----------------------------------------------------------------------------------------------
            %-----------------------------------------------------------------------------------------------

            % se inicia variables para mejorar rapidez del código
            x = zeros(size(lambda));
            Qabs = zeros(size(lambda)); Qsca = zeros(size(lambda)); Qext = zeros(size(lambda));
            CSabs = zeros(size(lambda)); CSsca = zeros(size(lambda)); CSext = zeros(size(lambda));
            MAC = zeros(size(lambda)); MSC = zeros(size(lambda)); MEC = zeros(size(lambda));
            Alb = zeros(size(lambda)); Coalb = zeros(size(lambda));

            Qabsp0 = zeros(size(lambda)); Qscap0 = zeros(size(lambda));
            GFB = zeros(size(lambda)); GKF = zeros(size(lambda));

            % Caracterización morfológica media
            if app.EstudioDropDown.Value == "Tamaño único"
                %----------------------------------------
                %%% PARTÍCULAS ESFÉRICAS
                if app.TreeAglomerados.SelectedNodes.NodeData == 2

                    % Como MAC suele darse en m^2/g se pasa masa a gramos y rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000; % g
                    % Como Ap está en nm hay que pasarlo a m porque es un área
                    Ap0=(pi()*(rp0/(10^9))^2); % m^2

                    %% MÉTODO DE RAYLEIGH
                    for j = 1:length(lambda)
                        x(j)=(2*pi*rp0)/lambda(j); % Diámetro adimensional
                        Qsca(j)=(8/3)*x(j).^4*(abs(((m(j).^2)-1)/((m(j).^2)+2)).^2);
                    end

                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for j=1:length(lambda)
                            Qabs(j)=-4*x(j)*(imag(((m(j).^2)-1)/((m(j).^2)+2)));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*imag(((m(j).^2)-1)/((m(j).^2)+2).*(1+((((x(j).^2)./15)).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3)))+((8/3).*x(j).^4.*real(((m(j).^2)-1)./((m(j).^2)+2)).^2)-((8/3).*x(j).^4.*(abs(((m(j).^2)-1)./((m(j).^2)+2)).^2))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for j=1:length(lambda)
                            Qabs(j)=-4.*x(j).*((imag(((m(j).^2)-1)./((m(j).^2)+2)))+imag((((x(j).^2)./15)).*((m(j).^2)-1)./((m(j).^2)+2).*(((m(j).^4)+27.*(m(j).^2)+38)./(2.*(m(j).^2)+3))));
                            Qext(j)=Qabs(j)+Qsca(j);
                        end

                        %% MÉTODO DE MIE
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11
                        incr=0.000000001;
                        dp=2*rp0;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            y=m(j)*x;
                            n=1;
                            nmax=round(2+x+4*x^(1/3));
                            suma=0;sumasca=0;
                            while n<=nmax
                                shix=(pi.*x/2).^0.5.*besselj(n+0.5,x);shix1=(pi.*(x+incr)./2).^0.5.*besselj(n+0.5,x+incr);
                                dshix=(shix1-shix)./incr;
                                shiy=(pi.*y./2).^0.5.*besselj(n+0.5,y);shiy1=(pi.*(y+incr)./2).^0.5.*besselj(n+0.5,y+incr);
                                dshiy=(shiy1-shiy)./incr;
                                psix=(pi.*x./2)^0.5.*besselh(n+0.5,2,x);psix1=(pi.*(x+incr)./2).^0.5.*besselh(n+0.5,2,x+incr);
                                dpsix=(psix1-psix)./incr;
                                an=(dshiy.*shix-m(j).*shiy.*dshix)./(dshiy.*psix-m(j).*shiy.*dpsix);
                                bn=(m(j).*dshiy.*shix-shiy.*dshix)./(m(j).*dshiy.*psix-shiy.*dpsix);
                                suma=suma+(2.*n+1).*real(an+bn);
                                sumasca=sumasca+(2.*n+1).*((abs(an)).^2+(abs(bn)).^2);
                                n=n+1;
                            end
                            Qext(j)=(2/x^2)*suma;
                            Qsca(j)=(2/x^2)*sumasca;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            Coalb(j) = 1-Alb(j);
                        end
                        %-----------------------------------------------------------------------------------------------------------------------
                        % Cálculo del valor del factor de asimetría
                        refractive_index=m;
                        for j=1:length(lambda)
                            x=2*pi*rp0/lambda(j);
                            m=refractive_index(j);
                            nmax=round(2+x+4*x^(1/3));
                            y=m*x;
                            Eext_sum=0;g_sum=0;Esca_sum=0;
                            for n=1:nmax
                                shix=(pi*x/2)^0.5*besselj(n+0.5,x);
                                dshix=(pi/(8*x))^0.5*besselj(n+0.5,x)+(pi*x/2)^0.5*(-besselj(n+1.5,x)+((n+0.5)/x)*besselj(n+0.5,x));
                                shiy=(pi*y/2)^0.5*besselj(n+0.5,y);
                                dshiy=(pi/(8*y))^0.5*besselj(n+0.5,y)+(pi*y/2)^0.5*(-besselj(n+1.5,y)+((n+0.5)/y)*besselj(n+0.5,y));
                                psix=(pi*x/2)^0.5*besselh(n+0.5,2,x);
                                dpsix=(pi/(8*x))^0.5*besselh(n+0.5,2,x)+(pi*x/2)^0.5*(-besselh(n+1.5,2,x)+((n+0.5)/x)*besselh(n+0.5,2,x));
                                an(n,1)=(dshiy*shix-m*shiy*dshix)/(dshiy*psix-m*shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                bn(n,1)=(m*dshiy*shix-shiy*dshix)/(m*dshiy*psix-shiy*dpsix); % ecuación Mie van de Hulst pág 123
                                Eext_sum=Eext_sum+(2*n+1)*real(an(n,1)+bn(n,1));
                                Esca_sum=Esca_sum+(2*n+1)*((abs(an(n,1)))^2+(abs(bn(n,1)))^2);
                                As(n,1)=shix;As(n,2)=dshix;As(n,3)=shiy;As(n,4)=dshiy;As(n,5)=psix;As(n,6)=dpsix;As(n,7)=an(n,1);As(n,8)=bn(n,1);
                                if (abs(an(n,1))<1e-8)
                                    break
                                end
                                if (abs(bn(n,1))<1e-8)
                                    break
                                end
                            end
                            nmat(j,1)=n;
                            for t=1:(n-1)
                                g_sum=g_sum+t*(t+2)/(t+1)*real(an(t,1)*conj(an(t+1,1))+bn(t,1)*conj(bn(t+1,1)))+(2*t+1)/(t*(t+1))*real(an(t,1)*conj(bn(t,1)));
                            end
                            Qext(j)=2/x^2*Eext_sum;
                            Qsca(j)=2/x^2*Esca_sum;
                            Qabs(j) = Qext(j)-Qsca(j);
                            Alb(j)=Qsca(j)/Qext(j);
                            g(j)=4/(x^2*Qsca(j))*g_sum; % Ecuación Debye por términos de Mie
                            FAsymmetry=g;
                        end
                        %-----------------------------------------------------------------------------------------------------------------------
                    end

                    % en el caso de partículas esféricas el área proyectada
                    % es la de un círculo del radio de la esfera
                    Ap = pi*rp0^2;
                    save Ap_soot Ap

                    for j=1:length(lambda)
                        Alb(j)=Qsca(j)/Qext(j);
                        Coalb(j)=1-Alb(j);

                        CSabs(j)= Qabs(j)*Ap0; % m^2
                        CSsca(j)= Qsca(j)*Ap0;
                        CSext(j)= Qext(j)*Ap0;
                        MAC(j)= CSabs(j)/mp; % m^2/g
                        MSC(j)= CSsca(j)/mp;
                        MEC(j)= CSext(j)/mp;
                    end

                    %----------------------------------------
                elseif app.TreeAglomerados.SelectedNodes.NodeData == 3
                    %% AGLOMERADOS DE ESFERAS
                    Ap0=(pi()*rp0^2);
                    z1=log(np0)/(log((0.8488*np0)+0.1512));
                    z3=1/(0.6667+(0.2003/log(np0)));
                    z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                    Ap=Ap0*(np0^(1/z));
                    K1=np0/(((3/5)+(1/3)*(np0^2-1))^0.5);
                    K2=np0/((3/5)+((10*(np0^2)-(8*np0)-2)/(18*np0)));
                    in=((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0)/20)+((1/120)*((324*(np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                    K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                    Kf=(((K1/2)-K2+(K3/2))*(Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df)+((3*K1)-(3*K2)+K3);
                    rg=rp0*((np0/Kf)^(1/Df));
                    K=(2*pi)./lambda;
                    x=(2*pi*rp0)./lambda;

                    % Como Ap está en nm hay que pasarlo a m porque es un área (se denotan prima porque son de otras dimensiones a las empleadas anteriormente)
                    Ap0_prima=(pi()*(rp0/(10^9))^2); % m^2
                    Ap_prima=Ap0_prima*(np0^(1/z)); % m^2
                    % Como MAC suele darse en m^2/g se pasa masa a gramos rp0 pasa de nm a m
                    mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g

                    %%%%%%------------------------------------------------
                    %%%%%%------------------------------------------------
                    %% MÉTODO DE RAYLEIGH
                    for i = 1: length(lambda)
                        % Ecuación de Rayleigh
                        if app.TreeMethod.SelectedNodes.NodeData == 8
                            Qabsp0(i)=-4*x(i)*(imag(((m(i)^2)-1)/((m(i)^2)+2)));

                            % Ecuación de Bohren & Huffmann
                        elseif app.TreeMethod.SelectedNodes.NodeData == 9
                            Qabsp0(i)=-4*x(i)*imag(((m(i)^2)-1)/((m(i)^2)+2)*(1+((((x(i)^2)/15))*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3)))+((8/3)*x(i).^4*real(((m(i)^2)-1)/((m(i)^2)+2))^2)-((8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2))));

                            % Ecuación de Dobbins & Megaridis
                        elseif app.TreeMethod.SelectedNodes.NodeData == 10
                            Qabsp0(i)=-4*x(i)*((imag(((m.^2)-1)/((m.^2)+2)))+imag((((x(i)^2)/15))*((m.^2)-1)/((m.^2)+2)*(((m.^4)+27*(m.^2)+38)/(2*(m.^2)+3))));

                        end
                        Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                    end
                    %%%%%%------------------------------------------------
                    %%%%%%------------------------------------------------

                    %% MÉTODO DE RAYLEIGH
                    % Ecuación de Rayleigh
                    if app.TreeMethod.SelectedNodes.NodeData == 8
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*(imag(((m(i)^2)-1)/((m(i)^2)+2)));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de (Mountain-Mullholland) Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end

                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Bohren & Huffmann
                    elseif app.TreeMethod.SelectedNodes.NodeData == 9
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*imag(((m(i)^2)-1)/((m(i)^2)+2)*(1+((((x(i)^2)/15))*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3)))+((8/3)*x(i).^4*real(((m(i)^2)-1)/((m(i)^2)+2))^2)-((8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2))));
                            Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        % Ecuación de Dobbins & Megaridis
                    elseif app.TreeMethod.SelectedNodes.NodeData == 10
                        for i = 1: length(lambda)
                            Qabsp0(i)=-4*x(i)*((imag(((m(i)^2)-1)/((m(i)^2)+2)))+imag((((x(i)^2)/15))*((m(i)^2)-1)/((m(i)^2)+2)*(((m(i)^4)+27*(m(i)^2)+38)/(2*(m(i)^2)+3))));
                            Qabs(i)=(np0^(1-(1/z)))*Qabsp0(i);
                        end

                        %% RAYLEIGH-DEBYE-GANS correcciones de la dispersión
                        %% Se escoge el de Fisher-Burford
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                            for i = 1: length(lambda)
                                GFB(i)=(1+((4*(K(i)^2)*(rg^2))/(3*Df)))^(-Df*0.5);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GFB(i);
                            end

                            %% Se escoge el de Köylü-Faeth
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                            for i = 1: length(lambda)
                                beta=(3*Df)/(8*(K(i)*rg)^2);
                                Qscap0(i)=(8/3)*x(i).^4*(abs(((m(i)^2)-1)/((m(i)^2)+2))^2);
                                if beta>=1
                                    GKF(i)=1-((2*(K(i)*rg)^2)/3);
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                elseif beta<1 && Df==2
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2.001-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2.001-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                else
                                    GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg*K(i))^2^0.5)^(-Df)*(3/(2-Df)-12/(6-Df)/(4-Df)-3*beta^(1-Df/2)*(1/(2-Df)-2*beta/(4-Df)+2*beta^2/(6-Df)));
                                    Qsca(i)=(np0^(2-(1/z)))*Qscap0(i)*GKF(i);
                                end
                            end
                        end
                        for i = 1: length(lambda)
                            Qext(i)=Qabs(i)+Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap_prima; % m^2
                            CSsca(i) = Qsca(i)*Ap_prima;
                            CSext(i) = Qext(i)*Ap_prima;

                            MAC(i) = CSabs(i)/mp;
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end

                        %% MÉTODO DE MIE--> MODELOS DE MEDIO EFECTIVO
                    elseif app.TreeMethod.SelectedNodes.NodeData == 11

                        %% Método de estimación del radio máximo
                        % Método geométrico
                        if app.TreeMethodRmax.SelectedNodes.NodeData == 15
                            in2=(sqrt((4*np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            R1=rp0*np0;
                            R2=((2*in2)+1)*rp0;
                            R3=((2*in3)+1)*rp0;
                            R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                            fv=(np0*(rp0^3))/(exp(R)^3);

                            % Método aproximado
                        elseif app.TreeMethodRmax.SelectedNodes.NodeData == 16
                            fv = np0.^(1-(3/Df));
                            % Para calcular rmax se debe conocer el rg que, a su vez, se debe conocer el prefactor, Kf, que se obtiene en dunción de generar
                            % una expresión de segundo orden en función de tres puntos (Df=1,2 y 3)
                            Kf1= np0/(((3/5)+(1/3)*((np0.^2)-1)).^0.5);
                            Kf2= np0/(3/5+((10*np0.^2-8*np0-47)/(18*np0)));
                            in3=(3*np0/20+((324*np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0/20+(((324*np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            Kf3= ((((3/10)*(in3^3))+(5*(in3^2))+((11/3)*in3)+1)^(5/2))/(((((118/5)*(in3^3))+(14*(in3^4))+(13*(in3^2))+((23/5)*in3)+(3/5)))^(3/2));
                            % Generar con esos puntos los valores de A,B,C
                            % polyfit(x,y,2) devuelve un vector con los coeficientes del
                            % polinomio (x es la Df(1,2,3), y son los valores de Kf y 2 es el grado)
                            % Para representar la recta, se sacan los puntos con polyval
                            a = polyfit([1 2 3],[Kf1 Kf2 Kf3],2);
                            A=a(1); B=a(2); C=a(3);
                            Kf = A*(Df.^2)+B*Df+C;
                            rg = rp0*((np0/Kf).^(1/Df));
                            R = rg*((2+Df)/Df).^0.5; % R lo considera como si fuese ln R, así que hay que pasarlo:
                            R = log(R);
                        end
                        % Parámetros comunes independientemente de la regla de mezcla
                        incr=0.000000001;
                        load indice_refraccion.mat landa n k f_eV f_Hz
                        real_part=n; imag_part=k;

                        for i=1:length(lambda)
                            x=(2*pi*exp(R))./lambda;
                            %                             n=1;
                            nmax=round(2+x+4*x.^(1/3));
                            if landa(i)==floor(lambda(i))% revisar que aproxime al más próximo
                                m(i) = real_part(i)-imag_part(i)*1i;
                            end
                        end
                        % área proyectada
                        Ap0=(pi()*(rp0/(10^9))^2); %m^2
                        z1=log(np0)/(log((0.8488*np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.95));
                        Ap=Ap0*(np0^(1/z)); %m^2

                        % densidad hollín
                        ro = 1850; % en kg/m^3
                        % Como MAC suele darse en m^2/g se pasa masa a gramos
                        mp = (4/3)*pi*((rp0/(10^9)).^3)*ro*1000*np0; % g

                        % Se escoge Bruggeman
                        if app.TreeRDG_ME.SelectedNodes.NodeData == 18
                            for i=1:length(lambda)
                                m2B(i)=((((-1.5*fv)+1)+(((1.5*fv)-0.5)*m(i)^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m(i)^2))^2+(2*m(i)^2)))/2)^0.5;
                                yB=sqrt(m2B(i))*x;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end

                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Maxwell-Garnett
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                            for i=1:length(lambda)
                                m2MG(i)=((((m(i)^2)*((2*fv)+1))+(2*(-fv+1)))/(((m(i)^2)*((-1*fv)+1))+((fv+2))))^0.5;
                                m2B = m2MG;
                                yMG=sqrt(m2MG(i))*x; yB=yMG;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lorentz-Lorenz
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                            for i=1:length(lambda)
                                m2LL(i)=((1+(2*fv*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv*(((m(i)^2)-1)/((m(i)^2)+2)))))^0.5;
                                m2B = m2LL;
                                yLL=sqrt(m2LL(i))*x; yB=yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lakatia-Vitkam
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                            for i=1:length(lambda)
                                k_(i)=2*pi/lambda(i); % k_ para diferenciarlo de la parte imaginaria de m
                                alfa(i)=(m(i)^2-1)/(1-(m(i)^2-1)*(2/3*(1-k_(i)*rp0*1i)*exp(k_(i)*rp0*1i)-1));
                                m2LV(i)=(1+(2*alfa(i)*fv)/3)^0.5/(1-(alfa(i)*fv)/3)^0.5;
                                m2B = m2LV;
                                yLV=sqrt(m2LV(i))*x; yB=yLV;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Lumme-Rahola
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                            % Se coge el valor del parámetro v
                            v=app.vEditField.Value;
                            for i=1:length(lambda)
                                % Expresión general (para v=0 se obtiene Maxwell Garnett)
                                a = v;
                                b(i) = m(i)^2+2-2*v -fv*(m(i)^2-1)*(v+1);
                                c(i) = v - (m(i)^2+2) - fv*(m(i)^2-1)*(2-v);
                                % Soluciones lineales en el caso de v=0 porque sale indeterminación
                                if v == 0
                                    mef1(i) = ((-c(i)/b(i))^0.5);
                                    mef2(i) = -((-c(i)/b(i))^0.5);
                                    mef = [mef1;mef2];

                                    % Soluciones a la bicuadrático
                                else
                                    mef1(i) = ((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef2(i) = -((-b(i)+(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef3(i) = ((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef4(i) = -((-b(i)-(b(i)^2-4*a*c(i))^0.5)/(2*a))^0.5;
                                    mef = [mef1;mef2;mef3;mef4];
                                end
                                %Cogemos el que tenga parte real positiva y parte imaginaria negativa
                                m2LL(i) = mef(real(mef(:,i)) > 0 & imag(mef(:,i)) < 0);
                                m2B = m2LL;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end

                            % Se escoge Promedio Potencial
                        elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                            % Se coge el valor del parámetro "a" de la potencial
                            for i = 1:length(lambda)
                                a=app.aEditField.Value;
                                mef(i)=(fv*(m(i)^a)+(1-fv))^1/a;
                                m2B = mef;
                                yLL=sqrt(m2B(i))*x; yB = yLL;
                                sumaB=0;  sumascaB=0; n=1; nmax(i)=round(2+x(i)+4*x(i)^(1/3));
                                while n<=nmax
                                    shix=(pi*x(i)/2).^0.5.*besselj(n+0.5,x(i)); shix1=(pi.*(x(i)+incr)/2).^0.5.*besselj(n+0.5,x(i)+incr);
                                    dshix=(shix1-shix)/incr;
                                    shiyB=(pi.*yB(i)/2).^0.5.*besselj(n+0.5,yB(i));shiy1B=(pi.*(yB(i)+incr)/2).^0.5.*besselj(n+0.5,yB(i)+incr);
                                    dshiyB=(shiy1B-shiyB)/incr;
                                    psix=(pi*x(i)/2).^0.5.*besselh(n+0.5,2,x(i));psix1=(pi*(x(i)+incr)/2).^0.5.*besselh(n+0.5,2,x(i)+incr);
                                    dpsix=(psix1-psix)/incr;
                                    anB=(dshiyB.*shix-sqrt(m2B(i)).*shiyB.*dshix)/(dshiyB.*psix-sqrt(m2B(i)).*shiyB.*dpsix);
                                    bnB=(sqrt(m2B(i)).*dshiyB.*shix-shiyB.*dshix)/(sqrt(m2B(i)).*dshiyB.*psix-shiyB.*dpsix);
                                    sumaB=sumaB+(2*n+1).*real(anB+bnB);
                                    sumascaB=sumascaB+(2*n+1).*((abs(anB))^2+(abs(bnB))^2);
                                    n=n+1;
                                end
                                Qext(i)=(2/x(i)^2)*sumaB;
                                Qsca(i)=(2/x(i)^2)*sumascaB;
                            end
                        end
                        for i = 1:length(lambda)
                            Qabs(i)=Qext(i)-Qsca(i);
                            Alb(i)=Qsca(i)/Qext(i);
                            Coalb(i)=1-Alb(i);

                            CSabs(i) = Qabs(i)*Ap; % m^2
                            CSsca(i) = Qsca(i)*Ap;
                            CSext(i) = Qext(i)*Ap;

                            MAC(i) = CSabs(i)/mp; % m^2/g
                            MSC(i) = CSsca(i)/mp;
                            MEC(i) = CSext(i)/mp;
                        end
                    end
                    save Ap_soot Ap

                end
                Alb_soot = Alb;

                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------

                % Distribución de partículas: Imponiendo Df
            elseif app.EstudioDropDown.Value == "Imponer Df"

                Df_Df = app.DimensinfractalEditField.Value;
                np0_Df = app.NdepartculasprimariasEditField.Value;
                rp0_Df = app.RadiodelapartculaesfricanmEditField.Value;
                %                 lambda = app.ValormaximoEditField.Value - app.ValorminimoEditField.Value;

                incr=0.000000001;
                K=(2*pi)./lambda;
                x=(2*pi*rp0_Df)./lambda;

                for i=1:length(lambda)
                    % modificar la iteración para un vector
                    K1=0;K2=0;K3=0;Kf=0;in=0;
                    %z=2;
                    np0_Df=Inf;
                    count=0;
                    while np0_Df>5
                        K1=3^(1/2);
                        K2=1.8;
                        K3=1.5933;
                        Kf=(((K1/2)-K2+(K3/2))*(Df_Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df_Df)+((3*K1)-(3*K2)+K3);
                        np0_Df=[];
                        for pos=1:length(rg)
                            np0_pre=Kf*((rg(pos)/rp0_Df)^Df_Df);
                            np0_Df=[np0_Df np0_pre];
                        end
                        np0_Df1=[];
                        for posi=1:length(rg)
                            K1=(np0_Df(posi))/(((3/5)+(1/3)*(((np0_Df(posi))^2)-1))^0.5);
                            K2=(np0_Df(posi))/((3/5)+((10*(np0_Df(posi)^2)-(8*np0_Df(posi))-2)/(18*np0_Df(posi))));
                            in=((((3*np0_Df(posi))/20)+((1/120)*((324*(np0_Df(posi)^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0_Df(posi))/20)+((1/120)*((324*(np0_Df(posi)^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                            K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                            Kf=(((K1/2)-K2+(K3/2))*(Df_Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df_Df)+((3*K1)-(3*K2)+K3);
                            np0_pre1=Kf*((rg(posi)/rp0_Df)^Df_Df); %np0=Kf*(rg/rp0)^Df;
                            np0_Df1=[np0_Df1 np0_pre1];
                        end
                        count=count+1;
                        break
                    end
                    np0_Df=np0_Df1;
                    %                     %---------------------
                    %                     %cálculos en los que no afecta que sea escalar o vector rg
                    %                      K=(2*pi)/lambda;
                    %                      x=(2*pi*rp0_Df)/lambda;
                    %                     %----------------------------
                    Ap0=(pi()*rp0_Df^2);
                    z1=log(np0_Df)/(log((0.8488*np0_Df)+0.1512));

                    % bucle if para si el es un escalar,1 división, o un vector, más divisiones
                    if length(np0_Df) == 1
                        z3=1/(0.6667+(0.2003/log(np0_Df)));
                        z=abs(z1-1+((z3+1-z1)^(((Df_Df-1)/2)^1.86))); % Cambio valor m 1.95 a 1.86
                        Ap=Ap0*(np0_Df^(1/z));
                        K=(2*pi)./lambda;
                        assignin('base','K',K)
                        assignin('base','rg',rg)
                        assignin('base','Df_Df',Df_Df)

                        GFB=(1+((4.*(K.^2).*(rg.^2))./(3*(Df_Df)))).^(-(Df_Df)*0.5);

                        Qabsp0=-4.*x.*(imag(((m.^2)-1)./((m.^2)+2))); %[3.19]
                        Qscap0=(8/3).*x.^4.*(abs(((m.^2)-1)./((m.^2)+2)).^2);
                        Qabs=((np0_Df).^(1-(1/z))).*Qabsp0;
                        QscaFB=((np0_Df).^(2-(1/z))).*Qscap0.*GFB;
                        QextFB=Qabs+QscaFB;
                        AlbFB=QscaFB./QextFB;
                        beta=(3.*(Df_Df))./(8.*(K.*rg).^2);
                        for b =1:length(beta)
                            if beta(b)>=1
                                GKF=1-((2.*(K.*rg).^2)./3);
                                QscaKF=(np0_Df.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            elseif beta(b)<1 && Df_Df==2
                                GKF=beta./2.*(3-3.*beta+2.*beta.^2)-beta.^2.*(rg.*K).^2/3.*(3-4.*beta+3.*beta.^2)+(2.*(rg.*K).^2.^0.5).^(-Df_Df).*(3./(2.001-Df_Df)-12./(6-Df_Df)./(4-Df_Df)-3.*beta.^(1-Df_Df./2).*(1./(2.001-Df_Df)-2.*beta./(4-Df_Df)+2.*beta.^2./(6-Df_Df)));
                                QscaKF=(np0_Df.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            else
                                GKF=beta./2.*(3-3.*beta+2.*beta.^2)-beta.^2.*(rg.*K).^2./3.*(3-4.*beta+3.*beta.^2)+(2.*(rg.*K).^2.^0.5).^(-Df_Df).*(3./(2-Df_Df)-12./(6-Df_Df)./(4-Df_Df)-3.*beta.^(1-Df_Df./2).*(1./(2-Df_Df)-2.*beta./(4-Df_Df)+2.*beta.^2./(6-Df_Df)));
                                QscaKF=(np0_Df.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            end
                        end
                        in2=(sqrt((4*np0_Df)-1)/(2*sqrt(3)))-(1/2);
                        in3=(3*np0_Df/20+((324*np0_Df^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_Df/20+(((324*np0_Df^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                        R1=rp0_Df*np0_Df;
                        R2=((2*in2)+1)*rp0_Df;
                        R3=((2*in3)+1)*rp0_Df;
                        R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df_Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df_Df)+((3*log(R1))-(3*log(R2))+log(R3));
                        fv=(np0_Df*(rp0_Df^3))/(exp(R)^3);
                        m2LL=(1+(2.*fv.*(((m.^2)-1)./((m.^2)+2))))./(1-(fv.*(((m.^2)-1)./((m.^2)+2))));
                        m2MG=(((m.^2)*((2.*fv)+1))+(2.*(-fv+1)))./(((m.^2).*((-1.*fv)+1))+((fv+2)));
                        m2B=(((-1.5.*fv)+1)+(((1.5.*fv)-0.5).*m.^2)+sqrt((((1.5.*fv)-1)-(((1.5.*fv)-0.5).*m.^2)).^2+(2.*m.^2)))./2;
                        x1=2.*pi.*exp(R)./lambda;yLL=sqrt(m2LL).*x1;yMG=sqrt(m2MG).*x1;yB=sqrt(m2B).*x1;n=1;nmax=round(2+x1+4.*x1.^(1/3));
                        sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                        while n<=nmax % expresiones en TFG Joaquín desde página 21 documento
                            shix=(pi.*x1./2).^0.5.*besselj(n+0.5,x1);shix1=(pi.*(x1+incr)./2).^0.5.*besselj(n+0.5,x1+incr);
                            dshix=(shix1-shix)/incr;
                            shiyLL=(pi.*yLL./2).^0.5.*besselj(n+0.5,yLL);shiy1LL=(pi.*(yLL+incr)./2).^0.5.*besselj(n+0.5,yLL+incr);
                            shiyMG=(pi.*yMG./2).^0.5.*besselj(n+0.5,yMG);shiy1MG=(pi.*(yMG+incr)./2).^0.5.*besselj(n+0.5,yMG+incr);
                            shiyB=(pi.*yB./2).^0.5.*besselj(n+0.5,yB);shiy1B=(pi.*(yB+incr)./2).^0.5.*besselj(n+0.5,yB+incr);
                            dshiyLL=(shiy1LL-shiyLL)/incr;
                            dshiyMG=(shiy1MG-shiyMG)/incr;
                            dshiyB=(shiy1B-shiyB)/incr;
                            psix=(pi.*x1./2).^0.5.*besselh(n+0.5,2,x1);psix1=(pi.*(x1+incr)./2).^0.5.*besselh(n+0.5,2,x1+incr);
                            dpsix=(psix1-psix)/incr;
                            anLL=(dshiyLL.*shix-sqrt(m2LL).*shiyLL.*dshix)./(dshiyLL.*psix-sqrt(m2LL).*shiyLL.*dpsix);
                            anMG=(dshiyMG.*shix-sqrt(m2MG).*shiyMG.*dshix)./(dshiyMG.*psix-sqrt(m2MG).*shiyMG.*dpsix);
                            anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)./(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                            bnLL=(sqrt(m2LL).*dshiyLL.*shix-shiyLL.*dshix)./(sqrt(m2LL).*dshiyLL.*psix-shiyLL.*dpsix);
                            bnMG=(sqrt(m2MG).*dshiyMG.*shix-shiyMG.*dshix)./(sqrt(m2MG).*dshiyMG.*psix-shiyMG.*dpsix);
                            bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)./(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                            sumaLL=sumaLL+(2.*n+1).*real(anLL+bnLL);
                            sumaMG=sumaMG+(2.*n+1).*real(anMG+bnMG);
                            sumaB=sumaB+(2.*n+1).*real(anB+bnB);
                            sumascaLL=sumascaLL+(2.*n+1).*((abs(anLL)).^2+(abs(bnLL)).^2);
                            sumascaMG=sumascaMG+(2.*n+1).*((abs(anMG)).^2+(abs(bnMG)).^2);
                            sumascaB=sumascaB+(2.*n+1).*((abs(anB)).^2+(abs(bnB)).^2);
                            n=n+1;
                        end
                        QextLL=2./x1.^2.*sumaLL;
                        QscaLL=2./x1.^2.*sumascaLL;
                        QabsLL=QextLL-QscaLL;
                        QextMG=2./x1.^2.*sumaMG;
                        QscaMG=2./x1.^2.*sumascaMG;
                        QabsMG=QextMG-QscaMG;
                        QextB=2./x1.^2.*sumaB;
                        QscaB=2./x1.^2.*sumascaB;
                        QabsB=QextB-QscaB;
                        assignin('base','QabsB',QabsB)
                        % en el caso de que rg sea un vector
                    else
                        z3=[]; z=[]; Ap=[];
                        for p=1:length(np0_Df)
                            z3_prev=1/(0.6667+(0.2003/log(np0_Df(p))));
                            z3=[z3 z3_prev];
                            z_prev=abs(z1-1+((z3(p)+1-z1)^(((Df_Df-1)/2)^1.95)));
                            z=[z z_prev];
                            Ap_prev=Ap0*(np0_Df(p)^(1/z(p)));
                            Ap=[Ap Ap_prev];

                            %                             GFB(i,p)=(1+((4*(K^2)*(rg(p)^2))/(3*(Df_Df))))^(-(Df_Df)*0.5);
                            GFB(i,p)=(1+((4.*(K(i).^2).*(rg(p).^2))./(3.*(Df_Df)))).^(-(Df_Df).*0.5);
                            Qabsp0(i,p)=-4.*x(i).*(imag(((m(i).^2)-1)./((m(i).^2)+2)));
                            Qscap0(i,p)=(8/3).*x(i).^4.*(abs(((m(i).^2)-1)./((m(i).^2)+2)).^2);
                            Qabs(i,p)=((np0_Df(p)).^(1-(1/z(p))))*Qabsp0(i,p);
                            QscaFB(i,p)=((np0_Df(p))^(2-(1/z(p))))*Qscap0(i,p)*GFB(i,p);
                            QextFB(i,p)=Qabs(i,p)+QscaFB(i,p);
                            AlbFB(i,p)=QscaFB(i,p)/QextFB(i,p);
                            beta=(3.*(Df_Df))./(8.*(K(i).*rg(p)).^2);
                            if beta>=1
                                GKF(i,p)=1-((2*(K(i)*rg(p))^2)/3);
                                QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            elseif beta<1 && Df_Df==2
                                GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K)^2^0.5)^(-Df_Df)*(3/(2.001-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2.001-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                                QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            else
                                GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K(i))^2^0.5)^(-Df_Df)*(3/(2-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                                %                                 GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K)^2^0.5)^(-Df_Df)*(3/(2-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                                QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            end

                            in2(p)=(sqrt((4*np0_Df(p))-1)/(2*sqrt(3)))-(1/2);
                            in3(p)=(3*np0_Df(p)/20+((324*np0_Df(p)^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_Df(p)/20+(((324*np0_Df(p)^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            R1(p)=rp0_Df*np0_Df(p);
                            R2(p)=((2*in2(p))+1)*rp0_Df;
                            R3(p)=((2*in3(p))+1)*rp0_Df;
                            R(p)=(((log(R1(p))-(2*log(R2(p)))+log(R3(p)))/2)*Df_Df^2)+((((-5*log(R1(p)))+(8*log(R2(p)))-(3*log(R3(p))))/2)*Df_Df)+((3*log(R1(p)))-(3*log(R2(p)))+log(R3(p)));
                            fv(p)=(np0_Df(p)*(rp0_Df.^3))/(exp(R(p)).^3);
                            m2LL(i,p)=(1+(2*fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))));
                            m2MG(i,p)=(((m(i)^2)*((2*fv(p))+1))+(2*(-fv(p)+1)))/(((m(i)^2)*((-1*fv(p))+1))+((fv(p)+2)));
                            m2B(i,p)=(((-1.5*fv(p))+1)+(((1.5*fv(p))-0.5)*m(i).^2)+sqrt((((1.5*fv(p))-1)-(((1.5*fv(p))-0.5)*m(i).^2)).^2+(2*m(i).^2)))/2;
                            x1(p)=2*pi*exp(R(p))/lambda(i);
                            yLL(p)=sqrt(m2LL(i,p))*x1(p);
                            yMG(p)=sqrt(m2MG(i,p))*x1(p);
                            yB(p)=sqrt(m2B(i,p))*x1(p);
                            n=1;nmax(p)=round(2+(x1(p))+4*(x1(p)).^(1/3));
                            %sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                            while n<=nmax(p) % expresiones en TFG Joaquín desde página 21 documento
                                shix(p)=(pi*x1(p)/2)^0.5*besselj(n+0.5,x1(p));shix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselj(n+0.5,x1(p)+incr);
                                dshix(p)=(shix1(p)-shix(p))/incr;
                                shiyLL(p)=(pi*yLL(p)/2)^0.5*besselj(n+0.5,yLL(p));shiy1LL(p)=(pi*(yLL(p)+incr)/2)^0.5*besselj(n+0.5,yLL(p)+incr);
                                shiyMG(p)=(pi*yMG(p)/2)^0.5*besselj(n+0.5,yMG(p));shiy1MG(p)=(pi*(yMG(p)+incr)/2)^0.5*besselj(n+0.5,yMG(p)+incr);
                                shiyB(p)=(pi*yB(p)/2)^0.5*besselj(n+0.5,yB(p));shiy1B(p)=(pi*(yB(p)+incr)/2)^0.5*besselj(n+0.5,yB(p)+incr);
                                dshiyLL(p)=(shiy1LL(p)-shiyLL(p))/incr;
                                dshiyMG(p)=(shiy1MG(p)-shiyMG(p))/incr;
                                dshiyB(p)=(shiy1B(p)-shiyB(p))/incr;
                                psix(p)=(pi*x1(p)/2)^0.5*besselh(n+0.5,2,x1(p));psix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselh(n+0.5,2,x1(p)+incr);
                                dpsix(p)=(psix1(p)-psix(p))/incr;
                                anLL(p)=(dshiyLL(p)*shix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dshix(p))/(dshiyLL(p)*psix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dpsix(p));
                                anMG(p)=(dshiyMG(p)*shix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dshix(p))/(dshiyMG(p)*psix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dpsix(p));
                                anB(p)=(dshiyB(p)*shix(p)-sqrt(m2B(i,p))*shiyB(p)*dshix(p))/(dshiyB(p)*psix(p)-sqrt(m2B(i,p))*shiyB(p)*dpsix(p));
                                bnLL(p)=(sqrt(m2LL(i,p))*dshiyLL(p)*shix(p)-shiyLL(p)*dshix(p))/(sqrt(m2LL(i,p))*dshiyLL(p)*psix(p)-shiyLL(p)*dpsix(p));
                                bnMG(p)=(sqrt(m2MG(i,p))*dshiyMG(p)*shix(p)-shiyMG(p)*dshix(p))/(sqrt(m2MG(i,p))*dshiyMG(p)*psix(p)-shiyMG(p)*dpsix(p));
                                bnB(p)=(sqrt(m2B(i,p))*dshiyB(p)*shix(p)-shiyB(p)*dshix(p))/(sqrt(m2B(i,p))*dshiyB(p)*psix(p)-shiyB(p)*dpsix(p));
                                if n==1
                                    sumaLL(p)=(2*n+1)*real(anLL(p)+bnLL(p));
                                    sumaMG(p)=(2*n+1)*real(anMG(p)+bnMG(p));
                                    sumaB(p)=(2*n+1)*real(anB(p)+bnB(p));
                                    sumascaLL(p)=(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                    sumascaMG(p)=(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                    sumascaB(p)=(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                                else
                                    sumaLL(p)=sumaLL(p)+(2*n+1)*real(anLL(p)+bnLL(p));
                                    sumaMG(p)=sumaMG(p)+(2*n+1)*real(anMG(p)+bnMG(p));
                                    sumaB(p)=sumaB(p)+(2*n+1)*real(anB(p)+bnB(p));
                                    sumascaLL(p)=sumascaLL(p)+(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                    sumascaMG(p)=sumascaMG(p)+(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                    sumascaB(p)=sumascaB(p)+(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                                end
                                n=n+1;
                            end
                            QextLL(i,p)=2/(x1(p)).^2*sumaLL(p);
                            QscaLL(i,p)=2/(x1(p)).^2*sumascaLL(p);
                            QabsLL(i,p)=QextLL(i,p)-QscaLL(i,p);
                            QextMG(i,p)=2/(x1(p)).^2*sumaMG(p);
                            QscaMG(i,p)=2/(x1(p)).^2*sumascaMG(p);
                            QabsMG(i,p)=QextMG(i,p)-QscaMG(i,p);
                            QextB(i,p)=2/(x1(p)).^2*sumaB(p);
                            QscaB(i,p)=2/(x1(p)).^2*sumascaB(p);
                            QabsB(i,p)=QextB(i,p)-QscaB(i,p);
                        end
                    end
                end
                %                 nd = nd_Df;
                %  assignin('base','z',z)
                assignin('base','x',x)
                assignin('base','m',m)
                assignin('base','z',z)
                assignin('base','Qabsp0',Qabsp0)
                assignin('base','np0_Df',np0_Df)
                assignin('base','Qabs_inicial',Qabs)
                assignin('base','QabsMG', QabsMG)
                assignin('base','QscaFB',QscaFB)
                assignin('base','QextFB',QextFB)
                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------
                %-----------------------------------------------------------------------------------
                % Distribución de partículas: Imponiendo np0
            elseif app.EstudioDropDown.Value == "Imponer np0"

                Df_np0 = app.DimensinfractalEditField.Value;
                np0_np0 = app.NdepartculasprimariasEditField.Value;
                rp0_np0= app.RadiodelapartculaesfricanmEditField.Value;
                nd_np0 = app.divisionesEditField.Value;

                incr=0.000000001;
                for i=1:length(lambda)
                    %--------------------------------------------------------------------------------------------
                    % ahora se introduce la iteración

                    K1=np0_np0/(((3/5)+(1/3)*(np0_np0^2-1))^0.5);
                    K2=np0_np0/((3/5)+((10*(np0_np0^2)-(8*np0_np0)-2)/(18*np0_np0)));
                    in=((((3*np0_np0)/20)+((1/120)*((324*(np0_np0^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0_np0)/20)+((1/120)*((324*(np0_np0^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                    K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                    if nd_np0==1
                        Dfo=2;
                        pos=1;
                        while pos<=length(rg)
                            Kf=(((K1/2)-K2+(K3/2))*(Dfo^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Dfo)+((3*K1)-(3*K2)+K3);
                            %pos=1:length(rg);
                            Df1(pos)=(log(np0_np0)-log(Kf))/(log(rg(pos))-log(rp0_np0));
                            pos=pos+1;
                        end
                        Df=Df1;
                    else
                        Dfo=2;
                        pos=1;
                        while pos<=length(rg)
                            Kf=(((K1/2)-K2+(K3/2))*(Dfo^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Dfo)+((3*K1)-(3*K2)+K3);
                            %pos=1:length(rg);
                            a=log(np0_np0);
                            b=log(Kf);
                            c=log(rg(pos));
                            d=log(rp0_np0);
                            Df1(pos)=(log(np0_np0)-log(Kf))/(log(rg(pos))-log(rp0_np0));
                            pos=pos+1;
                        end
                        Df=Df1;
                    end

                    K=(2*pi)./lambda;
                    x=(2*pi*rp0_np0)./lambda;
                    Ap0=(pi()*rp0_np0^2);
                    % iteración hasta aquí
                    %--------------------------------------------------------------------------------------------

                    % bucle if para si el es un escalar,1 división, o un vector, más
                    % divisiones
                    if nd_np0 == 1
                        z1=log(np0_np0)/(log((0.8488*np0_np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0_np0)));
                        z=z1-1+((z3+1-z1)^(((Df-1)/2)^1.86));% Cambio valor m 1.95 a 1.86

                        Ap=Ap0*(np0_np0^(1/z));
                        Qabsp0=-4.*x.*(imag(((m.^2)-1)./((m.^2)+2)));
                        Qscap0=(8./3).*x.^4.*(abs(((m.^2)-1)./((m.^2)+2)).^2);
                        GFB=(1+((4.*(K.^2).*(rg.^2))./(3.*Df))).^(-Df.*0.5);
                        Qabs=(np0_np0.^(1-(1./z))).*Qabsp0;
                        QscaFB=(np0_np0.^(2-(1./z))).*Qscap0.*GFB;
                        QextFB=Qabs+QscaFB;
                        AlbFB=QscaFB./QextFB;
                        beta=(3.*Df)./(8.*(K.*rg).^2);
                        for b = 1:length(beta)
                            if beta(b)>=1
                                GKF=1-((2.*(K.*rg).^2)./3);
                                QscaKF=(np0_np0.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            elseif beta(b)<1 && Df==2
                                GKF=beta./2.*(3-3.*beta+2.*beta.^2)-beta.^2.*(rg.*K).^2./3.*(3-4.*beta+3.*beta.^2)+(2.*(rg.*K).^2.^0.5).^(-Df).*(3./(2.001-Df)-12./(6-Df)./(4-Df)-3.*beta.^(1-Df./2).*(1./(2.001-Df)-2.*beta./(4-Df)+2.*beta.^2./(6-Df)));
                                QscaKF=(np0_np0.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            else
                                GKF=beta./2.*(3-3.*beta+2.*beta.^2)-beta.^2.*(rg.*K).^2./3.*(3-4.*beta+3.*beta.^2)+(2.*(rg.*K).^2.^0.5).^(-Df).*(3./(2-Df)-12./(6-Df)./(4-Df)-3.*beta.^(1-Df./2).*(1./(2-Df)-2.*beta./(4-Df)+2.*beta.^2./(6-Df)));
                                QscaKF=(np0_np0.^(2-(1./z))).*Qscap0.*GKF;
                                QextKF=Qabs+QscaKF;
                                AlbKF=QscaKF./QextKF;
                            end
                        end
                        in2=(sqrt((4*np0_np0)-1)/(2*sqrt(3)))-(1/2);
                        in3=(3*np0_np0/20+((324*np0_np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_np0/20+(((324*np0_np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                        R1=rp0_np0*np0_np0;
                        R2=((2*in2)+1)*rp0_np0;
                        R3=((2*in3)+1)*rp0_np0;
                        R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df)+((3*log(R1))-(3*log(R2))+log(R3));
                        fv=(np0_np0*(rp0_np0^3))/(exp(R)^3);
                        m2LL=(1+(2.*fv*(((m.^2)-1)./((m.^2)+2))))./(1-(fv.*(((m.^2)-1)./((m.^2)+2))));
                        m2MG=(((m.^2).*((2.*fv)+1))+(2.*(-fv+1)))./(((m.^2).*((-1.*fv)+1))+((fv+2)));
                        m2B=(((-1.5.*fv)+1)+(((1.5.*fv)-0.5).*m.^2)+sqrt((((1.5.*fv)-1)-(((1.5.*fv)-0.5).*m.^2)).^2+(2.*m.^2)))./2;
                        x1=2.*pi.*exp(R)./lambda;yLL=sqrt(m2LL).*x1;yMG=sqrt(m2MG).*x1;yB=sqrt(m2B).*x1;n=1;nmax=round(2+x1+4.*x1.^(1/3));
                        sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                        while n<=nmax % expresiones en TFG Joaquín desde página 21 documento
                            shix=(pi.*x1./2).^0.5.*besselj(n+0.5,x1);shix1=(pi.*(x1+incr)./2).^0.5.*besselj(n+0.5,x1+incr);
                            dshix=(shix1-shix)/incr;
                            shiyLL=(pi.*yLL./2).^0.5.*besselj(n+0.5,yLL);shiy1LL=(pi.*(yLL+incr)./2).^0.5.*besselj(n+0.5,yLL+incr);
                            shiyMG=(pi.*yMG./2).^0.5.*besselj(n+0.5,yMG);shiy1MG=(pi.*(yMG+incr)./2).^0.5.*besselj(n+0.5,yMG+incr);
                            shiyB=(pi.*yB./2).^0.5.*besselj(n+0.5,yB);shiy1B=(pi.*(yB+incr)./2).^0.5.*besselj(n+0.5,yB+incr);
                            dshiyLL=(shiy1LL-shiyLL)/incr;
                            dshiyMG=(shiy1MG-shiyMG)/incr;
                            dshiyB=(shiy1B-shiyB)/incr;
                            psix=(pi.*x1./2).^0.5.*besselh(n+0.5,2,x1);psix1=(pi.*(x1+incr)./2).^0.5.*besselh(n+0.5,2,x1+incr);
                            dpsix=(psix1-psix)/incr;
                            anLL=(dshiyLL.*shix-sqrt(m2LL).*shiyLL.*dshix)./(dshiyLL.*psix-sqrt(m2LL).*shiyLL.*dpsix);
                            anMG=(dshiyMG.*shix-sqrt(m2MG).*shiyMG.*dshix)./(dshiyMG.*psix-sqrt(m2MG).*shiyMG.*dpsix);
                            anB=(dshiyB.*shix-sqrt(m2B).*shiyB.*dshix)./(dshiyB.*psix-sqrt(m2B).*shiyB.*dpsix);
                            bnLL=(sqrt(m2LL).*dshiyLL.*shix-shiyLL.*dshix)./(sqrt(m2LL).*dshiyLL.*psix-shiyLL.*dpsix);
                            bnMG=(sqrt(m2MG).*dshiyMG.*shix-shiyMG.*dshix)./(sqrt(m2MG).*dshiyMG.*psix-shiyMG.*dpsix);
                            bnB=(sqrt(m2B).*dshiyB.*shix-shiyB.*dshix)./(sqrt(m2B).*dshiyB.*psix-shiyB.*dpsix);
                            sumaLL=sumaLL+(2.*n+1).*real(anLL+bnLL);
                            sumaMG=sumaMG+(2.*n+1).*real(anMG+bnMG);
                            sumaB=sumaB+(2.*n+1).*real(anB+bnB);
                            sumascaLL=sumascaLL+(2.*n+1).*((abs(anLL)).^2+(abs(bnLL)).^2);
                            sumascaMG=sumascaMG+(2.*n+1).*((abs(anMG)).^2+(abs(bnMG)).^2);
                            sumascaB=sumascaB+(2.*n+1).*((abs(anB)).^2+(abs(bnB)).^2);
                            n=n+1;
                        end
                        QextLL=2./x1.^2.*sumaLL;
                        QscaLL=2./x1.^2.*sumascaLL;
                        QabsLL=QextLL-QscaLL;
                        QextMG=2./x1.^2.*sumaMG;
                        QscaMG=2./x1.^2.*sumascaMG;
                        QabsMG=QextMG-QscaMG;
                        QextB=2./x1.^2.*sumaB;
                        QscaB=2./x1.^2.*sumascaB;
                        QabsB=QextB-QscaB;

                        % en el caso de que rg sea un vector
                    else
                        z1=log(np0_np0)/(log((0.8488*np0_np0)+0.1512));
                        z3=1/(0.6667+(0.2003/log(np0_np0)));

                        for p=1:length(Df)
                            z(p)=z1-1+((z3+1-z1)^(((Df(p)-1)/2)^1.95));
                            Ap(p)=Ap0*(np0_np0^(1/z(p)));

                            Qabsp0(i,p)=-4.*x(i).*(imag(((m(i).^2)-1)./((m(i).^2)+2)));
                            Qscap0(i,p)=(8/3).*x(i).^4.*(abs(((m(i).^2)-1)./((m(i).^2)+2)).^2);

                            % a partir de aquí falta por modificar incorporar el bucle
                            % for de p
                            K(i)=(2*pi)/lambda(i);
                            GFB(i,p)=(1+((4*(K(i)^2)*(rg(p)^2))/(3*Df(p))))^(-Df(p)*0.5);
                            Qabs(i,p)=(np0_np0^(1-(1/z(p))))*Qabsp0(i);
                            QscaFB(i,p)=(np0_np0^(2-(1/z(p))))*Qscap0(i)*GFB(i,p);
                            QextFB(i,p)=Qabs(i,p)+QscaFB(i,p);
                            AlbFB(i,p)=QscaFB(i,p)/QextFB(i,p);
                            beta(p)=(3*Df(p))/(8*(K(i)*rg(p))^2);
                            if beta(p)>=1
                                GKF(i,p)=1-((2*(K(i)*rg(p))^2)/3);
                                QscaKF(i,p)=(np0_np0^(2-(1/z(p))))*Qscap0(i)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            elseif beta(p)<1 && Df(p)==2
                                GKF(i,p)=beta(p)/2*(3-3*beta(p)+2*beta(p).^2)-beta(p).^2*(rg(p)*K(i))^2/3*(3-4*beta(p)+3*beta(p)^2)+(2*(rg(p)*K(i))^2^0.5)^(-Df(p))*(3/(2.001-Df(p))-12/(6-Df(p))/(4-Df(p))-3*beta(p)^(1-Df(p)/2)*(1/(2.001-Df(p))-2*beta(p)/(4-Df(p))+2*beta(p).^2/(6-Df(p))));
                                QscaKF(i,p)=(np0_np0^(2-(1/z(p))))*Qscap0(i)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            else
                                GKF(i,p)=beta(p)/2*(3-3*beta(p)+2*beta(p).^2)-beta(p).^2*(rg(p)*K(i))^2/3*(3-4*beta(p)+3*beta(p).^2)+(2*(rg(p)*K(i)).^2^0.5).^(-Df(p))*(3/(2-Df(p))-12/(6-Df(p))/(4-Df(p))-3*beta(p).^(1-Df(p)/2)*(1/(2-Df(p))-2*beta(p)/(4-Df(p))+2*beta(p).^2/(6-Df(p))));
                                QscaKF(i,p)=(np0_np0^(2-(1/z(p))))*Qscap0(i)*GKF(i,p);
                                QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                                AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                            end
                            in2=(sqrt((4*np0_np0)-1)/(2*sqrt(3)))-(1/2);
                            in3=(3*np0_np0/20+((324*np0_np0^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_np0/20+(((324*np0_np0^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                            R1=rp0_np0*np0_np0;
                            R2=((2*in2)+1)*rp0_np0;
                            R3=((2*in3)+1)*rp0_np0;
                            R(p)=(((log(R1)-(2*log(R2))+log(R3))/2)*Df(p).^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df(p))+((3*log(R1))-(3*log(R2))+log(R3));
                            fv(p)=(np0_np0*(rp0_np0^3))/(exp(R(p)).^3);
                            m2LL(i,p)=(1+(2*fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))));
                            m2MG(i,p)=(((m(i)^2)*((2*fv(p))+1))+(2*(-fv(p)+1)))/(((m(i)^2)*((-1*fv(p))+1))+((fv(p)+2)));
                            m2B(i,p)=(((-1.5*fv(p))+1)+(((1.5*fv(p))-0.5)*m(i)^2)+sqrt((((1.5*fv(p))-1)-(((1.5*fv(p))-0.5)*m(i)^2))^2+(2*m(i)^2)))/2;
                            x1(p)=2*pi*exp(R(p))/lambda(i);
                            yLL(p)=sqrt(m2LL(i,p))*x1(p);
                            yMG(p)=sqrt(m2MG(i,p))*x1(p);
                            yB(p)=sqrt(m2B(i,p))*x1(p);
                            n=1;nmax(p)=round(2+(x1(p))+4*(x1(p)).^(1/3));
                            % sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                            while n<=nmax(p) % expresiones en TFG Joaquín desde página 21 documento
                                shix(p)=(pi*x1(p)/2)^0.5*besselj(n+0.5,x1(p));shix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselj(n+0.5,x1(p)+incr);
                                dshix(p)=(shix1(p)-shix(p))/incr;
                                shiyLL(p)=(pi*yLL(p)/2)^0.5*besselj(n+0.5,yLL(p));shiy1LL(p)=(pi*(yLL(p)+incr)/2)^0.5*besselj(n+0.5,yLL(p)+incr);
                                shiyMG(p)=(pi*yMG(p)/2)^0.5*besselj(n+0.5,yMG(p));shiy1MG(p)=(pi*(yMG(p)+incr)/2)^0.5*besselj(n+0.5,yMG(p)+incr);
                                shiyB(p)=(pi*yB(p)/2)^0.5*besselj(n+0.5,yB(p));shiy1B(p)=(pi*(yB(p)+incr)/2)^0.5*besselj(n+0.5,yB(p)+incr);
                                dshiyLL(p)=(shiy1LL(p)-shiyLL(p))/incr;
                                dshiyMG(p)=(shiy1MG(p)-shiyMG(p))/incr;
                                dshiyB(p)=(shiy1B(p)-shiyB(p))/incr;
                                psix(p)=(pi*x1(p)/2)^0.5*besselh(n+0.5,2,x1(p));psix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselh(n+0.5,2,x1(p)+incr);
                                dpsix(p)=(psix1(p)-psix(p))/incr;
                                anLL(p)=(dshiyLL(p)*shix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dshix(p))/(dshiyLL(p)*psix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dpsix(p));
                                anMG(p)=(dshiyMG(p)*shix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dshix(p))/(dshiyMG(p)*psix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dpsix(p));
                                anB(p)=(dshiyB(p)*shix(p)-sqrt(m2B(i,p))*shiyB(p)*dshix(p))/(dshiyB(p)*psix(p)-sqrt(m2B(i,p))*shiyB(p)*dpsix(p));
                                bnLL(p)=(sqrt(m2LL(i,p))*dshiyLL(p)*shix(p)-shiyLL(p)*dshix(p))/(sqrt(m2LL(i,p))*dshiyLL(p)*psix(p)-shiyLL(p)*dpsix(p));
                                bnMG(p)=(sqrt(m2MG(i,p))*dshiyMG(p)*shix(p)-shiyMG(p)*dshix(p))/(sqrt(m2MG(i,p))*dshiyMG(p)*psix(p)-shiyMG(p)*dpsix(p));
                                bnB(p)=(sqrt(m2B(i,p))*dshiyB(p)*shix(p)-shiyB(p)*dshix(p))/(sqrt(m2B(i,p))*dshiyB(p)*psix(p)-shiyB(p)*dpsix(p));
                                if n==1
                                    sumaLL(p)=(2*n+1)*real(anLL(p)+bnLL(p));
                                    sumaMG(p)=(2*n+1)*real(anMG(p)+bnMG(p));
                                    sumaB(p)=(2*n+1)*real(anB(p)+bnB(p));
                                    sumascaLL(p)=(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                    sumascaMG(p)=(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                    sumascaB(p)=(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                                else
                                    sumaLL(p)=sumaLL(p)+(2*n+1)*real(anLL(p)+bnLL(p));
                                    sumaMG(p)=sumaMG(p)+(2*n+1)*real(anMG(p)+bnMG(p));
                                    sumaB(p)=sumaB(p)+(2*n+1)*real(anB(p)+bnB(p));
                                    sumascaLL(p)=sumascaLL(p)+(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                    sumascaMG(p)=sumascaMG(p)+(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                    sumascaB(p)=sumascaB(p)+(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                                end
                                n=n+1;
                            end
                            QextLL(i,p)=2/(x1(p)).^2*sumaLL(p);
                            QscaLL(i,p)=2/(x1(p)).^2*sumascaLL(p);
                            QabsLL(i,p)=QextLL(i,p)-QscaLL(i,p);
                            QextMG(i,p)=2/(x1(p)).^2*sumaMG(p);
                            QscaMG(i,p)=2/(x1(p)).^2*sumascaMG(p);
                            QabsMG(i,p)=QextMG(i,p)-QscaMG(i,p);
                            QextB(i,p)=2/(x1(p)).^2*sumaB(p);
                            QscaB(i,p)=2/(x1(p)).^2*sumascaB(p);
                            QabsB(i,p)=QextB(i,p)-QscaB(i,p);
                        end
                        nd = nd_np0;
                    end
                end
            end

            %----------------------------------------------------------------------------------------
            %%%% RENOMBRAR LAS VARIABLES EN FUNCIÓN DEL ESTUDIO ESCOGIDO para imponer Df y np0
            %-----------------------------------------------------------------------------------------
            %%%% NO ESTUDIO AEROSOLES, SÓLO DE AGLOMERADOS

            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                % Fisher-Buford es Mountain - Mullholland
                if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                    Qsca = QscaFB; Qext = QextFB; Alb_soot= Qsca./Qext;Coalb_soot = 1-Alb_soot;
                    assignin('base','Qsca_prueba', Qsca)
                    assignin('base','Qext_prueba', Qext)

                    % Köylü-Faeth
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                    Qsca = QscaKF; Qext = QextKF; Alb_soot= Qsca./Qext; Coalb_soot = 1-Alb_soot;

                    % Maxwell-Garnett
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                    Qabs = QabsMG; Qsca = QscaMG; Qext = QextMG; Alb_soot= Qsca./Qext; Coalb_soot = 1-Alb_soot;
                    assignin('base','Qabs_renomMG', Qabs)

                    % Bruggemann
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 18
                    Qabs = QabsB; Qsca = QscaB; Qext = QextB; Alb_soot= Qsca./Qext; Coalb_soot = 1-Alb_soot;

                    % Lorentz-Lorenz
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                    Qabs = QabsLL; Qsca = QscaLL; Qext = QextLL; Alb_soot= Qsca./Qext; Coalb_soot = 1-Alb_soot;

                    % FALTA AÑADIR ESTOS TRES CÁLCULOS  ANTES Y TAMBIÉN LO DEL MÉTODO GEOMÉTRICO Y APROXIMADO
                    %             % Lakatia-Vitkam
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                    %                Qabs = QabsLV; Qsca = QscaLV; Qext = QextLV;
                    %             % Lumme-Rahola
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                    %                 Qabs = QabsLR; Qsca = QscaLR; Qext = QextLR;
                    %             % Promedio potencial
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                    %                 Qabs = QabsPP; Qsca = QscaPP; Qext = QextPP;
                end
                assignin('base','Qabs_renom', Qabs)
            end


            % Estudio canales para caso distribuciones
            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"

                % Selección de los canales (nº de divisiones que se ha realizado)
                % Se dibujan todos los canales, la media o cada uno de ellos de  forma independiente
                if Todos == 1
                    % este caso es el que está en este momento fuera del bucle if
                    Qabs=Qabs;
                    Qsca=Qsca;
                    Qext=Qext;
                    Alb=Alb_soot;
                    Coalb=Coalb_soot;
                elseif Media == 1
                    if nd==1
                        Qabs=mean(Qabs,1);
                        Qsca=mean(Qsca,1);
                        Qext=mean(Qext,1);
                        Alb=mean(Alb,1);
                        Coalb=mean(Coalb,1);
                    else
                        Qabs=mean(Qabs,2);
                        Qsca=mean(Qsca,2);
                        Qext=mean(QextFB,2);
                        Alb=mean(Alb,2);
                        Coalb=mean(Coalb,2);
                    end
                elseif Canal_1 == 1
                    %para cada canal es una posición de rg de cada valor de eficiencias
                    %único para "barra" de la entrada de divisiones
                    if nd==1
                        Qabs=Qabs(:);
                    else
                        Qabs=Qabs(:,1);
                        Qsca=Qsca(:,1);
                        Qext=Qext(:,1);
                        AlbFB=Alb(:,1);
                        Coalb=Coalb(:,1);
                    end
                elseif Canal_2 == 1
                    Qabs=Qabs(:,2);
                    Qsca=Qsca(:,2);
                    Qext=Qext(:,2);
                    Alb=Alb(:,2);
                    Coalb=Coalb(:,2);
                elseif Canal_3 == 1
                    Qabs=Qabs(:,3);
                    Qsca=Qsca(:,3);
                    Qext=Qext(:,3);
                    Alb=Alb(:,3);
                    Coalb=Coalb(:,3);
                elseif Canal_4 == 1
                    Qabs=Qabs(:,4);
                    Qsca=Qsca(:,4);
                    Qext=Qext(:,4);
                    Alb=Alb(:,4);
                    Coalb=Coalb(:,4);
                elseif Canal_5 == 1
                    Qabs=Qabs(:,5);
                    Qsca=Qsca(:,5);
                    Qext=Qext(:,5);
                    Alb=Alb(:,5);
                    Coalb=Coalb(:,5);
                elseif Canal_6 == 1
                    Qabs=Qabs(:,6);
                    Qsca=Qsca(:,6);
                    Qext=Qext(:,6);
                    Alb=Alb(:,6);
                    Coalb=Coalb(:,6);
                elseif Canal_7 == 1
                    Qabs=Qabs(:,7);
                    Qsca=Qsca(:,7);
                    Qext=Qext(:,7);
                    Alb=Alb(:,7);
                    Coalb=Coalb(:,7);
                elseif Canal_8 == 1
                    Qabs=Qabs(:,8);
                    Qsca=Qsca(:,8);
                    Qext=Qext(:,8);
                    Alb=Alb(:,8);
                    Coalb=Coalb(:,8);
                elseif Canal_9 == 1
                    Qabs=Qabs(:,9);
                    Qsca=Qsca(:,9);
                    Qext=Qext(:,9);
                    Alb=Alb(:,9);
                    Coalb=Coalb(:,9);
                elseif Canal_10 == 1
                    Qabs=Qabs(:,10);
                    Qsca=Qsca(:,10);
                    Qext=Qext(:,10);
                    Alb=Alb(:,10);
                    Coalb=Coalb(:,10);
                end
                dg = 2*rg;
            end
            Alb_soot = Alb; Coalb_soot = Coalb;

            dp0 =2*rp0;
            %             dg = 2*rg;

            %%%%%%%% poner la representación en la figura y en la tabla los valores numéricos
            %%%%%%%% de IMPONER DF e IMPONER NP0

            %------------------------------------------------------------------
            % ESTUDIO CONJUNTO MANTO CON LO CALCULADO ANTES CONTAMINADO
            %------------------------------------------------------------------

            % ES NECESARIO CARGAR datos calculados del MATERIAL MANTO: Estudio manto (cont = 1)
            % Especificar las variables que se quieren cargar
            load Datos2 lambda Ap rp0
            load Albedo_nieve Alb FAsymmetry FAsymmetryED lambda Profundidad radio_nieve
            assignin('base','radio_nieve', radio_nieve)
            radio = radio_nieve; % en (nm)

            % %%%% Albedo no nieve, sino el blanco anterior:
            %             load Albedos_blanco Albedo_blanco_soot Albedo_blanco_sand Albedo_blanco_ash
            %             if mat == 2
            %                 Alb = Albedo_blanco_soot';
            %                  assignin('base','Albedo_blanco_soot',Albedo_blanco_soot)
            %
            %             elseif  mat == 9
            %                 Alb = Albedo_blanco_sand';
            %                  assignin('base','Albedo_blanco_sand',Albedo_blanco_sand)
            %
            %             elseif mat == 7
            %                 Alb = Albedo_blanco_ash';
            %                  assignin('base','Albedo_blanco_ash',Albedo_blanco_ash)
            %             end
            %
            %
            % %%%%%%

            % Coger valor de la variable concentración
            Concentracion = app.ConcentracincontaminanteppmEditField.Value;
            Densidadsuperficial = app.Densidadsuperficialkgm3EditField.Value;

            % Insertar cálculo de número de aglomerados (nag) para los dos
            % casos de distribución y el caso orignal, por lo que con un bucle
            % if se tendrá que mantener el caso original y con los dos elseif
            % restantes calcularlo con la densidad específica de cada uno
            % hace referencia al nº de canales

            %--------------------------------------------------------------------------------
            % Caracterización morfológica media
            if app.EstudioDropDown.Value == "Tamaño único"
                for i = 1:length(Alb)
                    %                     Profundidad=6; % cm
                    %                     ro=1850; % kg/m^3 densidad del hollín
                    %
                    %                     Anieve=(pi()*(Diametro^2))/(4); %m^2
                    %                     ronieve=(Densidadsuperficial-((20470/Profundidad)*(1-exp(-Profundidad/67.3))))*1e-3; % g/m^3
                    %                     mhielo=ronieve*Profundidad*Anieve*10000; % g
                    %
                    %                     rp0 = app.RadiodelapartculaesfricanmEditField.Value;
                    %                     np0 = app.NdepartculasprimariasEditField.Value;
                    %                     mag=np0*ro*pi()*(4/3)*(rp0^3)*1000*1e-27; % g
                    %
                    %                     %magtotal=Concentracion*mhielo*0.000001;
                    %                     % se ha corregido la fórmula inicial de la
                    %                     % concentración para tener en cuenta en el total la de
                    %                     % los aglomerados
                    %                     % expresión:Concentracion = mag /(mag+mhielo);
                    %                     % despejando la masa total se tiene:
                    %                     magtotal=(Concentracion*0.000001*mhielo)/(1-(Concentracion*0.000001)); % g
                    %                     nag=magtotal/mag; % []

                    Diametro=1; % m
                    Anieve=(pi()*(Diametro^2))/(4); %m^2
                    % Se asume que como mucho los aglomerados pueden
                    % penetrar 10 cm del espesor del manto. Se coge este
                    % valor porque es el que se empleó en Cotos
                    % Este valor tiene que verse como depender del tamaño
                    % de grano de la nieve y de las partículas, porque
                    % dependerá de lo que penetren
                    %                     Profundidad = 10; %cm
                    % se corrige y se vuelve a poner otro valor de profundidad del indicado por Sara (6cm)
                    %                     Profundidad = 5; %cm % normalmente se coge este valor como referencia
                    % Se va a poner que la profundidad la especifique el usuario
                    Profundidad = app.EspesorcmEditField.Value;

                    ronieve=(Densidadsuperficial-((20470/Profundidad)*(1-exp(-Profundidad/67.3)))); % kg/m^3
                    assignin('base','ronieve_ok',ronieve)
%                     ronieve = 255; %experimental Sierra Nevada
%                     aceleraciones marzo

                    mhielo=ronieve*Profundidad*Anieve*1e-2; % kg

                    rp0 = app.RadiodelapartculaesfricanmEditField.Value; % nm
                    np0 = app.NdepartculasprimariasEditField.Value;     % []

                    % En función material
                    %Si es hollín:
                    if mat == 2
                        ro=1850; % kg/m^3 densidad hollín
                        load Ap_soot Ap

                        % Si es polvo o arena:
                    elseif mat == 6 || mat == 9
                        ro = 2500;
                        Ap = pi*rp0^2;

                        % Si es ceniza:
                    elseif mat == 7
                        ro = 2600;
                        Ap = pi*rp0^2;
                    end

                    %                     ro=1850; % kg/m^3 densidad hollín
                    mag=np0*ro*pi()*(4/3)*(rp0^3)*1e-27; % kg

                    % Concentración pasar de ppm a tanto por uno:
                    %   1 ppm son 0.0001     1e-4
                    %   % a tanto por 1      1e-2
                    % la contribución de ambos es 1e-6

                    %magtotal=Concentracion*mhielo*0.000001;
                    % se ha corregido la fórmula inicial de la concentración
                    % para tener en cuenta en el total la de los aglomerados
                    % expresión:Concentracion = mag /(mag+mhielo);
                    % despejando la masa total se tiene:
                    magtotal=(Concentracion*1e-6*mhielo)/(1-(Concentracion*1e-6)); % kg
                    nag=magtotal/mag; % []

                    %                     load Ap_soot Ap

                    Aph=Ap*nag*1e-18; % m^2
                    % Fracción área manchada es xi
                    % Sin superposición de partículas (Expresión 4.1 TFM Sara)
                    xisin=Aph/Anieve;
                    % Con superposición de partículas (Expresión 4.9 TFM Sara)
                    xicon=1-(1/(exp(xisin)));

                    radio = radio_nieve/1000;

                    % Ajuste del parámetro (Figura 6.8 TFM Sara)
                    % radio en micrómetros en la fórmula de ahí que se multiplque por 0.001 porque viene dado en nm
                    %                     teta=-7e-7*((radio*0.001)^2)+(0.0015*radio*0.001)+0.1604;
                    %
                    %                     % CAMBIO DE LA EXPRESIÓN USANDO LA DE WIEBE
                    %                     % Radio tiene que ir expresado en micras
                    %                     radio = radio_nieve/1000;
                    %                     % (1 - 0.18) es porque parte de ese valor la curva
                    % Factor de forma = b
                    %                     b = 3.6;
                    b = 3.6;

                    % expesión original
                    %                     teta = 1-(1-0.18)*exp(-6.9078*((radio/2000)^b));

                    % se modifica la expresión de Wiebe para que valga para
                    % partículas essféricas y haya una detención del albedo
                    % al aumentar la concentración
                    % expesión modificada
                    a = 1.5; %factor que ajusta la detención
                    % 12.5 es el rp0 de aglomerados de referencia que se toma en este caso
                    % rp0 es el radio (de la esfera o de partículas  primarias  de los aglomerados)
                    teta = 1-(1-0.18)*exp(-6.9078*((radio/2000)^b)*((rp0/12.5)^a));

                    % EXPRESIÓN MODELO COMBINADO ORIGINAL CON EFICIENCIAS
                    Albedo(i)=(Alb(i)*(1-(xicon*(Qabs(i)+teta-(teta*Qabs(i))))))+(xicon*teta*Qsca(i));


                    %                     %% EXPRESIsitÓN MODIFICADA CON ALBEDO DE DISPERSIÓN DE HOLLÍN
                    %                     % hay que reajustar el teta a los datos de COTOS
                    %                     % Factor de forma = b
                    %                     b = 1.3;
                    %                     teta = 1-(1-0.18)*exp(-6.9078*((radio/2000)^b));
                    %                     assignin('base','teta_modificado',teta)
                    %
                    %                     Albedo(i)=(1-xicon)*Alb(i)+xicon*((teta*Alb_soot(i))+(1-teta)*(Alb(i)*Alb_soot(i)));
                    %                     assignin('base','teta',teta)

                    %                     %                     load Albedo_prueba_trasparente
                    %
                    %                     %                     % Si es profundidad finita
                    %                     %                     if h < hmax
                    %                     %                         Alb = Albedo_trasparente;
                    %                     %                     end
                    %                     % para hacer el estudio del método directo e indirecto
                    %                     % (combinado) se parte del albedo de la nieve si es
                    %                     % semi-infinito, pero si es profundidad finita se debe
                    %                     % considerar el albedo trasparente
                    Albedo_modificado(i)=(1-xicon)*Alb(i)+xicon*((teta*Alb_soot(i))+(1-teta)*(Alb(i)*Alb_soot(i)));


                    assignin('base','mag',mag)
                    assignin('base','radio',radio)
                    assignin('base','Anieve',Anieve)
                    assignin('base','xicon',xicon)
                    assignin('base','xisin',xisin)
                    assignin('base','ronieve',ronieve)
                    assignin('base','Alb',Alb)
                    assignin('base','mhielo',mhielo)
                    assignin('base','magtotal',magtotal)
                    assignin('base','Aph',Aph)
                    assignin('base','Ap',Ap)
                    assignin('base','nag',nag)
                    assignin('base','teta',teta)
                    assignin('base','Qabs',Qabs)
                    assignin('base','Qsca',Qsca)
                    assignin('base','Albedo',Albedo)
                    assignin('base','Albedo_modificado',Albedo_modificado)

                    assignin('base','Alb',Alb)
                    assignin('base','Alb_soot',Alb_soot)
                    assignin('base','Profundidad',Profundidad)

                    % Para mostrar tiempo de ejecución en el botón
                    cont=(i/length(Alb))*100;
                    cont= [num2str(round(cont)) ' %'];
                    app.ResultadosmantocontaminadoButton.Text = cont;
                    drawnow
                end
                save Datos_nieve_cont lambda Albedo
                % CÁLCULO ALBEDO MEDIO
                load EspectroSolar RadiacionIncidente
                % Bucle para los valores de longitud de onda del albedo contaminado
                for i = 1: length(lambda)
                    % Bucle para la longitud de onda de la irradiancia (espectro solar)
                    for j =1:size(RadiacionIncidente(:,1))
                        % Condición de que sean iguales las landas para generar el bucle de
                        % irradiancia con las lambdas del Albedo
                        if RadiacionIncidente(j,1) == lambda(i)
                            irrad(i) = RadiacionIncidente(j,2);
                        end
                    end
                end
                assignin('base','irrad',irrad)
                % Cálculo del albedo medio
                albedo_medio_nieve = (sum((lambda(2)-lambda(1)).*Alb.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                albedo_medio_cont = (sum((lambda(2)-lambda(1)).*Albedo.*irrad))/(sum(((lambda(2)-lambda(1)).*irrad)));
                assignin('base','albedo_medio_nieve',albedo_medio_nieve)
                assignin('base','albedo_medio_cont',albedo_medio_cont)

                % Mostrar albedo medio en pantalla del manto contaminado
                app.UITable_alb_medio.ColumnName = ["Albedo medio manto mevado" "Albedo medio manto contaminado"];
                app.UITable_alb_medio.Data = [albedo_medio_nieve albedo_medio_cont];


                %--------------------------------------------------------------------------------
                % Imponer Df y np0
            elseif app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                % Densidad hollín
                ro=1850;%kg/m3
                for aa=1:length(dg)
                    if app.EstudioDropDown.Value == "Imponer Df"
                        ro_p(aa)=Kf*ro*((dg(aa)/(rp0*2)).^(Df-3));

                    elseif app.EstudioDropDown.Value == "Imponer np0"
                        ro_p(aa)=np0*ro*(((rp0*2)/dg(aa)).^3);
                    end
                    dg(aa)=dg(aa)*1e-09;
                end
                fn=H;

                %                     % Ajuste del parámetro (Figura 6.8 TFM Sara)
                %                     % radio en micrómetros en la fórmula de ahí que se multiplque por 0.001 porque viene dado en nm
                %                     teta=-7e-7*((radio*0.001)^2)+(0.0015*radio*0.001)+0.1604;

                % CAMBIO DE LA EXPRESIÓN USANDO LA DE WIEBE
                % Radio tiene que ir expresado en micras
                radio = radio_nieve/1000;
                % (1 - 0.18) es porque parte de ese valor la curva
                % Factor de forma = b
                b = 3.6;

                % se modifica la expresión de Wiebe para que valga para
                % partículas esféricas y haya una detención del albedo
                % al aumentaar la concentración

                % expesión original
                teta = 1-(1-0.18)*exp(-6.9078*((radio/2000)^b));
                %---------------------------------
                Diametro=1; %m
                %                 Profundidad=6; %cm


                Anieve=(pi()*(Diametro^2))/(4); %m2
                ronieve=(Densidadsuperficial-((20470/Profundidad)*(1-exp(-Profundidad/67.3))))*1e-3;
                mhielo=ronieve*Profundidad*Anieve*10000;
                magtotal=(Concentracion*0.000001*mhielo)/(1-(Concentracion*0.000001)); % g
                producto=[];
                for posicion=1:length(fn)
                    producto(posicion)=fn(posicion)*ro_p(posicion)*((dg(posicion)).^3);
                end
                sumatorio=sum(producto);
                nag=(magtotal*1000)/((pi()/6)*sumatorio);

                for j=1:length(dg)
                    nag_p(j)=nag*fn(j);
                    %                     Aph(j)=Ap(j)*nag_p(j)*1e-18*1e-6;
                    Aph(j)=Ap.*nag_p(j)*1e-18*1e-6;
                    xisin(j)=Aph(j)/Anieve;
                    xicon(j)=1-(1/(exp(xisin(j))));
                    mp(j)=(nag_p(j)*fn(j)*ro_p(j)*(pi()/6)*(((dg(j)).^3)));

                    %% Cálculo de separar la matriz Qabs en varios vectores en función del número de canales introducido %%
                    % Cada vector corresponde a un radio de giro, y se va a representar todos los vectores en la pantalla de la gráfica del albedo,
                    % mostrando la superposición de los mismos
                    A= Qabs';
                    Z = Qsca';
                    Qabs1=[];Qabs2=[];Qabs3=[];Qabs4=[];Qabs5=[];Qabs6=[];Qabs7=[]; Qabs8=[];Qabs9=[];Qabs10=[];
                    Qsca1=[];Qsca2=[];Qsca3=[];Qsca4=[];Qsca5=[];Qsca6=[];Qsca7=[]; Qsca8=[];Qsca9=[];Qsca10=[];

                    if j>=1 || j<=10

                        for c=1:size(A,1)
                            for m=1:size(A,2)
                                if c==1 || j==1
                                    Qabs1=[Qabs1 A(c,m)];
                                    Qsca1=[Qsca1 Z(c,m)];
                                elseif c==2 || j==2
                                    Qabs2=[Qabs2 A(c,m)];
                                    Qsca2=[Qsca2 Z(c,m)];
                                elseif c==3
                                    Qabs3=[Qabs3 A(c,m)];
                                    Qsca3=[Qsca3 Z(c,m)];
                                elseif c==4
                                    Qabs4=[Qabs4 A(c,m)];
                                    Qsca4=[Qsca4 Z(c,m)];
                                elseif c==5
                                    Qabs5=[Qabs5 A(c,m)];
                                    Qsca5=[Qsca5 Z(c,m)];
                                elseif c==6
                                    Qabs6=[Qabs6 A(c,m)];
                                    Qsca6=[Qsca6 Z(c,m)];
                                elseif c==7
                                    Qabs7=[Qabs7 A(c,m)];
                                    Qsca7=[Qsca7 Z(c,m)];
                                elseif c==8
                                    Qabs8=[Qabs8 A(c,m)];
                                    Qsca8=[Qsca8 Z(c,m)];
                                elseif c==9
                                    Qabs9=[Qabs9 A(c,m)];
                                    Qsca9=[Qsca9 Z(c,m)];
                                elseif c==10
                                    Qabs10=[Qabs10 A(c,m)];
                                    Qsca10=[Qsca10 Z(c,m)];
                                end
                            end
                        end
                    end

                    for i=1:size(Alb,2)
                        if j==1
                            Albedo(i)=(Alb(i)*(1-(xicon(j)*(Qabs1(i)+teta-(teta*Qabs1(i))))))+(xicon(j)*teta*Qsca1(i));

                        elseif  j==2
                            Albedo2(i)=(Albedo(i)*(1-(xicon(j)*(Qabs2(i)+teta-(teta*Qabs2(i))))))+(xicon(j)*teta*Qsca2(i));

                        elseif j==3
                            Albedo3(i)=(Albedo2(i)*(1-(xicon(j)*(Qabs3(i)+teta-(teta*Qabs3(i))))))+(xicon(j)*teta*Qsca3(i));

                        elseif j==4
                            Albedo4(i)=(Albedo3(i)*(1-(xicon(j)*(Qabs4(i)+teta-(teta*Qabs4(i))))))+(xicon(j)*teta*Qsca4(i));

                        elseif j==5
                            Albedo5(i)=(Albedo4(i)*(1-(xicon(j)*(Qabs5(i)+teta-(teta*Qabs5(i))))))+(xicon(j)*teta*Qsca5(i));

                        elseif j==6
                            Albedo6(i)=(Albedo5(i)*(1-(xicon(j)*(Qabs6(i)+teta-(teta*Qabs6(i))))))+(xicon(j)*teta*Qsca6(i));

                        elseif j==7
                            Albedo7(i)=(Albedo6(i)*(1-(xicon(j)*(Qabs7(i)+teta-(teta*Qabs7(i))))))+(xicon(j)*teta*Qsca7(i));

                        elseif j==8
                            Albedo8(i)=(Albedo7(i)*(1-(xicon(j)*(Qabs8(i)+teta-(teta*Qabs8(i))))))+(xicon(j)*teta*Qsca8(i));

                        elseif j==9
                            Albedo9(i)=(Albedo8(i)*(1-(xicon(j)*(Qabs9(i)+teta-(teta*Qabs9(i))))))+(xicon(j)*teta*Qsca9(i));

                        elseif j==10
                            Albedo10(i)=(Albedo9(i)*(1-(xicon(j)*(Qabs10(i)+teta-(teta*Qabs10(i))))))+(xicon(j)*teta*Qsca10(i));

                        end
                    end
                end
            end
            %----------------------------------------------
            %----------------------------------------------
            %%%% REPRESENTAR EN LAS DOS GRÁFICAS Y TABLAS LOS VALORES
            %----------------------------------------------
            %----------------------------------------------
            % Representar las curvas del albedo
            plot(app.UIAxes_abajo,lambda,Alb,'b',lambda,Albedo,'k'); xlabel(app.UIAxes_abajo,'Longitud de onda (nm)')
            ylabel(app.UIAxes_abajo,'Albedo');
            legend(app.UIAxes_abajo,{'Albedo manto limpio' 'Albedo manto contaminado'},'Location','northoutside')
            xticks(app.UIAxes_abajo,'auto')
            % Mostrar los resultados en la tabla
            % HIELO
            if mat == 1
                if app.FactordeasimetraDropDown.Value == "Debye"
                    Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetryED'];
                else
                    Datos =  [lambda Qabs' Qsca' Qext' Alb' Coalb' FAsymmetry'];
                end
                assignin('base','lambda', lambda)

                % HOLLÍN
            else
                if app.EstudioDropDown.Value == "Tamaño único"
                    %                     Datos =  [lambda_soot Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb_soot' Coalb_soot'];
                    %                     Datos =  [lambda_soot' Qabs' Qsca' Qext' CSabs' CSsca' CSext' MAC' MSC' MEC' Alb_soot' Coalb_soot'];
                    Datos =  [lambda_soot Qabs Qsca Qext CSabs CSsca CSext MAC MSC MEC Alb_soot Coalb_soot];
                    app.UITable_contaminado.ColumnName = ["Longitud de onda (nm)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción" , "Sección óptica de absorción", "Sección óptica de dispersión", "Sección óptica de extinción", "Sección específica de absorción", "Sección específica de dispersión", "Sección específica de extinción","Albedo", "Coalbedo"];
                    app.UITable_contaminado.ColumnFormat = {'short','shortE','shortE','shortE','shortE','shortE','shortE','short','short','short','short','short'};
                    % TABLA ESTUDIO MAT CONTAMINANTE
                    app.UITable_contaminado.Data = Datos;

                    save Datos_estudio_paramétrico Datos
                end
            end


            if app.EstudioDropDown.Value == "Tamaño único"
                % TABLA ESTUDIO MANTO CONTAMINADO
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Albedo manto limpio","Albedo manto contaminado", "Coalbedo manto limpio", "Coalbedo manto contaminado"];
                Coalb = 1-Alb;
                Coalbedo = 1 -Albedo;
                app.UITable_estudio.Data = [lambda Alb' Albedo' Coalb' Coalbedo'];
                Datos_sup_cont = [lambda Alb' Albedo' Coalb' Coalbedo'];
                save Datos_sup_cont Datos_sup_cont

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;


                % como ya se ha representado el albedo de los mantos se
                % representa el el estudio de los aglomerados de hollín
                lambda = lambda_soot; Alb = Alb_soot;

                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qabs,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qsca,lambda,Qext); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, Qabs,'r',lambda,Qsca,'b',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ÓPTICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSabs,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,CSabs,'r',lambda,CSsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,CSabs,'r',lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,CSsca,lambda,CSext); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, CSabs,'r',lambda,CSsca,'b',lambda,CSext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección óptica (m^2)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % SECCIONES ESPECÍFICAS
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MAC,'r'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MSC,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,MAC,'r',lambda,MSC,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,MAC,'r',lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,MSC,lambda,MEC); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, MAC,'r',lambda,MSC,'b',lambda,MEC,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Sección específica (m^2/g)');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,lambda,Alb,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,lambda,1-Alb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,lambda,Alb,'k',lambda, 1-Alb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,lambda,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    xticks(app.UIAxes_arriba,'auto')
                end

                %----------------------------------------------
                %----------------------------------------------
            elseif app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                % TABLA ESTUDIO MANTO CONTAMINADO
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Albedo manto limpio","Albedo manto contaminado", "Coalbedo manto limpio", "Coalbedo manto contaminado"];
                Coalb = 1-Alb;
                Coalbedo = 1 -Albedo;
                app.UITable_estudio.Data = [lambda Alb' Albedo' Coalb' Coalbedo'];

                % Plotear en función de lo que se decida (ORDENADAS)
                % Valores que indican si están seleccionados la
                % absorción, dispersión, extinción, albedo o coalbedo
                abso = app.AbsorcinCheckBox.Value;
                sca = app.DispersinCheckBox.Value;
                ext = app.ExtincinCheckBox.Value;
                alb = app.AlbedodispersinCheckBox.Value;
                coalb = app.CoalbedodispersinCheckBox.Value;


                % como ya se ha representado el albedo de los mantos se
                % representa el el estudio de los aglomerados de hollín
                lambda = lambda_soot; Alb = Alb_soot; Coalb = Coalb_soot;


                % EFICIENCIAS
                if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                    % Absorción
                    if abso == 1 && sca == 0 && ext == 0
                        %                         plot(app.UIAxes_imagen_inicial,lambda,Qabs,'r'); xlabel(app.UIAxes_imagen_inicial,'Longitud de onda (nm)')
                        %                         ylabel(app.UIAxes_imagen_inicial,'Eficiencia');
                        %                         legend(app.UIAxes_imagen_inicial,{'Absorción'},'Location','best')
                        %                         % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        %                         xticks(app.UIAxes_imagen_inicial,'auto')
                        plot(app.UIAxes_arriba,lambda,Qabs)

                        %                         plot(app.UIAxes_imagen_inicial,lambda,Qabs(:,1),lambda,Qabs(:,2),lambda,Qabs(:,3),lambda,Qabs(:,4),lambda,Qabs(:,5),lambda,Qabs(:,6),lambda,Qabs(:,7),lambda,Qabs(:,8),lambda,Qabs(:,9),lambda,Qabs(:,10));
                        xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia de absorción');
                        legend(app.UIAxes_arriba,{'Canal 1', 'Canal 2', 'Canal 3', 'Canal 4','Canal 5', 'Canal 6','Canal 7','Canal 8','Canal 9','Canal 10'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión
                    elseif sca == 1 && abso == 0 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Extinción
                    elseif ext == 1 && abso == 0 && sca == 0
                        plot(app.UIAxes_arriba,lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y dispersión
                    elseif abso == 1 && sca == 1 && ext == 0
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qsca,'b'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión'},'Location','best')
                        % Función de ajustar el eje X entre el min y el max establecido y con un intervalo dado
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción y extinción
                    elseif abso == 1 && sca == 0 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qabs,'r',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Dispersión y extinción
                    elseif abso == 0 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda,Qsca,lambda,Qext); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Dispersión', 'Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Absorción, dispersión y extinción
                    elseif abso == 1 && sca == 1 && ext == 1
                        plot(app.UIAxes_arriba,lambda, Qabs,'r',lambda,Qsca,'b',lambda,Qext,'g'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Eficiencia');
                        legend(app.UIAxes_arriba,{'Absorción', 'Dispersión','Extinción'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % ALBEDO DE DISPERSIÓN
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                    % Albedo
                    if alb == 1 && coalb == 0
                        plot(app.UIAxes_arriba,lambda,Alb,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                        yticks(app.UIAxes_arriba,'auto')
                        assignin('base','Alb_ploteado',Alb)

                        % Coalbedo
                    elseif alb == 0 && coalb == 1
                        plot(app.UIAxes_arriba,lambda,1-Alb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                        legend(app.UIAxes_arriba,{'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')

                        % Albedo y coalbedo
                    elseif alb == 1 && coalb == 1
                        plot(app.UIAxes_arriba,lambda,Alb,'k',lambda, Coalb,'--k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                        ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                        legend(app.UIAxes_arriba,{'Albedo', 'Coalbedo'},'Location','best')
                        xticks(app.UIAxes_arriba,'auto')
                    end

                    % FACTOR DE ASIMETRÍA
                elseif app.TreeOrdenadas.SelectedNodes.NodeData == 205
                    plot(app.UIAxes_arriba,lambda,FAsymmetry,'k'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                    ylabel(app.UIAxes_arriba,'Factor de asimetría');
                    legend(app.UIAxes_arriba,{'Factor de asimetría'},'Location','best')
                    xticks(app.UIAxes_arriba,'auto')
                end

            end
            %%%% mostrar datos en tabla pero se pone después para uqe no
            %%%% coja la media las gráficas
            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                assignin('base','Qabs_previo', Qabs)
                % se mete en la tabla sólo el valor de la media en el caso
                % de que se escoja más de una divisiones (nd es 1 división)
                if nd~=1
                    Qabs=mean(Qabs,1);
                    Qsca=mean(Qsca,1);
                    Qext=mean(Qext,1);
                    Alb_soot=mean(Alb_soot,1);
                    Coalb_soot=mean(Coalb_soot,1);
                end
                assignin('base','lambda_soot', lambda_soot)
                assignin('base','Qabs', Qabs)
                assignin('base','Qsca', Qsca)
                assignin('base','Qext', Qext)
                assignin('base','Alb_soot', Alb_soot)
                assignin('base','Coalb_soot', Coalb_soot)
                Datos =  [lambda_soot Qabs Qsca Qext Alb_soot Coalb_soot];

                app.UITable_contaminado.ColumnName = ["Longitud de onda (nm)", "Eficiencia de absorción", "Eficiencia de dispersión", "Eficiencia de extinción" ,"Albedo", "Coalbedo"];
                app.UITable_contaminado.ColumnFormat = {'short','shortE','shortE','shortE','short','short'};
                % TABLA ESTUDIO MAT CONTAMINANTE
                app.UITable_contaminado.Data = Datos;
                save Datos_nieve_cont lambda_soot Qabs Qsca Qext Alb_soot Coalb_soot
            end
        end

        % Value changed function: EstudioDropDown
        function EstudioDropDownValueChanged(app, event)
            % En función de si se realiza un estudio del tamaño único, de
            % imponer Df o el np0 se deben mostrar el radio de giro medio,
            % la desviación y las divisiones, así como la gráfica de la
            % distribución o no
            if app.EstudioDropDown.Value == "Tamaño único"
                app.rgmedianmEditField.Visible = 'Off';
                app.rgmedianmEditFieldLabel.Visible = 'Off';
                app.rgdesviacinnmEditField.Visible = 'Off';
                app.rgdesviacinnmEditFieldLabel.Visible = 'Off';
                app.divisionesEditField.Visible = 'Off';
                app.divisionesEditFieldLabel.Visible = 'Off';
                app.Panel_canales.Visible = 'Off';
                app.MostrarButton.Visible = 'Off';
                app.UIAxesDIST.Visible = 'Off';
            elseif app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                app.rgmedianmEditField.Visible = 'On';
                app.rgmedianmEditFieldLabel.Visible = 'On';
                app.rgdesviacinnmEditField.Visible = 'On';
                app.rgdesviacinnmEditFieldLabel.Visible = 'On';
                app.divisionesEditField.Visible = 'On';
                app.divisionesEditFieldLabel.Visible = 'On';
                app.Panel_canales.Visible = 'On';
                app.MostrarButton.Visible = 'On';
                app.UIAxesDIST.Visible = 'On';
            end
        end

        % Value changed function: divisionesEditField
        function divisionesEditFieldValueChanged(app, event)
            divisiones = app.divisionesEditField.Value;
            rg_media = app.rgmedianmEditField.Value;
            rg_desv = app.rgdesviacinnmEditField.Value;
            if divisiones > 10
                msgbox('El máximo número de canales disponibles es de 10','Información','help')
            end

            if app.EstudioDropDown.Value == "Imponer Df"
                if divisiones <= 10
                    %introducir cambios en iteraciones y distribución

                    nd_Df = divisiones;
                    % Introducir la media y la desviación para sacar la distribución gaussiana
                    % LA ECUACIÓN VIENE CON EL DIÁMETRO, de ahí que haya que multiplicar por 2 la media y la desviación
                    mu_Df=2*rg_media; % la expresión de la normal viene en diámetro expresada
                    sigma_Df=2*rg_desv;
                    lim_inf_Df=mu_Df-2*sigma_Df;
                    lim_sup_Df=mu_Df+2*sigma_Df;
                    y_Df = lim_inf_Df:0.1:lim_sup_Df;
                    f_Df = exp(-(((y_Df)-(mu_Df)).^2)./(2*(sigma_Df)^2))./((sigma_Df)*sqrt(2*pi));

                    % Crear dos vectores que vayan desde la media, máximo, hasta los límites especificados ("mm" y "p") y juntarlos en un vector "t"
                    a_Df=0;
                    if rem(nd_Df,2)==0
                        a_Df=nd_Df/2;
                        mm_Df=mu_Df-(nd_Df-1)*sigma_Df/a_Df:sigma_Df*2/a_Df:mu_Df-sigma_Df/a_Df;
                        p_Df=mu_Df+sigma_Df/a_Df:sigma_Df*2/a_Df:mu_Df+(nd_Df-1)*sigma_Df/a_Df;
                        t_Df=[mm_Df p_Df];
                    elseif nd_Df==1
                        t_Df=mu_Df;
                    elseif rem(nd_Df,2)~=0
                        a_Df=nd_Df/2;
                        b_Df=1:100;
                        c_Df=nd_Df-nd_Df/2-1/2;
                        mm_Df=mu_Df-c_Df*sigma_Df*4/nd_Df:(sigma_Df*4/nd_Df):mu_Df-(sigma_Df*4/nd_Df);
                        p_Df=mu_Df+sigma_Df*4/nd_Df:sigma_Df*4/nd_Df:mu_Df+c_Df*sigma_Df*4/nd_Df;
                        t_Df=[mm_Df mu_Df p_Df];
                    end
                    rg=t_Df/2;
                    % crear el vector de tamaño el de "t" para poder crear el diagrama de barras
                    d_Df=[];
                    if rem(nd_Df,2)==0
                        for i_Df=1:length(y_Df)/2
                            for j_Df=1:length(t_Df)
                                if y_Df(i_Df) == round(t_Df(j_Df))
                                    d_Df=[d_Df f_Df(i_Df)];
                                end
                            end
                        end
                        e_Df=fliplr(d_Df);
                        d_Df=[d_Df e_Df];
                    elseif rem(nd_Df,2)~=0
                        for l_Df=1:length(y_Df)/2
                            for k_Df=1:length(t_Df)
                                if y_Df(l_Df)== round(t_Df(k_Df))
                                    d_Df=[d_Df f_Df(l_Df)];
                                end
                            end
                        end
                        e_Df=fliplr(d_Df);
                        d_Df=[d_Df max(f_Df) e_Df];
                    elseif nd_Df==1
                        d_Df=max(f_Df);
                    end
                    if rem(nd_Df,2)==0
                        g_Df=d_Df/0.93; %factor del error del 95.5% y de la discretización
                    elseif rem(nd_Df,2)~=0
                        g_Df=d_Df;
                    elseif nd_Df==1
                        g_Df=d_Df;
                    end

                    %-------------------------------------------------------
                    % plot de diámetro de giro con diagrama de barras
                    dg=2*rg;%diámetro de giro
                    ancho=(lim_sup_Df-lim_inf_Df)/nd_Df;
                    for rep=1:length(dg)
                        fun=@(w) exp(-(((w)-(mu_Df)).^2)./(2*(sigma_Df)^2))./((sigma_Df)*sqrt(2*pi()));
                        if nd_Df==1
                            I(rep)=integral(fun,lim_inf_Df,lim_sup_Df);
                        else
                            ancho=dg(2)-dg(1);
                            I(rep)=integral(fun ,dg(rep)-(ancho/2),dg(rep)+(ancho/2));
                            %I(rep)=integral(fun,160,200);
                        end
                        H(rep)=(I(rep))/ancho;
                        H(rep)=H(rep)/0.9545;
                        % considerar que el alto no es el total, se ha limitado para 95.45%, y no es en tanto por uno
                        % Esta H va a ser fn (FRACCIÓN NUMÉRICA) empleada en la siguiente pantalla
                    end

                    n=divisiones; %número divisionses
                    Liminf=lim_inf_Df; %ímite inferior
                    a=ancho; %ancho columna
                    q=0; %contador
                    pos=1; %contador

                    %Eje de abcisas
                    x=ones(1,n*3+1)*Liminf;
                    for i=3:length(x)
                        if q==3
                            q=0;
                            pos=pos+1;
                        end
                        x(i)=x(i)+pos*a;
                        q=q+1;
                    end
                    H=H*ancho;

                    %Eje de ordenadas
                    y=[0];
                    for j=1:length(H)
                        y=[y,H(j),H(j),0];
                    end

                    %representación
                    plot(app.UIAxesDIST,x,y,y_Df,f_Df*ancho,'r','LineWidth',1.5)
                    xlabel(app.UIAxesDIST,'Diámetro de giro (nm)'); ylabel(app.UIAxesDIST,'Np/Npt');
                    xticks(app.UIAxesDIST,'auto')
                    %----------------------------------------------------------------------
                end
            elseif app.EstudioDropDown.Value == "Imponer np0"
                %--------------------------------------------------------------------------
                %introducir cambios en iteraciones y distribución a partir de aquí
                nd_np0 = divisiones;
                % Introducir la media y la desviación para sacar la distribución gaussiana
                mu_np0=2*rg_media;
                sigma_np0=2*rg_desv;
                lim_inf_np0=mu_np0-2*sigma_np0;
                lim_sup_np0=mu_np0+2*sigma_np0;
                y_np0 = lim_inf_np0:lim_sup_np0;
                f_np0 = exp(-(((y_np0)-(mu_np0)).^2)./(2*(sigma_np0)^2))./((sigma_np0)*sqrt(2*pi));

                % Crear dos vectores que vayan desde la media, máximo, hasta los
                % límites especificados ("mm" y "p") y juntarlos en un vector "t"
                a_np0=0;
                if rem(nd_np0,2)==0
                    a_np0=nd_np0/2;
                    mm_np0=mu_np0-(nd_np0-1)*sigma_np0/a_np0:sigma_np0*2/a_np0:mu_np0-sigma_np0/a_np0;
                    p_np0=mu_np0+sigma_np0/a_np0:sigma_np0*2/a_np0:mu_np0+(nd_np0-1)*sigma_np0/a_np0;
                    t_np0=[mm_np0 p_np0];
                elseif nd_np0==1
                    t_np0=mu_np0;
                elseif rem(nd_np0,2)~=0
                    a_np0=nd_np0/2;
                    b_np0=1:100;
                    c_np0=nd_np0-nd_np0/2-1/2;
                    mm_np0=mu_np0-c_np0*sigma_np0*4/nd_np0:sigma_np0*4/nd_np0:mu_np0-sigma_np0*4/nd_np0;
                    p_np0=mu_np0+sigma_np0*4/nd_np0:sigma_np0*4/nd_np0:mu_np0+c_np0*sigma_np0*4/nd_np0;
                    t_np0=[mm_np0 mu_np0 p_np0];
                end
                rg=t_np0/2;
                % crear el vector de tamaño el de "t" para poder crear el diagrama de barras
                d_np0=[];
                if rem(nd_np0,2)==0
                    for i_np0=1:length(y_np0)/2
                        for j_np0=1:length(t_np0)
                            if y_np0(i_np0)== round(t_np0(j_np0))
                                d_np0=[d_np0 f_np0(i_np0)];
                            end
                        end
                    end
                    e_np0=fliplr(d_np0);
                    d_np0=[d_np0 e_np0];
                elseif rem(nd_np0,2)~=0
                    for l_np0=1:length(y_np0)/2
                        for k_np0=1:length(t_np0)
                            if y_np0(l_np0)== round(t_np0(k_np0))
                                d_np0=[d_np0 f_np0(l_np0)];
                            end
                        end
                    end
                    e_np0=fliplr(d_np0);
                    d_np0=[d_np0 max(f_np0) e_np0];
                elseif nd_np0==1
                    d_np0=max(f_np0);
                end

                %-------------------------------------------------------
                % plot de diámetro de giro con diagrama de barras
                dg=2*rg;%diámetro de giro
                ancho=(lim_sup_np0-lim_inf_np0)/nd_np0;
                for rep=1:length(dg)
                    fun=@(w) exp(-(((w)-(mu_np0)).^2)./(2*(sigma_np0)^2))./((sigma_np0)*sqrt(2*pi()));
                    if nd_np0==1
                        I(rep)=integral(fun ,lim_inf_np0,lim_sup_np0);
                    else
                        ancho=dg(2)-dg(1);
                        I(rep)=integral(fun ,dg(rep)-(ancho/2),dg(rep)+(ancho/2));
                    end
                    H(rep)=(I(rep))/ancho;
                    H(rep)=H(rep)/0.9545;

                    % considerar que el alto no es el total, se ha limitado para 95.45%, y no es en tanto por uno
                    % Esta H va a ser fn (FRACCIÓN NUMÉRICA) empleada en la siguiente pantalla
                end

                n=nd_np0; %número divisionses
                Liminf=lim_inf_np0; %ímite inferior
                a=ancho; %ancho columna
                q=0; %contador
                pos=1; %contador

                %Eje de abcisas
                x=ones(1,n*3+1)*Liminf;
                for i=3:length(x)
                    if q==3
                        q=0;
                        pos=pos+1;
                    end
                    x(i)=x(i)+pos*a;
                    q=q+1;
                end
                H=H*ancho;

                %Eje de ordenadas
                y=[0];
                for j=1:length(H)
                    y=[y,H(j),H(j),0];
                end

                %representación
                plot(app.UIAxesDIST,x,y,y_np0,f_np0*ancho,'r','LineWidth',1.5)
                xlabel(app.UIAxesDIST,'Diámetro de giro (nm)'); ylabel(app.UIAxesDIST,'Np/Npt');
                xticks(app.UIAxesDIST,'auto')
            end
            save Datos_dist

            %% REPRESENTAR O OCULTAR LOS TICKS DE LOS CANALES A LA DERECHA
            if divisiones == 1
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'Off';
                app.Canal3CheckBox.Enable = 'Off';
                app.Canal4CheckBox.Enable = 'Off';
                app.Canal5CheckBox.Enable = 'Off';
                app.Canal6CheckBox.Enable = 'Off';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 2
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'Off';
                app.Canal4CheckBox.Enable = 'Off';
                app.Canal5CheckBox.Enable = 'Off';
                app.Canal6CheckBox.Enable = 'Off';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 3
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'Off';
                app.Canal5CheckBox.Enable = 'Off';
                app.Canal6CheckBox.Enable = 'Off';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 4
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'Off';
                app.Canal6CheckBox.Enable = 'Off';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 5
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'Off';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 6
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'On';
                app.Canal7CheckBox.Enable = 'Off';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 7
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'On';
                app.Canal7CheckBox.Enable = 'On';
                app.Canal8CheckBox.Enable = 'Off';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 8
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'On';
                app.Canal7CheckBox.Enable = 'On';
                app.Canal8CheckBox.Enable = 'On';
                app.Canal9CheckBox.Enable = 'Off';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 9
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'On';
                app.Canal7CheckBox.Enable = 'On';
                app.Canal8CheckBox.Enable = 'On';
                app.Canal9CheckBox.Enable = 'On';
                app.Canal10CheckBox.Enable = 'Off';
            elseif divisiones == 10
                app.TodosCheckBox.Enable = 'On';
                app.MediaCheckBox.Enable = 'On';
                app.Canal1CheckBox.Enable = 'On';
                app.Canal2CheckBox.Enable = 'On';
                app.Canal3CheckBox.Enable = 'On';
                app.Canal4CheckBox.Enable = 'On';
                app.Canal5CheckBox.Enable = 'On';
                app.Canal6CheckBox.Enable = 'On';
                app.Canal7CheckBox.Enable = 'On';
                app.Canal8CheckBox.Enable = 'On';
                app.Canal9CheckBox.Enable = 'On';
                app.Canal10CheckBox.Enable = 'On';
            end
        end

        % Button pushed function: AadiralbedoButton
        function AadiralbedoButtonPushed(app, event)
            winopen('NuevoAlbedo.xlsx')
            % Guardar el nombre del nuevo material y ponerlo en el
            % dropdown de Espectro Solar, Fuente Halógena
            nombre = readcell('NuevoAlbedo.xlsx');
            nombre = nombre(3,2);
            app.AlbedosuperficieinferiorDropDown.Items = ['Albedo Asfalto' nombre];
        end

        % Value changed function: cmEditField
        function cmEditFieldValueChanged(app, event)
%             % Estudio profundidad finita y semi-infinita
%             % Tomar la profundidad a la que se desea realizar el cálculo del manto
%             h = app.cmEditField.Value;
%             radio = app.RadiodelapartculaesfricanmEditField.Value; % nm
% 
%             % Indicar si es profundidad semi-infinita o finita, por lo que el albedo de
%             % la superficie de abajo afecta. Profundidad a partir de la cuál es semi infinito,
%             % profundidad límite  tiene que ser una expresión que depende del tamaño del grano
%             % y de la longitud de onda
% 
%             % Los parámetos de las correlaciones para cada índice
%             % de refracción
%             if app.ndicederefraccinDropDown_ICE.Value == "Warren & Brandt (2008)"
%                 b1 = 1.9e-4;
%                 b2 = 1.951;
%                 h1 = 0;
%                 h2 = 0.564;
%                 p1 = 7.1e-04;
%                 p2 = 0.069;
% 
%             elseif app.ndicederefraccinDropDown_ICE.Value == "Picard et al. (2016)"
%                 b1 = 1.9e-4;
%                 b2 = 2;
%                 h1 = 0;
%                 h2 = 0.588;
%                 p1 = 6.2e-04;
%                 p2 = 0.075;
% 
%             end
% 
%             % Correlaciones de los parámetros de la función similar
%             % a la de densidad de probabilidad de Weibull
%             gs = radio/1000; % gs es gran siez en micras
% 
%             beta = b1*gs + b2;
%             mu = h1*gs + h2;
%             phi = p1*gs + p2;
% 
%             % Para poder establecer la condición de si la profundidad
%             % introducida es menor o mayor que la profundidad semi-infinita
%             % se debe tomar una profundidad para comparar. En este caso se va a tomar
%             % la profundidad del primer valor de longitud de onda introducido en pantalla,
%             % ya que se ha visto en las huellas del modelo que el valor
%             % máximo de profundidad corresponde con menores longitudes de
%             % onda, a diferencia de si se observa respecto al radio que aumenta al aumentar éste.
% 
%             % Valor de longitud  de onda mínimo
%             landa = app.ValorminimoEditField.Value;
%             landa_h = landa/1000; % lambda en micras
%             
%             hmax_condicion  = phi*(beta/mu).*((landa_h/mu).^(beta-1)).*exp((landa_h/mu).^beta);
% 
%             save hmax_condicion hmax_condicion
%             assignin('base','hmax_condicion',hmax_condicion)
% 
%             % Selección semi-inf o introducir profundidad
%             selectedButton = app.ProfundidaddelmantoButtonGroup.SelectedObject;
% 
%             % Si es profundidad semi-infinita porque sea profundidad mayor
%             % que la introducida o escogido en toggle button
%             if h >= hmax_condicion || selectedButton.Value == app.SemiinfinitaButton.Value
%                 msgbox('La profundidad indicada es mayor que la considerada como finita y y por lo tanto se considerará profundidad semi-infinita','Información','help')
%                 app.AlbedosuperficieinferiorDropDown.Enable = 'Off';
%                 app.AlbedosuperficieinferiorDropDownLabel.Enable = 'Off';
%                 app.AadiralbedoButton.Enable = 'Off';
% 
%                 % Si es profundidad finita
%             elseif h<hmax_condicion || selectedButton.Value == app.Button_cm.Value
%                 msgbox('La profundidad indicada es menor que la considerada como semi-infinita y y por lo tanto es necesario intriducir el albedo del suelo','Información','help')
%                 app.AlbedosuperficieinferiorDropDown.Enable = 'On';
%                 app.AlbedosuperficieinferiorDropDownLabel.Enable = 'On';
%                 app.AadiralbedoButton.Enable = 'On';
%             end
        end

        % Selection changed function: ProfundidaddelmantoButtonGroup
        function ProfundidaddelmantoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProfundidaddelmantoButtonGroup.SelectedObject;
            if selectedButton.Value == app.SemiinfinitaButton.Value
                app.AlbedosuperficieinferiorDropDown.Enable = 'Off';
                app.AlbedosuperficieinferiorDropDownLabel.Enable = 'Off';
                app.AadiralbedoButton.Enable = 'Off';
                app.cmEditField.Enable = 'Off';

                % Si es profundidad finita
            elseif selectedButton.Value == app.Button_cm.Value
                app.AlbedosuperficieinferiorDropDown.Enable = 'On';
                app.AlbedosuperficieinferiorDropDownLabel.Enable = 'On';
                app.AadiralbedoButton.Enable = 'On';
                app.cmEditField.Enable = 'On';
            end
        end

        % Button pushed function: MostrarButton
        function MostrarButtonPushed(app, event)
            % Asignar los valores de los canales que se deseen mostrar
            Todos = app.TodosCheckBox.Value;
            Media = app.MediaCheckBox.Value;
            Canal_1 = app.Canal1CheckBox.Value;
            Canal_2 = app.Canal2CheckBox.Value;
            Canal_3 = app.Canal3CheckBox.Value;
            Canal_4 = app.Canal4CheckBox.Value;
            Canal_5 = app.Canal5CheckBox.Value;
            Canal_6 = app.Canal6CheckBox.Value;
            Canal_7 = app.Canal7CheckBox.Value;
            Canal_8 = app.Canal8CheckBox.Value;
            Canal_9 = app.Canal9CheckBox.Value;
            Canal_10 = app.Canal10CheckBox.Value;

            %%---------------------
            %Recoger los valores introducidos por el usuario
            lambda = app.LongituddeondanmEditField.Value;
            rp0 = app.RadiodelapartculaesfricanmEditField.Value;
            np0 = app.NdepartculasprimariasEditField.Value;
            Df = app.DimensinfractalEditField.Value;
            % Cargar datos del índice de refracción
            load indice_refraccion.mat landa n k f_eV f_Hz

            % Valores del rango del estudio escogido
            min = app.ValorminimoEditField.Value;
            maximo = app.ValormaximoEditField.Value;

            % densidad hollín
            ro = 1850; % en kg/m^3
            %             landa = landa( landa >= min & landa <= max );
            load landa_hielo landa_hielo
            landa = landa_hielo; % tiene que tener el mismo tamaño de longitud de onda que el manto nevado

            n = n( landa >= min & landa <= maximo );
            k = k( landa >= min & landa <= maximo );
            m = n-k*1i;
            lambda = landa;
            lambda_soot = lambda;
            assignin('base','lambda_hollin',lambda)
            %-----------------------------------------------------------------------------------------------
            %-----------------------------------------------------------------------------------------------
            %%%% CÁLCULO DISTRIBUCIÓN

            % --------------------------------------------------------
            divisiones = app.divisionesEditField.Value;
            rg_media = app.rgmedianmEditField.Value;
            rg_desv = app.rgdesviacinnmEditField.Value;

            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                if divisiones <= 10
                    %introducir cambios en iteraciones y distribución
                    nd = divisiones;
                    % Introducir la media y la desviación para sacar la distribución gaussiana
                    % LA ECUACIÓN VIENE CON EL DIÁMETRO, de ahí que haya que multiplicar por 2 la media y la desviación
                    mu=2*rg_media; % la expresión de la normal viene en diámetro expresada
                    sigma=2*rg_desv;
                    % se sacan los límites de la distribución para sacar el
                    % vector "y" de las abcisas y la función gaussiana "f"
                    lim_inf=mu-2*sigma;
                    lim_sup=mu+2*sigma;
                    y = lim_inf:0.1:lim_sup;
                    f = exp(-(((y)-(mu)).^2)./(2*(sigma)^2))./((sigma)*sqrt(2*pi));

                    % Para generar el diagrama de barras con la contribución
                    % de cada tamaño se debe generar un vector de tamaños:
                    % Crear dos vectores que vayan desde la media, máximo, hasta los límites especificados ("mm" y "p") y juntarlos en un vector "t"
                    a=0;
                    % Si es par
                    if rem(nd,2)==0
                        a=nd/2;
                        mm=mu-(nd-1)*sigma/a:sigma*2/a:mu-sigma/a;
                        p=mu+sigma/a:sigma*2/a:mu+(nd-1)*sigma/a;
                        t=[mm p];

                        % Si sólo hay una división es la media
                    elseif nd==1
                        t=mu;

                        % SI es impar la media está en el centro
                    elseif rem(nd,2)~=0
                        a=nd/2;
                        b=1:100;
                        c=nd-nd/2-1/2;
                        mm=mu-c*sigma*4/nd:(sigma*4/nd):mu-(sigma*4/nd);
                        p=mu+sigma*4/nd:sigma*4/nd:mu+c*sigma*4/nd;
                        t=[mm mu p];
                    end
                    rg=t/2;

                    % crear el vector de tamaño el de "t" para poder crear el diagrama de barras
                    d=[];
                    if rem(nd,2)==0
                        for i=1:length(y)/2
                            for j=1:length(t)
                                if y(i) == round(t(j))
                                    d=[d f(i)];
                                end
                            end
                        end
                        e=fliplr(d);
                        d=[d e];
                    elseif rem(nd,2)~=0
                        for l=1:length(y)/2
                            for k=1:length(t)
                                if y(l)== round(t(k))
                                    d=[d f(l)];
                                end
                            end
                        end
                        e=fliplr(d);
                        d=[d max(f) e];
                    elseif nd==1
                        d=max(f);
                    end
                    if rem(nd,2)==0
                        g=d/0.93; %factor del error del 95.5% y de la discretización
                    elseif rem(nd,2)~=0
                        g=d;
                    elseif nd==1
                        g=d;
                    end

                    %-------------------------------------------------------
                    % plot de diámetro de giro con diagrama de barras
                    dg=2*rg; % diámetro de giro
                    ancho=(lim_sup-lim_inf)/nd;
                    for rep=1:length(dg)
                        % Se define la función a integrar
                        fun=@(w) exp(-(((w)-(mu)).^2)./(2*(sigma)^2))./((sigma)*sqrt(2*pi()));
                        if nd==1
                            I(rep)=integral(fun,lim_inf,lim_sup);
                        else
                            ancho=dg(2)-dg(1);
                            I(rep)=integral(fun ,dg(rep)-(ancho/2),dg(rep)+(ancho/2));
                            %I(rep)=integral(fun,160,200);
                        end
                        H(rep)=(I(rep))/ancho;
                        H(rep)=H(rep)/0.9545;
                        % considerar que el alto no es el total, se ha limitado para 95.45%, y no es en tanto por uno
                        % Esta H va a ser fn (FRACCIÓN NUMÉRICA) empleada en la siguiente pantalla
                    end

                    n=divisiones; %número divisionses
                    Liminf=lim_inf; %ímite inferior
                    a=ancho; %ancho columna
                    q=0; %contador
                    pos=1; %contador

                    %Eje de abcisas
                    x_eje=ones(1,n*3+1)*Liminf;
                    for i=3:length(x_eje)
                        if q==3
                            q=0;
                            pos=pos+1;
                        end
                        x_eje(i)=x_eje(i)+pos*a;
                        q=q+1;
                    end
                    H=H*ancho;

                    %Eje de ordenadas
                    y_eje=[0];
                    for j=1:length(H)
                        y_eje=[y_eje,H(j),H(j),0];
                    end

                    %representación
                    plot(app.UIAxesDIST,x_eje,y_eje,y,f*ancho,'r','LineWidth',1.5)
                    xlabel(app.UIAxesDIST,'Diámetro de giro (nm)'); ylabel(app.UIAxesDIST,'Np/Npt');
                    xticks(app.UIAxesDIST,'auto')
                    %----------------------------------------------------------------------
                end
            end
            %-----------------------------------------------------

            Df_Df = app.DimensinfractalEditField.Value;
            np0_Df = app.NdepartculasprimariasEditField.Value;
            rp0_Df = app.RadiodelapartculaesfricanmEditField.Value;
            assignin('base','lambda_previo',lambda)
            %                 lambda = app.ValormaximoEditField.Value - app.ValorminimoEditField.Value;

            incr=0.000000001;
            %                 for i=1:length(lambda)
            % %                     K=(2*pi)/lambda;
            % %                     x=(2*pi*rp0_Df)/lambda;
            K=(2*pi)./lambda;
            x=(2*pi*rp0_Df)./lambda;
            % %                     K(i)=(2*pi)/lambda(i);
            % %                     x(i)=(2*pi*rp0_Df)/lambda(i);
            %                 end
            assignin('base','x',x)

            for i=1:length(lambda)
                % modificar la iteración para un vector
                K1=0;K2=0;K3=0;Kf=0;in=0;
                %z=2;
                np0_Df=Inf;
                count=0;
                while np0_Df>5
                    K1=3^(1/2);
                    K2=1.8;
                    K3=1.5933;
                    Kf=(((K1/2)-K2+(K3/2))*(Df_Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df_Df)+((3*K1)-(3*K2)+K3);
                    np0_Df=[];
                    for pos=1:length(rg)
                        np0_pre=Kf*((rg(pos)/rp0_Df)^Df_Df);
                        np0_Df=[np0_Df np0_pre];
                    end
                    np0_Df1=[];
                    for posi=1:length(rg)
                        K1=(np0_Df(posi))/(((3/5)+(1/3)*(((np0_Df(posi))^2)-1))^0.5);
                        K2=(np0_Df(posi))/((3/5)+((10*(np0_Df(posi)^2)-(8*np0_Df(posi))-2)/(18*np0_Df(posi))));
                        in=((((3*np0_Df(posi))/20)+((1/120)*((324*(np0_Df(posi)^2)+(343/15))^0.5)))^(1/3))-((7)/(60*((((3*np0_Df(posi))/20)+((1/120)*((324*(np0_Df(posi)^2)+(343/15))^0.5)))^(1/3))))-(0.5);
                        K3=((((10/3)*(in^3))+(5*(in^2))+((11/3)*in)+1)^(5/2))/((((28/5)*(in^5))+(14*(in^4))+(18*(in^3))+(13*(in^2))+((22/5)*in)+(3/5))^(3/2));
                        Kf=(((K1/2)-K2+(K3/2))*(Df_Df^2))+((((-5/2)*K1)+(4*K2)-((3/2)*K3))*Df_Df)+((3*K1)-(3*K2)+K3);
                        np0_pre1=Kf*((rg(posi)/rp0_Df)^Df_Df); %np0=Kf*(rg/rp0)^Df;
                        np0_Df1=[np0_Df1 np0_pre1];
                    end
                    count=count+1;
                    break
                end
                np0_Df=np0_Df1;
                %                     %---------------------
                %                     %cálculos en los que no afecta que sea escalar o vector rg
                %                      K=(2*pi)/lambda;
                %                      x=(2*pi*rp0_Df)/lambda;
                %                     %----------------------------
                Ap0=(pi()*rp0_Df^2);
                z1=log(np0_Df)/(log((0.8488*np0_Df)+0.1512));

                % bucle if para si el es un escalar,1 división, o un vector, más divisiones
                if length(np0_Df) == 1
                    z3=1/(0.6667+(0.2003/log(np0_Df)));
                    z=abs(z1-1+((z3+1-z1)^(((Df_Df-1)/2)^1.86))); % Cambio valor m 1.95 a 1.86
                    Ap=Ap0*(np0_Df^(1/z));
                    GFB(i)=(1+((4*(K^2)*(rg^2))/(3*(Df_Df))))^(-(Df_Df)*0.5);

                    Qabsp0(i)=-4*x*(imag(((m^2)-1)/((m^2)+2))); %[3.19]
                    Qscap0(i)=(8/3)*x^4*(abs(((m^2)-1)/((m^2)+2))^2);
                    Qabs(i)=((np0_Df)^(1-(1/z)))*Qabsp0(i);
                    QscaFB(i)=((np0_Df)^(2-(1/z)))*Qscap0(i)*GFB(i);
                    QextFB(i)=Qabs(i)+QscaFB(i);
                    AlbFB(i)=QscaFB(i)/QextFB(i);
                    beta=(3*(Df_Df))/(8*(K*rg)^2);
                    if beta>=1
                        GKF(i)=1-((2*(K*rg)^2)/3);
                        QscaKF(i)=(np0_Df^(2-(1/z)))*Qscap0(i)*GKF(i);
                        QextKF(i)=Qabs(i)+QscaKF(i);
                        AlbKF(i)=QscaKF(i)/QextKF(i);
                    elseif beta<1 && Df_Df==2
                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df_Df)*(3/(2.001-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2.001-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                        QscaKF(i)=(np0_Df^(2-(1/z)))*Qscap0(i)*GKF(i);
                        QextKF(i)=Qabs(i)+QscaKF(i);
                        AlbKF(i)=QscaKF(i)/QextKF(i);
                    else
                        GKF(i)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg*K)^2^0.5)^(-Df_Df)*(3/(2-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                        QscaKF(i)=(np0_Df^(2-(1/z)))*Qscap0(i)*GKF(i);
                        QextKF(i)=Qabs(i)+QscaKF(i);
                        AlbKF(i)=QscaKF(i)/QextKF(i);
                    end
                    in2=(sqrt((4*np0_Df)-1)/(2*sqrt(3)))-(1/2);
                    in3=(3*np0_Df/20+((324*np0_Df^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_Df/20+(((324*np0_Df^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                    R1=rp0_Df*np0_Df;
                    R2=((2*in2)+1)*rp0_Df;
                    R3=((2*in3)+1)*rp0_Df;
                    R=(((log(R1)-(2*log(R2))+log(R3))/2)*Df_Df^2)+((((-5*log(R1))+(8*log(R2))-(3*log(R3)))/2)*Df_Df)+((3*log(R1))-(3*log(R2))+log(R3));
                    fv=(np0_Df*(rp0_Df^3))/(exp(R)^3);
                    m2LL(i)=(1+(2*fv*(((m^2)-1)/((m^2)+2))))/(1-(fv*(((m^2)-1)/((m^2)+2))));
                    m2MG(i)=(((m^2)*((2*fv)+1))+(2*(-fv+1)))/(((m^2)*((-1*fv)+1))+((fv+2)));
                    m2B(i)=(((-1.5*fv)+1)+(((1.5*fv)-0.5)*m^2)+sqrt((((1.5*fv)-1)-(((1.5*fv)-0.5)*m^2))^2+(2*m^2)))/2;
                    x1=2*pi*exp(R)/lambda;yLL=sqrt(m2LL(i))*x1;yMG=sqrt(m2MG(i))*x1;yB=sqrt(m2B(i))*x1;n=1;nmax=round(2+x1+4*x1^(1/3));
                    sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                    while n<=nmax % expresiones en TFG Joaquín desde página 21 documento
                        shix=(pi*x1/2)^0.5*besselj(n+0.5,x1);shix1=(pi*(x1+incr)/2)^0.5*besselj(n+0.5,x1+incr);
                        dshix=(shix1-shix)/incr;
                        shiyLL=(pi*yLL/2)^0.5*besselj(n+0.5,yLL);shiy1LL=(pi*(yLL+incr)/2)^0.5*besselj(n+0.5,yLL+incr);
                        shiyMG=(pi*yMG/2)^0.5*besselj(n+0.5,yMG);shiy1MG=(pi*(yMG+incr)/2)^0.5*besselj(n+0.5,yMG+incr);
                        shiyB=(pi*yB/2)^0.5*besselj(n+0.5,yB);shiy1B=(pi*(yB+incr)/2)^0.5*besselj(n+0.5,yB+incr);
                        dshiyLL=(shiy1LL-shiyLL)/incr;
                        dshiyMG=(shiy1MG-shiyMG)/incr;
                        dshiyB=(shiy1B-shiyB)/incr;
                        psix=(pi*x1/2)^0.5*besselh(n+0.5,2,x1);psix1=(pi*(x1+incr)/2)^0.5*besselh(n+0.5,2,x1+incr);
                        dpsix=(psix1-psix)/incr;
                        anLL=(dshiyLL*shix-sqrt(m2LL(i))*shiyLL*dshix)/(dshiyLL*psix-sqrt(m2LL(i))*shiyLL*dpsix);
                        anMG=(dshiyMG*shix-sqrt(m2MG(i))*shiyMG*dshix)/(dshiyMG*psix-sqrt(m2MG(i))*shiyMG*dpsix);
                        anB=(dshiyB*shix-sqrt(m2B(i))*shiyB*dshix)/(dshiyB*psix-sqrt(m2B(i))*shiyB*dpsix);
                        bnLL=(sqrt(m2LL(i))*dshiyLL*shix-shiyLL*dshix)/(sqrt(m2LL(i))*dshiyLL*psix-shiyLL*dpsix);
                        bnMG=(sqrt(m2MG(i))*dshiyMG*shix-shiyMG*dshix)/(sqrt(m2MG(i))*dshiyMG*psix-shiyMG*dpsix);
                        bnB=(sqrt(m2B(i))*dshiyB*shix-shiyB*dshix)/(sqrt(m2B(i))*dshiyB*psix-shiyB*dpsix);
                        sumaLL=sumaLL+(2*n+1)*real(anLL+bnLL);
                        sumaMG=sumaMG+(2*n+1)*real(anMG+bnMG);
                        sumaB=sumaB+(2*n+1)*real(anB+bnB);
                        sumascaLL=sumascaLL+(2*n+1)*((abs(anLL))^2+(abs(bnLL))^2);
                        sumascaMG=sumascaMG+(2*n+1)*((abs(anMG))^2+(abs(bnMG))^2);
                        sumascaB=sumascaB+(2*n+1)*((abs(anB))^2+(abs(bnB))^2);
                        n=n+1;
                    end
                    QextLL(i)=2/x1^2*sumaLL;
                    QscaLL(i)=2/x1^2*sumascaLL;
                    QabsLL(i)=QextLL(i)-QscaLL(i);
                    QextMG(i)=2/x1^2*sumaMG;
                    QscaMG(i)=2/x1^2*sumascaMG;
                    QabsMG(i)=QextMG(i)-QscaMG(i);
                    QextB(i)=2/x1^2*sumaB;
                    QscaB(i)=2/x1^2*sumascaB;
                    QabsB(i)=QextB(i)-QscaB(i);
                    % en el caso de que rg sea un vector
                else
                    z3=[]; z=[]; Ap=[];
                    for p=1:length(np0_Df)
                        z3_prev=1/(0.6667+(0.2003/log(np0_Df(p))));
                        z3=[z3 z3_prev];
                        z_prev=abs(z1-1+((z3(p)+1-z1)^(((Df_Df-1)/2)^1.95)));
                        z=[z z_prev];
                        Ap_prev=Ap0*(np0_Df(p)^(1/z(p)));
                        Ap=[Ap Ap_prev];

                        %                             GFB(i,p)=(1+((4*(K^2)*(rg(p)^2))/(3*(Df_Df))))^(-(Df_Df)*0.5);
                        GFB(i,p)=(1+((4.*(K(i).^2).*(rg(p).^2))./(3.*(Df_Df)))).^(-(Df_Df).*0.5);
                        Qabsp0(i,p)=-4.*x(i).*(imag(((m(i).^2)-1)./((m(i).^2)+2)));
                        Qscap0(i,p)=(8/3).*x(i).^4.*(abs(((m(i).^2)-1)./((m(i).^2)+2)).^2);
                        Qabs(i,p)=((np0_Df(p)).^(1-(1/z(p))))*Qabsp0(i,p);
                        QscaFB(i,p)=((np0_Df(p))^(2-(1/z(p))))*Qscap0(i,p)*GFB(i,p);
                        QextFB(i,p)=Qabs(i,p)+QscaFB(i,p);
                        AlbFB(i,p)=QscaFB(i,p)/QextFB(i,p);
                        beta=(3.*(Df_Df))./(8.*(K(i).*rg(p)).^2);
                        if beta>=1
                            GKF(i,p)=1-((2*(K(i)*rg(p))^2)/3);
                            QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                            QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                            AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                        elseif beta<1 && Df_Df==2
                            GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K)^2^0.5)^(-Df_Df)*(3/(2.001-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2.001-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                            QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                            QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                            AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                        else
                            GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K(i))^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K(i))^2^0.5)^(-Df_Df)*(3/(2-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                            %                                 GKF(i,p)=beta/2*(3-3*beta+2*beta^2)-beta^2*(rg(p)*K)^2/3*(3-4*beta+3*beta^2)+(2*(rg(p)*K)^2^0.5)^(-Df_Df)*(3/(2-Df_Df)-12/(6-Df_Df)/(4-Df_Df)-3*beta^(1-Df_Df/2)*(1/(2-Df_Df)-2*beta/(4-Df_Df)+2*beta^2/(6-Df_Df)));
                            QscaKF(i,p)=(np0_Df(p)^(2-(1/z(p))))*Qscap0(i,p)*GKF(i,p);
                            QextKF(i,p)=Qabs(i,p)+QscaKF(i,p);
                            AlbKF(i,p)=QscaKF(i,p)/QextKF(i,p);
                        end

                        in2(p)=(sqrt((4*np0_Df(p))-1)/(2*sqrt(3)))-(1/2);
                        in3(p)=(3*np0_Df(p)/20+((324*np0_Df(p)^2+343/15)^0.5*(1/120)))^(1/3)-(7/(60*(3*np0_Df(p)/20+(((324*np0_Df(p)^2+343/15)^0.5)*1/120))^(1/3)))-0.5;
                        R1(p)=rp0_Df*np0_Df(p);
                        R2(p)=((2*in2(p))+1)*rp0_Df;
                        R3(p)=((2*in3(p))+1)*rp0_Df;
                        R(p)=(((log(R1(p))-(2*log(R2(p)))+log(R3(p)))/2)*Df_Df^2)+((((-5*log(R1(p)))+(8*log(R2(p)))-(3*log(R3(p))))/2)*Df_Df)+((3*log(R1(p)))-(3*log(R2(p)))+log(R3(p)));
                        fv(p)=(np0_Df(p)*(rp0_Df.^3))/(exp(R(p)).^3);
                        m2LL(i,p)=(1+(2*fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))))/(1-(fv(p)*(((m(i)^2)-1)/((m(i)^2)+2))));
                        m2MG(i,p)=(((m(i)^2)*((2*fv(p))+1))+(2*(-fv(p)+1)))/(((m(i)^2)*((-1*fv(p))+1))+((fv(p)+2)));
                        m2B(i,p)=(((-1.5*fv(p))+1)+(((1.5*fv(p))-0.5)*m(i).^2)+sqrt((((1.5*fv(p))-1)-(((1.5*fv(p))-0.5)*m(i).^2)).^2+(2*m(i).^2)))/2;
                        x1(p)=2*pi*exp(R(p))/lambda(i);
                        yLL(p)=sqrt(m2LL(i,p))*x1(p);
                        yMG(p)=sqrt(m2MG(i,p))*x1(p);
                        yB(p)=sqrt(m2B(i,p))*x1(p);
                        n=1;nmax(p)=round(2+(x1(p))+4*(x1(p)).^(1/3));
                        %sumaLL=0;sumaMG=0;sumaB=0;sumascaLL=0;sumascaMG=0;sumascaB=0;
                        while n<=nmax(p) % expresiones en TFG Joaquín desde página 21 documento
                            shix(p)=(pi*x1(p)/2)^0.5*besselj(n+0.5,x1(p));shix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselj(n+0.5,x1(p)+incr);
                            dshix(p)=(shix1(p)-shix(p))/incr;
                            shiyLL(p)=(pi*yLL(p)/2)^0.5*besselj(n+0.5,yLL(p));shiy1LL(p)=(pi*(yLL(p)+incr)/2)^0.5*besselj(n+0.5,yLL(p)+incr);
                            shiyMG(p)=(pi*yMG(p)/2)^0.5*besselj(n+0.5,yMG(p));shiy1MG(p)=(pi*(yMG(p)+incr)/2)^0.5*besselj(n+0.5,yMG(p)+incr);
                            shiyB(p)=(pi*yB(p)/2)^0.5*besselj(n+0.5,yB(p));shiy1B(p)=(pi*(yB(p)+incr)/2)^0.5*besselj(n+0.5,yB(p)+incr);
                            dshiyLL(p)=(shiy1LL(p)-shiyLL(p))/incr;
                            dshiyMG(p)=(shiy1MG(p)-shiyMG(p))/incr;
                            dshiyB(p)=(shiy1B(p)-shiyB(p))/incr;
                            psix(p)=(pi*x1(p)/2)^0.5*besselh(n+0.5,2,x1(p));psix1(p)=(pi*(x1(p)+incr)/2)^0.5*besselh(n+0.5,2,x1(p)+incr);
                            dpsix(p)=(psix1(p)-psix(p))/incr;
                            anLL(p)=(dshiyLL(p)*shix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dshix(p))/(dshiyLL(p)*psix(p)-sqrt(m2LL(i,p))*shiyLL(p)*dpsix(p));
                            anMG(p)=(dshiyMG(p)*shix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dshix(p))/(dshiyMG(p)*psix(p)-sqrt(m2MG(i,p))*shiyMG(p)*dpsix(p));
                            anB(p)=(dshiyB(p)*shix(p)-sqrt(m2B(i,p))*shiyB(p)*dshix(p))/(dshiyB(p)*psix(p)-sqrt(m2B(i,p))*shiyB(p)*dpsix(p));
                            bnLL(p)=(sqrt(m2LL(i,p))*dshiyLL(p)*shix(p)-shiyLL(p)*dshix(p))/(sqrt(m2LL(i,p))*dshiyLL(p)*psix(p)-shiyLL(p)*dpsix(p));
                            bnMG(p)=(sqrt(m2MG(i,p))*dshiyMG(p)*shix(p)-shiyMG(p)*dshix(p))/(sqrt(m2MG(i,p))*dshiyMG(p)*psix(p)-shiyMG(p)*dpsix(p));
                            bnB(p)=(sqrt(m2B(i,p))*dshiyB(p)*shix(p)-shiyB(p)*dshix(p))/(sqrt(m2B(i,p))*dshiyB(p)*psix(p)-shiyB(p)*dpsix(p));
                            if n==1
                                sumaLL(p)=(2*n+1)*real(anLL(p)+bnLL(p));
                                sumaMG(p)=(2*n+1)*real(anMG(p)+bnMG(p));
                                sumaB(p)=(2*n+1)*real(anB(p)+bnB(p));
                                sumascaLL(p)=(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                sumascaMG(p)=(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                sumascaB(p)=(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                            else
                                sumaLL(p)=sumaLL(p)+(2*n+1)*real(anLL(p)+bnLL(p));
                                sumaMG(p)=sumaMG(p)+(2*n+1)*real(anMG(p)+bnMG(p));
                                sumaB(p)=sumaB(p)+(2*n+1)*real(anB(p)+bnB(p));
                                sumascaLL(p)=sumascaLL(p)+(2*n+1)*((abs(anLL(p)))^2+(abs(bnLL(p)))^2);
                                sumascaMG(p)=sumascaMG(p)+(2*n+1)*((abs(anMG(p)))^2+(abs(bnMG(p)))^2);
                                sumascaB(p)=sumascaB(p)+(2*n+1)*((abs(anB(p)))^2+(abs(bnB(p)))^2);
                            end
                            n=n+1;
                        end
                        QextLL(i,p)=2/(x1(p)).^2*sumaLL(p);
                        QscaLL(i,p)=2/(x1(p)).^2*sumascaLL(p);
                        QabsLL(i,p)=QextLL(i,p)-QscaLL(i,p);
                        QextMG(i,p)=2/(x1(p)).^2*sumaMG(p);
                        QscaMG(i,p)=2/(x1(p)).^2*sumascaMG(p);
                        QabsMG(i,p)=QextMG(i,p)-QscaMG(i,p);
                        QextB(i,p)=2/(x1(p)).^2*sumaB(p);
                        QscaB(i,p)=2/(x1(p)).^2*sumascaB(p);
                        QabsB(i,p)=QextB(i,p)-QscaB(i,p);
                    end
                end
            end
            %                 nd = nd_Df;
            %  assignin('base','z',z)
            assignin('base','x',x)
            assignin('base','m',m)
            assignin('base','z',z)
            assignin('base','Qabsp0',Qabsp0)
            assignin('base','np0_Df',np0_Df)
            assignin('base','Qabs_inicial',Qabs)
            assignin('base','QabsMG', QabsMG)

            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                % Fisher-Buford es Mountain - Mullholland
                if app.TreeRDG_ME.SelectedNodes.NodeData == 13
                    Qsca = QscaFB; Qext = QextFB;

                    % Köylü-Faeth
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 14
                    Qsca = QscaKF; Qext = QextKF;

                    % Maxwell-Garnett
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 17
                    Qabs = QabsMG; Qsca = QscaMG; Qext = QextMG;
                    assignin('base','Qabs_renomMG', Qabs)

                    % Bruggemann
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 18
                    Qabs = QabsB; Qsca = QscaB; Qext = QextB;

                    % Lorentz-Lorenz
                elseif app.TreeRDG_ME.SelectedNodes.NodeData == 19
                    Qabs = QabsLL; Qsca = QscaLL; Qext = QextLL;

                    % FALTA AÑADIR ESTOS TRES CÁLCULOS  ANTES Y TAMBIÉN LO DEL MÉTODO GEOMÉTRICO Y APROXIMADO
                    %             % Lakatia-Vitkam
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 20
                    %                Qabs = QabsLV; Qsca = QscaLV; Qext = QextLV;
                    %             % Lumme-Rahola
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 21
                    %                 Qabs = QabsLR; Qsca = QscaLR; Qext = QextLR;
                    %             % Promedio potencial
                    %             elseif app.TreeRDG_ME.SelectedNodes.NodeData == 22
                    %                 Qabs = QabsPP; Qsca = QscaPP; Qext = QextPP;
                end
                Alb_soot = Qsca/Qext; Coalb_soot = 1-Alb_soot;
                assignin('base','Qabs_renom', Qabs)
            end


            % Estudio canales para caso distribuciones
            if app.EstudioDropDown.Value == "Imponer Df" || app.EstudioDropDown.Value == "Imponer np0"
                Alb=Alb_soot;
                Coalb = 1-Alb;
                % Selección de los canales (nº de divisiones que se ha realizado)
                % Se dibujan todos los canales, la media o cada uno de ellos de  forma independiente
                if Todos == 1
                    % este caso es el que está en este momento fuera del bucle if
                    Qabs=Qabs;
                    Qsca=Qsca;
                    Qext=Qext;
                    Alb=Alb_soot;
                    Coalb=Coalb_soot;
                elseif Media == 1
                    if nd==1
                        Qabs=mean(Qabs,1);
                        Qsca=mean(Qsca,1);
                        Qext=mean(Qext,1);
                        Alb=mean(Alb,1);
                        Coalb=mean(Coalb,1);
                    else
                        Qabs=mean(Qabs,2);
                        Qsca=mean(Qsca,2);
                        Qext=mean(QextFB,2);
                        Alb=mean(Alb,2);
                        Coalb=mean(Coalb,2);
                    end
                elseif Canal_1 == 1
                    %para cada canal es una posición de rg de cada valor de eficiencias
                    %único para "barra" de la entrada de divisiones
                    if nd==1
                        Qabs=Qabs(:);
                    else
                        Qabs=Qabs(:,1);
                        Qsca=Qsca(:,1);
                        Qext=Qext(:,1);
                        AlbFB=Alb(:,1);
                        Coalb=Coalb(:,1);
                    end
                elseif Canal_2 == 1
                    Qabs=Qabs(:,2);
                    Qsca=Qsca(:,2);
                    Qext=Qext(:,2);
                    Alb=Alb(:,2);
                    Coalb=Coalb(:,2);
                elseif Canal_3 == 1
                    Qabs=Qabs(:,3);
                    Qsca=Qsca(:,3);
                    Qext=Qext(:,3);
                    Alb=Alb(:,3);
                    Coalb=Coalb(:,3);
                elseif Canal_4 == 1
                    Qabs=Qabs(:,4);
                    Qsca=Qsca(:,4);
                    Qext=Qext(:,4);
                    Alb=Alb(:,4);
                    Coalb=Coalb(:,4);
                elseif Canal_5 == 1
                    Qabs=Qabs(:,5);
                    Qsca=Qsca(:,5);
                    Qext=Qext(:,5);
                    Alb=Alb(:,5);
                    Coalb=Coalb(:,5);
                elseif Canal_6 == 1
                    Qabs=Qabs(:,6);
                    Qsca=Qsca(:,6);
                    Qext=Qext(:,6);
                    Alb=Alb(:,6);
                    Coalb=Coalb(:,6);
                elseif Canal_7 == 1
                    Qabs=Qabs(:,7);
                    Qsca=Qsca(:,7);
                    Qext=Qext(:,7);
                    Alb=Alb(:,7);
                    Coalb=Coalb(:,7);
                elseif Canal_8 == 1
                    Qabs=Qabs(:,8);
                    Qsca=Qsca(:,8);
                    Qext=Qext(:,8);
                    Alb=Alb(:,8);
                    Coalb=Coalb(:,8);
                elseif Canal_9 == 1
                    Qabs=Qabs(:,9);
                    Qsca=Qsca(:,9);
                    Qext=Qext(:,9);
                    Alb=Alb(:,9);
                    Coalb=Coalb(:,9);
                elseif Canal_10 == 1
                    Qabs=Qabs(:,10);
                    Qsca=Qsca(:,10);
                    Qext=Qext(:,10);
                    Alb=Alb(:,10);
                    Coalb=Coalb(:,10);
                end
                dg = 2*rg;
            end
            Alb_soot = Alb; Coalb_soot = Coalb;

            dp0 =2*rp0;

            %%%-------------------------------------------------------------

            % Plotear las eficiencias, secciones ópticas o específicas, o
            % albedo de todos los canales juntos o indendientemente al
            % igual que la posibilidad de representar la media

            % Plotear en función de lo que se decida (ORDENADAS)
            % Valores que indican si están seleccionados la
            % absorción, dispersión, extinción, albedo o coalbedo
            abso = app.AbsorcinCheckBox.Value;
            sca = app.DispersinCheckBox.Value;
            ext = app.ExtincinCheckBox.Value;
            alb = app.AlbedodispersinCheckBox.Value;
            coalb = app.CoalbedodispersinCheckBox.Value;
            %-------------------------------------------------------------------------

            % EFICIENCIAS
            if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,Qabs); ylabel(app.UIAxes_arriba,'Eficiencia de absorción')
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,Qsca); ylabel(app.UIAxes_arriba,'Eficiencia de dispersión')
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    plot(app.UIAxes_arriba,lambda,Qext); ylabel(app.UIAxes_arriba,'Eficiencia de extinción')
                end

                % SECCIONES ÓPTICAS
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,CSabs); ylabel(app.UIAxes_arriba,'Sección óptica de absorción (m^2)');
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,CSsca); ylabel(app.UIAxes_arriba,'Sección óptica de dispersión (m^2)');
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    plot(app.UIAxes_arriba,lambda,CSext); ylabel(app.UIAxes_arriba,'Sección óptica de extinción (m^2)');
                end

                % SECCIONES ESPECÍFICAS
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,MAC); ylabel(app.UIAxes_arriba,'Sección específica de absorción (m^2/g)');
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    plot(app.UIAxes_arriba,lambda,MSC); ylabel(app.UIAxes_arriba,'Sección específica de dispersión (m^2/g)');
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    plot(app.UIAxes_arriba,lambda,MEC); ylabel(app.UIAxes_arriba,'Sección específica de extinción (m^2/g)');
                end

                % ALBEDO DE DISPERSIÓN
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                % Albedo
                if alb == 1 && coalb == 0
                    plot(app.UIAxes_arriba,lambda,Alb); ylabel(app.UIAxes_arriba,'Albedo de dispersión');
                    % Coalbedo
                elseif alb == 0 && coalb == 1
                    plot(app.UIAxes_arriba,lambda,1-Alb); ylabel(app.UIAxes_arriba,'Coalbedo de dispersión');
                end
            end
            xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
            xticks(app.UIAxes_arriba,'auto');

            % EN función del canal escogido se muestra en la leyenda
            if Todos == 1
                legend(app.UIAxes_arriba,'Canal 1', 'Canal 2', 'Canal 3', 'Canal 4', 'Canal 5', 'Canal 6', 'Canal 7', 'Canal 8', 'Canal 9', 'Canal 10')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 1", "Canal 2","Canal 3","Canal 4","Canal 5","Canal 6","Canal 7","Canal 8","Canal 9","Canal 10"];

            elseif Media == 1
                legend(app.UIAxes_arriba,'Media')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Media"];

            elseif Canal_1 == 1
                legend(app.UIAxes_arriba,'Canal 1')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 1"];

            elseif Canal_2 == 1
                legend(app.UIAxes_arriba,'Canal 2')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 2"];

            elseif Canal_3 == 1
                legend(app.UIAxes_arriba,'Canal 3')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 3"];

            elseif Canal_4 == 1
                legend(app.UIAxes_arriba,'Canal 4')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 4"];

            elseif Canal_5 == 1
                legend(app.UIAxes_arriba,'Canal 5')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 5"];

            elseif Canal_6 == 1
                legend(app.UIAxes_arriba,'Canal 6')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 6"];

            elseif Canal_7 == 1
                legend(app.UIAxes_arriba,'Canal 7')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 7"];

            elseif Canal_8 == 1
                legend(app.UIAxes_arriba,'Canal 8')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 8"];

            elseif Canal_9 == 1
                legend(app.UIAxes_arriba,'Canal 9')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 9"];

            elseif Canal_10 == 1
                legend(app.UIAxes_arriba,'Canal 10')
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Canal 10"];
            end

            % MOSTRAR EN LA TABLA DE ABAJO LOS RESULTADOS DE TODA LA MATRIZ
            % ESCOGIDA EN LAS ORDENADAS (eficiencias,albedo...)

            % Se genera la matriz de datos en función de si es eficiencia,
            % albedo, secciones ópticas....

            % EFICIENCIAS
            if app.TreeOrdenadas.SelectedNodes.NodeData == 201
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    Matriz = [lambda,Qabs];
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    Matriz = [lambda,Qsca];
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    Matriz = [lambda,Qext];
                end

                % SECCIONES ÓPTICAS
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 202
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    Matriz = [lambda,CSabs];
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    Matriz = [lambda,CSsca];
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    Matriz = [lambda,CSext];
                end

                % SECCIONES ESPECÍFICAS
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 203
                % Absorción
                if abso == 1 && sca == 0 && ext == 0
                    Matriz = [lambda,MAC];
                    % Dispersión
                elseif sca == 1 && abso == 0 && ext == 0
                    Matriz = [lambda,MSC];
                    % Extinción
                elseif ext == 1 && abso == 0 && sca == 0
                    Matriz = [lambda,MEC];
                end

                % ALBEDO DE DISPERSIÓN
            elseif app.TreeOrdenadas.SelectedNodes.NodeData == 204
                % Albedo
                if alb == 1 && coalb == 0
                    Matriz = [lambda,Alb];
                    % Coalbedo
                elseif alb == 0 && coalb == 1
                    Matriz = [lambda,1-Alb];
                end
            end
            % Se hace visible la tabla
            app.UITable_estudio.Visible = 'On';
            app.UITable_estudio.Data = Matriz;
        end

        % Value changed function: IrradianciaCheckBox
        function IrradianciaCheckBoxValueChanged(app, event)
            irra = app.IrradianciaCheckBox.Value;
            rad = app.RadianciaCheckBox.Value;
            % RADIANCIA / IRRADIANCIA
            load Radiancias lambda radiancia irrad
            if rad == 1 && irra == 0
                plot(app.UIAxes_arriba,lambda,radiancia,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Radiancia'},'Location','best')
                ylabel(app.UIAxes_arriba,'Radiancia');
                Data =  [lambda radiancia'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Radiancia"];
                app.UITable_estudio.Data = Data;

            elseif rad == 0 && irra == 1
                plot(app.UIAxes_arriba,lambda,irrad,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Irradiancia'},'Location','best')
                ylabel(app.UIAxes_arriba,'Irradiancia');
                Data =  [lambda irrad'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Irradiancia"];
                app.UITable_estudio.Data = Data;

            elseif rad == 1 && irra == 1
                plot(app.UIAxes_arriba,lambda,radiancia,'m',lambda,irrad,'--m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Radiancia', 'Irradiancia'},'Location','best')
                Data =  [lambda radiancia' irrad'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Radiancia", "Irradiancia"];
                app.UITable_estudio.Data = Data;
            end
            xticks(app.UIAxes_arriba,'auto')
            yticks(app.UIAxes_arriba,'auto')
        end

        % Value changed function: RadianciaCheckBox
        function RadianciaCheckBoxValueChanged(app, event)
            irra = app.IrradianciaCheckBox.Value;
            rad = app.RadianciaCheckBox.Value;
            % RADIANCIA / IRRADIANCIA
            load Radiancias lambda radiancia irrad
            if rad == 1 && irra == 0
                plot(app.UIAxes_arriba,lambda,radiancia,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Radiancia'},'Location','best')
                ylabel(app.UIAxes_arriba,'Radiancia');
                Data =  [lambda radiancia'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Radiancia"];
                app.UITable_estudio.Data = Data;

            elseif rad == 0 && irra == 1
                plot(app.UIAxes_arriba,lambda,irrad,'m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Irradiancia'},'Location','best')
                ylabel(app.UIAxes_arriba,'Irradiancia');
                Data =  [lambda irrad'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Irradiancia"];
                app.UITable_estudio.Data = Data;

            elseif rad == 1 && irra == 1
                plot(app.UIAxes_arriba,lambda,radiancia,'m',lambda,irrad,'--m'); xlabel(app.UIAxes_arriba,'Longitud de onda (nm)')
                legend(app.UIAxes_arriba,{'Radiancia', 'Irradiancia'},'Location','best')
                Data =  [lambda radiancia' irrad'];
                app.UITable_estudio.ColumnName = ["Longitud de onda (nm)", "Radiancia", "Irradiancia"];
                app.UITable_estudio.Data = Data;
            end
            xticks(app.UIAxes_arriba,'auto')
            yticks(app.UIAxes_arriba,'auto')
        end

        % Menu selected function: ContactoMenu
        function ContactoMenuSelected(app, event)
            app.ContactoPanel.Visible = 'On';
        end

        % Menu selected function: CerrarMenu_2
        function CerrarMenu_2Selected(app, event)
            app.ContactoPanel.Visible = 'Off';
        end

        % Button pushed function: EstudiorugosidadButton
        function EstudiorugosidadButtonPushed(app, event)
            app.EstudiorugosidadPanel.Visible = 'On';
        end

        % Menu selected function: CerrarMenu_3
        function CerrarMenu_3Selected(app, event)
            app.EstudiorugosidadPanel.Visible = 'Off';
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            % Se abre una ventana para que cargue una imagen específica
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            % Abre el lector de archivos y te devuelve f,  que es el nombre, y p, que es donde está el archivo
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            % (comprueba que p no sea -1 (si p es -1, es que el usuario no ha cargado imagen y le ha dado a cancelar)
            % Luego junta p con f
            if (ischar(p))
                fname = [p f];
                img = imread(fname);
                imshow(img,'Parent',app.UIAxes_imagen_inicial);
            end
            % Se guarda la imagen para su tratamiento posterior
            save img img
        end

        % Button pushed function: ProcesarimagenButton
        function ProcesarimagenButtonPushed(app, event)
            % Se carga la imagen deseada
            load img img

            % Se recorta la imagen
            % img2 = img(430:460,610:1160,:);
            img2 = img;

            %             % Se convierte a escala de grises
            %             img2 = rgb2gray(img2);

            % Utilizar el canal rojo en lugar de la imagen en escala de grises
            % Las imágenes son matrices 3D. Las imágenes son 3 capas (R G B). R es la primera
            % img(x,y,z); La x es la posición vertical, la y la horizontal y z el color.
            % Como queremos toda la imagen, ponemos : tanto en x como en y Y luego elegimos el color
            % Se coge sólo el de rojo (RGB)
            img2 = img(:,:,1);

            % Se binariza. Existen varios algoritmos para realizar la binarización. En
            % este caso, se ha escogido un algoritmo global (el valor umbral es el
            % mismo para toda la imagen. El valor umbral marca el valor de color que
            % hace frontera. Si es por debajo, se convierte en negro, y sí está por
            % debajo, se convierte en blanco). Determina de forma automática cual es el
            % valor umbral óptimo. Se puede usar también un método adaptativo (usando
            % 'adaptative'). Con estos métodos, el valor umbral puede variar en cada
            % píxel, en función de iluminación etc. Útil si hay sombras, pero en estas
            % imágenes no hay.
            BW = ~imbinarize(img2,'global');

            % Se quitan agujeros
            BW2 = imfill(BW,'holes');

            % Se aplica un filtro para limpiar la imagen. Este filtro consiste en
            % contraer las zonas con color negro para luego expandirlas. Deforma
            % ligeramente pero elimina por completo morfologías pequeñas, como líneas.
            % Con strel se crea el núcleo (kernel), que es la forma geométrica usada
            % para contraer y expandir. En este caso, es un círculo de 2 píxeles de
            % tamaño. Con imclose, se reduce la zona negra y con imopen se expande.
            % Luego se quitan los agujeros otra vez para terminar de limpiar
            se = strel('disk',2);
            BW3 = imclose(BW2,se);
            BW3 = imopen(BW3,se);
            BW3 = ~imfill(~BW3,'holes');
            BW3 = limpia_bordes(BW3);
            BW3 = imfill(BW3,'holes');

            % Representación gráfica
            imshow(BW3,'Parent',app.UIAxes_imagen_procesada);

            % Guardar imágenes
            save images img BW3

        end

        % Button pushed function: CalcularperfilButton
        function CalcularperfilButtonPushed(app, event)
            % Se cargan los procesos anteriores
            load images img BW3

            % Variables
            umbral = 0.5; % Umbral para la binarización (rejilla). 0 ->
            umbral_grupos = 1;

            % Se aplica la máscara calculada (BW3). Al aplicar la máscara, nos quedamos
            % con las regiones de la imagen que estén en blanco en la máscara y se
            % elimnan las que estén en negro en la máscara
            img3 = bsxfun(@times, img, cast(BW3, 'like', img));

            % Se convierte a escala de grises
            %             img3_BW = rgb2gray(img3);
            img3_BW = img3(:,:,1);

            % Se binariza la imagen. En este caso, se hace a lo bestia porque para un
            % umbral dado, es más rápido (el otro método no funcionaba bien)
            umbral = 0.5*255;
            BW_rej = ~(img3_BW > umbral);

            % Se filtra la imagen. Al hacer el filtro, se obtienen los puntos de las
            % intersecciones
            se = strel('disk',1);
            BW_rej = imopen(BW_rej,se);
            BW_rej = imclose(BW_rej,se);

            % Se obtiene una colección preliminar de objetos
            stats = regionprops(~BW_rej,'Centroid');

            n = 0;

            % Si se ven más de 400 objetos, se reduce el área negra para eliminar
            while (length(stats) > 400)
                BW_rej = imdilate(BW_rej,se);
                stats = regionprops(~BW_rej,'Centroid');
                n = n + 1;
            end

            % Se amplian los objetos restantes
            for i = 0:n
                BW_rej = imerode(BW_rej,se);
            end

            % Se obtienen las coordenadas de los puntos. Lo malo es que se obtienen los
            % centros de todos los objetos, así que toca limpiar luego. El resultado se
            % devuelve en una estructura, así que se recorre para guardar las
            % coordenadas en un vector
            stats = regionprops(~BW_rej,'Centroid');
            centroides = zeros(length(stats),2);
            for i = 1:length(stats)
                centroides(i,:) = stats(i).Centroid;
            end

            %figure; plot(centroides(:,1),'*');

            % Se ordenan por filas los centroides
            [aux,indices] = sort(centroides(:,2));
            aux2 = centroides(indices,1);
            centroides = [aux2,aux];
            %figure; plot(centroides(:,2),'*');

            % Se realiza un primer filtrado de puntos. Aquellos que tengan una
            % coordenada x distinta respecto al anterior punto será eliminado (las
            % rendijas están horizontales y los puntos se encuentran sobre la misma
            % coordenada x)
            control = 1;
            dc = diff(centroides(:,2));
            centroides2 = centroides(dc < control,:);

            % Se agrupan los puntos por filas. Al separar los puntos por filas,
            % podremos ordenar luego por columnas y tener filas de puntos consecutivas
            centroides3 = separacion_grupos(centroides2,umbral_grupos);

            % Se eliminan los grupos de objetos con menos de 4 unidades (se consideran
            % ruido)
            centroides4 = {};
            j = 1;
            for i = 1:length(centroides3)
                if length(centroides3{i}) > 4
                    centroides4{j} = centroides3{i};
                    j = j + 1;
                end
            end

            % Se obtiene la escala a partir de los grupos de filas. El umbral indica la
            % desviación estándar aceptable. Una desviación estándar de un grupo
            % superior al del umbral hará que se descarte ese grupo del cálculo de la
            % escala

            % La variable escala son el número de píxeles por centímetro
            umbral = 50;
            [escala, desv] = diferencias(centroides4,umbral);
            % sI ESCALA no es un nº se le asigna 1
            if isnan(escala)
                escala = 1;
            end

            % figure; imshow(BW_rej);
            % hold on;
            % for i = 1:length(centroides)
            %     plot(centroides(i,1),centroides(i,2),'bo');
            % end
            % for i = 1:length(centroides4)
            %     for j = 1:length(centroides4{i})
            %         plot(centroides4{i}(j,1),centroides4{i}(j,2),'ro');
            %     end
            % end

            % Cálculo del profile
            v = mi_profile(BW3)/escala;
            eje_x = (1:length(v))/escala;

            assignin('base','v',v)
            assignin('base','eje_x',eje_x)

            % Representación gráfica
            plot(app.UIAxes_imagen_perfil,eje_x,v); xlabel(app.UIAxes_imagen_perfil,'Distancia (cm)'); ylabel(app.UIAxes_imagen_perfil,'Grey Value');
            xlim(app.UIAxes_imagen_perfil,[eje_x(1),eje_x(end)]);


            % Calcular la Pendiente media de mínimos cuadrados (º)
            % Se calcula la pendiente de cada punto con diff es hacer la
            % resta de un valor con el anterior y se hace el root mean
            % square de cada pendiente para sacar la media y hacer el
            % arcotangente (que saca en radianes) el ángulo medio del
            % perfil

            %             beta = atan(sqrt(sum((diff(v)).^2)/(length(v)-1))); % rad
            beta = atan(sqrt(sum((diff(v)./diff(eje_x)).^2)/(length(v)-1))); %rad
            % El ns se va a calcular fuera de este bloque porque hay que
            % definir el theta que está en otro Panel, por lo que se
            % calcula en la Ejecución Paramétrica
            %
            %             theta = 60*pi/180; % rad
            %             ns = 1 + 0.355332*(cos(theta)^4)*beta - 1.08275*(1-exp(1.75*(cos(theta)^0.25)*beta.^4)); % Fórmula 7 Manninen 2021 (los exponentes van con el coseno y el beta fuera)

            % Se asigna al edit field el valor del ángulo de rms
            app.ngulomediodemnimoscuadradosradEditField.Value = beta;

            save beta beta
            % Como se ha hecho un estudio de rugosidad se actualiza el
            % contador rug
            load rug rug
            rug = 1;
            save rug rug

            assignin('base','beta',beta)
            %             assignin('base','ns',ns)
        end

        % Value changed function: ndicederefraccinDropDown_SAND
        function ndicederefraccinDropDown_SANDValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ndicederefraccinDropDown_SAND.Value == "Ponderación por composición mineralógica" || app.ndicederefraccinDropDown_SAND.Value == "Mineralogical Composition Weighting"
                app.UITable_minerales.Visible='On';
                app.ReglademezclaDropDownLabel.Visible = 'On';
                app.ReglademezclaDropDown.Visible = 'On';
                app.AgregarmineralButton.Visible = 'On';
                t = readtable('fv_compuestos_arena.xlsx');
                app.UITable_minerales.Data = t;

            else
                app.UITable_minerales.Visible='Off';
                app.AgregarmineralButton.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
            end

        end

        % Value changed function: ndicederefraccinDropDown_ASH
        function ndicederefraccinDropDown_ASHValueChanged(app, event)
            % OCULTAR LAS OPCIONES QUE NO CORRESPONDEN
            if app.ndicederefraccinDropDown_ASH.Value == "Cumbre Vieja (2021)"
                app.UITable_minerales.Visible='On';
                app.ReglademezclaDropDownLabel.Visible = 'On';
                app.ReglademezclaDropDown.Visible = 'On';
                app.AgregarmineralButton.Visible = 'On';
                t = readtable('fv_compuestos_ceniza.xlsx');
                app.UITable_minerales.Data = t;

            elseif app.ndicederefraccinDropDown_ASH.Value == "Etna (Dequine (2020))" || app.ndicederefraccinDropDown_ASH.Value == "Eyjafjallajökull (Dequine (2020))" || app.ndicederefraccinDropDown_ASH.Value == "Linear model (Liu 1993)"
                app.UITable_minerales.Visible='Off';
                app.AgregarmineralButton.Visible = 'Off';
                app.ReglademezclaDropDownLabel.Visible = 'Off';
                app.ReglademezclaDropDown.Visible = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.FuentedeluzTab = uitab(app.TabGroup);
            app.FuentedeluzTab.Title = 'Fuente de luz';
            app.UIAxes_luz = uiaxes(app.FuentedeluzTab);
            xlabel(app.UIAxes_luz, 'Longitud de onda (nm)')
            ylabel(app.UIAxes_luz, 'Irradiancia (W*m^-2*nm^-1)')
            app.UITable_luz = uitable(app.FuentedeluzTab);
            app.UITable_luz.ColumnName = {'Longitud de onda (nm)'; 'Irradiancia (W*m^-2*nm^-1)'};
            app.UITable_luz.RowName = {};
            app.FuentedeluzDropDown = uidropdown(app.FuentedeluzTab);
            app.FuentedeluzDropDown.Items = {'Espectro Solar', 'Fuente Halógena', 'Otra fuente de luz'};
            app.FuentedeluzDropDown.ValueChangedFcn = createCallbackFcn(app, @FuentedeluzDropDownValueChanged, true);
            app.FuentedeluzDropDown.Value = 'Espectro Solar';
            app.RepresentarButton = uibutton(app.FuentedeluzTab, 'push');
            app.RepresentarButton.ButtonPushedFcn = createCallbackFcn(app, @RepresentarButtonPushed, true);
            app.RepresentarButton.Text = 'Representar';
            app.AgregarespectroButton = uibutton(app.FuentedeluzTab, 'push');
            app.AgregarespectroButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarespectroButtonPushed, true);
            app.AgregarespectroButton.Visible = 'off';
            app.AgregarespectroButton.Text = 'Agregar espectro';
            app.ContactoPanel = uipanel(app.FuentedeluzTab);
            app.ContactoPanel.Title = 'Contacto';
            app.ContactoPanel.Visible = 'off';
            app.DesarrolladoradelainterfazLabel = uilabel(app.ContactoPanel);
            app.DesarrolladoradelainterfazLabel.Text = 'Desarrolladora de la interfaz';
            app.Hyperlink = uihyperlink(app.ContactoPanel);
            app.Hyperlink.URL = 'sofia.gonzalez@uclm.es';
            app.Hyperlink.Text = 'sofia.gonzalez@uclm.es';
            app.Hyperlink_2 = uihyperlink(app.ContactoPanel);
            app.Hyperlink_2.URL = 'magin.lapuerta@uclm.es';
            app.Hyperlink_2.Text = 'magin.lapuerta@uclm.es';
            app.CoordinadordelainterfazLabel = uilabel(app.ContactoPanel);
            app.CoordinadordelainterfazLabel.Text = 'Coordinador de la interfaz';
            app.DescripcindelProgramaPanel = uipanel(app.FuentedeluzTab);
            app.DescripcindelProgramaPanel.Title = 'Descripción del Programa';
            app.DescripcindelProgramaPanel.Visible = 'off';
            app.Image = uiimage(app.DescripcindelProgramaPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'UCLM_todo_Color.png';
            app.Image2 = uiimage(app.DescripcindelProgramaPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'logo_gcm.PNG';
            app.OptiParLabel = uilabel(app.DescripcindelProgramaPanel);
            app.OptiParLabel.Text = 'OptiPar';
            app.Label_titulo2 = uilabel(app.DescripcindelProgramaPanel);
            app.Label_titulo2.Text = 'en forma de aerosoles o depositados sobre superficies';
            app.Label_titulo1 = uilabel(app.DescripcindelProgramaPanel);
            app.Label_titulo1.Text = 'Cálculo de las propiedades ópticas de partículas esféricas o aglomerados';
            app.RealizadoporLabel = uilabel(app.DescripcindelProgramaPanel);
            app.RealizadoporLabel.Text = 'Realizado por:';
            app.DirigidoporLabel = uilabel(app.DescripcindelProgramaPanel);
            app.DirigidoporLabel.Text = 'Dirigido por:';
            app.versinJoaqunDazLozanoLabel = uilabel(app.DescripcindelProgramaPanel);
            app.versinJoaqunDazLozanoLabel.Text = '1ª versión: Joaquín Díaz Lozano';
            app.MagnLapuertaAmigoLabel = uilabel(app.DescripcindelProgramaPanel);
            app.MagnLapuertaAmigoLabel.Text = 'Magín Lapuerta Amigo';
            app.versinSaraRubioMorenoLabel = uilabel(app.DescripcindelProgramaPanel);
            app.versinSaraRubioMorenoLabel.Text = '2ª versión: Sara Rubio Moreno';
            app.versinSofaGonzlezCorreaLabel = uilabel(app.DescripcindelProgramaPanel);
            app.versinSofaGonzlezCorreaLabel.Text = '3ª versión: Sofía González Correa';
            app.MaterialesTab = uitab(app.TabGroup);
            app.MaterialesTab.Title = 'Materiales';
            app.UIAxes_material = uiaxes(app.MaterialesTab);
            app.AgregarmaterialButton = uibutton(app.MaterialesTab, 'push');
            app.AgregarmaterialButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarmaterialButtonPushed, true);
            app.AgregarmaterialButton.Visible = 'off';
            app.AgregarmaterialButton.Text = 'Agregar material';
            app.MaterialDropDown = uidropdown(app.MaterialesTab);
            app.MaterialDropDown.Items = {'Hollín', 'Hielo', 'Grafito', 'Diamante', 'Agua', 'Polvo', 'Arena', 'Ceniza', 'Nuevo material'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Hollín';
            app.RepresentarButton_2 = uibutton(app.MaterialesTab, 'push');
            app.RepresentarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RepresentarButton_2Pushed, true);
            app.RepresentarButton_2.Text = 'Representar';
            app.MaterialLabel = uilabel(app.MaterialesTab);
            app.MaterialLabel.Text = 'Material';
            app.ndicederefraccinDropDown_SOOT = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_SOOT.Items = {'Liu & Bescond (2015)', 'Chang & Charalampopoulos (1990)', 'Modelo de dispersión'};
            app.ndicederefraccinDropDown_SOOT.ValueChangedFcn = createCallbackFcn(app, @ndicederefraccinDropDown_SOOTValueChanged, true);
            app.ndicederefraccinDropDown_SOOT.Value = 'Modelo de dispersión';
            app.UITableosciladores = uitable(app.MaterialesTab);
            app.UITableosciladores.ColumnName = {' Osciladores'; 'n (e-/m^3)'; 'g (s^-1)'; 'wres (s^-1)'};
            app.UITableosciladores.RowName = {};
            app.UITableosciladores.ColumnEditable = true;
            app.ParmetrosdeDropDown_SOOT = uidropdown(app.MaterialesTab);
            app.ParmetrosdeDropDown_SOOT.Items = {'Dazell & Sarofim (1969)', 'Lee & Tien (1981)', 'Harbib & Vervisch (1988)', 'Stagg & Charalampopoulos (1993)', 'Otros'};
            app.ParmetrosdeDropDown_SOOT.ValueChangedFcn = createCallbackFcn(app, @ParmetrosdeDropDown_SOOTValueChanged, true);
            app.ParmetrosdeDropDown_SOOT.Value = 'Lee & Tien (1981)';
            app.NosciladoresSpinner = uispinner(app.MaterialesTab);
            app.NosciladoresSpinner.Limits = [2 10];
            app.NosciladoresSpinner.ValueChangedFcn = createCallbackFcn(app, @NosciladoresSpinnerValueChanged, true);
            app.NosciladoresSpinner.Value = 2;
            app.ndicederefraccinDropDown_GRAFITO = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_GRAFITO.Items = {'Djurišić & Li (1999)', 'Modelo de dispersión'};
            app.ndicederefraccinDropDown_GRAFITO.ValueChangedFcn = createCallbackFcn(app, @ndicederefraccinDropDown_GRAFITOValueChanged, true);
            app.ndicederefraccinDropDown_GRAFITO.Visible = 'off';
            app.ndicederefraccinDropDown_GRAFITO.Value = 'Modelo de dispersión';
            app.ndicederefraccinDropDown_DIAMANTE = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_DIAMANTE.Items = {'Philip & Taft (1964)'};
            app.ndicederefraccinDropDown_DIAMANTE.Visible = 'off';
            app.ndicederefraccinDropDown_DIAMANTE.Value = 'Philip & Taft (1964)';
            app.ParmetrosdeDropDown_GRAFITO = uidropdown(app.MaterialesTab);
            app.ParmetrosdeDropDown_GRAFITO.Items = {'Stagg & Charalampopoulos (1993)', 'Otros'};
            app.ParmetrosdeDropDown_GRAFITO.ValueChangedFcn = createCallbackFcn(app, @ParmetrosdeDropDown_GRAFITOValueChanged, true);
            app.ParmetrosdeDropDown_GRAFITO.Visible = 'off';
            app.ParmetrosdeDropDown_GRAFITO.Value = 'Stagg & Charalampopoulos (1993)';
            app.FactormasaEditField = uieditfield(app.MaterialesTab, 'numeric');
            app.FactormasaEditField.Value = 0.0556;
            app.FactormasaLabel = uilabel(app.MaterialesTab);
            app.FactormasaLabel.Text = 'Factor masa';
            app.NosciladoresLabel = uilabel(app.MaterialesTab);
            app.NosciladoresLabel.Text = 'Nº osciladores';
            app.ndicederefraccinLabel = uilabel(app.MaterialesTab);
            app.ndicederefraccinLabel.Text = 'Índice de refracción';
            app.ParmetrosdeLabel = uilabel(app.MaterialesTab);
            app.ParmetrosdeLabel.Text = 'Parámetros de';
            app.ndicederefraccinDropDown_WATER = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_WATER.Items = {'Irvine & Pollack (1968)'};
            app.ndicederefraccinDropDown_WATER.Visible = 'off';
            app.ndicederefraccinDropDown_WATER.Value = 'Irvine & Pollack (1968)';
            app.ndicederefraccinDropDown_ICE = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_ICE.Items = {'Warren & Brandt (2008)', 'Picard et al. (2016)'};
            app.ndicederefraccinDropDown_ICE.Visible = 'off';
            app.ndicederefraccinDropDown_ICE.Value = 'Warren & Brandt (2008)';
            app.ndicederefraccinDropDown_DUST = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_DUST.Items = {'Origen sahariano (Patterson (1977))', 'Volz (1972)', 'Ponderación por composición mineralógica'};
            app.ndicederefraccinDropDown_DUST.ValueChangedFcn = createCallbackFcn(app, @ndicederefraccinDropDown_DUSTValueChanged, true);
            app.ndicederefraccinDropDown_DUST.Visible = 'off';
            app.ndicederefraccinDropDown_DUST.Value = 'Origen sahariano (Patterson (1977))';
            app.UITable_minerales = uitable(app.MaterialesTab);
            app.UITable_minerales.ColumnName = {'Minerales'; 'Fracción volumétrica'};
            app.UITable_minerales.RowName = {};
            app.UITable_minerales.ColumnEditable = true;
            app.UITable_minerales.Visible = 'off';
            app.AgregarmineralButton = uibutton(app.MaterialesTab, 'push');
            app.AgregarmineralButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarmineralButtonPushed, true);
            app.AgregarmineralButton.Visible = 'off';
            app.AgregarmineralButton.Text = 'Agregar mineral';
            app.ndicederefraccinDropDown_ASH = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_ASH.Items = {'Cumbre Vieja (2021)', 'Etna (Dequine (2020))', 'Eyjafjallajökull (Dequine (2020))', 'Linear model (Liu 1993)'};
            app.ndicederefraccinDropDown_ASH.ValueChangedFcn = createCallbackFcn(app, @ndicederefraccinDropDown_ASHValueChanged, true);
            app.ndicederefraccinDropDown_ASH.Visible = 'off';
            app.ndicederefraccinDropDown_ASH.Value = 'Cumbre Vieja (2021)';
            app.EjedeAbscisasButtonGroup = uibuttongroup(app.MaterialesTab);
            app.EjedeAbscisasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EjedeAbscisasButtonGroupSelectionChanged, true);
            app.EjedeAbscisasButtonGroup.Title = 'Eje de Abscisas';
            app.LongituddeondanmButton = uiradiobutton(app.EjedeAbscisasButtonGroup);
            app.LongituddeondanmButton.Text = 'Longitud de onda (nm)';
            app.LongituddeondanmButton.Value = true;
            app.EnergaeVButton = uiradiobutton(app.EjedeAbscisasButtonGroup);
            app.EnergaeVButton.Text = 'Energía (eV)';
            app.FrecuenciaHzButton = uiradiobutton(app.EjedeAbscisasButtonGroup);
            app.FrecuenciaHzButton.Text = 'Frecuencia (Hz)';
            app.minimoEditField = uieditfield(app.EjedeAbscisasButtonGroup, 'numeric');
            app.minimoEditField.Value = 300;
            app.EditFieldLabel = uilabel(app.EjedeAbscisasButtonGroup);
            app.EditFieldLabel.Text = '-';
            app.maximoEditField = uieditfield(app.EjedeAbscisasButtonGroup, 'numeric');
            app.maximoEditField.Value = 2500;
            app.mnimomximoLabel = uilabel(app.EjedeAbscisasButtonGroup);
            app.mnimomximoLabel.Text = 'mínimo - máximo';
            app.EjedeordenadasButtonGroup = uibuttongroup(app.MaterialesTab);
            app.EjedeordenadasButtonGroup.Title = 'Eje de ordenadas';
            app.ndicederefraccinButton = uiradiobutton(app.EjedeordenadasButtonGroup);
            app.ndicederefraccinButton.Text = 'Índice de refracción';
            app.ndicederefraccinButton.Value = true;
            app.PermitividadButton = uiradiobutton(app.EjedeordenadasButtonGroup);
            app.PermitividadButton.Text = 'Permitividad';
            app.ParterealECheckBox = uicheckbox(app.EjedeordenadasButtonGroup);
            app.ParterealECheckBox.Text = 'Parte real';
            app.ParterealECheckBox.Value = true;
            app.ParteimaginariaECheckBox = uicheckbox(app.EjedeordenadasButtonGroup);
            app.ParteimaginariaECheckBox.Text = 'Parte imaginaria';
            app.ParteimaginariaECheckBox.Value = true;
            app.ParterealMCheckBox = uicheckbox(app.EjedeordenadasButtonGroup);
            app.ParterealMCheckBox.Text = 'Parte real';
            app.ParterealMCheckBox.Value = true;
            app.ParteimaginariaMCheckBox = uicheckbox(app.EjedeordenadasButtonGroup);
            app.ParteimaginariaMCheckBox.Text = 'Parte imaginaria';
            app.ParteimaginariaMCheckBox.Value = true;
            app.UITable_material = uitable(app.MaterialesTab);
            app.UITable_material.ColumnName = {'Abscisas'; 'Parte real'; 'Parte imaginaria'};
            app.UITable_material.RowName = {};
            app.ReglademezclaDropDownLabel = uilabel(app.MaterialesTab);
            app.ReglademezclaDropDownLabel.Visible = 'off';
            app.ReglademezclaDropDownLabel.Text = 'Regla de mezcla';
            app.ReglademezclaDropDown = uidropdown(app.MaterialesTab);
            app.ReglademezclaDropDown.Items = {'Bruggeman', 'Lorentz-Lorenz'};
            app.ReglademezclaDropDown.Visible = 'off';
            app.ReglademezclaDropDown.Value = 'Bruggeman';
            app.ndicederefraccinDropDown_SAND = uidropdown(app.MaterialesTab);
            app.ndicederefraccinDropDown_SAND.Items = {'Ponderación por composión mineralógica'};
            app.ndicederefraccinDropDown_SAND.ValueChangedFcn = createCallbackFcn(app, @ndicederefraccinDropDown_SANDValueChanged, true);
            app.ndicederefraccinDropDown_SAND.Visible = 'off';
            app.ndicederefraccinDropDown_SAND.Value = 'Ponderación por composión mineralógica';
            app.CenterPanel = uipanel(app.GridLayout);
            app.EstudiodelmaterialLabel = uilabel(app.CenterPanel);
            app.EstudiodelmaterialLabel.Text = 'Estudio del material';
            app.TreeAglomerados = uitree(app.CenterPanel);
            app.TreeAglomerados.SelectionChangedFcn = createCallbackFcn(app, @TreeAglomeradosSelectionChanged, true);
            app.AerosolesNode = uitreenode(app.TreeAglomerados);
            app.AerosolesNode.NodeData = 1;
            app.AerosolesNode.Text = 'Aerosoles';
            app.PartculasesfricasNode = uitreenode(app.AerosolesNode);
            app.PartculasesfricasNode.NodeData = 2;
            app.PartculasesfricasNode.Text = 'Partículas esféricas';
            app.AglomeradosdeesferasNode = uitreenode(app.AerosolesNode);
            app.AglomeradosdeesferasNode.NodeData = 3;
            app.AglomeradosdeesferasNode.Text = 'Aglomerados de esferas';
            app.MantodepartculasNode = uitreenode(app.TreeAglomerados);
            app.MantodepartculasNode.NodeData = 4;
            app.MantodepartculasNode.Text = 'Manto de partículas';
            app.TreeMethod = uitree(app.CenterPanel);
            app.MtododeRayleighNode = uitreenode(app.TreeMethod);
            app.MtododeRayleighNode.NodeData = 7;
            app.MtododeRayleighNode.Text = 'Método de Rayleigh';
            app.RayleighNode = uitreenode(app.MtododeRayleighNode);
            app.RayleighNode.NodeData = 8;
            app.RayleighNode.Text = 'Rayleigh';
            app.BohrenHuffmannNode = uitreenode(app.MtododeRayleighNode);
            app.BohrenHuffmannNode.NodeData = 9;
            app.BohrenHuffmannNode.Text = 'Bohren & Huffmann';
            app.DobbinsMegaridisNode = uitreenode(app.MtododeRayleighNode);
            app.DobbinsMegaridisNode.NodeData = 10;
            app.DobbinsMegaridisNode.Text = 'Dobbins & Megaridis';
            app.MtododeMieNode = uitreenode(app.TreeMethod);
            app.MtododeMieNode.NodeData = 11;
            app.MtododeMieNode.Text = 'Método de Mie';
            app.TreeRDG_ME = uitree(app.CenterPanel);
            app.TreeRDG_ME.SelectionChangedFcn = createCallbackFcn(app, @TreeRDG_MESelectionChanged, true);
            app.RayleighDebyeGansNode = uitreenode(app.TreeRDG_ME);
            app.RayleighDebyeGansNode.NodeData = 12;
            app.RayleighDebyeGansNode.Text = 'Rayleigh-Debye-Gans';
            app.MountainMulhollandNode = uitreenode(app.RayleighDebyeGansNode);
            app.MountainMulhollandNode.NodeData = 13;
            app.MountainMulhollandNode.Text = 'Mountain-Mulholland';
            app.KylFaethNode = uitreenode(app.RayleighDebyeGansNode);
            app.KylFaethNode.NodeData = 14;
            app.KylFaethNode.Text = 'Köylü-Faeth';
            app.MedioEfectivoNode = uitreenode(app.TreeRDG_ME);
            app.MedioEfectivoNode.NodeData = 15;
            app.MedioEfectivoNode.Text = 'Medio Efectivo';
            app.ReglademezclaNode = uitreenode(app.MedioEfectivoNode);
            app.ReglademezclaNode.Text = 'Regla de mezcla';
            app.MaxwellGarnettNode = uitreenode(app.ReglademezclaNode);
            app.MaxwellGarnettNode.NodeData = 17;
            app.MaxwellGarnettNode.Text = 'Maxwell-Garnett';
            app.BruggemanNode = uitreenode(app.ReglademezclaNode);
            app.BruggemanNode.NodeData = 18;
            app.BruggemanNode.Text = 'Bruggeman';
            app.LorentzLorenzNode = uitreenode(app.ReglademezclaNode);
            app.LorentzLorenzNode.NodeData = 19;
            app.LorentzLorenzNode.Text = 'Lorentz-Lorenz';
            app.LakatiaVitkamNode = uitreenode(app.ReglademezclaNode);
            app.LakatiaVitkamNode.NodeData = 20;
            app.LakatiaVitkamNode.Text = 'Lakatia-Vitkam';
            app.LummeRaholaNode = uitreenode(app.ReglademezclaNode);
            app.LummeRaholaNode.NodeData = 21;
            app.LummeRaholaNode.Text = 'Lumme-Rahola';
            app.PromediopotencialNode = uitreenode(app.ReglademezclaNode);
            app.PromediopotencialNode.NodeData = 22;
            app.PromediopotencialNode.Text = 'Promedio potencial';
            app.TreeMethodRmax = uitree(app.CenterPanel);
            app.MtodoestimacinradiomximoNode = uitreenode(app.TreeMethodRmax);
            app.MtodoestimacinradiomximoNode.Text = 'Método estimación radio máximo';
            app.MtodogeomtricoNode = uitreenode(app.MtodoestimacinradiomximoNode);
            app.MtodogeomtricoNode.NodeData = 15;
            app.MtodogeomtricoNode.Text = 'Método geométrico';
            app.MtodoaproximadoNode = uitreenode(app.MtodoestimacinradiomximoNode);
            app.MtodoaproximadoNode.NodeData = 16;
            app.MtodoaproximadoNode.Text = 'Método aproximado';
            app.vEditFieldLabel = uilabel(app.CenterPanel);
            app.vEditFieldLabel.Enable = 'off';
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.CenterPanel, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.vEditField.Enable = 'off';
            app.aEditFieldLabel = uilabel(app.CenterPanel);
            app.aEditFieldLabel.Enable = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.CenterPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Enable = 'off';
            app.aEditField.Value = 1;
            app.EstudiomantocontaminadoButton = uibutton(app.CenterPanel, 'push');
            app.EstudiomantocontaminadoButton.ButtonPushedFcn = createCallbackFcn(app, @EstudiomantocontaminadoButtonPushed, true);
            app.EstudiomantocontaminadoButton.Text = 'Estudio manto contaminado';
            app.IntroduccindedatosPanel = uipanel(app.CenterPanel);
            app.IntroduccindedatosPanel.Title = 'Introducción de datos';
            app.Panel = uipanel(app.IntroduccindedatosPanel);
            app.LongituddeondanmEditFieldLabel = uilabel(app.Panel);
            app.LongituddeondanmEditFieldLabel.Text = 'Longitud de onda (nm)';
            app.LongituddeondanmEditField = uieditfield(app.Panel, 'numeric');
            app.LongituddeondanmEditField.Value = 300;
            app.RadiodelapartculaesfricanmEditFieldLabel = uilabel(app.Panel);
            app.RadiodelapartculaesfricanmEditFieldLabel.Text = 'Radio de la partícula esférica (nm)';
            app.RadiodelapartculaesfricanmEditField = uieditfield(app.Panel, 'numeric');
            app.RadiodelapartculaesfricanmEditField.Value = 12.5;
            app.NdepartculasprimariasEditFieldLabel = uilabel(app.Panel);
            app.NdepartculasprimariasEditFieldLabel.Text = 'Nº de partículas primarias';
            app.NdepartculasprimariasEditField = uieditfield(app.Panel, 'numeric');
            app.NdepartculasprimariasEditField.Value = 80;
            app.DimensinfractalEditFieldLabel = uilabel(app.Panel);
            app.DimensinfractalEditFieldLabel.Text = 'Dimensión fractal';
            app.DimensinfractalEditField = uieditfield(app.Panel, 'numeric');
            app.DimensinfractalEditField.Value = 1.85;
            app.EjecucinindividualButton = uibutton(app.Panel, 'push');
            app.EjecucinindividualButton.ButtonPushedFcn = createCallbackFcn(app, @EjecucinindividualButtonPushed, true);
            app.EjecucinindividualButton.Enable = 'off';
            app.EjecucinindividualButton.Text = 'Ejecución individual';
            app.Panel_2 = uipanel(app.IntroduccindedatosPanel);
            app.ngulocenitalEditFieldLabel = uilabel(app.Panel_2);
            app.ngulocenitalEditFieldLabel.Text = 'Ángulo cenital';
            app.ngulocenitalEditField = uieditfield(app.Panel_2, 'numeric');
            app.ngulocenitalEditField.Value = 60;
            app.AlbedoDropDownLabel = uilabel(app.Panel_2);
            app.AlbedoDropDownLabel.Text = 'Albedo';
            app.AlbedoDropDown = uidropdown(app.Panel_2);
            app.AlbedoDropDown.Items = {'Directo', 'Difuso', 'Neto'};
            app.AlbedoDropDown.ValueChangedFcn = createCallbackFcn(app, @AlbedoDropDownValueChanged, true);
            app.AlbedoDropDown.Value = 'Directo';
            app.NubosidadEditFieldLabel = uilabel(app.Panel_2);
            app.NubosidadEditFieldLabel.Visible = 'off';
            app.NubosidadEditFieldLabel.Text = 'Nubosidad (%)';
            app.NubosidadEditField = uieditfield(app.Panel_2, 'numeric');
            app.NubosidadEditField.Visible = 'off';
            app.NubosidadEditField.Value = 50;
            app.Densidadsuperficialkgm3EditFieldLabel = uilabel(app.Panel_2);
            app.Densidadsuperficialkgm3EditFieldLabel.Text = 'Densidad superficial (kg/m^3)';
            app.Densidadsuperficialkgm3EditField = uieditfield(app.Panel_2, 'numeric');
            app.Densidadsuperficialkgm3EditField.Value = 580;
            app.RepresentardensidadButton = uibutton(app.Panel_2, 'push');
            app.RepresentardensidadButton.ButtonPushedFcn = createCallbackFcn(app, @RepresentardensidadButtonPushed, true);
            app.RepresentardensidadButton.Text = 'Representar densidad';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '-';
            app.Profundidad_max = uieditfield(app.Panel_2, 'numeric');
            app.Profundidad_max.Value = 100;
            app.Profundidad_min = uieditfield(app.Panel_2, 'numeric');
            app.Profundidad_min.Value = 1;
            app.ProfundidaddelmantoButtonGroup = uibuttongroup(app.Panel_2);
            app.ProfundidaddelmantoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProfundidaddelmantoButtonGroupSelectionChanged, true);
            app.ProfundidaddelmantoButtonGroup.Title = 'Profundidad del manto';
            app.Button_cm = uiradiobutton(app.ProfundidaddelmantoButtonGroup);
            app.Button_cm.Text = '';
            app.SemiinfinitaButton = uiradiobutton(app.ProfundidaddelmantoButtonGroup);
            app.SemiinfinitaButton.Text = 'Semi-infinita';
            app.SemiinfinitaButton.Value = true;
            app.cmEditFieldLabel = uilabel(app.ProfundidaddelmantoButtonGroup);
            app.cmEditFieldLabel.Text = 'cm';
            app.cmEditField = uieditfield(app.ProfundidaddelmantoButtonGroup, 'numeric');
            app.cmEditField.ValueChangedFcn = createCallbackFcn(app, @cmEditFieldValueChanged, true);
            app.cmEditField.Enable = 'off';
            app.cmEditField.Value = 10;
            app.AadiralbedoButton = uibutton(app.Panel_2, 'push');
            app.AadiralbedoButton.ButtonPushedFcn = createCallbackFcn(app, @AadiralbedoButtonPushed, true);
            app.AadiralbedoButton.Enable = 'off';
            app.AadiralbedoButton.Text = 'Añadir albedo';
            app.AlbedosuperficieinferiorDropDownLabel = uilabel(app.Panel_2);
            app.AlbedosuperficieinferiorDropDownLabel.Enable = 'off';
            app.AlbedosuperficieinferiorDropDownLabel.Text = 'Albedo superficie inferior';
            app.AlbedosuperficieinferiorDropDown = uidropdown(app.Panel_2);
            app.AlbedosuperficieinferiorDropDown.Items = {'Albedo asfalto', 'Nuevo albedo'};
            app.AlbedosuperficieinferiorDropDown.Enable = 'off';
            app.AlbedosuperficieinferiorDropDown.Value = 'Albedo asfalto';
            app.FactordeasimetraDropDownLabel = uilabel(app.Panel_2);
            app.FactordeasimetraDropDownLabel.Text = 'Factor de asimetría';
            app.FactordeasimetraDropDown = uidropdown(app.Panel_2);
            app.FactordeasimetraDropDown.Items = {'Debye', 'Macke et al.', 'Yang'};
            app.FactordeasimetraDropDown.ValueChangedFcn = createCallbackFcn(app, @FactordeasimetraDropDownValueChanged, true);
            app.FactordeasimetraDropDown.Value = 'Yang';
            app.fdEditFieldLabel = uilabel(app.Panel_2);
            app.fdEditFieldLabel.Text = 'fd';
            app.fdEditField = uieditfield(app.Panel_2, 'numeric');
            app.fdEditField.Value = 0.02;
            app.grefEditFieldLabel = uilabel(app.Panel_2);
            app.grefEditFieldLabel.Text = 'g ref';
            app.grefEditField = uieditfield(app.Panel_2, 'numeric');
            app.grefEditField.Value = 0.785;
            app.gdifEditFieldLabel = uilabel(app.Panel_2);
            app.gdifEditFieldLabel.Text = 'g dif';
            app.gdifEditField = uieditfield(app.Panel_2, 'numeric');
            app.gdifEditField.Value = 0.987;
            app.ProfundidadmnmxcmLabel = uilabel(app.Panel_2);
            app.ProfundidadmnmxcmLabel.Text = 'Profundidad mín - máx (cm)';
            app.EstudiorugosidadButton = uibutton(app.Panel_2, 'push');
            app.EstudiorugosidadButton.ButtonPushedFcn = createCallbackFcn(app, @EstudiorugosidadButtonPushed, true);
            app.EstudiorugosidadButton.Text = 'Estudio rugosidad';
            app.HumedadEditFieldLabel = uilabel(app.Panel_2);
            app.HumedadEditFieldLabel.Text = 'Humedad (%)';
            app.HumedadEditField = uieditfield(app.Panel_2, 'numeric');
            app.TipodeestudioButtonGroup = uibuttongroup(app.CenterPanel);
            app.TipodeestudioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeestudioButtonGroupSelectionChanged, true);
            app.TipodeestudioButtonGroup.Title = 'Tipo de estudio';
            app.EstudioparamtricoButton = uiradiobutton(app.TipodeestudioButtonGroup);
            app.EstudioparamtricoButton.Text = 'Estudio paramétrico';
            app.EstudioparamtricoButton.Value = true;
            app.EjecucinindividualButton_2 = uiradiobutton(app.TipodeestudioButtonGroup);
            app.EjecucinindividualButton_2.Text = 'Ejecución individual';
            app.EstudioparamtricoPanel = uipanel(app.CenterPanel);
            app.EstudioparamtricoPanel.Title = 'Estudio paramétrico';
            app.VariableparametrizadaAbscisasButtonGroup = uibuttongroup(app.EstudioparamtricoPanel);
            app.VariableparametrizadaAbscisasButtonGroup.Title = 'Variable parametrizada (Abscisas)';
            app.ValorminimoEditField = uieditfield(app.VariableparametrizadaAbscisasButtonGroup, 'numeric');
            app.ValorminimoEditField.Value = 300;
            app.Label_4 = uilabel(app.VariableparametrizadaAbscisasButtonGroup);
            app.Label_4.Text = '-';
            app.ValormaximoEditField = uieditfield(app.VariableparametrizadaAbscisasButtonGroup, 'numeric');
            app.ValormaximoEditField.Value = 2500;
            app.ValormnimomximoEditFieldLabel = uilabel(app.VariableparametrizadaAbscisasButtonGroup);
            app.ValormnimomximoEditFieldLabel.Text = 'Valor mínimo - máximo';
            app.TreeAbcisas = uitree(app.VariableparametrizadaAbscisasButtonGroup);
            app.TreeAbcisas.SelectionChangedFcn = createCallbackFcn(app, @TreeAbcisasSelectionChanged, true);
            app.LongituddeondanmNode = uitreenode(app.TreeAbcisas);
            app.LongituddeondanmNode.NodeData = 111;
            app.LongituddeondanmNode.Text = 'Longitud de onda (nm)';
            app.DimetrodepartculamNode = uitreenode(app.TreeAbcisas);
            app.DimetrodepartculamNode.NodeData = 112;
            app.DimetrodepartculamNode.Text = 'Diámetro de la partícula (nm)';
            app.DimetroadimensionaldelapartculaNode = uitreenode(app.TreeAbcisas);
            app.DimetroadimensionaldelapartculaNode.NodeData = 113;
            app.DimetroadimensionaldelapartculaNode.Text = 'Diámetro adimensional de la partícula';
            app.NdepartculasprimariasNode = uitreenode(app.TreeAbcisas);
            app.NdepartculasprimariasNode.NodeData = 114;
            app.NdepartculasprimariasNode.Text = 'Nº de partículas primarias';
            app.EnergaeVNode = uitreenode(app.TreeAbcisas);
            app.EnergaeVNode.NodeData = 115;
            app.EnergaeVNode.Text = 'Energía (eV)';
            app.FrecuenciaHzNode = uitreenode(app.TreeAbcisas);
            app.FrecuenciaHzNode.NodeData = 116;
            app.FrecuenciaHzNode.Text = 'Frecuencia (Hz)';
            app.EjecucinparamtricaButton = uibutton(app.EstudioparamtricoPanel, 'push');
            app.EjecucinparamtricaButton.ButtonPushedFcn = createCallbackFcn(app, @EjecucinparamtricaButtonPushed, true);
            app.EjecucinparamtricaButton.Text = 'Ejecución paramétrica';
            app.DatoscontaminacinsuperficiePanel = uipanel(app.CenterPanel);
            app.DatoscontaminacinsuperficiePanel.Title = 'Datos contaminación superficie';
            app.UIAxesDIST = uiaxes(app.DatoscontaminacinsuperficiePanel);
            xlabel(app.UIAxesDIST, 'Diámetro de giro')
            ylabel(app.UIAxesDIST, 'Np/Npt')
            app.UIAxesDIST.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxesDIST.XTickLabel = {'0'; '0.5'; '1'};
            app.ConcentracincontaminanteppmEditFieldLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.ConcentracincontaminanteppmEditFieldLabel.Text = 'Concentración contaminante (ppm)';
            app.ConcentracincontaminanteppmEditField = uieditfield(app.DatoscontaminacinsuperficiePanel, 'numeric');
            app.ConcentracincontaminanteppmEditField.Value = 1;
            app.rgmedianmEditFieldLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.rgmedianmEditFieldLabel.Visible = 'off';
            app.rgmedianmEditFieldLabel.Text = 'rg media (nm)';
            app.DeseaintroduciruntamaonicoLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.DeseaintroduciruntamaonicoLabel.Text = '¿Desea introducir un tamaño único';
            app.rgmedianmEditField = uieditfield(app.DatoscontaminacinsuperficiePanel, 'numeric');
            app.rgmedianmEditField.Visible = 'off';
            app.rgmedianmEditField.Value = 100;
            app.rgdesviacinnmEditFieldLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.rgdesviacinnmEditFieldLabel.Visible = 'off';
            app.rgdesviacinnmEditFieldLabel.Text = 'rg desviación (nm)';
            app.rgdesviacinnmEditField = uieditfield(app.DatoscontaminacinsuperficiePanel, 'numeric');
            app.rgdesviacinnmEditField.Visible = 'off';
            app.rgdesviacinnmEditField.Value = 10;
            app.divisionesEditFieldLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.divisionesEditFieldLabel.Visible = 'off';
            app.divisionesEditFieldLabel.Text = 'divisiones';
            app.divisionesEditField = uieditfield(app.DatoscontaminacinsuperficiePanel, 'numeric');
            app.divisionesEditField.ValueChangedFcn = createCallbackFcn(app, @divisionesEditFieldValueChanged, true);
            app.divisionesEditField.Visible = 'off';
            app.divisionesEditField.Value = 10;
            app.EstudioDropDown = uidropdown(app.DatoscontaminacinsuperficiePanel);
            app.EstudioDropDown.Items = {'Tamaño único', 'Imponer Df', 'Imponer np0'};
            app.EstudioDropDown.ValueChangedFcn = createCallbackFcn(app, @EstudioDropDownValueChanged, true);
            app.EstudioDropDown.Value = 'Tamaño único';
            app.ResultadosmantocontaminadoButton = uibutton(app.DatoscontaminacinsuperficiePanel, 'push');
            app.ResultadosmantocontaminadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosmantocontaminadoButtonPushed, true);
            app.ResultadosmantocontaminadoButton.Text = 'Resultados manto contaminado';
            app.EstudioDropDownLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.EstudioDropDownLabel.Text = 'Estudio';
            app.ounadistribucindetamaosdepartculasLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.ounadistribucindetamaosdepartculasLabel.Text = 'o una distribución de tamaños de partículas?';
            app.EspesorcmEditFieldLabel = uilabel(app.DatoscontaminacinsuperficiePanel);
            app.EspesorcmEditFieldLabel.Text = 'Espesor (cm)';
            app.EspesorcmEditField = uieditfield(app.DatoscontaminacinsuperficiePanel, 'numeric');
            app.EspesorcmEditField.Value = 5;
            app.EstudiorugosidadPanel = uipanel(app.CenterPanel);
            app.EstudiorugosidadPanel.Title = 'Estudio rugosidad';
            app.EstudiorugosidadPanel.Visible = 'off';
            app.UIAxes_imagen_inicial = uiaxes(app.EstudiorugosidadPanel);
            title(app.UIAxes_imagen_inicial, 'Imagen inicial')
            zlabel(app.UIAxes_imagen_inicial, 'Z')
            app.UIAxes_imagen_procesada = uiaxes(app.EstudiorugosidadPanel);
            title(app.UIAxes_imagen_procesada, 'Imagen procesada')
            zlabel(app.UIAxes_imagen_procesada, 'Z')
            app.UIAxes_imagen_perfil = uiaxes(app.EstudiorugosidadPanel);
            title(app.UIAxes_imagen_perfil, {'Perfil de rugosidad'; ''})
            xlabel(app.UIAxes_imagen_perfil, 'Distancia (cm)')
            zlabel(app.UIAxes_imagen_perfil, 'Z')
            app.ngulomediodemnimoscuadradosradEditFieldLabel = uilabel(app.EstudiorugosidadPanel);
            app.ngulomediodemnimoscuadradosradEditFieldLabel.Text = 'Ángulo medio de mínimos cuadrados (rad)';
            app.ngulomediodemnimoscuadradosradEditField = uieditfield(app.EstudiorugosidadPanel, 'numeric');
            app.CargarimagenButton = uibutton(app.EstudiorugosidadPanel, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.ProcesarimagenButton = uibutton(app.EstudiorugosidadPanel, 'push');
            app.ProcesarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarimagenButtonPushed, true);
            app.ProcesarimagenButton.Text = 'Procesar imagen';
            app.CalcularperfilButton = uibutton(app.EstudiorugosidadPanel, 'push');
            app.CalcularperfilButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularperfilButtonPushed, true);
            app.CalcularperfilButton.Text = 'Calcular perfil';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_abajo = uiaxes(app.RightPanel);
            app.UIAxes_abajo.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_abajo.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_arriba = uiaxes(app.RightPanel);
            app.UIAxes_arriba.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_arriba.YTick = [0 0.5 1];
            app.ResultadosLabel = uilabel(app.RightPanel);
            app.ResultadosLabel.Text = 'Resultados';
            app.ResultadosButtonGroup = uibuttongroup(app.RightPanel);
            app.ResultadosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultadosButtonGroupSelectionChanged, true);
            app.GrficosButton = uiradiobutton(app.ResultadosButtonGroup);
            app.GrficosButton.Text = 'Gráficos';
            app.GrficosButton.Value = true;
            app.NumricosButton = uiradiobutton(app.ResultadosButtonGroup);
            app.NumricosButton.Text = 'Numéricos';
            app.EjedeordenadasPanel = uipanel(app.RightPanel);
            app.EjedeordenadasPanel.Title = 'Eje de ordenadas';
            app.TreeOrdenadas = uitree(app.EjedeordenadasPanel);
            app.TreeOrdenadas.SelectionChangedFcn = createCallbackFcn(app, @TreeOrdenadasSelectionChanged, true);
            app.EficienciasNode = uitreenode(app.TreeOrdenadas);
            app.EficienciasNode.NodeData = 201;
            app.EficienciasNode.Text = 'Eficiencias';
            app.Seccionespticasm2Node = uitreenode(app.TreeOrdenadas);
            app.Seccionespticasm2Node.NodeData = 202;
            app.Seccionespticasm2Node.Text = 'Secciones ópticas (m^2)';
            app.Seccionesespecficasm2gNode = uitreenode(app.TreeOrdenadas);
            app.Seccionesespecficasm2gNode.NodeData = 203;
            app.Seccionesespecficasm2gNode.Text = 'Secciones específicas (m^2/g)';
            app.AlbedoCoalbedodedispersinNode = uitreenode(app.TreeOrdenadas);
            app.AlbedoCoalbedodedispersinNode.NodeData = 204;
            app.AlbedoCoalbedodedispersinNode.Text = 'Albedo/Coalbedo de dispersión';
            app.RadianciaIrradianciaNode = uitreenode(app.TreeOrdenadas);
            app.RadianciaIrradianciaNode.NodeData = 206;
            app.RadianciaIrradianciaNode.Text = 'Radiancia/Irradiancia';
            app.FactordeasimetraNode = uitreenode(app.TreeOrdenadas);
            app.FactordeasimetraNode.NodeData = 205;
            app.FactordeasimetraNode.Text = 'Factor de asimetría';
            app.AbsorcinCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.AbsorcinCheckBox.Text = 'Absorción';
            app.DispersinCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.DispersinCheckBox.Text = 'Dispersión';
            app.ExtincinCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.ExtincinCheckBox.Text = 'Extinción';
            app.AlbedodispersinCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.AlbedodispersinCheckBox.Text = 'Albedo dispersión';
            app.CoalbedodispersinCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.CoalbedodispersinCheckBox.Text = 'Coalbedo dispersión';
            app.RadianciaCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.RadianciaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadianciaCheckBoxValueChanged, true);
            app.RadianciaCheckBox.Text = 'Radiancia';
            app.IrradianciaCheckBox = uicheckbox(app.EjedeordenadasPanel);
            app.IrradianciaCheckBox.ValueChangedFcn = createCallbackFcn(app, @IrradianciaCheckBoxValueChanged, true);
            app.IrradianciaCheckBox.Text = 'Irradiancia';
            app.UITable_contaminado = uitable(app.RightPanel);
            app.UITable_contaminado.ColumnName = {'Abscisas'; 'Eficiencia de absorción'; 'Eficiencia de dispersión'; 'Eficiencia de extinción'; 'Sección específica de absorción'; 'Sección específica de dispersión'; 'Sección específica de extinción'; 'Sección óptica de abrsorción'; 'Sección óptica de dispersión'; 'Sección óptica de extinción'; 'Albedo de dispersión'; 'Coalbedo de dispersión'; 'Factor de asimetría'};
            app.UITable_contaminado.RowName = {};
            app.UITable_estudio = uitable(app.RightPanel);
            app.UITable_estudio.ColumnName = {'Abscisas'; 'Eficiencia de absorción'; 'Eficiencia de dispersión'; 'Eficiencia de extinción'; 'Sección específica de absorción'; 'Sección específica de dispersión'; 'Sección específica de extinción'; 'Sección óptica de abrsorción'; 'Sección óptica de dispersión'; 'Sección óptica de extinción'; 'Albedo de dispersión'; 'Coalbedo de dispersión'; 'Factor de asimetría'};
            app.UITable_estudio.RowName = {};
            app.UITable_alb_medio = uitable(app.RightPanel);
            app.UITable_alb_medio.ColumnName = {'Albedo medio nieve'; 'Albedo medio suelo'; 'Albedo medio nieve "trasparente"'};
            app.UITable_alb_medio.RowName = {};
            app.Panel_canales = uipanel(app.RightPanel);
            app.TodosCheckBox = uicheckbox(app.Panel_canales);
            app.TodosCheckBox.Text = 'Todos';
            app.TodosCheckBox.Value = true;
            app.MediaCheckBox = uicheckbox(app.Panel_canales);
            app.MediaCheckBox.Text = 'Media';
            app.Canal1CheckBox = uicheckbox(app.Panel_canales);
            app.Canal1CheckBox.Text = 'Canal 1';
            app.Canal2CheckBox = uicheckbox(app.Panel_canales);
            app.Canal2CheckBox.Text = 'Canal 2';
            app.Canal3CheckBox = uicheckbox(app.Panel_canales);
            app.Canal3CheckBox.Text = 'Canal 3';
            app.Canal4CheckBox = uicheckbox(app.Panel_canales);
            app.Canal4CheckBox.Text = 'Canal 4';
            app.Canal5CheckBox = uicheckbox(app.Panel_canales);
            app.Canal5CheckBox.Text = 'Canal 5';
            app.Canal6CheckBox = uicheckbox(app.Panel_canales);
            app.Canal6CheckBox.Text = 'Canal 6';
            app.Canal7CheckBox = uicheckbox(app.Panel_canales);
            app.Canal7CheckBox.Text = 'Canal 7';
            app.Canal8CheckBox = uicheckbox(app.Panel_canales);
            app.Canal8CheckBox.Text = 'Canal 8';
            app.Canal9CheckBox = uicheckbox(app.Panel_canales);
            app.Canal9CheckBox.Text = 'Canal 9';
            app.Canal10CheckBox = uicheckbox(app.Panel_canales);
            app.Canal10CheckBox.Text = 'Canal 10';
            app.MostrarButton = uibutton(app.Panel_canales, 'push');
            app.MostrarButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarButtonPushed, true);
            app.MostrarButton.Text = 'Mostrar';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.Text = 'Guardar';
            app.GuardarComoMenu = uimenu(app.ArchivoMenu);
            app.GuardarComoMenu.Text = 'Guardar Como';
            app.ExportarMenu = uimenu(app.ArchivoMenu);
            app.ExportarMenu.Text = 'Exportar';
            app.HojadeClculoxlsxMenu = uimenu(app.ExportarMenu);
            app.HojadeClculoxlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @HojadeClculoxlsxMenuSelected, true);
            app.HojadeClculoxlsxMenu.Text = 'Hoja de Cálculo .xlsx';
            app.BlocdeNotastxtMenu = uimenu(app.ExportarMenu);
            app.BlocdeNotastxtMenu.Text = 'Bloc de Notas .txt';
            app.InformacinMenu = uimenu(app.UIFigure);
            app.InformacinMenu.Text = 'Información';
            app.DescripcindelprogramaMenu = uimenu(app.InformacinMenu);
            app.DescripcindelprogramaMenu.MenuSelectedFcn = createCallbackFcn(app, @DescripcindelprogramaMenuSelected, true);
            app.DescripcindelprogramaMenu.Text = 'Descripción del programa';
            app.ContactoMenu = uimenu(app.InformacinMenu);
            app.ContactoMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactoMenuSelected, true);
            app.ContactoMenu.Text = 'Contacto';
            app.IdiomaMenu = uimenu(app.UIFigure);
            app.IdiomaMenu.Text = 'Idioma';
            app.EspaolMenu = uimenu(app.IdiomaMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenu_Selected, true);
            app.EspaolMenu.Text = 'Español';
            app.InglsMenu = uimenu(app.IdiomaMenu);
            app.InglsMenu.MenuSelectedFcn = createCallbackFcn(app, @InglsMenu_Selected, true);
            app.InglsMenu.Text = 'Inglés';
            app.AyudaMenu = uimenu(app.UIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.ManualdeUsuarioMenu = uimenu(app.AyudaMenu);
            app.ManualdeUsuarioMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualdeUsuarioMenuSelected, true);
            app.ManualdeUsuarioMenu.Text = 'Manual de Usuario';
            app.AcercadeAppDesignerMenu = uimenu(app.AyudaMenu);
            app.AcercadeAppDesignerMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeAppDesignerMenuSelected, true);
            app.AcercadeAppDesignerMenu.Text = 'Acerca de AppDesigner';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EjeXMenu = uimenu(app.ContextMenu);
            app.EjeXMenu.Text = 'Eje X ';
            app.LogartmicoMenu = uimenu(app.EjeXMenu);
            app.LogartmicoMenu.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenuSelected, true);
            app.LogartmicoMenu.Text = 'Logarítmico';
            app.LinealMenu = uimenu(app.EjeXMenu);
            app.LinealMenu.MenuSelectedFcn = createCallbackFcn(app, @LinealMenuSelected, true);
            app.LinealMenu.Text = 'Lineal';
            app.EjeYMenu = uimenu(app.ContextMenu);
            app.EjeYMenu.Text = 'Eje Y ';
            app.LogartmicoMenu_2 = uimenu(app.EjeYMenu);
            app.LogartmicoMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_2Selected, true);
            app.LogartmicoMenu_2.Text = 'Logarítmico';
            app.LinealMenu_3 = uimenu(app.EjeYMenu);
            app.LinealMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_3Selected, true);
            app.LinealMenu_3.Text = 'Lineal';
            % Assign app.ContextMenu
            app.UIAxes_luz.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.EjeXMenu_2 = uimenu(app.ContextMenu2);
            app.EjeXMenu_2.Text = 'Eje X';
            app.LogartmicoMenu_3 = uimenu(app.EjeXMenu_2);
            app.LogartmicoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_3Selected, true);
            app.LogartmicoMenu_3.Text = 'Logarítmico';
            app.LinealMenu_4 = uimenu(app.EjeXMenu_2);
            app.LinealMenu_4.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_4Selected, true);
            app.LinealMenu_4.Text = 'Lineal';
            app.EjeYMenu_2 = uimenu(app.ContextMenu2);
            app.EjeYMenu_2.Text = 'Eje Y';
            app.LogartmicoMenu_4 = uimenu(app.EjeYMenu_2);
            app.LogartmicoMenu_4.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_4Selected, true);
            app.LogartmicoMenu_4.Text = 'Logarítmico';
            app.LinealMenu_5 = uimenu(app.EjeYMenu_2);
            app.LinealMenu_5.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_5Selected, true);
            app.LinealMenu_5.Text = 'Lineal';
            % Assign app.ContextMenu2
            app.UIAxes_material.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.CerrarMenu = uimenu(app.ContextMenu3);
            app.CerrarMenu.MenuSelectedFcn = createCallbackFcn(app, @CerrarMenuSelected, true);
            app.CerrarMenu.Text = 'Cerrar';
            % Assign app.ContextMenu3
            app.DescripcindelProgramaPanel.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.EjeXMenu_3 = uimenu(app.ContextMenu4);
            app.EjeXMenu_3.Text = 'Eje X';
            app.LogartmicoMenu_5 = uimenu(app.EjeXMenu_3);
            app.LogartmicoMenu_5.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_5Selected, true);
            app.LogartmicoMenu_5.Text = 'Logarítmico';
            app.LinealMenu_6 = uimenu(app.EjeXMenu_3);
            app.LinealMenu_6.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_6Selected, true);
            app.LinealMenu_6.Text = 'Lineal';
            app.EjeYMenu_3 = uimenu(app.ContextMenu4);
            app.EjeYMenu_3.Text = 'Eje Y';
            app.LogartmicoMenu_6 = uimenu(app.EjeYMenu_3);
            app.LogartmicoMenu_6.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_6Selected, true);
            app.LogartmicoMenu_6.Text = 'Logarítmico';
            app.LinealMenu_7 = uimenu(app.EjeYMenu_3);
            app.LinealMenu_7.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_7Selected, true);
            app.LinealMenu_7.Text = 'Lineal';
            % Assign app.ContextMenu4
            app.UIAxes_abajo.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.EjeXMenu_4 = uimenu(app.ContextMenu5);
            app.EjeXMenu_4.Text = 'Eje X';
            app.LogartmicoMenu_7 = uimenu(app.EjeXMenu_4);
            app.LogartmicoMenu_7.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_7Selected, true);
            app.LogartmicoMenu_7.Text = 'Logarítmico';
            app.LinealMenu_8 = uimenu(app.EjeXMenu_4);
            app.LinealMenu_8.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_8Selected, true);
            app.LinealMenu_8.Text = 'Lineal';
            app.EjeYMenu_4 = uimenu(app.ContextMenu5);
            app.EjeYMenu_4.Text = 'Eje Y';
            app.LogartmicoMenu_8 = uimenu(app.EjeYMenu_4);
            app.LogartmicoMenu_8.MenuSelectedFcn = createCallbackFcn(app, @LogartmicoMenu_8Selected, true);
            app.LogartmicoMenu_8.Text = 'Logarítmico';
            app.LinealMenu_9 = uimenu(app.EjeYMenu_4);
            app.LinealMenu_9.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_9Selected, true);
            app.LinealMenu_9.Text = 'Lineal';
            % Assign app.ContextMenu5
            app.UIAxes_arriba.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.InfoMenu = uimenu(app.ContextMenu6);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = '+ Info';
            % Assign app.ContextMenu6
            app.DeseaintroduciruntamaonicoLabel.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.CerrarMenu_2 = uimenu(app.ContextMenu7);
            app.CerrarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CerrarMenu_2Selected, true);
            app.CerrarMenu_2.Text = 'Cerrar';
            % Assign app.ContextMenu7
            app.ContactoPanel.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.CerrarMenu_3 = uimenu(app.ContextMenu8);
            app.CerrarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CerrarMenu_3Selected, true);
            app.CerrarMenu_3.Text = 'Cerrar';
            % Assign app.ContextMenu8
            app.EstudiorugosidadPanel.ContextMenu = app.ContextMenu8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Programa_OptiPar_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end